classdef HistoLens_AdvancedCompartmentSegmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_AdvancedCompartmentSegmentationUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SubCompartmentPostProcessingOptionsPanel  matlab.ui.container.Panel
        BackgroundCheckBox              matlab.ui.control.CheckBox
        SplitConnectedCheckBox          matlab.ui.control.CheckBox
        MinimumObjectSizeEditField      matlab.ui.control.NumericEditField
        MinimumObjectSizeEditFieldLabel  matlab.ui.control.Label
        SmoothingEditField              matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel         matlab.ui.control.Label
        ToleranceThresholdEditField     matlab.ui.control.NumericEditField
        ToleranceThresholdEditFieldLabel  matlab.ui.control.Label
        StructureNameDropDown           matlab.ui.control.DropDown
        StructureNameDropDownLabel      matlab.ui.control.Label
        SemanticSegmentationOptionsPanel  matlab.ui.container.Panel
        SubCompartmentNamescommaseparatedEditField  matlab.ui.control.EditField
        SubCompartmentNamescommaseparatedEditFieldLabel  matlab.ui.control.Label
        NumberofSubCompartmentsSpinner  matlab.ui.control.Spinner
        NumberofSubCompartmentsSpinnerLabel  matlab.ui.control.Label
        StainTypeDropDown               matlab.ui.control.DropDown
        StainTypeDropDownLabel          matlab.ui.control.Label
        NetworkNameDropDown             matlab.ui.control.DropDown
        NetworkNameDropDownLabel        matlab.ui.control.Label
        SubComp_InfoSym                 matlab.ui.control.Image
        SemanticSeg_InfoSym             matlab.ui.control.Image
        SelectNetworkFileButton         matlab.ui.control.Button
        SubCompartmentTree              matlab.ui.container.Tree
        AddSubCompartmentButton         matlab.ui.control.Button
        NewSubCompartmentNameEditField  matlab.ui.control.EditField
        NewSubCompartmentNameEditFieldLabel  matlab.ui.control.Label
        SemanticSegmentationModelLabel  matlab.ui.control.Label
        SubcompartmentSegmentationLabel  matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        Knob                            matlab.ui.control.DiscreteKnob
        SubCompartmentsSelectRegionsPanel  matlab.ui.container.Panel
        DoneButton                      matlab.ui.control.Button
        UndoRegionSelectionButton       matlab.ui.control.Button
        NewRegionSelectionButton        matlab.ui.control.Button
        SubCompartmentNameDropDown      matlab.ui.control.DropDown
        SubCompartmentNameDropDownLabel  matlab.ui.control.Label
        EditStructureBoundaryButton     matlab.ui.control.Button
        RemoveStructurefromAnnotationFileButton  matlab.ui.control.Button
        SubCompSelect_InfoSym           matlab.ui.control.Image
        Switch                          matlab.ui.control.Switch
        NewImageNewSlideButton          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Stain1Tab                       matlab.ui.container.Tab
        NewImageSameSlideButton         matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
       
        
    end
    
    properties (Access = public)
        % Properties from File-Input
        main_app
        Slide_Path
        Stain_Table
        Structure_Names

        % Properties from Multi-Stain Registration
        Registration_Transforms

        % Properties generated in this window
        Sub_Compartment_Details
        Semantic_Sub_Compartment_Details
        Stain_Img_Axes
        Current_Img
        Slide_Pair_Index
        Segmentation_Methods
        Current_Combined
        Editable_Boundary
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;
            
            % Items from file input window
            app.Slide_Path = main_app.Slide_Path;
            app.Stain_Table = main_app.Stain_Table;
            app.Structure_Names = main_app.Structure_Names;
            app.Registration_Transforms = main_app.Registration_Transforms;

            % Populating structure name drop down
            app.StructureNameDropDown.Items = fieldnames(app.Structure_Names);

            if width(app.Stain_Table)>1
                
                % Stain_Table organized like Filename,Stain1,Stain2,etc.
                stain_names = app.Stain_Table.Properties.VariableNames;
                app.Stain1Tab.Title = stain_names{1};

                app.Stain_Img_Axes = [uiaxes(app.Stain1Tab)];

                for i = 2:width(app.Stain_Table)
                    new_tab = uitab(app.TabGroup,'Title',stain_names{i});
                    
                    app.Stain_Img_Axes = [app.Stain_Img_Axes,uiaxes(new_tab)];

                end
            else

                app.Stain1Tab.Title = app.Stain_Table.Properties.VariableNames{2};
                app.Stain_Img_Axes = uiaxes(app.Stain1Tab);
            end

            % Adding stain types to stain type dropdown 
            app.StainTypeDropDown.Items = app.Stain_Table.Properties.VariableNames(2:end);
            app.StainTypeDropDown.Value = app.StainTypeDropDown.Items(1);

            % Loading first image
            % Have to get names of structures annotated in both slides (if there are annotations in the other stain) 
            % Save for later, assume now that the only annotations are for
            % one stain and the other stain is to look at other properties
            % of the same structures

            % Don't have to use "dir" because should have the names of all
            % the slides in the Stain_Table and also the slide paths in
            % Slide_Path
            %app.Slide_Pair_Index = randi(height(app.Stain_Table));           
            app.Slide_Pair_Index = 1;
            
            Advanced_Extract_Rand_Img(app)

            
        end

        % Value changed function: NewSubCompartmentNameEditField
        function NewSubCompartmentNameEditFieldValueChanged(app, event)
            value = app.NewSubCompartmentNameEditField.Value;
            
            if ~isempty(value)
                if isempty(app.Sub_Compartment_Details)
                    app.AddSubCompartmentButton.Enable = 'on';
                else
                    if ~any(ismember(fieldnames(app.Sub_Compartment_Details),value))
                        app.AddSubCompartmentButton.Enable = 'on';
                    else
                        f = msgbox('That Sub-Compartment is already in the list you dyngus');
                    end
                end
            end

        end

        % Button pushed function: AddSubCompartmentButton
        function AddSubCompartmentButtonPushed(app, event)
           
            value = app.NewSubCompartmentNameEditField.Value;

            current_tab = app.TabGroup.SelectedTab;
            stain_idx = find(strcmp(current_tab.Title,app.Stain_Table.Properties.VariableNames));

            % Adding the new Sub-Compartment to the hierarchical tree
            N = uitreenode(app.SubCompartmentTree);
            N.Text = value;

            if ~isempty(app.Sub_Compartment_Details)
                n_sub = length(fieldnames(app.Sub_Compartment_Details));
                new_name = strcat('Sub_Comp_',num2str(n_sub+1));
            else
                new_name = 'Sub_Comp_1';
            end

            app.Sub_Compartment_Details.(new_name).Stain = app.Stain_Table.Properties.VariableNames(stain_idx);
            app.Sub_Compartment_Details.(new_name).DisplayName = value;
            app.Sub_Compartment_Details.(new_name).Type = 'HSV';
            app.Sub_Compartment_Details.(new_name).Values_Matrix = zeros(1,3);
            app.Sub_Compartment_Details.(new_name).Tolerance = 0.5;
            app.Sub_Compartment_Details.(new_name).Smoothing = 0;
            app.Sub_Compartment_Details.(new_name).MinSize = 0;
            app.Sub_Compartment_Details.(new_name).Background = 0;
            app.Sub_Compartment_Details.(new_name).Split = 0;

            % Adding the new sub-compartment to the sub-compartment list
            % box
            all_sub_compartments = cell(1,length(fieldnames(app.Sub_Compartment_Details)));
            for i = 1:length(fieldnames(app.Sub_Compartment_Details))
                all_sub_compartments{i} = app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(i))).DisplayName;
            end

            app.SubCompartmentNameDropDown.Items = all_sub_compartments;
            
            if length(all_sub_compartments)==1
                app.SubCompartmentNameDropDown.Enable = 'on';
                app.NewRegionSelectionButton.Enable = 'on';
            end
            
            app.NewSubCompartmentNameEditField.Value = '';
            app.AddSubCompartmentButton.Enable = 'off';

        end

        % Button pushed function: NewRegionSelectionButton
        function NewRegionSelectionButtonPushed(app, event)
            
            % Go back to image and not mask
            if app.Switch.Visible && ~strcmp(app.Switch.Value,'Image')
                current_tab = app.TabGroup.SelectedTab;
                current_axis = app.Stain_Img_Axes(find(strcmp(current_tab.Title,app.Stain_Table.Properties.VariableNames)));

                app.Switch.Value = 'Image';
                axes(current_axis)
                imshow(app.Current_Img.(strcat('Image',find(strcmp(current_tab.Title,app.Stain_Table.Properties.VariableNames)))))
                
            end

            if app.Knob.Visible && ~strcmp(app.Knob.Value,'Image')
                current_tab = app.TabGroup.SelectedTab;
                current_axis = app.Stain_Img_Axes(find(strcmp(current_tab.Title,app.Stain_Table.Properties.VariableNames)));

                app.Knob.Value = 'Image';
                axes(current_axis)
                imshow(app.Current_Img.(strcat('Image',find(strcmp(current_tab.Title,app.Stain_Table.Properties.VariableNames)))))

            end

            New_Region_Annotation(app)
            
            % Need a check here for whether or not there are any regions
            % annotated in the current image

            app.UndoRegionSelectionButton.Enable = 'on';

        end

        % Button pushed function: UndoRegionSelectionButton
        function UndoRegionSelectionButtonPushed(app, event)
            
            % Removing the information from the last annotation

            % Get the sub-compartment
            current_sub_comp = app.SubCompartmentNameDropDown.Value;
            sub_comp_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,current_sub_comp));

            % Remove the last node added
            app.SubCompartmentTree.Children(sub_comp_idx).Children(end) = [];
            % Remove last row of HSV_Matrix
            current_matrix = app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(sub_comp_idx))).HSV_Matrix; 
            adjusted_matrix = current_matrix(size(current_matrix,1)-1,:);
            
            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(sub_comp_idx))).HSV_Matrix = adjusted_matrix;

            % Removing sub-compartment completely if that was the only one
            % left in that one
            if size(adjusted_matrix,1)==0
                app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(sub_comp_idx))) = [];
                app.SubCompartmentTree.Children(sub_comp_idx) = [];

                app.SubCompartmentNameDropDown.Items(sub_comp_idx) = [];

                if length(app.SubCompartmentNameDropDown.Items)==0
                    app.SubCompartmentNameDropDown.Enable = 'off';
                    app.NewRegionSelectionButton.Enable = 'off';
                    app.UndoRegionSelectionButton.Enable = 'off';
                    if length(fieldnames(app.Semantic_Sub_Compartment_Details))==0
                        app.Switch.Enable = 'off';
                    end
                end
            
            end

            % Placeholder, add in something to keep track of annotations
            % across multiple images and keep undo button enabled until the
            % current image doesn't have any 
            app.UndoRegionSelectionButton.Enable = 'off';

        end

        % Button pushed function: SelectNetworkFileButton
        function SelectNetworkFileButtonPushed(app, event)
        
            [filename,path] = uigetfile('*','Select Network file');

            if path ~= 0

                network_file = load(strcat(path,filename));

                % Put in a check here that the file is correct %
                
                new_name = strcat('Sem_Network_',num2str(length(fieldnames(app.Semantic_Sub_Compartment_Details))+1));
                app.Semantic_Sub_Compartment_Details.(new_name).Network = network_file;
                app.Semantic_Sub_Compartment_Details.(new_name).DisplayName = filename;

                % Initializing options here
                app.Semantic_Sub_Compartment_Details.(new_name).Stain = app.StainTypeDropDown.Items(1);
                app.Semantic_Sub_Compartment_Details.(new_name).NumCompartments = 1;
                app.Semantic_Sub_Compartment_Details.(new_name).CompartmentNames = 'Sem_Network_1';

                if length(fieldnames(app.Semantic_Sub_Compartment_Details))==1
                    app.NetworkNameDropDown.Enable = 'on';
                    app.StainTypeDropDown.Enable = 'on';
                    app.NumberofSubCompartmentsSpinner.Enable = 'on';
                    app.SubCompartmentNamescommaseparatedEditField.Enable = 'on';
                end

                % Adding Network display name to dropdown
                app.NetworkNameDropDown.Items = [app.NetworkNameDropDown.Items,filename];
                
                % Switching to Knob
                app.Switch.Visible = 'off';
                app.Switch.Enable = 'off';
                app.Knob.Visible = 'on';
                app.Knob.Enable = 'on';

            end


        end

        % Value changed function: NetworkNameDropDown
        function NetworkNameDropDownValueChanged(app, event)
            value = app.NetworkNameDropDown.Value;
            
            % Updating network options based on what is appropriate for the
            % selected network
            
            % Find the semantic network index
            network_idx = find(strcmp(app.NetworkNameDropDown.Items,value));
            network_details = app.Semantic_Sub_Compartment_Details.(strcat('Sem_Network_',num2str(network_idx)));
            app.StainTypeDropDown.Value = network_details.Stain;
            app.NumberofSubCompartmentsSpinner = network_details.NumCompartments;
            if length(network_details.CompartmentNames)>1
                app.SubCompartmentNamescommaseparatedEditField.Value = strjoin(network_details.CompartmentNames,',');
            else
                app.SubCompartmentNamescommaseparatedEditField.Value = network_details.CompartmentNames;
            end


        end

        % Value changed function: StainTypeDropDown
        function StainTypeDropDownValueChanged(app, event)
            value = app.StainTypeDropDown.Value;
            
            % Updating network stain type
            network_idx = find(strcmp(app.NetworkNameDropDown.Items,app.NetworkNameDropDown.Value));
            app.Semantic_Sub_Compartment_Details.(strcat('Sem_Network_',num2str(network_idx))).Stain = value;

            
        end

        % Value changed function: NumberofSubCompartmentsSpinner
        function NumberofSubCompartmentsSpinnerValueChanged(app, event)
            value = app.NumberofSubCompartmentsSpinner.Value;
            
            % Updating network number of compartments
            network_idx = find(strcmp(app.NetworkNameDropDown.Items,app.NetworkNameDropDown.Value));
            app.Semantic_Sub_Compartment_Details.(strcat('Sem_Network_',num2str(network_idx))).NumCompartments = value;
            
        end

        % Value changed function: 
        % SubCompartmentNamescommaseparatedEditField
        function SubCompartmentNamescommaseparatedEditFieldValueChanged(app, event)
            value = app.SubCompartmentNamescommaseparatedEditField.Value;
            
            comp_names = strsplit(strrep(value,' ',''),',');

            % Updating name of sub-compartments segmented by network
            network_idx = find(strcmp(app.NetworkNameDropDown.Items,app.NetworkNameDropDown.Value));
            app.Semantic_Sub_Compartment_Details.(strcat('Sem_Network_',num2str(network_idx))).CompartmentNames = comp_names;

        end

        % Button pushed function: NewImageSameSlideButton
        function NewImageSameSlideButtonPushed(app, event)
            
            % To stay in the same slide, don't change the Slide_Pair_Index
            Advanced_Extract_Rand_Img(app)

            % Run Segmentation (if present)
            New_Sub_Segmentation(app)


        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            % Get current tab
            current_tab = app.TabGroup.SelectedTab;
            img_idx = find(strcmp(current_tab.Title,app.Stain_Table.Properties.VariableNames));
            current_axis = app.Stain_Img_Axes(img_idx);
            
            cla(current_axis)
            
            for i = 1:length(fieldnames(app.Sub_Compartment_Details))
                display_names{i} = app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(i))).DisplayName;
            end

            if strcmp(value,'Mask')
                imagesc(app.Current_Combined,'Parent',current_axis)
                colorbar(current_axis,'Ticks',[1:length(fieldnames(app.Sub_Compartment_Details))],...
                    'TickLabels',display_names)
            else
                colorbar(current_axis,'off')
                imshow(app.Current_Img.(strcat('Image',num2str(img_idx))),'Parent',current_axis)
            end

        end

        % Value changed function: ToleranceThresholdEditField
        function ToleranceThresholdEditFieldValueChanged(app, event)
            value = app.ToleranceThresholdEditField.Value;
            
            % Find current sub-compartment 
            current_sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,app.SubCompartmentNameDropDown.Value));

            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(current_sub_idx))).Tolerance = value;

            % Re-run sub-compartment segmentation
            New_Sub_Segmentation(app)

        end

        % Value changed function: SmoothingEditField
        function SmoothingEditFieldValueChanged(app, event)
            value = app.SmoothingEditField.Value;
            
            % Find current sub-comparmtnet
            current_sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,app.SubCompartmentNameDropDown.Value));

            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(current_sub_idx))).Smoothing = value;

            % Re-run sub-compartment segmentation
            New_Sub_Segmentation(app)
        end

        % Value changed function: MinimumObjectSizeEditField
        function MinimumObjectSizeEditFieldValueChanged(app, event)
            value = app.MinimumObjectSizeEditField.Value;
            
            % Find current sub-compartment
            current_sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,app.SubCompartmentNameDropDown.Value));

            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(current_sub_idx))).MinSize = value;

            % Re-run sub-compartment segmentation
            New_Sub_Segmentation(app)
            
        end

        % Callback function
        function TransformationTypeDropDownValueChanged(app, event)
            value = app.TransformationTypeDropDown.Value;
            
            % Find current sub-compartment
            current_sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,app.SubCompartmentNameDropDown.Value));

            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(current_sub_idx))).Type = value;

            % Updating distribution by transforming all stored values in
            % values matrix
            Update_Stored_Values(app)

            % Re-run sub-compartment segmentation
            New_Sub_Segmentation(app)
        end

        % Value changed function: SubCompartmentNameDropDown
        function SubCompartmentNameDropDownValueChanged(app, event)
            value = app.SubCompartmentNameDropDown.Value;
            
            sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,value));
            current_details = app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(sub_idx)));


            app.ToleranceThresholdEditField.Value = current_details.Tolerance;
            app.SmoothingEditField.Value = current_details.Smoothing;
            app.MinimumObjectSizeEditField.Value = current_details.MinSize;
            app.SplitConnectedCheckBox.Value = current_details.Split;
            app.BackgroundCheckBox.Value = current_details.Background;

            if current_details.Background
                app.ToleranceThresholdEditField.Enable = 'off';
                app.SmoothingEditField.Enable = 'off';
                app.MinimumObjectSizeEditField.Enable = 'off';
                app.SplitConnectedCheckBox.Enable = 'off';
            else

                app.ToleranceThresholdEditField.Enable = 'on';
                app.SmoothingEditField.Enable = 'on';
                app.MinimumObjectSizeEditField.Enable = 'on';
                app.SplitConnectedCheckBox.Enable = 'on';
            end                


        end

        % Button pushed function: EditStructureBoundaryButton
        function EditStructureBoundaryButtonPushed(app, event)
            
            % Editing the boundary of the current structure or adding one
            % if not specified
            if strcmp(app.EditStructureBoundaryButton.Text,'Edit Structure Boundary')
                
                app.EditStructureBoundaryButton.Text = 'Done';

                Edit_Boundary(app)
                
            else
                
                Write_Boundary(app)
                delete(app.Editable_Boundary)
                
                app.EditStructureBoundaryButton.Text = 'Edit Structure Boundary';

            end


        end

        % Value changed function: SplitConnectedCheckBox
        function SplitConnectedCheckBoxValueChanged(app, event)
            value = app.SplitConnectedCheckBox.Value;
            
            current_sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,app.SubCompartmentNameDropDown.Value));
            
            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(current_sub_idx))).Split = value;
            

        end

        % Value changed function: BackgroundCheckBox
        function BackgroundCheckBoxValueChanged(app, event)
            value = app.BackgroundCheckBox.Value;
            
            current_sub_idx = find(strcmp(app.SubCompartmentNameDropDown.Items,app.SubCompartmentNameDropDown.Value));
            
            app.Sub_Compartment_Details.(strcat('Sub_Comp_',num2str(current_sub_idx))).Background = value;

            if value
                
                app.ToleranceThresholdEditField.Enable = 'off';
                app.SmoothingEditField.Enable = 'off';
                app.MinimumObjectSizeEditField.Enable = 'off';
                app.SplitConnectedCheckBox.Enable = 'off';
            else

                app.ToleranceThresholdEditField.Enable = 'on';
                app.SmoothingEditField.Enable = 'on';
                app.MinimumObjectSizeEditField.Enable = 'on';
                app.SplitConnectedCheckBox.Enable = 'on';
            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.HistoLens_AdvancedCompartmentSegmentationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {686, 686};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {358, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_AdvancedCompartmentSegmentationUIFigure = uifigure('Visible', 'off');
            app.HistoLens_AdvancedCompartmentSegmentationUIFigure.Name = 'HistoLens_AdvancedCompartmentSegmentation';
            app.HistoLens_AdvancedCompartmentSegmentationUIFigure.Icon = 'Logo.png';
            app.HistoLens_AdvancedCompartmentSegmentationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.HistoLens_AdvancedCompartmentSegmentationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SubcompartmentSegmentationLabel = uilabel(app.LeftPanel);
            app.SubcompartmentSegmentationLabel.Text = 'Sub-compartment Segmentation';
            app.SemanticSegmentationModelLabel = uilabel(app.LeftPanel);
            app.SemanticSegmentationModelLabel.Text = 'Semantic Segmentation Model';
            app.NewSubCompartmentNameEditFieldLabel = uilabel(app.LeftPanel);
            app.NewSubCompartmentNameEditFieldLabel.Text = 'New Sub-Compartment Name';
            app.NewSubCompartmentNameEditField = uieditfield(app.LeftPanel, 'text');
            app.NewSubCompartmentNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewSubCompartmentNameEditFieldValueChanged, true);
            app.AddSubCompartmentButton = uibutton(app.LeftPanel, 'push');
            app.AddSubCompartmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubCompartmentButtonPushed, true);
            app.AddSubCompartmentButton.Enable = 'off';
            app.AddSubCompartmentButton.Text = 'Add Sub-Compartment';
            app.SubCompartmentTree = uitree(app.LeftPanel);
            app.SelectNetworkFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectNetworkFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNetworkFileButtonPushed, true);
            app.SelectNetworkFileButton.Text = 'Select Network File';
            app.SemanticSeg_InfoSym = uiimage(app.LeftPanel);
            app.SemanticSeg_InfoSym.ImageSource = 'info_symbol.png';
            app.SubComp_InfoSym = uiimage(app.LeftPanel);
            app.SubComp_InfoSym.ImageSource = 'info_symbol.png';
            app.SemanticSegmentationOptionsPanel = uipanel(app.LeftPanel);
            app.SemanticSegmentationOptionsPanel.Title = 'Semantic Segmentation Options';
            app.NetworkNameDropDownLabel = uilabel(app.SemanticSegmentationOptionsPanel);
            app.NetworkNameDropDownLabel.Enable = 'off';
            app.NetworkNameDropDownLabel.Text = 'Network Name';
            app.NetworkNameDropDown = uidropdown(app.SemanticSegmentationOptionsPanel);
            app.NetworkNameDropDown.Items = {};
            app.NetworkNameDropDown.ValueChangedFcn = createCallbackFcn(app, @NetworkNameDropDownValueChanged, true);
            app.NetworkNameDropDown.Enable = 'off';
            app.NetworkNameDropDown.Value = {};
            app.StainTypeDropDownLabel = uilabel(app.SemanticSegmentationOptionsPanel);
            app.StainTypeDropDownLabel.Enable = 'off';
            app.StainTypeDropDownLabel.Text = 'Stain Type';
            app.StainTypeDropDown = uidropdown(app.SemanticSegmentationOptionsPanel);
            app.StainTypeDropDown.Items = {};
            app.StainTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StainTypeDropDownValueChanged, true);
            app.StainTypeDropDown.Enable = 'off';
            app.StainTypeDropDown.Value = {};
            app.NumberofSubCompartmentsSpinnerLabel = uilabel(app.SemanticSegmentationOptionsPanel);
            app.NumberofSubCompartmentsSpinnerLabel.Enable = 'off';
            app.NumberofSubCompartmentsSpinnerLabel.Text = 'Number of Sub-Compartments';
            app.NumberofSubCompartmentsSpinner = uispinner(app.SemanticSegmentationOptionsPanel);
            app.NumberofSubCompartmentsSpinner.Limits = [1 Inf];
            app.NumberofSubCompartmentsSpinner.RoundFractionalValues = 'on';
            app.NumberofSubCompartmentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSubCompartmentsSpinnerValueChanged, true);
            app.NumberofSubCompartmentsSpinner.Enable = 'off';
            app.NumberofSubCompartmentsSpinner.Value = 1;
            app.SubCompartmentNamescommaseparatedEditFieldLabel = uilabel(app.SemanticSegmentationOptionsPanel);
            app.SubCompartmentNamescommaseparatedEditFieldLabel.Enable = 'off';
            app.SubCompartmentNamescommaseparatedEditFieldLabel.Text = 'Sub-Compartment Name(s) (comma separated)';
            app.SubCompartmentNamescommaseparatedEditField = uieditfield(app.SemanticSegmentationOptionsPanel, 'text');
            app.SubCompartmentNamescommaseparatedEditField.ValueChangedFcn = createCallbackFcn(app, @SubCompartmentNamescommaseparatedEditFieldValueChanged, true);
            app.SubCompartmentNamescommaseparatedEditField.Enable = 'off';
            app.StructureNameDropDownLabel = uilabel(app.LeftPanel);
            app.StructureNameDropDownLabel.Text = 'Structure Name';
            app.StructureNameDropDown = uidropdown(app.LeftPanel);
            app.StructureNameDropDown.Items = {};
            app.StructureNameDropDown.Value = {};
            app.SubCompartmentPostProcessingOptionsPanel = uipanel(app.LeftPanel);
            app.SubCompartmentPostProcessingOptionsPanel.Title = 'Sub-Compartment Post-Processing Options';
            app.ToleranceThresholdEditFieldLabel = uilabel(app.SubCompartmentPostProcessingOptionsPanel);
            app.ToleranceThresholdEditFieldLabel.Text = 'Tolerance Threshold';
            app.ToleranceThresholdEditField = uieditfield(app.SubCompartmentPostProcessingOptionsPanel, 'numeric');
            app.ToleranceThresholdEditField.Limits = [0 1];
            app.ToleranceThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceThresholdEditFieldValueChanged, true);
            app.ToleranceThresholdEditField.Value = 0.5;
            app.SmoothingEditFieldLabel = uilabel(app.SubCompartmentPostProcessingOptionsPanel);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.SmoothingEditField = uieditfield(app.SubCompartmentPostProcessingOptionsPanel, 'numeric');
            app.SmoothingEditField.Limits = [0 Inf];
            app.SmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @SmoothingEditFieldValueChanged, true);
            app.MinimumObjectSizeEditFieldLabel = uilabel(app.SubCompartmentPostProcessingOptionsPanel);
            app.MinimumObjectSizeEditFieldLabel.Text = 'Minimum Object Size';
            app.MinimumObjectSizeEditField = uieditfield(app.SubCompartmentPostProcessingOptionsPanel, 'numeric');
            app.MinimumObjectSizeEditField.Limits = [0 Inf];
            app.MinimumObjectSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumObjectSizeEditFieldValueChanged, true);
            app.SplitConnectedCheckBox = uicheckbox(app.SubCompartmentPostProcessingOptionsPanel);
            app.SplitConnectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @SplitConnectedCheckBoxValueChanged, true);
            app.SplitConnectedCheckBox.Text = 'Split Connected?';
            app.BackgroundCheckBox = uicheckbox(app.SubCompartmentPostProcessingOptionsPanel);
            app.BackgroundCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackgroundCheckBoxValueChanged, true);
            app.BackgroundCheckBox.Text = 'Background';
            app.RightPanel = uipanel(app.GridLayout);
            app.NewImageSameSlideButton = uibutton(app.RightPanel, 'push');
            app.NewImageSameSlideButton.ButtonPushedFcn = createCallbackFcn(app, @NewImageSameSlideButtonPushed, true);
            app.NewImageSameSlideButton.Text = 'New Image, Same Slide';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Stain1Tab = uitab(app.TabGroup);
            app.Stain1Tab.Title = 'Stain 1';
            app.NewImageNewSlideButton = uibutton(app.RightPanel, 'push');
            app.NewImageNewSlideButton.Text = 'New Image, New Slide';
            app.Switch = uiswitch(app.RightPanel, 'slider');
            app.Switch.Items = {'Image', 'Mask'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Switch.Value = 'Image';
            app.SubCompSelect_InfoSym = uiimage(app.RightPanel);
            app.SubCompSelect_InfoSym.ImageSource = 'info_symbol.png';
            app.RemoveStructurefromAnnotationFileButton = uibutton(app.RightPanel, 'push');
            app.RemoveStructurefromAnnotationFileButton.Text = 'Remove Structure from Annotation File';
            app.EditStructureBoundaryButton = uibutton(app.RightPanel, 'push');
            app.EditStructureBoundaryButton.ButtonPushedFcn = createCallbackFcn(app, @EditStructureBoundaryButtonPushed, true);
            app.EditStructureBoundaryButton.Text = 'Edit Structure Boundary';
            app.SubCompartmentsSelectRegionsPanel = uipanel(app.RightPanel);
            app.SubCompartmentsSelectRegionsPanel.Title = 'Sub-Compartments Select Regions';
            app.SubCompartmentNameDropDownLabel = uilabel(app.SubCompartmentsSelectRegionsPanel);
            app.SubCompartmentNameDropDownLabel.Enable = 'off';
            app.SubCompartmentNameDropDownLabel.Text = 'Sub-Compartment Name';
            app.SubCompartmentNameDropDown = uidropdown(app.SubCompartmentsSelectRegionsPanel);
            app.SubCompartmentNameDropDown.Items = {};
            app.SubCompartmentNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SubCompartmentNameDropDownValueChanged, true);
            app.SubCompartmentNameDropDown.Enable = 'off';
            app.SubCompartmentNameDropDown.Value = {};
            app.NewRegionSelectionButton = uibutton(app.SubCompartmentsSelectRegionsPanel, 'push');
            app.NewRegionSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @NewRegionSelectionButtonPushed, true);
            app.NewRegionSelectionButton.Enable = 'off';
            app.NewRegionSelectionButton.Text = 'New Region Selection';
            app.UndoRegionSelectionButton = uibutton(app.SubCompartmentsSelectRegionsPanel, 'push');
            app.UndoRegionSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @UndoRegionSelectionButtonPushed, true);
            app.UndoRegionSelectionButton.Enable = 'off';
            app.UndoRegionSelectionButton.Text = 'Undo Region Selection';
            app.DoneButton = uibutton(app.SubCompartmentsSelectRegionsPanel, 'push');
            app.DoneButton.Text = 'Done';
            app.Knob = uiknob(app.RightPanel, 'discrete');
            app.Knob.Items = {'Image', 'Color Segmented', 'Network Output'};
            app.Knob.Enable = 'off';
            app.Knob.Visible = 'off';
            app.Knob.Value = 'Image';
            % Show the figure after all components are created
            app.HistoLens_AdvancedCompartmentSegmentationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_AdvancedCompartmentSegmentation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_AdvancedCompartmentSegmentationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_AdvancedCompartmentSegmentationUIFigure)
        end
    end
end