classdef HistoLens_ColorNormalizationComparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_ColorNormalizationCompareUIFigure  matlab.ui.Figure
        LabelDropDown                  matlab.ui.control.DropDown
        LabelDropDownLabel             matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        ReplaceCurrentColorFeaturesButton  matlab.ui.control.Button
        ColorNormalizationComparisonLabel  matlab.ui.control.Label
        SpecificFeaturesDropDown       matlab.ui.control.DropDown
        SpecificFeaturesDropDownLabel  matlab.ui.control.Label
        CompareIm_Ax                   matlab.ui.control.UIAxes
        CompareDist_Ax                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        main_app

        Structure
        Replace_Features
        Full_Feature_set
        base_Feature_set
        Aligned_Labels
        Slide_Path
        Experiment_File
        Combined_Features
        Example_Img
        structure_idx
        img_text
    
    end
    
    methods (Access = private)
        
        function Plot_Compare_Feature(app)

            cla(app.CompareDist_Ax,'reset')
            % Selected feature
            selected_feature = app.SpecificFeaturesDropDown.Value;
            selected_label = app.LabelDropDown.Value;

            old_features = app.base_Feature_set.(app.Structure)(:,find(ismember(app.base_Feature_set.(app.Structure).Properties.VariableNames,selected_feature)));
            new_features = app.Replace_Features(:,find(ismember(app.Replace_Features.Properties.VariableNames,selected_feature)));

            old_features.ImgLabel = app.base_Feature_set.(app.Structure).ImgLabel;
            new_features.ImgLabel = app.Replace_Features.ImgLabel;


            if ismember(old_features.Properties.VariableNames,'Class')
                old_features.Class = [];
            end
            if ismember(new_features.Properties.VariableNames,'Class')
                new_features.Class = [];
            end

            % Aligning with class labels
            aligned_class_labels = app.Aligned_Labels.(app.Structure).(selected_label).Aligned;

            old_features_aligned = innerjoin(old_features,aligned_class_labels);
            new_features_aligned = innerjoin(new_features,aligned_class_labels);

            old_features_aligned.NormClass = repmat({'Original'},height(old_features_aligned),1);
            new_features_aligned.NormClass = repmat({'New'},height(new_features_aligned),1);

            old_features_aligned.Label = strcat(old_features_aligned.NormClass,' ',old_features_aligned.Class);
            new_features_aligned.Label = strcat(new_features_aligned.NormClass,' ',new_features_aligned.Class);

            old_features_aligned.NormClass = [];
            old_features_aligned.Class = [];
            new_features_aligned.NormClass = [];
            new_features_aligned.Class = [];

            % Now the columns should just be (feature_name),ImgLabel,Label
            % for both old and new features.
            combined_features = [old_features_aligned;new_features_aligned];
            combined_features = combined_features(randperm(height(combined_features)),:);

            % Plotting violinplot, (some dumb stuff going on here with the
            % current axis, thanks Matlab)
            if ~isnumeric(combined_features.Label)
                vp = violinplot(combined_features.(selected_feature),cellstr(combined_features.Label),'GroupOrder',unique(combined_features.Label));
            else
                vp = violinplot(combined_features.(selected_feature),combined_features.Label,'GroupOrder',unique(combined_features.Label));
            end

            for v = 1:length(vp)
                current_v = vp(1,v);
                current_v.ScatterPlot.Parent = app.CompareDist_Ax;
                current_v.ViolinPlot.Parent = app.CompareDist_Ax;
                current_v.BoxPlot.Parent = app.CompareDist_Ax;
                current_v.WhiskerPlot.Parent = app.CompareDist_Ax;
                current_v.MedianPlot.Parent = app.CompareDist_Ax;
                current_v.NotchPlots(1,1).Parent = app.CompareDist_Ax;
                current_v.NotchPlots(1,2).Parent = app.CompareDist_Ax;
                current_v.MeanPlot.Parent = app.CompareDist_Ax;
            end
            
            % Also messing with the tick labels :/
            unique_labels = unique(combined_features.Label);
            cat_labels = {};
            for u = 1:length(unique_labels)
                cat_labels{u} = char(unique_labels(u));
            end
            set(app.CompareDist_Ax,'XTick',1:length(cat_labels),'XTickLabels',cat_labels)
            xlabel(app.CompareDist_Ax,selected_label)
            ylabel(app.CompareDist_Ax,selected_feature)
            title(app.CompareDist_Ax,selected_feature)

            app.Combined_Features = combined_features;

        end
        
        function Show_Example_Image(app)

            img_name = app.Combined_Features.ImgLabel{app.Example_Img};
            slide_name = strsplit(img_name,'_');
            if length(slide_name)==2
                img_idx = str2double(slide_name{end});
                slide_name = slide_name{1};
            else
                img_idx = str2double(slide_name{end});
                slide_name = strjoin(slide_name(1:end-1),'_');
            end
    
            read_xml = xmlread(strcat(app.Slide_Path,filesep,slide_name,'.xml'));
            annotations = read_xml.getElementsByTagName('Annotation');
            structure_regions = annotations.item(app.structure_idx.(app.Structure)-1);
            regions = structure_regions.getElementsByTagName('Region');
            
            reg = regions.item(img_idx-1);
            verts = reg.getElementsByTagName('Vertex');
            xy = zeros(verts.getLength-1,2);
            for vi = 0:verts.getLength-1
                x = str2double(verts.item(vi).getAttribute('X'));
                y = str2double(verts.item(vi).getAttribute('Y'));
                xy(vi+1,:) = [x,y];
            end

            bbox_coords = [min(xy(:,1))-100,max(xy(:,1))+100,min(xy(:,2))-100,max(xy(:,2))+100];
            img = imread(strcat(app.Slide_Path,filesep,slide_name,'.svs'),'Index',1,'PixelRegion',{bbox_coords(3:4),bbox_coords(1:2)});
            
            imshow(img,'Parent',app.CompareIm_Ax)
            title(app.CompareIm_Ax,img_name)
            
            delete(app.img_text)

            % Adding annotation to plot
            if ~isnumeric(app.Combined_Features.Label)
                all_class = unique(app.Combined_Features.Label);
                label_data = app.Combined_Features(app.Example_Img,:);
                app.img_text = text(app.CompareDist_Ax,find(strcmp(label_data{1,3},all_class)),...
                    label_data{1,1},strcat('\leftarrow','Current Image'));
            else
                all_class = unique(app.Combined_Features.Label);
                label_data = app.Combined_Features(app.Example_Img,:);
                app.img_text = text(app.CompareDist_Ax,find(label_data{1,3}==all_class),...
                    label_data{1,1},strcat('\leftarrow','Current_Image'));
            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Structure = main_app.Structure;
            % These are the features which may be switched out
            app.Replace_Features = main_app.Replace_Features;
            app.Full_Feature_set.(app.Structure) = main_app.Full_Feature_set.(app.Structure);
            app.base_Feature_set.(app.Structure) = main_app.base_Feature_set.(app.Structure);
            app.structure_idx.(app.Structure) = main_app.structure_idx.(app.Structure);

            app.Slide_Path = main_app.Slide_Path;

            app.Aligned_Labels.(app.Structure) = main_app.Aligned_Labels.(app.Structure);
            
            % Populating label dropdown
            app.LabelDropDown.Items = fieldnames(app.Aligned_Labels.(app.Structure));

            % Populating feature dropdown (exclude ImgLabel column)
            app.SpecificFeaturesDropDown.Items = app.Replace_Features.Properties.VariableNames(1:end-1);

            % Plotting the first feature and getting example image
            Plot_Compare_Feature(app)

            % Showing example image comparison
            app.Example_Img = 1;
            Show_Example_Image(app)


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            if app.Example_Img == height(app.Combined_Features)
                app.Example_Img = 1;
            else
                app.Example_Img = app.Example_Img+1;
            end

            cla(app.CompareIm_Ax,'reset')
            Show_Example_Image(app)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if app.Example_Img == 1
                app.Example_Img = height(app.Combined_Features);
            else
                app.Example_Img = app.Example_Img-1;
            end

            cla(app.CompareIm_Ax,'reset')
            Show_Example_Image(app)
        end

        % Value changed function: SpecificFeaturesDropDown
        function SpecificFeaturesDropDownValueChanged(app, event)
            value = app.SpecificFeaturesDropDown.Value;
            
            Plot_Compare_Feature(app)
            Show_Example_Image(app)
            
        end

        % Value changed function: LabelDropDown
        function LabelDropDownValueChanged(app, event)
            value = app.LabelDropDown.Value;
            
            Plot_Compare_Feature(app)
            Show_Example_Image(app)
            
        end

        % Button pushed function: ReplaceCurrentColorFeaturesButton
        function ReplaceCurrentColorFeaturesButtonPushed(app, event)
            
            % Replacing color features in app.base_Feature_set and
            % app.Full_Feature_set with those from the new normalized set
            
            Replace_in_FeatureSet(app.main_app,app)
            
            delete(app)


        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            

            % Cancel without replacement
            delete(app)
            
        end

        % Close request function: 
        % HistoLens_ColorNormalizationCompareUIFigure
        function HistoLens_ColorNormalizationCompareUIFigureCloseRequest(app, event)
            
            % Close without transferral
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_ColorNormalizationCompareUIFigure = uifigure('Visible', 'off');
            app.HistoLens_ColorNormalizationCompareUIFigure.Name = 'HistoLens_ColorNormalizationCompare';
            app.HistoLens_ColorNormalizationCompareUIFigure.Icon = 'Logo.png';
            app.HistoLens_ColorNormalizationCompareUIFigure.CloseRequestFcn = createCallbackFcn(app, @HistoLens_ColorNormalizationCompareUIFigureCloseRequest, true);
            app.CompareDist_Ax = uiaxes(app.HistoLens_ColorNormalizationCompareUIFigure);
            title(app.CompareDist_Ax, 'Feature Name')
            zlabel(app.CompareDist_Ax, 'Z')
            app.CompareDist_Ax.XTick = [];
            app.CompareDist_Ax.YTick = [];
            app.CompareIm_Ax = uiaxes(app.HistoLens_ColorNormalizationCompareUIFigure);
            zlabel(app.CompareIm_Ax, 'Z')
            app.CompareIm_Ax.XTick = [];
            app.CompareIm_Ax.YTick = [];
            app.SpecificFeaturesDropDownLabel = uilabel(app.HistoLens_ColorNormalizationCompareUIFigure);
            app.SpecificFeaturesDropDownLabel.Text = 'Specific Features';
            app.SpecificFeaturesDropDown = uidropdown(app.HistoLens_ColorNormalizationCompareUIFigure);
            app.SpecificFeaturesDropDown.Items = {};
            app.SpecificFeaturesDropDown.ValueChangedFcn = createCallbackFcn(app, @SpecificFeaturesDropDownValueChanged, true);
            app.SpecificFeaturesDropDown.Value = {};
            app.ColorNormalizationComparisonLabel = uilabel(app.HistoLens_ColorNormalizationCompareUIFigure);
            app.ColorNormalizationComparisonLabel.Text = 'Color Normalization Comparison';
            app.ReplaceCurrentColorFeaturesButton = uibutton(app.HistoLens_ColorNormalizationCompareUIFigure, 'push');
            app.ReplaceCurrentColorFeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceCurrentColorFeaturesButtonPushed, true);
            app.ReplaceCurrentColorFeaturesButton.Text = 'Replace Current Color Features';
            app.CancelButton = uibutton(app.HistoLens_ColorNormalizationCompareUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Button = uibutton(app.HistoLens_ColorNormalizationCompareUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'PrevButt.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.HistoLens_ColorNormalizationCompareUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'NextButt.png';
            app.Button_2.Text = '';
            app.LabelDropDownLabel = uilabel(app.HistoLens_ColorNormalizationCompareUIFigure);
            app.LabelDropDown = uidropdown(app.HistoLens_ColorNormalizationCompareUIFigure);
            app.LabelDropDown.Items = {};
            app.LabelDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelDropDownValueChanged, true);
            app.LabelDropDown.Value = {};
            % Show the figure after all components are created
            app.HistoLens_ColorNormalizationCompareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_ColorNormalizationComparison(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_ColorNormalizationCompareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_ColorNormalizationCompareUIFigure)
        end
    end
end