classdef HistoLens_Extract < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StructureNamesUIFigure         matlab.ui.Figure
        Label_2                        matlab.ui.control.Label
        CustomStainTypeEditField       matlab.ui.control.EditField
        CustomStainTypeEditFieldLabel  matlab.ui.control.Label
        StainTypeDropDown              matlab.ui.control.DropDown
        StainTypeDropDownLabel         matlab.ui.control.Label
        CustomMPPEditField             matlab.ui.control.NumericEditField
        CustomMPPEditFieldLabel        matlab.ui.control.Label
        BaselineMPPDropDown            matlab.ui.control.DropDown
        BaselineMPPDropDownLabel       matlab.ui.control.Label
        AvailableAnnotationIDsLabel    matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        ResetButton                    matlab.ui.control.Button
        ExperimentFileNameEditField    matlab.ui.control.EditField
        ExperimentFileNameEditFieldLabel  matlab.ui.control.Label
        IgnorethisStructureButton      matlab.ui.control.Button
        StructureNameEditField         matlab.ui.control.EditField
        StructureNameEditFieldLabel    matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        ExtractingStructuresfromWSILabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        main_app
        Slide_Path
        
        HistoLens_CompartmentSegmentation
        HistoLens_WSI_Annotation

        Structure_Names
        Stain_Names = {'Hematoxylin', 'Eosin', 'Background'};
        MPP
        Baseline_MPP
        Experiment_File
        Annotation_Format
        Current_Selection
        Original_Names

    end

    methods (Access = public)
        
        function new_var_name = Sanitize_UserInput(app,old_var_name)

            old_var_name = strrep(old_var_name,'_','');
            if isvarname(old_var_name)
                new_var_name = old_var_name;
            else
                if isstring(old_var_name)
                    replace_name = char(old_var_name);
                else
                    replace_name = old_var_name;
                end
                replace_name(find(~isletter(replace_name))) = [];

                if ~isempty(replace_name)
                    if isvarname(replace_name)
                        if isstring(old_var_name)
                            new_var_name = string(replace_name);
                        else
                            new_var_name = replace_name;
                        end
                    else
                        new_var_name = [];
                    end
                else
                    new_var_name = [];
                end
            end
            

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Path = main_app.Slide_Path;
            
            % Getting only the xml files in the slide path
            dir_contents = dir(app.Slide_Path);
            dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
            dir_contents = dir_contents(contains({dir_contents.name},{'.xml','.json','.geojson'}));
            dir_contents = {dir_contents.name};
            
            % Adding json and geojson compatibility
            if isempty(dir_contents)
                
                app.StructureNamesUIFigure.Visible = 'off';
                % This would be if no annotations are initially provided
                question = strcat('No annotation files found in:',app.Slide_Path,'. Would you like to annotate your slides in HistoLens? (Note: This feature is still under development)');
                question_title = 'No Annotations';

                response = questdlg(question,question_title);

                switch response
                    case 'Yes'

                        app.HistoLens_WSI_Annotation = HistoLens_WSI_Annotation(app.Slide_Path);
                        waitfor(app.HistoLens_WSI_Annotation)

                        app.StructureNamesUIFigure.Visible = 'on';

                        % Getting only the xml files in the slide path
                        dir_contents = dir(app.Slide_Path);
                        dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
                        dir_contents = dir_contents(contains({dir_contents.name},{'.xml','.json','.geojson'}));
                        dir_contents = {dir_contents.name};

                        if length(dir_contents(contains(dir_contents,{'.json','.geojson'})))>0
                            app.Annotation_Format = 'JSON';
                        elseif length(dir_contents(contains(dir_contents,{'.xml'})))>0
                            app.Annotation_Format = 'XML';
                        else
                            msgbox('No annotations found!')
                            delete(app)
                            delete(app.main_app)
                        end
            
                    case 'No'
                        delete(app)
                        delete(app.main_app)
                    case ''
                        delete(app)
                        delete(app.main_app)
                end

            
            elseif length(dir_contents(contains(dir_contents,{'.json','.geojson'})))>0

                app.Annotation_Format = 'JSON';

            elseif length(dir_contents(contains(dir_contents,{'.xml'})))>0
                app.Annotation_Format = 'XML';
            end

            ann_waitbar = waitbar(0,'Going through slides and finding annotation layers');

            % Initializing list of labeled structures
            app.Structure_Names = [];
            if strcmp(app.Annotation_Format,'XML')
                annotation_IDs = zeros(length(dir_contents),1);

                for i = 1:length(dir_contents)
                    read_xml = xmlread(strcat(app.Slide_Path,filesep,dir_contents{i}));
                    annotations = read_xml.getElementsByTagName('Annotation');

                    % Extracting MPP from this slide
                    try
                        mpp = read_xml.getElementsByTagName('Annotations');
                        mpp = str2double(mpp.item(0).getAttribute('MicronsPerPixel'));
                        if ~isnan(mpp)
                            app.MPP = [app.MPP;mpp];
                        end
                    end
                    annotation_IDs(i) = annotations.getLength;

                    for s = 1:annotations.getLength
                        current_layer = annotations.item(s-1);

                        if current_layer.hasAttribute('Name') && string(current_layer.getAttribute('Name'))~=""
                            if ~isempty(app.Structure_Names)
                                if ~ismember(num2str(s),app.Structure_Names(:,2))
                                    app.Structure_Names = [app.Structure_Names;{string(current_layer.getAttribute('Name')),num2str(s)}];
                                else
                                    layer_idx = find(strcmp(app.Structure_Names(:,2),num2str(s)));
                                    if ~strcmp(app.Structure_Names(layer_idx,1),string(current_layer.getAttribute('Name'))) && ~strcmp(string(current_layer.getAttribute('Name')),strcat('Layer ',num2str(s)))
                                        app.Structure_Names(layer_idx,1) = {string(current_layer.getAttribute('Name'))};
                                    end
                                end
                            else
                                app.Structure_Names = {string(current_layer.getAttribute('Name')),num2str(s)};
                            end
                        else
                            if ~isempty(app.Structure_Names)
                                if ~ismember(num2str(s),app.Structure_Names(:,2))
                                    app.Structure_Names = [app.Structure_Names;{strcat('Layer ',num2str(s)),num2str(s)}];
                                end
                            else
                                app.Structure_Names = {strcat('Layer ',num2str(s)),num2str(s)};
                            end
            
                        end
                    end
                    
                    waitbar(i/length(dir_contents),ann_waitbar,'Going through slides and finding annotation layers');

                end

            else
                % Placeholder for JSON annotations
                msgbox('Working on it!')
                app.Structure_Names = {'JSON Annotation Layer',num2str(1)};

            end
            
            waitbar(1,ann_waitbar,'Done!')
            pause(0.2)
            delete(ann_waitbar)

            app.Structure_Names = cell2table(app.Structure_Names);
            
            app.UITable.Data = app.Structure_Names;
            app.Original_Names = app.Structure_Names;

            % Populating MPP dropdown options:
            if ~isempty(app.MPP)
                % Options should be each unique MPP for each slide (with
                % count), mean, median, or custom
                mpp_options = {};
                unique_mpps = unique(app.MPP);
                for m = 1:length(unique_mpps)
                    current_mpp = unique_mpps(m);
                    count = length(find(app.MPP==current_mpp));
                    mpp_options = [mpp_options;{strcat([num2str(current_mpp),' (',num2str(count),')'])}];
            
                end

                mpp_options = [mpp_options;{strcat(['Mean: ',num2str(mean(app.MPP))])}];
                mpp_options = [mpp_options;{strcat(['Median: ',num2str(median(app.MPP))])}];
                mpp_options = [mpp_options;{'Custom MPP'}];
                
                base_mpp = strsplit(mpp_options{1},' ');
                app.Baseline_MPP = str2double(base_mpp{1});

                app.CustomMPPEditField.Enable = 'off';

            else
                mpp_options = {'Custom MPP','Unknown'};
                app.CustomMPPEditField.Enable = 'on';
                app.CustomMPPEditFieldLabel.Enable = 'on';
                app.Baseline_MPP = 0.25;
                
            end
            
            app.BaselineMPPDropDown.Items = mpp_options;

            % Populating Experiment File Name field
            default_experiment_name = strsplit(app.Slide_Path,filesep);
            default_experiment_name = default_experiment_name{end};

            % Testing for if the folder name is a number (and changing
            % default experiment name)
            if isnumeric(default_experiment_name)
                default_experiment_name = strcat(num2str(default_experiment_name),'_HistoLens_Experiment');
            end

            if ~isempty(str2num(default_experiment_name))
                default_experiment_name = strcat(default_experiment_name,'_HistoLens_Experiment');
            end
            
            app.Experiment_File = default_experiment_name;
            app.ExperimentFileNameEditField.Value = default_experiment_name;


        end

        % Value changed function: StructureNameEditField
        function StructureNameEditFieldValueChanged(app, event)
            value = app.StructureNameEditField.Value;
        
            % Getting previous annotation ID values
            old_value = app.Current_Selection;
            if length(old_value)==1

                app.UITable.Data{old_value,1} = {value};

            else
                
                % Replacing multiple values
                old_layer_ids = app.UITable.Data{old_value,2};
                combined = strjoin(old_layer_ids,',');

                app.UITable.Data(old_value,:) = [];
                app.UITable.Data = [{value,combined};app.UITable.Data];

            end
            
            app.Structure_Names = app.UITable.Data;
            app.StructureNameEditField.Value = '';
            
        end

        % Button pushed function: IgnorethisStructureButton
        function IgnorethisStructureButtonPushed(app, event)
            
            % Updating table of structure names and layers
            app.UITable.Data(app.Current_Selection,:) = [];
            app.Structure_Names = app.UITable.Data;

            app.StructureNameEditField.Value = '';
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            if ~isempty(app.Structure_Names) && ~isempty(app.ExperimentFileNameEditField.Value)
                % Collect and sort the structure names and the annotation IDs
                % associated with that structure
                Get_Structure_Names(app.main_app,app)
                delete(app)
            elseif isempty(app.Structure_Names) && isempty(app.ExperimentFileNameEditField.Value)
                msgbox('Uh oh! No annotation ids or experiment file name!')
            elseif isempty(app.Structure_Names) && ~isempty(app.ExperimentFileNameEditField.Value)
                msgbox('Uh oh! No annotation ids selected!')
            elseif ~isempty(app.Structure_Names) && isempty(app.ExperimentFileNameEditField.Value)
                msgbox('Uh oh! No experiment file name selected!')
            end

        end

        % Value changed function: ExperimentFileNameEditField
        function ExperimentFileNameEditFieldValueChanged(app, event)
            value = app.ExperimentFileNameEditField.Value;

            if isnumeric(value)
                msgbox('Experiment names cannot be numbers')
                app.ExperimentFileNameEditField.Value = '';
            else
                
                app.Experiment_File = value;
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            % Returning to original labels extracted from xml files
            app.UITable.Data = app.Original_Names;
            app.Structure_Names = app.UITable.Data;

            app.StructureNameEditField.Value = ''; 

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            indices = indices(:,1);
            
            app.Current_Selection = indices;
            % Getting the row that is selected
            if length(indices)>1
                names = strjoin(app.UITable.Data{indices,1},',');
            else
                names = app.UITable.Data{indices,1};
            end
            
            if iscell(names)
                names = names{1};
            end

            app.StructureNameEditField.Value = names;

            
        end

        % Value changed function: BaselineMPPDropDown
        function BaselineMPPDropDownValueChanged(app, event)
            value = app.BaselineMPPDropDown.Value;
            
            if strcmp(value,'Custom MPP')
                app.CustomMPPEditFieldLabel.Enable = 'on';
                app.CustomMPPEditField.Enable = 'on';
            else
                app.CustomMPPEditFieldLabel.Enable = 'off';
                app.CustomMPPEditField.Enable = 'off';
            end

            if strcmp(value,'Custom MPP')

                app.Baseline_MPP = app.CustomMPPEditField.Value;

            elseif contains(value,'(')
                
                value = strsplit(value,' ');
                value = str2double(value{1});

                app.Baseline_MPP = value;
            elseif contains(value,'M')
                value = strsplit(value,' ');
                value = str2double(value{end});

                app.Baseline_MPP = value;

            elseif strcmp(value,'Unknown')
                app.Baseline_MPP = 'Unknown';
            end

        end

        % Value changed function: CustomMPPEditField
        function CustomMPPEditFieldValueChanged(app, event)
            value = app.CustomMPPEditField.Value;
            
            app.Baseline_MPP = value;
        end

        % Value changed function: StainTypeDropDown
        function StainTypeDropDownValueChanged(app, event)
            value = app.StainTypeDropDown.Value;
            
            % Specifying the type of stain used
            app.CustomStainTypeEditField.Enable = 'off';

            if strcmp(value,'H&E')
                
                app.Stain_Names = {'Hematoxylin', 'Eosin', 'Background'};

            elseif strcmp(value,'PAS')
                
                app.Stain_Names = {'Hematoxylin','PAS+','Background'};

            elseif strcmp(value,'Sil')

                app.Stain_Names = {'Silver','Background'};

            elseif strcmp(value, 'Tri')

                app.Stain_Names = {'Collagen','Cytoplasm/Muscle','Background'};
            
            elseif strcmp(value,'Custom')

                app.CustomStainTypeEditField.Enable = 'on';

            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StructureNamesUIFigure = uifigure('Visible', 'off');
            app.StructureNamesUIFigure.Name = 'Structure Names';
            app.StructureNamesUIFigure.Icon = 'Logo.png';
            app.ExtractingStructuresfromWSILabel = uilabel(app.StructureNamesUIFigure);
            app.ExtractingStructuresfromWSILabel.Text = 'Extracting Structures from WSI';
            app.DoneButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.Label = uilabel(app.StructureNamesUIFigure);
            app.Label.Text = 'Listbox below indicates annotation IDs in the slides specified by the Slide Path.  Select annotation IDs below and either re-name to the desired structure name or click "Ignore this Structure" to remove from analysis.  Alternatively, you can combine structures together by selecting multiple structures in the table and setting them to the same structure name.';
            app.StructureNameEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.StructureNameEditFieldLabel.Text = 'Structure Name';
            app.StructureNameEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.StructureNameEditField.ValueChangedFcn = createCallbackFcn(app, @StructureNameEditFieldValueChanged, true);
            app.IgnorethisStructureButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.IgnorethisStructureButton.ButtonPushedFcn = createCallbackFcn(app, @IgnorethisStructureButtonPushed, true);
            app.IgnorethisStructureButton.Text = 'Ignore this Structure';
            app.ExperimentFileNameEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.ExperimentFileNameEditFieldLabel.Text = 'Experiment File Name';
            app.ExperimentFileNameEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.ExperimentFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentFileNameEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.StructureNamesUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UITable = uitable(app.StructureNamesUIFigure);
            app.UITable.ColumnName = {'Structure'; 'Layer'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AvailableAnnotationIDsLabel = uilabel(app.StructureNamesUIFigure);
            app.AvailableAnnotationIDsLabel.Text = 'Available Annotation IDs';
            app.BaselineMPPDropDownLabel = uilabel(app.StructureNamesUIFigure);
            app.BaselineMPPDropDownLabel.Text = 'Baseline MPP';
            app.BaselineMPPDropDown = uidropdown(app.StructureNamesUIFigure);
            app.BaselineMPPDropDown.Items = {'MPP value to use'};
            app.BaselineMPPDropDown.ValueChangedFcn = createCallbackFcn(app, @BaselineMPPDropDownValueChanged, true);
            app.BaselineMPPDropDown.Value = 'MPP value to use';
            app.CustomMPPEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.CustomMPPEditFieldLabel.Enable = 'off';
            app.CustomMPPEditFieldLabel.Text = 'Custom MPP';
            app.CustomMPPEditField = uieditfield(app.StructureNamesUIFigure, 'numeric');
            app.CustomMPPEditField.Limits = [1e-08 Inf];
            app.CustomMPPEditField.ValueChangedFcn = createCallbackFcn(app, @CustomMPPEditFieldValueChanged, true);
            app.CustomMPPEditField.Enable = 'off';
            app.CustomMPPEditField.Value = 0.25;
            app.StainTypeDropDownLabel = uilabel(app.StructureNamesUIFigure);
            app.StainTypeDropDownLabel.Text = 'Stain Type';
            app.StainTypeDropDown = uidropdown(app.StructureNamesUIFigure);
            app.StainTypeDropDown.Items = {'Hemotoxylin & Eosin', 'Periodic Acid Schiff', 'Silver', 'Trichrome', 'Custom'};
            app.StainTypeDropDown.ItemsData = {'H&E', 'PAS', 'Sil', 'Tri', 'Custom'};
            app.StainTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StainTypeDropDownValueChanged, true);
            app.StainTypeDropDown.Placeholder = 'Type of stain to use';
            app.StainTypeDropDown.Value = 'H&E';
            app.CustomStainTypeEditFieldLabel = uilabel(app.StructureNamesUIFigure);
            app.CustomStainTypeEditFieldLabel.Text = 'Custom Stain Type';
            app.CustomStainTypeEditField = uieditfield(app.StructureNamesUIFigure, 'text');
            app.CustomStainTypeEditField.Enable = 'off';
            app.Label_2 = uilabel(app.StructureNamesUIFigure);
            app.Label_2.Text = 'If using a stain type not listed in the dropdown menu, list each of the stains separated by a comma. Background will be added by default. (e.g. Stain 1,Stain 2,Stain 3)';
            % Show the figure after all components are created
            app.StructureNamesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_Extract(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StructureNamesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StructureNamesUIFigure)
        end
    end
end