classdef HistoLens_FeatureExtraction < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_FeatureExtractionUIFigure  matlab.ui.Figure
        GoButton                      matlab.ui.control.Button
        FeatureSetFilenameLabel       matlab.ui.control.Label
        SelectFeaturestoExtractLabel  matlab.ui.control.Label
        FeatureExtractionLabel        matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        main_app
        
    end
    
    properties (Access = public)
        Slide_Names
        Structure_Names
        Stain_Names
        Slide_Path
        FeatSet_File
        MPP
        Baseline_MPP
        Experiment_File
        Experiment_Struct
        Full_Feature_set
        all_feature_defs
        Annotation_Format
        
        Structure_Tab
        Structure_Feature_Tree
        Structure_Include_List
        Structure_Continue_Butt
        Structure_Feature_Check
        Structure_Checkbox
        Seg_Params
        Slide_NormVals


        WSI_Formats = {'.svs','.tif','.tiff','.vms','.vmu','.ndpi','.scn',...
            '.mrxs','svslide','.bif'};
        
    end
    
    methods (Access = public)
        
        function Structure_Feats(app,structure_idx_name,grid_layouter)

            structures = app.Structure_Names{:,1};
            structure_idx_name = strcat('Structure_',num2str(find(strcmp(structure_idx_name,structures))));
                        
            % If one of the structures is selected then show the features that are
            % specific to that structure and the non-structure specific ones.
            % Otherwise just show the non-structure specific ones
            
            app.Structure_Feature_Tree.(structure_idx_name) = uitree(grid_layouter,'checkbox','CheckedNodesChangedFcn',@(varargin)Update_Feature_Check(app));
            app.Structure_Feature_Tree.(structure_idx_name).Layout.Row = 3;
            app.Structure_Feature_Tree.(structure_idx_name).Layout.Column = [1 length(unique(app.all_feature_defs.Structure_Specificity))];
            struct_include = app.Structure_Include_List.(structure_idx_name);
            
            reduced_feature_defs = app.all_feature_defs(ismember(app.all_feature_defs.Structure_Specificity,struct_include),:);
            compartments = unique(reduced_feature_defs.Compartment);
            
            % Adding feature types to each compartment
            for i = 1:length(compartments)
                parent = uitreenode(app.Structure_Feature_Tree.(structure_idx_name),'Text',compartments{i});
                
                % Feature types included with this given compartment
                compartment_feature_types = reduced_feature_defs(strcmp(reduced_feature_defs.Compartment,compartments{i}),:);
                
                types = unique(compartment_feature_types.Type);
                for j = 1:length(types)
                    child = uitreenode(parent,'Text',types{j});
                    
                    types_specific_features = compartment_feature_types(strcmp(compartment_feature_types.Type,types{j}),:);
                    
                    specs = unique(types_specific_features.Feature_Names);
                    for k = 1:length(specs)
                        grandchild = uitreenode(child,'Text',specs{k});
                    end
                    
                end
            end
            
            app.Structure_Continue_Butt.(structure_idx_name).Enable = 'off';
            
        end
        
        function CheckBox_Change(app,structure_idx_name,cbx)
            % Adding or removing a structure-specificity from the structure
            % include list for that specific structure
            val = cbx.Value;

            structures = app.Structure_Names{:,1};
            structure_idx_name = strcat('Structure_',num2str(find(strcmp(structure_idx_name,structures))));
            
            add_text = cbx.Text;
            
            prev_list = app.Structure_Include_List.(structure_idx_name);
            if val
                if ~isempty(prev_list)
                    app.Structure_Include_List.(structure_idx_name) = [prev_list,add_text];
                else
                    app.Structure_Include_List.(structure_idx_name) = {add_text};
                end
            else
                rem_idx = find(strcmp(prev_list,add_text));
                prev_list(rem_idx) = [];
                app.Structure_Include_List.(structure_idx_name) = prev_list;
                
            end
            
        end
        
        function Update_Feature_Check(app)
            
            % Verifying that there are features selected for all structures

            for st = 1:length(fieldnames(app.Structure_Feature_Tree))
                structure_name = fieldnames(app.Structure_Feature_Tree);
                structure_name = structure_name{st};
                structure_idx_name = strcat('Structure_',num2str(st));
                
                if length(app.Structure_Feature_Tree.(structure_idx_name).CheckedNodes) > 0
                    app.Structure_Feature_Check(st) = true;
                end
            
            end
            
            if all(app.Structure_Feature_Check)
                app.GoButton.Enable = 'on';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Slide_Names = main_app.Slide_Names;
            app.Slide_Path = main_app.Slide_Path;
            app.Structure_Names = main_app.Structure_Names;
            app.Stain_Names = main_app.Stain_Names;
            app.Slide_Names = main_app.Slide_Names;
            app.Seg_Params = main_app.Seg_Params;
            app.Experiment_File = main_app.Experiment_File;
            app.Experiment_Struct = main_app.Experiment_Struct;
            app.Annotation_Format = main_app.Annotation_Format;
            app.Baseline_MPP = main_app.Baseline_MPP;

            app.all_feature_defs = readtable('Master_Feature_Categories.csv');
            
            % Adding custom features here
            try
                custom_features = readtable('Custom_Feature_Categories.csv');

                app.all_feature_defs = [app.all_feature_defs;custom_features];
            end
            
            structure_specificities = unique(app.all_feature_defs.Structure_Specificity);

            % Creating tab group with new tab for each structure
            features_tab_group = uitabgroup(app.HistoLens_FeatureExtractionUIFigure,'Position',[11,301,346,188]);
            structures = app.Structure_Names{:,1};
            for st = 1:length(structures)
                structure_name = structures{st};
                structure_idx_name = strcat('Structure_',num2str(st));
                app.Structure_Tab.(structure_idx_name) = uitab(features_tab_group,'Title',structure_name,'Scrollable','on');
                
                % Changing to check-boxes and making the feature type tree
                % afterwards
                grid_layouter = uigridlayout(app.Structure_Tab.(structure_idx_name));
                grid_layouter.RowHeight = {'1x','1x','4x'};
                for sp = 1:length(structure_specificities)
                    cbx_name = strcat('cbx',num2str(sp));
                    app.Structure_Checkbox.(structure_idx_name).(cbx_name) = uicheckbox(grid_layouter,...
                        'Text',structure_specificities{sp},...
                        'WordWrap','on',...
                        'ValueChangedFcn',@(cbx,event)CheckBox_Change(app,structure_name,cbx));

                    app.Structure_Checkbox.(structure_idx_name).(cbx_name).Layout.Row = 1;
                    app.Structure_Checkbox.(structure_idx_name).(cbx_name).Layout.Column = sp;
                    
                end
                
                app.Structure_Include_List.(structure_idx_name) = [];

                continue_butt = uibutton(grid_layouter,...
                    'Text','Continue','ButtonPushedFcn',@(varargin)Structure_Feats(app,structure_name,grid_layouter));
                continue_butt.Layout = matlab.ui.layout.GridLayoutOptions('Row',2,'Column',[1 length(structure_specificities)]);

                app.Structure_Continue_Butt.(structure_idx_name) = continue_butt;

            end
                        
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            % Turning off functionality of everything else in this window
            app.GoButton.Enable = 'off';
            
            structures = app.Structure_Names{:,1};
            for st = 1:length(structures)
                struct_name = structures{st};
                structure_idx_name = strcat('Structure_',num2str(st));

                app.Structure_Feature_Tree.(structure_idx_name).Enable = 'off';
                
                cbx_names = fieldnames(app.Structure_Checkbox.(structure_idx_name));
                for cb = 1:length(cbx_names)
                    app.Structure_Checkbox.(structure_idx_name).(cbx_names{cb}).Enable = 'off';
                end

            end
                        
            % Creating parallel pool
            %delete(gcp('nocreate'))
            %histolens_pool = parpool('Processes');
            %display(strcat('Starting parallel pool with: ',num2str(histolens_pool.NumWorkers),'_workers'))

            % Defining structure_name from app.main_app.Structure
            hold(app.UIAxes,"on")
            app.UIAxes.XLim = [0,1];
            app.UIAxes.YLim = [0 length(structures)];

            structures = app.Structure_Names{:,1};
            for st = 1:length(structures)
                structure_name = structures{st};
                structure_idx_name = strcat('Structure_',num2str(st));
                
                % Get Features that are going to be extracted
                checked_nodes = app.Structure_Feature_Tree.(structure_idx_name).CheckedNodes;
                checked_text = [];
                
                for i = 1:length(checked_nodes)
                    if isempty(checked_nodes(i).Children)
                        if isempty(checked_text)
                            checked_text = {checked_nodes(i).Text};
                        else
                            checked_text = [checked_text,checked_nodes(i).Text];
                        end
                    end
                end
                
                % checked_text only has the specific feature names
                % Getting the feature names from the feature categories
                %subset_table = app.all_feature_defs(ismember(app.all_feature_defs.Feature_Names,checked_text)&ismember(app.all_feature_defs.Structure_Specificity,app.Structure_Include_List.(structure_name)),:)
                feature_indices = ismember(app.all_feature_defs.Feature_Names,checked_text)&ismember(app.all_feature_defs.Structure_Specificity,app.Structure_Include_List.(structure_idx_name));
                feature_indices = find(feature_indices);
                % Constructing the feature set filename
                app.FeatSet_File = strcat(app.Slide_Path,filesep,structure_name,'_Features.csv');
            
                % Generating some properties for the structure-progress bar
                patch_x = [0,0,0,0];
                patch_y = [st-1,st-1,st,st];
                structure_prog_color = [rand,rand,rand];
                progress_patch = patch(app.UIAxes,patch_x,patch_y,structure_prog_color);

                display('Starting feature extraction')
                % Run Catch_N_Release feature extraction
                Catch_N_Release(app,structure_name,feature_indices,progress_patch)
                %cla(app.UIAxes)
                
                % Replacing table2struct with custom (write rows as
                % comma-delimited instead of as cell arrays for each field
                % which ends up with a huge xml file)
                app.Experiment_Struct.Structure.(structure_idx_name).FeatureSet = Special_Table2Struct(app.Full_Feature_set.(structure_idx_name));
                
            end
            hold(app.UIAxes,'off')

            Experiment_Struct = app.Experiment_Struct;
            writestruct(Experiment_Struct,strcat(app.Slide_Path,filesep,app.Experiment_File,'.xml'),'StructNodeName','HistoLensExperimentParameters')
            
            % Transferring experiment struct to HistoLens_File_Input
            Get_Experiment_Struct(app.main_app,app)
            
            % Removing parallel pool
            %delete(gcp('nocreate'))

            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_FeatureExtractionUIFigure = uifigure('Visible', 'off');
            app.HistoLens_FeatureExtractionUIFigure.Name = 'HistoLens_FeatureExtraction';
            app.HistoLens_FeatureExtractionUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.HistoLens_FeatureExtractionUIFigure);
            title(app.UIAxes, 'On Slide: Progress:')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UITable = uitable(app.HistoLens_FeatureExtractionUIFigure);
            app.UITable.ColumnName = {'Slide Name'; 'Structure Number'};
            app.UITable.RowName = {};
            app.FeatureExtractionLabel = uilabel(app.HistoLens_FeatureExtractionUIFigure);
            app.FeatureExtractionLabel.Text = 'Feature Extraction ';
            app.SelectFeaturestoExtractLabel = uilabel(app.HistoLens_FeatureExtractionUIFigure);
            app.SelectFeaturestoExtractLabel.Text = 'Select whether to use structure-specific features, followed by feature types:';
            app.FeatureSetFilenameLabel = uilabel(app.HistoLens_FeatureExtractionUIFigure);
            app.FeatureSetFilenameLabel.Text = 'Feature Set Filename: ';
            app.GoButton = uibutton(app.HistoLens_FeatureExtractionUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go!';
            % Show the figure after all components are created
            app.HistoLens_FeatureExtractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_FeatureExtraction(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_FeatureExtractionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_FeatureExtractionUIFigure)
        end
    end
end