classdef HistoLens_FeatureRanking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_FeatureRankingUIFigure  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        FeatureRankingMethodButtonGroup  matlab.ui.container.ButtonGroup
        Image                          matlab.ui.control.Image
        MRMRButton                     matlab.ui.control.ToggleButton
        ChiSquareButton                matlab.ui.control.ToggleButton
        ReliefFButton                  matlab.ui.control.ToggleButton
        LabelTypeButtonGroup           matlab.ui.container.ButtonGroup
        ImageLevelLabelsButton         matlab.ui.control.RadioButton
        SlideLevelLabelsButton         matlab.ui.control.RadioButton
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        LabelColumnDropDown            matlab.ui.control.DropDown
        ImageLabelColumnDropDownLabel  matlab.ui.control.Label
        ClassLabelColumnDropDown       matlab.ui.control.DropDown
        ClassLabelColumnDropDownLabel  matlab.ui.control.Label
        HasmultiplelabelsCheckBox      matlab.ui.control.CheckBox
        GoButton                       matlab.ui.control.Button
        Tree                           matlab.ui.container.CheckBoxTree
        LoadSlidelevelorStructurelevellabelsLabel  matlab.ui.control.Label
        SelectFileButton               matlab.ui.control.Button
        FeatureRankingWindowLabel      matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        TostopfeaturerankingatanytimepressCancelbelowLabel  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ReturntoFileInputWindowButton  matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        main_app
    end
    
    properties (Access = public)
        Full_Feature_set
        Feat_Rank
        Labels
        RankMethod = 'ReliefF';
        
        Aligned_Labels
        Tree_Labels
        Include_Class
        Cancel_Pushed = false;
        file_type
        file_label_col
        Feat_Rank_File
        %Feat_Rank_Path
    end
    
    methods (Access = public)
        
        function Multi_Label_Process(app)

            structures = fieldnames(app.Full_Feature_set);
            for s = 1:length(structures)
                img_labels = app.Full_Feature_set.(structures{s}).ImgLabel;
                file_type = app.file_type;
            
                all_classes = app.Labels;
                file_labels = all_classes.(app.file_label_col);
            
                for cl = 1:width(all_classes)
                    current_feature = all_classes.Properties.VariableNames{cl};
                    % Making sure this feature isn't the file label one
                    if ~strcmp(current_feature, app.file_label_col) && ~isempty(current_feature) && ~strcmp(current_feature,'')
                        % Aligning labels with the structure, different method
                        % if the labels are on a slide-level vs.
                        % structure-level
                        if strcmp(file_type,'slide')
                                                    
                            % Getting the slide labels for each image
                            if ~contains(file_labels,'_')
                                slide_imglabels = cellfun(@(x)strsplit(x,'_'),img_labels,'UniformOutput',false);
                                slide_imglabels = cellfun(@(x)x{1},slide_imglabels,'UniformOutput',false);
                            else
                                slide_imglabels = cellfun(@(x)strsplit(x,'_'),img_labels,'UniformOutput',false);
                                slide_imglabels = cellfun(@(x)strjoin(x(1:end-1),'_'),slide_imglabels,'UniformOutput',false);
                            end
                            
                            % Two tables, one with the slide names for each one
                            % of the structures and another with the slide
                            % names and labels
                            slide_imglabels_table = cell2table(slide_imglabels,'VariableNames',{'ImgLabel'});
                            if strcmp(class(all_classes.(current_feature)),'cell')
                                class_table = cell2table([file_labels,all_classes.(current_feature)],'VariableNames',{'ImgLabel','Class'});
                            else
                                class_table = cell2table(file_labels,'VariableNames',{'ImgLabel'});
                                class_table.Class = all_classes.(current_feature);
                            end
    
                            aligned_labels = innerjoin(slide_imglabels_table,class_table);
                            aligned_labels.Properties.VariableNames = {'ImgLabel','Class'};
                            
                            aligned_labels.ImgLabel = img_labels(find(ismember(slide_imglabels,aligned_labels.ImgLabel)));
                            
                        else
                            
                            % Easier when aligning structure-level labels since
                            % you don't have to get the slide name 
                            struct_imglabels_table = cell2table(img_labels,'VariableNames',{'ImgLabel'});
                            class_table = cell2table([file_labels,all_classes.(current_feature)],'VariableNames',{'ImgLabel','Class'});
                            
                            aligned_labels = innerjoin(struct_imglabels_table,class_table);
                            aligned_labels.Properties.VariableNames = {'ImgLabel','Class'};
                        end
                        
                        app.Aligned_Labels.(structures{s}).(current_feature) = aligned_labels;
                            
                    end
                end 
            end
        end
            
        
        function Single_Label_Process(app)

            structures = fieldnames(app.Full_Feature_set);
            for s = 1:length(structures)
                img_labels = app.Full_Feature_set.(structures{s}).ImgLabel;
                file_type = app.file_type;
                
                if strcmp(file_type,'slide')
                    all_classes = app.Labels;
                    file_labels = all_classes.(app.file_label_col);
                    current_feature = all_classes.Properties.VariableNames(~strcmp(all_classes.Properties.VariableNames,app.file_label_col));
                    current_feature = current_feature{1};
                    
                    % Getting the slide labels for each image
                    if ~contains(file_labels,'_')
                        slide_imglabels = cellfun(@(x)strsplit(x,'_'),img_labels,'UniformOutput',false);
                        slide_imglabels = cellfun(@(x)x{1},slide_imglabels,'UniformOutput',false);
                    else
                        slide_imglabels = cellfun(@(x)strsplit(x,'_'),img_labels,'UniformOutput',false);
                        slide_imglabels = cellfun(@(x)strjoin(x(1:end-1),'_'),slide_imglabels,'UniformOutput',false);
                    end
                    
                    % Two tables, one with the slide names for each one
                    % of the structures and another with the slide
                    % names and labels
                    slide_imglabels_table = cell2table(slide_imglabels,'VariableNames',{'ImgLabel'});
                    class_table = cell2table([file_labels,all_classes.(current_feature)],'VariableNames',{'ImgLabel','Class'});
                    
                    [aligned_labels,rows_in_t1] = innerjoin(slide_imglabels_table,class_table);
                    [~,sortinds] = sort(rows_in_t1);
                    aligned_labels = aligned_labels(sortinds,:);
                    aligned_labels.ImgLabel = img_labels;
    
                else
                    all_classes = app.Labels;
                    file_labels = all_classes.(app.file_label_col);
                    
                    current_feature = all_classes.Properties.VariableNames(~strcmp(all_classes.Properties.VariableNames,app.file_label_col));
                    
                    struct_imglabels_table = cell2table(img_labels,'VariableNames',{app.file_label_col});
                    
                    [aligned_labels,rows_in_t1] = innerjoin(struct_imglabels_table,all_classes);
                    [~,sortinds] = sort(rows_in_t1);
                    aligned_labels = aligned_labels(sortinds,:);
    
                    aligned_labels.Properties.VariableNames = {'ImgLabel','Class'};
                    aligned_labels.ImgLabel = file_labels;
    
                end
                app.Aligned_Labels.(structures{s}).(current_feature) = aligned_labels;
    
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Full_Feature_set = main_app.Full_Feature_set;  
           
            structures = fieldnames(app.Full_Feature_set);

            for s = 1:length(structures)

                app.Feat_Rank_File.(structures{s}) = strcat(app.main_app.Slide_Path,filesep,'Feature_Rankings',filesep,structures{s});
                if ~exist(app.Feat_Rank_File.(structures{s}),'dir')
                   
                    mkdir(strcat(app.Feat_Rank_File.(structures{s}),filesep,structures{s}))
                end
            end

            % Automatically getting the Class variable
            if any(ismember(app.Full_Feature_set.(structures{1}).Properties.VariableNames,'Class'))
                app.SelectFileButton.Enable = 'off';
                app.LabelColumnDropDown.Enable = 'off';
                app.ClassLabelColumnDropDown.Enable = 'off';
                app.HasmultiplelabelsCheckBox.Enable = 'off';

                app.file_type = app.main_app.file_type;
                app.file_label_col = app.main_app.file_label_col;
                
                app.Tree.Enable = 'on';
                app.Tree.Visible = 'on';
                app.Label_2.Enable = 'on';
                app.Label_2.Visible = 'on';

                if ~strcmp(app.file_type,'slide')
                    app.ImageLevelLabelsButton.Value = 1;
                    app.SlideLevelLabelsButton.Value = 0;

                    app.Labels = app.main_app.structure_labels;
                else
                    app.Labels = app.main_app.slide_labels;
                end

                if strcmp(unique(app.Full_Feature_set.(structures{1}).Class),'multi')
                    Multi_Label_Process(app)
                else
                    Single_Label_Process(app)
                end
                
                % Update this for multi-structure
                
                features = fieldnames(app.Aligned_Labels.(structures{1}));
                for i = 1:length(features)
                    if ~isempty(features{i}) && ~strcmp(features{i},'')
                        N = uitreenode(app.Tree);
                        N.Text = features{i};
                        
                        col_data = app.Aligned_Labels.(structures{1}).(features{i}).Class;
                        if ~isnumeric(col_data)
                            app.Tree_Labels.(features{i}).IsNumeric = false;
    
                            sub_class = unique(col_data);
                            app.Tree_Labels.(features{i}).Sub_Class = sub_class;
    
                            for j = 1:length(sub_class)
                                if ~isempty(sub_class{j}) && ~strcmp(sub_class{j},'')
                                    sN = uitreenode(N);
                                    sN.Text = sub_class{j};
                                else
                                    app.Tree_Labels.(features{i}).Sub_Class(j) = [];
                                end
                            end
                        else
        
                            app.Tree_Labels.(features{i}).IsNumeric = true;
                            
                            sN = uitreenode(N);
                            sN.Text = 'Quantitative Continuous';
                            ssN = uitreenode(N);
                            ssN.Text = 'Quantitative Categorical';
                        end
                    end
                end
            end
                        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.HistoLens_FeatureRankingUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {621, 621};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {306, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            
            % Reading in labels file 
            [filename, path] = uigetfile('*');
            
            if ~filename==0
                labels_file = strcat(path,filesep,filename);
                app.Labels = readtable(labels_file,"ReadVariableNames",true,'VariableNamingRule','preserve','Delimiter',',');
                
                app.SelectFileButton.Enable = 'off';
                app.SelectFileButton.Text = filename;
                
                app.file_type = 'slide';

                app.LabelTypeButtonGroup.Enable = 'on';
                app.SlideLevelLabelsButton.Enable = 'on';
                app.ImageLevelLabelsButton.Enable = 'on';

                app.LabelColumnDropDown.Enable = 'on';
                app.ClassLabelColumnDropDown.Enable = 'on';
                app.HasmultiplelabelsCheckBox.Enable = 'on';

                app.LabelColumnDropDown.Items = app.Labels.Properties.VariableNames;
                app.ClassLabelColumnDropDown.Items = app.Labels.Properties.VariableNames;
                
                app.file_label_col = app.LabelColumnDropDown.Value;

            else
                app.SelectFileButton.Enable = 'on';
            end
                        
        end

        % Value changed function: LabelColumnDropDown
        function LabelColumnDropDownValueChanged(app, event)
            value = app.LabelColumnDropDown.Value;
            app.file_label_col = value;
            
            app.ClassLabelColumnDropDown.Items = app.Labels.Properties.VariableNames;
            label_idx = find(strcmp(app.ClassLabelColumnDropDown.Items,value));
            if ~isempty(label_idx)                    
                app.ClassLabelColumnDropDown.Items(label_idx) = [];
            end
            
            % Filling in the multiple columns
            if app.HasmultiplelabelsCheckBox.Value == 1
                
                Multi_Label_Process(app)

                app.Label_2.Visible = 'on';
                app.Label_2.Enable = 'on';
                app.Tree.Enable = 'on';
                app.Tree.Visible = 'on';
                
                app.ClassLabelColumnDropDown.Enable = 'off';
                
                column_vals = app.Labels.Properties.VariableNames(~strcmp(app.Labels.Properties.VariableNames,value));
                for col = 1:length(column_vals)
                    N = uitreenode(app.Tree);
                    N.Text = column_vals{col};
                    
                    if ~isnumeric(app.Labels.(column_vals{col}))

                        app.Tree_Labels.(column_vals{col}).IsNumeric = false;

                        % Adding sub nodes for each possible value of this
                        % particular column
                        sub_class = unique(app.Labels.(column_vals{col}));
                        app.Tree_Labels.(column_vals{col}).Sub_Class = sub_class;

                        for sub_cl = 1:length(sub_class)
                            sN = uitreenode(N);
                            sN.Text = sub_class{sub_cl};
                        end
                    else

                        app.Tree_Labels.(column_vals{col}).IsNumeric = true;

                        % Treating a quantitative variable as either a
                        % continuous or categorical variable
                        sN = uitreenode(N);
                        sN.Text = 'Quantitative Continuous';
                        ssN = uitreenode(N);
                        ssN.Text = 'Quantitative Categorical';
                        
                    end
                end
            else
                if ~strcmp(app.ClassLabelColumnDropDown.Value,'Select One')
                    
                    Single_Label_Process(app)

                    N = uitreenode(app.Tree);
                    N.Text = app.ClassLabelColumnDropDown.Value;
                    if ~isnumeric(app.Labels.(app.ClassLabelColumnDropDown.Value))

                        app.Tree_Labels.(app.ClassLabelColumnDropDown.Value).IsNumeric = false;

                        sub_class = unique(app.Labels.(app.ClassLabelColumnDropDown.Value));
                        app.Tree_Labels.(app.ClassLabelColumnDropDown.Value).Sub_Class = sub_class;

                        for sub_cl = 1:length(sub_class)
                            sN = uitreenode(N);
                            sN.Text = sub_class{sub_cl};
                        end
                    else

                        app.Tree_Labels.(app.ClassLabelColumnDropDown.Value).IsNumeric = true;

                        sN = uitreenode(N);
                        sN.Text = 'Quantitative Continuous';
                        ssN = uitreenode(N);
                        ssN.Text = 'Quantitative Categorical';
                    end
                    
                    
                end
                
            end
                        
        end

        % Value changed function: ClassLabelColumnDropDown
        function ClassLabelColumnDropDownValueChanged(app, event)
            value = app.ClassLabelColumnDropDown.Value;
            
            app.LabelColumnDropDown.Items = app.Labels.Properties.VariableNames;
            label_idx = find(strcmp(app.LabelColumnDropDown.Items,value));
            if ~isempty(label_idx)
                app.LabelColumnDropDown.Items(label_idx) = [];
            end
            
            if ~strcmp(app.LabelColumnDropDown.Value,'Select One')
                
                Single_Label_Process(app)

                app.Label_2.Visible = 'on';
                app.Label_2.Enable = 'on';
                app.Tree.Enable = 'on';
                app.Tree.Visible = 'on';
                
                N = uitreenode(app.Tree);
                N.Text = app.ClassLabelColumnDropDown.Value;
                if ~isnumeric(app.Labels.(app.ClassLabelColumnDropDown.Value))
                    
                    app.Tree_Labels.(app.ClassLabelColumnDropDown.Value).IsNumeric = false;

                    sub_class = unique(app.Labels.(app.ClassLabelColumnDropDown.Value));
                    app.Tree_Labels.(app.ClassLabelColumnDropDown.Value).Sub_Class = sub_class;

                    for sub_cl = 1:length(sub_class)
                        sN = uitreenode(N);
                        sN.Text = sub_class{sub_cl};
                    end
                else

                    app.Tree_Labels.(app.ClassLabelColumnDropDown.Value).IsNumeric = true;

                    sN = uitreenode(N);
                    sN.Text = 'Quantitative Continuous';
                    ssN = uitreenode(N);
                    ssN.Text = 'Quantitative Categorical';
                end
            end
        end

        % Value changed function: HasmultiplelabelsCheckBox
        function HasmultiplelabelsCheckBoxValueChanged(app, event)
            value = app.HasmultiplelabelsCheckBox.Value;
            
            if value==1
                
                app.ClassLabelColumnDropDown.Enable = 'off';
                if ~strcmp(app.LabelColumnDropDown.Value,'Select One')
                                    
                    Multi_Label_Process(app)

                    app.Label_2.Visible = 'on';
                    app.Label_2.Enable = 'on';
                    app.Tree.Enable = 'on';
                    app.Tree.Visible = 'on';
                    
                    app.ClassLabelColumnDropDown.Enable = 'off';
                    column_vals = app.Labels.Properties.VariableNames(~strcmp(app.Labels.Properties.VariableNames,value));
                    for col = 1:length(column_vals)
                        N = uitreenode(app.Tree);
                        N.Text = column_vals{col};
                        
                        if ~isnumeric(app.Labels.(column_vals{col}))
                            % Adding sub nodes for each possible value of this
                            % particular column
                            app.Tree_Labels.(column_vals{col}).IsNumeric = false;
                            sub_class = unique(app.Labels.(column_vals{col}));
                            app.Tree_Labels.(column_vals{col}).Sub_Class = sub_class;

                            for sub_cl = 1:length(sub_class)
                                if ~isempty(sub_class{sub_cl}) && ~strcmp(sub_class{sub_cl},'')
                                    sN = uitreenode(N);
                                    sN.Text = sub_class{sub_cl};
                                else
                                    app.Tree_Labels.(column_vals{col}).Sub_Class(sub_cl) = [];
                                end

                            end
                        else
                            % Treating a quantitative variable as either a
                            % continuous or categorical variable
                            app.Tree_Labels.(column_vals{col}).IsNumeric = true;

                            sN = uitreenode(N);
                            sN.Text = 'Quantitative Continuous';
                            ssN = uitreenode(N);
                            sN.Text = 'Quantitative Categorical';
                            
                        end
                        
                    end
                                        
                end
                
                
            end
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            
            checked_text = cell(length(checkedNodes),1);
            for i = 1:length(checkedNodes)
                checked_text{i} = checkedNodes(i).Text;
            end
            
            % Comparing with columns of label table
            checked_columns = intersect(app.Labels.Properties.VariableNames,checked_text);
            
            % Checking for included features and correcting for
            % quantitative features that are double selected
            Check_The_Checked(app)
            
            if ~length(checkedNodes)==0
                app.GoButton.Enable = 'on';
                app.FeatureRankingMethodButtonGroup.Enable = 'on';
                app.MRMRButton.Enable = 'on';
                app.ChiSquareButton.Enable = 'on';
                app.ReliefFButton.Enable = 'on';
            end
            
        end

        % Close request function: HistoLens_FeatureRankingUIFigure
        function HistoLens_FeatureRankingUIFigureCloseRequest(app, event)
            
            main_app = app.main_app;
            delete(app)
            main_app.FeatureRankingsFilescsvButton.Enable = 'on';
            main_app.PerformFeatureRankingButton.Enable = 'on';
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            app.CancelButton.Enable = 'on';
            app.GoButton.Enable = 'off';
            
            % Generating feature ranks for each selected feature and class
            include_class = fieldnames(app.Include_Class);
            app.UITable.Data = [include_class,cell(length(include_class),1),...
                cell(length(include_class),1),cell(length(include_class),1)];
            
            all_structures = fieldnames(app.Full_Feature_set);
            while ~app.Cancel_Pushed
                % Iterating through structures
                for s = 1:length(all_structures)

                    current_structure = all_structures{s};
                    structure_feature_set = app.Full_Feature_set.(current_structure);

                    for i = 1:length(include_class)
                        if ~app.Tree_Labels.(include_class{i}).IsNumeric
                            
                            app.UITable.Data(i,2) = {strjoin(app.Include_Class.(include_class{i}).Sub_Classes,',')};
                            app.UITable.Data(i,3) = {'0%'};
                                
                            % For binary features
                            if length(app.Include_Class.(include_class{i}).Sub_Classes)<=2
                                
                                if ismember('Class',structure_feature_set.Properties.VariableNames)
                                    label_table = innerjoin(structure_feature_set(:,1:end-1),app.Aligned_Labels.(current_structure).(include_class{i}));
                                    label_table = label_table(find(ismember(label_table.Class,app.Include_Class.(include_class{i}).Sub_Classes)),:);
                                else
                                    label_table = innerjoin(structure_feature_set,app.Aligned_Labels.(current_structure).(include_class{i}));
                                    label_table = label_table(find(ismember(label_table.Class,app.Include_Class.(include_class{i}).Sub_Classes)),:);
                                end
                                
                                if ~isempty(label_table)
                                    save_path = strcat(app.Feat_Rank_File.(current_structure),filesep,include_class{i},'.csv');
                                    app.Feat_Rank.(current_structure).(include_class{i}) = Feature_Ranking(label_table,app.RankMethod,save_path);
                                end
                                app.UITable.Data(i,3) = {'100%'};
                            else
                                for j = 1:length(app.Include_Class.(include_class{i}).Sub_Classes)
                                    feat_to_rank = app.Include_Class.(include_class{i}).Sub_Classes{j};
    
                                    class_table = app.Aligned_Labels.(current_structure).(include_class{i});
                                    
                                    class_table.Class(~strcmp(class_table.Class,feat_to_rank)) = {'NotFeat'};
                                    
                                    if ismember('Class',structure_feature_set.Properties.VariableNames)
                                        label_table = innerjoin(structure_feature_set(:,1:end-1),class_table);
                                    else
                                        label_table = innerjoin(structure_feature_set,class_table);
                                    end    
                                    
                                    if ~isempty(label_table)
    
                                        save_path = strcat(app.Feat_Rank_File.(current_structure),filesep,include_class{i},'_',feat_to_rank,'.csv');
                                        app.Feat_Rank.(current_structure).(strrep(strcat(include_class{i},'_',feat_to_rank),' ','_')) = Feature_Ranking(label_table,app.RankMethod,save_path);
                                    end
        
                                    app.UITable.Data(i,3) = {strcat(num2str(round(100*(j/length(app.Include_Class.(include_class{i}).Sub_Classes)),2)),'%')};
                                        
                                end
                            end
                        else
                            % For quantitative features, either continuous or
                            % categorical
                            if strcmp(app.Include_Class.(include_class{i}).Quantitative,'Quantitative Continuous')
                                if ismember('Class',structure_feature_set.Properties.VariableNames)
                                    label_table = innerjoin(structure_feature_set(:,1:end-1),app.Aligned_Labels.(current_structure).(include_class{i}));
                                else
                                    label_table = innerjoin(structure_feature_set,app.Aligned_Labels.(current_structure).(include_class{i}));
                                end       
                                
                                if ~isempty(label_table)    
                                    save_path = strcat(app.Feat_Rank_File.(current_structure),filesep,include_class{i},'.csv');
                                    app.Feat_Rank.(include_class{i}) = Feature_Ranking(label_table,app.RankMethod,save_path);
                                end
                                
                                app.UITable.Data(i,3) = {'100%'};
                            else
                                % Treating quantitative labels as categorical
                                class_table = app.Aligned_Labels.(current_structure).(include_class{i});
                                class_table{:,2}= string(class_table{:,2});
                                
                                unique_vals = unique(class_table.Class);
                                for j = 1:length(unique_vals)
                                    feat_to_rank = unique_vals{j};
                                    new_table = class_table;
                                    new_table.Class(~strcmp(new_table.Class,feat_to_rank)) = 'NotVal';
                                    
                                    if ismember('Class',structure_feature_set.Properties.VariableNames)
                                        label_table = innerjoin(structure_feature_set(:,1:end-1),new_table);
                                    else
                                        label_table = innerjoin(structure_feature_set,new_table);
                                    end
                                    
                                    if ~isempty(label_table)
                                        save_path = strcat(app.Feat_Rank_File.(current_structure),filesep,include_class{i},'.csv');
                                        app.Feat_Rank.(strcat(include_class{i},'_',feat_to_rank)) = Feature_Ranking(label_table,app.RankMethod,save_path);
                                    end
                                    
                                    app.UITable.Data(i,3) = {strcat(num2str(round(100*(j/length(unique_vals)),2)),'%')};
    
                                end
                            end
                        end
                        drawnow
                    end
                end
                % If it got this far without the cancel button being pushed
                % then break out
                break
            end

            Ranks_to_Experiment_File(app,app.main_app)

            app.CancelButton.Enable = 'off';
            app.ReturntoFileInputWindowButton.Enable = 'on';

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            app.Cancel_Pushed = true;
            app.ReturntoFileInputWindowButton.Enable = 'on';

        end

        % Button pushed function: ReturntoFileInputWindowButton
        function ReturntoFileInputWindowButtonPushed(app, event)
            
            main_app = app.main_app;
            main_app.Feat_Rank = app.Feat_Rank;

            main_app.PerformFeatureRankingButton.Enable = 'off';
            main_app.FeatureRankingsFilescsvButton.Enable = 'off';
            delete(app)

        end

        % Selection changed function: LabelTypeButtonGroup
        function LabelTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.LabelTypeButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Slide-Level Labels')
                app.file_type = 'slide';
            else
                app.file_type = 'structure';
            end

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Information symbol about feature ranking methods
            f = msgbox({'ReliefF: Feature scoring based on the identification of feature value differences between nearest neighbor instance pairs (default = 10).  High differences in feature values of neighbors of the same class decrease feature score.';...
                'Chi-Square: Calculates the chi-square statistics between every feature and a target variable.  Features that are identified to be dependent on target variable are determined to be more important than features that are independent of the target.';...
                'MRMR (Minimum Redundancy Maximum Relevancy): Find features that are included in the minimal-optimal subset of features with the maximum possible predictive power.  Discards features that are similar in relevance but highly correlated (redundancy)';...
                '*Recommend Chi-Square for identifying features that are statistically significant on an individual basis*'});

        end

        % Selection changed function: FeatureRankingMethodButtonGroup
        function FeatureRankingMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.FeatureRankingMethodButtonGroup.SelectedObject;
            app.RankMethod = selectedButton.Text;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_FeatureRankingUIFigure = uifigure('Visible', 'off');
            app.HistoLens_FeatureRankingUIFigure.Name = 'HistoLens_FeatureRanking';
            app.HistoLens_FeatureRankingUIFigure.Icon = 'Logo.png';
            app.HistoLens_FeatureRankingUIFigure.CloseRequestFcn = createCallbackFcn(app, @HistoLens_FeatureRankingUIFigureCloseRequest, true);
            app.HistoLens_FeatureRankingUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.HistoLens_FeatureRankingUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FeatureRankingWindowLabel = uilabel(app.LeftPanel);
            app.FeatureRankingWindowLabel.Text = 'Feature Ranking Window';
            app.SelectFileButton = uibutton(app.LeftPanel, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select File';
            app.LoadSlidelevelorStructurelevellabelsLabel = uilabel(app.LeftPanel);
            app.LoadSlidelevelorStructurelevellabelsLabel.Text = '1. Load Slide-level or Structure-level labels';
            app.Tree = uitree(app.LeftPanel, 'checkbox');
            app.Tree.Enable = 'off';
            app.Tree.Visible = 'off';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.GoButton = uibutton(app.LeftPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Enable = 'off';
            app.GoButton.Text = 'Go';
            app.HasmultiplelabelsCheckBox = uicheckbox(app.LeftPanel);
            app.HasmultiplelabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HasmultiplelabelsCheckBoxValueChanged, true);
            app.HasmultiplelabelsCheckBox.Enable = 'off';
            app.HasmultiplelabelsCheckBox.Text = 'Has multiple labels';
            app.ClassLabelColumnDropDownLabel = uilabel(app.LeftPanel);
            app.ClassLabelColumnDropDownLabel.Enable = 'off';
            app.ClassLabelColumnDropDownLabel.Text = 'Class Label Column';
            app.ClassLabelColumnDropDown = uidropdown(app.LeftPanel);
            app.ClassLabelColumnDropDown.Items = {};
            app.ClassLabelColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassLabelColumnDropDownValueChanged, true);
            app.ClassLabelColumnDropDown.Enable = 'off';
            app.ClassLabelColumnDropDown.Placeholder = 'Select One';
            app.ClassLabelColumnDropDown.Value = {};
            app.ImageLabelColumnDropDownLabel = uilabel(app.LeftPanel);
            app.ImageLabelColumnDropDownLabel.Enable = 'off';
            app.ImageLabelColumnDropDownLabel.Text = 'Label Column';
            app.LabelColumnDropDown = uidropdown(app.LeftPanel);
            app.LabelColumnDropDown.Items = {};
            app.LabelColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @LabelColumnDropDownValueChanged, true);
            app.LabelColumnDropDown.Enable = 'off';
            app.LabelColumnDropDown.Placeholder = 'Select One';
            app.LabelColumnDropDown.Value = {};
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '2. Select Image/Slide Label Column and Class Label Column.  If you want to perform feature ranking with respect to multiple features, press "Has multiple labels"';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Enable = 'off';
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '3. Select Specific Columns to perform feature ranking on.  If you want to exclude specific classes (for columns with more than two possible values) click the classes you want to include.  Otherwise all will be included';
            app.LabelTypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.LabelTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LabelTypeButtonGroupSelectionChanged, true);
            app.LabelTypeButtonGroup.Enable = 'off';
            app.LabelTypeButtonGroup.Title = 'Label Type';
            app.SlideLevelLabelsButton = uiradiobutton(app.LabelTypeButtonGroup);
            app.SlideLevelLabelsButton.Enable = 'off';
            app.SlideLevelLabelsButton.Text = 'Slide-Level Labels';
            app.SlideLevelLabelsButton.Value = true;
            app.ImageLevelLabelsButton = uiradiobutton(app.LabelTypeButtonGroup);
            app.ImageLevelLabelsButton.Enable = 'off';
            app.ImageLevelLabelsButton.Text = 'Image-Level Labels';
            app.FeatureRankingMethodButtonGroup = uibuttongroup(app.LeftPanel);
            app.FeatureRankingMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FeatureRankingMethodButtonGroupSelectionChanged, true);
            app.FeatureRankingMethodButtonGroup.Enable = 'off';
            app.FeatureRankingMethodButtonGroup.Title = 'Feature Ranking Method';
            app.ReliefFButton = uitogglebutton(app.FeatureRankingMethodButtonGroup);
            app.ReliefFButton.Enable = 'off';
            app.ReliefFButton.Text = 'ReliefF';
            app.ReliefFButton.Value = true;
            app.ChiSquareButton = uitogglebutton(app.FeatureRankingMethodButtonGroup);
            app.ChiSquareButton.Enable = 'off';
            app.ChiSquareButton.Text = 'Chi-Square';
            app.MRMRButton = uitogglebutton(app.FeatureRankingMethodButtonGroup);
            app.MRMRButton.Enable = 'off';
            app.MRMRButton.Text = 'MRMR';
            app.Image = uiimage(app.FeatureRankingMethodButtonGroup);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'info_symbol.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.ReturntoFileInputWindowButton = uibutton(app.RightPanel, 'push');
            app.ReturntoFileInputWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoFileInputWindowButtonPushed, true);
            app.ReturntoFileInputWindowButton.Enable = 'off';
            app.ReturntoFileInputWindowButton.Text = 'Return to File Input Window';
            app.CancelButton = uibutton(app.RightPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Enable = 'off';
            app.CancelButton.Text = 'Cancel';
            app.TostopfeaturerankingatanytimepressCancelbelowLabel = uilabel(app.RightPanel);
            app.TostopfeaturerankingatanytimepressCancelbelowLabel.Text = 'To stop feature ranking at any time, press Cancel below.';
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Label Column'; 'Possible Values'; 'Progress'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.HistoLens_FeatureRankingUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_FeatureRanking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_FeatureRankingUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_FeatureRankingUIFigure)
        end
    end
end