classdef HistoLens_File_Input < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLens_FilesUIFigure        matlab.ui.Figure
        PerformFeatureRankingButton    matlab.ui.control.Button
        SelectFolderofSlidesButton     matlab.ui.control.Button
        SelectTypeofInputButtonGroup   matlab.ui.container.ButtonGroup
        HistoLensExperimentFileButton  matlab.ui.control.ToggleButton
        WSIsandAnnotationFilesButton   matlab.ui.control.ToggleButton
        MicronsPerPixelMPPifunknownleaveatzeroEditField  matlab.ui.control.NumericEditField
        MicronsPerPixelMPPifunknownleaveatzeroEditFieldLabel  matlab.ui.control.Label
        NotesFileLabel                 matlab.ui.control.Label
        ExistingNotesFilecsvButton     matlab.ui.control.Button
        LabelsFormatButtonGroup        matlab.ui.container.ButtonGroup
        FeatureSetImageLabelColumnEditField_2  matlab.ui.control.EditField
        FeatureSetImageLabelColumnEditField_2Label  matlab.ui.control.Label
        FeatureSetImageLabelColumnEditField  matlab.ui.control.EditField
        FeatureSetImageLabelColumnEditFieldLabel  matlab.ui.control.Label
        HasmultiplelabelsCheckBox_2    matlab.ui.control.CheckBox
        HasmultiplelabelsCheckBox      matlab.ui.control.CheckBox
        SlideLabelColumnDropDown       matlab.ui.control.DropDown
        SlideLabelColumnDropDownLabel  matlab.ui.control.Label
        ImageLabelColumnDropDown       matlab.ui.control.DropDown
        ImageLabelColumnDropDownLabel  matlab.ui.control.Label
        ClassLabelColumnDropDown_2     matlab.ui.control.DropDown
        ClassLabelColumnDropDown_2Label  matlab.ui.control.Label
        ClassLabelColumnDropDown       matlab.ui.control.DropDown
        ClassLabelColumnDropDownLabel  matlab.ui.control.Label
        SlidelevellabelsButton         matlab.ui.control.Button
        StructurelevellabelsButton     matlab.ui.control.Button
        AttachingseparatefilecontainingslidelevellabelsButton  matlab.ui.control.RadioButton
        AttachingseparatefilecontainingstructurelevellabelsButton  matlab.ui.control.RadioButton
        NumberofStructuresLabel        matlab.ui.control.Label
        NumberofSlidesLabel            matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        FeatureRankingsFileLabel       matlab.ui.control.Label
        FeatureSetFileLabel            matlab.ui.control.Label
        SlideDirectoryLabel            matlab.ui.control.Label
        FeatureRankingsFilescsvButton  matlab.ui.control.Button
        StructureTypeDropDown          matlab.ui.control.DropDown
        StructureTypeDropDownLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_app % Storing initial app
        msg_output % Warning output after pushing Done Button
        wait_for_structure = false % Used to read in image paths with specified structure
        multi_slide = false % After structure whether or not there are multiple slides in the directory
    end
    
    properties (Access = public)
        base_Feature_set % un-editable feature set
        
        HistoLens_CompartmentSegmentation
        HistoLens_Extract
        HistoLens_FeatureExtraction
        HistoLens_FeatureRanking
        
        Structure_Names
        CatchNRelease
        Input_Type = 'NewExtract';
        Experiment_File
        
        structure_labels % input class labels either from feature set or from separate input file
        slide_labels
        file_type
        file_label_col
        class_label_col
        label_file
       
        Full_Feature_set % input features set
        Slide_Path % base directory of raw data
        Slide_Names
        Slide_Num % Number of slides
        Structure % Structure within dataset
        Feat_Rank % Rank of each feature
        Feat_Set_File % Filename of feature set
        Feat_Rank_File % Filename of feature rankings
        Notes_File % Filename of Notes file
        Notes % Notes table for images in dataset
        MPP % Microns per Pixel value
        structure_info
        structure_idx
        Seg_Params

        % Adding parameters for AdvancedCompartmentSegmentation and
        % Multi-Stain Registration
        Stain_Table
        Registration_Transforms
        

    end
    
    methods (Access = private)
        
        function Process_SlideLevel(app,slide_names,slide_labels)
            
            % Removing WSI file ending if present                
            if iscell(slide_names)
                if any(contains(slide_names,'.'))
                    slide_names = cellfun(@(x)strsplit(x,'.'), slide_names);
                    slide_names = cellfun(@(x)x{1},slide_names);
                end
            else
                % For numerical file names
                new_slide_names = cell(length(slide_names),1);
                for j = 1:length(slide_names)
                    new_slide_names(j,1) = {num2str(slide_names(j))};
                end
                slide_names = new_slide_names;
            end
            labels = cell(1,2);
            include_row = zeros(height(app.Full_Feature_set),1);
            for i = 1:height(app.Full_Feature_set)
                img = app.Full_Feature_set.ImgLabel{i};
                
                slide = strsplit(img,'_');
                if length(slide)>1
                    slide = strjoin(slide(1:end-1),'_');
                end
                
                if any(contains(slide_names,'-'))
                    slide = strjoin(slide,'_');
                end
                
                label = slide_labels{find(strcmp(slide,slide_names))};
                if ~isempty(label)
                    labels{i,1} = img;
                    if iscell(label)
                        labels{i,2} = label;
                    else
                        if ~isnan(label)
                            labels{i,2} = num2str(label);
                        else
                            labels{i,2} = nan;
                        end
                    end
                    include_row(i) = 1;
                else
                    include_row(i) = 0;
                end
            
            end

            app.Full_Feature_set = app.Full_Feature_set(find(include_row),:);
            app.Full_Feature_set.ImgLabel = labels(:,1);
            app.Full_Feature_set.Class = labels(:,2);
            
            Labels_to_Experiment_File(app)
            
        end
        
        function Process_StructureLevel(app,structure_names,structure_labels)
            
            % Getting filenames from og_img_paths
%             og_filenames = cellfun(@(x)strsplit(x,filesep),app.og_img_paths,'UniformOutput',false);
%             og_filenames = cellfun(@(x)x{end},og_filenames,'UniformOutput',false);
%             
            og_filenames = app.Full_Feature_set.ImgLabel;
            labels = cell(1,2);
            include_row = zeros(length(og_filenames),1);
            for i = 1:length(og_filenames)
                label = structure_labels(find(strcmp(og_filenames{i},structure_names)));
                
                if ~isempty(label)
                    labels{i,1} = og_filenames(i);
                    if iscell(label)
                        labels{i,2} = label;
                    else
                        if ~isnan(label)
                            labels{i,2} = num2str(label);
                        else
                            labels{i,2} = nan;
                        end
                    end
                    include_row(i) = 1;
                else
                    include_row(i) = 0;
                end
                
            end
            
            app.Full_Feature_set = app.Full_Feature_set(find(include_row),:);
            app.Full_Feature_set.ImgLabel = labels(:,1);
            app.Full_Feature_set.Class = labels(:,2);
            
            Labels_to_Experiment_File(app)
            
        end
        
    end
    
    methods (Access = public)
        
        function Get_Structure_Names(app,dlg_app)
            
            structure_list = dlg_app.AvailableAnnotationIDsListBox.Items;
            structure_names = cellfun(@(x)strsplit(x,'_'),structure_list,'UniformOutput',false);
            
            %if ~isnan(dlg_app.MPP)
            %    app.MPP = dlg_app.MPP;
            %end
            
            % Comma separated list if multiple are grouped under a single
            % label
            annotation_ids = cellfun(@(x)x{end},structure_names,'UniformOutput',false);
            annotation_ids = cellfun(@(x)strsplit(x,','),annotation_ids,'UniformOutput',false);
            structure_names = cellfun(@(x)x{1},structure_names,'UniformOutput',false);
            
            id_count = 1;
            for i = structure_names
                %if length(annotation_ids(id_count))==1
                ids = annotation_ids(id_count);
                %ids = ids{1};
                if iscell(ids) && ischar(ids{1})
                    app.Structure_Names.(i{1}).Annotation_ID = str2double(ids);
                    id_count = id_count+1;
                else
                    app.Structure_Names.(i{1}).Annotation_ID = str2double(ids{1});
                    id_count = id_count+1;
                end
                %else
                %    ids = annotation_ids(id_count)
                %end
            end
            %dlg_app.Structure_Names = app.Structure_Names;
            
            delete(dlg_app)
            
        end
        
        function Get_Seg_Params(app,dlg_app)
            app.Seg_Params = dlg_app.Seg_Params;
            assignin('base','seg_params_file_input',app.Seg_Params)
            
        end
        
        function Get_Transforms(app,dlg_app)
            app.Registration_Transforms = dlg_app.Saved_Transforms;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            
        end

        % Selection changed function: LabelsFormatButtonGroup
        function LabelsFormatButtonGroupSelectionChanged(app, event)
            selectedButton = app.LabelsFormatButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'The Feature Set File Contains structure-level labels')
                app.ClassLabelColumnEditField.Enable = 'on';
                app.ImageLabelColumnEditField.Enable = 'on';
                
                app.ClassLabelColumnDropDown.Enable = 'off';
                app.ImageLabelColumnDropDown.Enable = 'off';
                app.ClassLabelColumnDropDown_2.Enable = 'off';
                app.SlideLabelColumnDropDown.Enable = 'off';
            end
            if strcmp(selectedButton.Text,'Attaching separate file containing structure-level labels')
                app.StructurelevellabelsButton.Enable = 'on';
                
                app.ClassLabelColumnEditField.Enable = 'off';
                app.ImageLabelColumnEditField.Enable = 'off';
                app.ClassLabelColumnDropDown_2.Enable = 'off';
                app.SlideLabelColumnDropDown.Enable = 'off';
                
            end
            if strcmp(selectedButton.Text,'Attaching separate file containing slide-level labels')
                app.SlidelevellabelsButton.Enable = 'on';
                
                app.ClassLabelColumnEditField.Enable = 'off';
                app.ImageLabelColumnEditField.Enable = 'off';
                app.ClassLabelColumnDropDown.Enable = 'off';
                app.ImageLabelColumnDropDown.Enable = 'off';
                
            end
        end

        % Value changed function: StructureTypeDropDown
        function StructureTypeDropDownValueChanged(app, event)
            value = app.StructureTypeDropDown.Value;
            
            if ~strcmp(value,'Select One')
                app.Structure = value;
                
                if ~app.CatchNRelease

                    if app.wait_for_structure
                       if ~app.multi_slide
                            app.og_img_paths = dir(fullfile(app.Slide_Path,filesep,app.Structure,filesep,'Images',filesep));
                            app.og_img_paths = app.og_img_paths(~ismember({app.og_img_paths.name},{'.','..'}));
                                
                            app.base_img_paths = app.og_img_paths;
                            
                            app.og_mask_paths = dir(fullfile(app.Slide_Path,filesep,app.Structure,filesep,'CompartmentSegmentations',filesep));
                            app.og_mask_paths = app.og_mask_paths(~ismember({app.og_mask_paths.name},{'.','..'}));
                            
                            app.NumberofSlidesLabel.Text = 'Number of Slides: 1';
                            app.Slide_Num = 1;
                            app.NumberofStructuresLabel.Text = strcat('Number of Structures: ',length(app.og_img_paths));
                       else
                           
                            dir_contents = dir(app.Slide_Path);
                            dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));                        
                            app.og_img_paths = cell(1);
                            app.og_mask_paths = cell(1);
                            
                            folders = [dir_contents.isdir];
                            
                            app.NumberofSlidesLabel.Text = strcat('Number of Slides: ',string(length(folders)));
                            app.Slide_Num = length(folders);
                            
                            img_wait = waitbar(0,'Reading in Image and Compartment Segmentations');
                            for f = 1:length(folders)
                                waitbar(f/length(folders),img_wait,'Reading in Image and Compartment Segmentations');
                                
                                current_path = fullfile(dir_contents(f).folder,dir_contents(f).name,filesep,app.Structure);
                                                            
                                current_image_dir = dir(fullfile(current_path,'Images',filesep));
                                current_image_dir = current_image_dir(~ismember({current_image_dir.name},{'.','..'}));
                                current_images = cell(1);
                                for i = 1:length(current_image_dir)
                                    current_images{i,1} = strcat(current_image_dir(i).folder,filesep,current_image_dir(i).name);
                                end
                                current_masks = cellfun(@(x)strrep(x,'Images','CompartmentSegmentations'),current_images,'UniformOutput',false);
                                
                                app.og_img_paths = [app.og_img_paths;current_images];
                                app.og_mask_paths = [app.og_mask_paths;current_masks];
                                
                            end
                            
                            app.og_img_paths = app.og_img_paths(~cellfun(@isempty,app.og_img_paths));
                            app.og_mask_paths = app.og_mask_paths(~cellfun(@isempty,app.og_mask_paths));
                            
                            app.base_img_paths = app.og_img_paths;
                            
                            waitbar(1,img_wait,'Finished!')
                            pause(0.2)
                            close(img_wait)
                           
                       end
                        
                    end
                else
                    app.Full_Feature_set = readtable(app.structure_info.(app.StructureTypeDropDown.Value).FeatureSet,'VariableNamingRule','preserve');
                
                    app.NumberofStructuresLabel.Text = strcat('Number of Structures:',string(height(app.Full_Feature_set)));
                    app.FeatureSetFileLabel = app.structure_info.(app.StructureTypeDropDown.Value).FeatureSet;
                    app.FeatureSetFilecsvButton.Enable = 'off';
    
                    app.SlidelevellabelsButton.Enable = 'on';
                    app.ExistingNotesFilecsvButton.Enable = 'on';
                    app.DoneButton.Enable = 'on';
    
                    if ~contains(fieldnames(app.structure_info.(app.StructureTypeDropDown.Value)),'FeatureRanks')
                        app.FeatureRankingsFilescsvButton.Enable = 'on';
                    else
                        app.FeatureRankingsFilescsvButton.Enable = 'off';
                        app.Feat_Rank = app.structure_info.(app.StructureTypeDropDown.Value).FeatureRanks;
                    end
                    
                    app.structure_idx = app.structure_info.(app.StructureTypeDropDown.Value).AnnotationID;
                    
                end

            end
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
                
            if isempty(app.Notes_File)
                t = now;
                d = datetime(t,'ConvertFrom','datenum');
                current_time = strrep(strrep(string(d),' ','__'),':','_');
                
                slide_dir = strsplit(app.Slide_Path,filesep);
                slide_dir = slide_dir{end};
                
                default_notes = strcat(pwd,filesep,'HistoLens_Notes_Archive',filesep,...
                    slide_dir,'_',current_time,'.csv');
            else
                default_notes = app.Notes_File;
            end
            
            if isempty(app.structure_idx) & app.CatchNRelease
                app.structure_idx = app.structure_info.(app.StructureTypeDropDown.Value).AnnotationID;
            end
            
            % Check to make sure all the required info is present first
            if ~isempty(app.Full_Feature_set)
                done_criteria = [isempty(app.Structure),...
                    isempty(app.Full_Feature_set),...
                   ~any(strcmp(app.Full_Feature_set.Properties.VariableNames,'ImgLabel')),...
                   ~any(strcmp(app.Full_Feature_set.Properties.VariableNames,'Class')),...
                   isempty(app.Feat_Rank),...
                   isempty(app.Notes_File)];
                
                messages = {'You need to select a Structure Type from the drop down menu';...
                    'Without inputting a Feature Set File you will not be able to select representatives';...
                    'No Image Names Column Name specified';...
                    'No Class Labels specified';...
                    'Input Feature Rankings File to weight feature maps by rank';...
                    strcat('Making notes file at:',default_notes)};
            else
                
                done_criteria = [true,...
                    isempty(app.Structure),...
                    isempty(app.Feat_Rank),...
                    isempty(app.Notes_File)];
                
                messages = {'Without inputting a Feature Set File you will not be able to select representatives';...
                    'You need to select a Structure Type from the drop down menu';...
                    'Input Feature Rankings to weight feature maps according to class importance';...
                    strcat('Making notes file at:',default_notes)};
                
            end
            
            messages = messages(done_criteria);    
            
            if ~isempty(app.Full_Feature_set)
                app.base_Feature_set = app.Full_Feature_set;
            end
            
            if ~isempty(messages)
                app.msg_output = msgbox(messages,'Warning Messages','non-modal','warn');
                
                Process_Inputs(app.main_app, app, event)
                app.main_app.InputFilesButton.Enable = 'on';
                delete(app)

            else
                
                Process_Inputs(app.main_app, app, event)
                app.main_app.InputFilesButton.Enable = 'on';
                
                delete(app)
            end
            
        end

        % Button pushed function: FeatureRankingsFilescsvButton
        function FeatureRankingsFilescsvButtonPushed(app, event)
            
            [filename, path] = uigetfile('*','Select all Feature Ranking Files','MultiSelect','on');
            
            if ~path==0
                
                if iscell(filename)
                    
                    app.FeatureRankingsFileLabel.Text = strjoin(filename,',');
                    
                    filenames = cellfun(@(x)strrep(x,'.csv',''),filename,'UniformOutput',false);
                    
                    app.Feat_Rank_File = strjoin(filenames,',');
                    for j = 1:length(filename)
                        name = strrep(strrep(filename{j},'.csv',''),' ','');
                        app.Feat_Rank.(name) = readtable(strcat(path,filename{j}),'Delimiter',',','ReadVariableNames',true,'VariableNamingRule','preserve');
                    end
                else
                    app.Feat_Rank_File = filename;
                    app.FeatureRankingsFileLabel.Text = filename;
                    name = strrep(filename,'.csv','');
                    app.Feat_Rank.(name) = readtable(strcat(path,filename),'Delimiter',',','ReadVariableNames',true,'ReadRowNames',true,'VariableNamingRule','preserve');
                    
                end
                
                % From File_Input window, use app for both inputs 
                Ranks_to_Experiment_File(app,app)
            end
        end

        % Button pushed function: StructurelevellabelsButton
        function StructurelevellabelsButtonPushed(app, event)
            
            [filename,path] = uigetfile('*','Select Structure level labels');
            
            if ~path==0
                app.label_file = strcat(path,filename);
                app.structure_labels = readtable(strcat(path,filename),'Delimiter',',');
                app.file_type = 'structure';
                
                app.HasmultiplelabelsCheckBox.Enable = 'on';
                app.FeatureSetImageLabelColumnEditField.Enable = 'on';
                
                app.ClassLabelColumnDropDown.Enable = 'on';
                app.ImageLabelColumnDropDown.Enable = 'on';
                app.ClassLabelColumnDropDown.Items = app.structure_labels.Properties.VariableNames;
                app.ImageLabelColumnDropDown.Items = app.structure_labels.Properties.VariableNames;

                
            end
            
        end

        % Button pushed function: SlidelevellabelsButton
        function SlidelevellabelsButtonPushed(app, event)
            
            [filename,path] = uigetfile('*');
            
            if ~path==0
                app.label_file = strcat(path,filename);
                app.file_type = 'slide';
                app.slide_labels = readtable(strcat(path,filename),"VariableNamingRule",'preserve',"Delimiter",',');
                
                app.SlidelevellabelsButton.Text = filename;
                app.SlidelevellabelsButton.Enable = 'off';
                app.HasmultiplelabelsCheckBox_2.Enable = 'on';
                app.FeatureSetImageLabelColumnEditField.Enable = 'on';

                
                app.ClassLabelColumnDropDown_2.Enable = 'on';
                app.SlideLabelColumnDropDown.Enable = 'on';
                app.ClassLabelColumnDropDown_2.Items = ['Select One',app.slide_labels.Properties.VariableNames];
                app.SlideLabelColumnDropDown.Items = ['Select One',app.slide_labels.Properties.VariableNames];

            end
        end

        % Close request function: HistoLens_FilesUIFigure
        function HistoLens_FilesUIFigureCloseRequest(app, event)
            
            try
                app.main_app.InputFilesButton.Enable = 'on';
            end
            delete(app.HistoLens_CompartmentSegmentation)
            delete(app)
            
        end

        % Button pushed function: ExistingNotesFilecsvButton
        function ExistingNotesFilecsvButtonPushed(app, event)
            
            [filename, path] = uigetfile('*');
            
            if ~path==0
                app.Notes_File = filename;
                app.Notes = readtable(strcat(path,filename));
                
            end

        end

        % Value changed function: ClassLabelColumnDropDown
        function ClassLabelColumnDropDownValueChanged(app, event)
            value = app.ClassLabelColumnDropDown.Value;
            
            if ~isempty(value)
                if ~isempty(app.Full_Feature_set)
                    if ~isempty(app.ImageLabelColumnDropDown.Value)
                        if any(ismember(app.Full_Feature_set.Properties.VariableNames,'ImgLabel'))
                            app.file_label_col = app.ImageLabelColumnDropDown.Value;
                            app.class_label_col = value;
                            Process_StructureLevel(app,app.structure_labels.(app.ImageLabelColumnDropDown.Value),app.structure_labels.(value))
                        end
                    end
                end
                
                label_idx = find(ismember(app.ImageLabelColumnDropDown.Items,value));
                if ~isempty(label_idx)
                    app.ImageLabelColumnDropDown.Items(label_idx) = [];
                end
                
            end    
        end

        % Value changed function: ClassLabelColumnDropDown_2
        function ClassLabelColumnDropDown_2ValueChanged(app, event)
            value = app.ClassLabelColumnDropDown_2.Value;
            
            if ~isempty(value)
                app.class_label_col = value;
                
                if ~isempty(app.Full_Feature_set)
                    if ~isempty(app.slide_labels)
                        if ~strcmp(app.ClassLabelColumnDropDown_2.Value, 'Select One') & ~strcmp(app.SlideLabelColumnDropDown.Value,'Select One')
                            if any(ismember(app.Full_Feature_set.Properties.VariableNames,'ImgLabel'))
                                if isempty(app.file_label_col)
                                    app.file_label_col = app.SlideLabelColumnDropDown.Value;
                                end
                                Process_SlideLevel(app,app.slide_labels.(app.SlideLabelColumnDropDown.Value),app.slide_labels.(app.ClassLabelColumnDropDown_2.Value));
                            end
                        end
                    end
                        
                end
                
                label_idx = find(strcmp(app.SlideLabelColumnDropDown.Items,value));
                if ~isempty(label_idx)
                    app.SlideLabelColumnDropDown.Items(label_idx) = [];
                end
            end           
            
        end

        % Value changed function: ImageLabelColumnDropDown
        function ImageLabelColumnDropDownValueChanged(app, event)
            value = app.ImageLabelColumnDropDown.Value;
            
            % Resetting Image Label column name in feature set
            if ~isempty(value)    
                if ~isempty(app.Full_Feature_set)        
                    if ~isempty(app.ClassLabelColumnDropDown.Value)
                        if any(ismember(app.Full_Feature_set.Properties.VariableNames,'ImgLabel'))
                            app.file_label_col = value;
                            
                            if isempty(app.class_label_col)
                                app.class_label_col = app.ClassLabelColumnDropDown.Value;
                            end
                            
                            Process_StructureLevel(app,app.structure_labels.(app.ImageLabelColumnDropDown.Value),app.structure_labels.(app.ClassLabelColumnDropDown.Value))
                        end
                    end
                end
                
                label_idx = find(strcmp(app.ClassLabelColumnDropDown.Items,value));
                if ~isempty(label_idx)                    
                    app.ClassLabelColumnDropDown.Items(label_idx) = [];
                end
            end     
            
            
        end

        % Value changed function: SlideLabelColumnDropDown
        function SlideLabelColumnDropDownValueChanged(app, event)
            value = app.SlideLabelColumnDropDown.Value;
            
           % Resetting Image Label column name in feature set
            if ~isempty(value)
                app.file_label_col = value;
                if ~isempty(app.Full_Feature_set)
                    if ~isempty(app.slide_labels)
                        if ~strcmp(app.ClassLabelColumnDropDown_2.Value, 'Select One') & ~strcmp(app.SlideLabelColumnDropDown.Value,'Select One')
                            if any(ismember(app.Full_Feature_set.Properties.VariableNames,'ImgLabel'))
                                Process_SlideLevel(app,app.slide_labels.(app.file_label_col),app.slide_labels.(app.class_label_col));
                                
                            end
                        end
                    end
                        
                end
                
                label_idx = find(strcmp(app.ClassLabelColumnDropDown_2.Items,value));
                if ~isempty(label_idx)
                    app.ClassLabelColumnDropDown_2.Items(label_idx) = [];
                end
            end     
        end

        % Value changed function: 
        % MicronsPerPixelMPPifunknownleaveatzeroEditField
        function MicronsPerPixelMPPifunknownleaveatzeroEditFieldValueChanged(app, event)
            value = app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Value;
            
            if ~isempty(value)
                app.MPP = value;
            else
                app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Value = 0;
                
            end

        end

        % Value changed function: HasmultiplelabelsCheckBox
        function HasmultiplelabelsCheckBoxValueChanged(app, event)
            value = app.HasmultiplelabelsCheckBox.Value;
            if value == 1
                app.ClassLabelColumnDropDown.Enable = 'off';
                app.structure_labels = app.structure_labels;
                
                app.Full_Feature_set.Class = repmat({'multi'},size(app.Full_Feature_set,1),1);

            else
                if strcmp(app.ClassLabelColumnDropDown.Enable,'off')
                    app.ClassLabelColumnDropDown.Enable = 'on';
                end
            end
            
        end

        % Value changed function: HasmultiplelabelsCheckBox_2
        function HasmultiplelabelsCheckBox_2ValueChanged(app, event)
            value = app.HasmultiplelabelsCheckBox_2.Value;            
            if value == 1
                app.ClassLabelColumnDropDown_2.Enable = 'off';
                app.class_label_col = 'multi';
                
                app.Full_Feature_set.Class = repmat({'multi'},size(app.Full_Feature_set,1),1);
                
                if ~strcmp(app.SlideLabelColumnDropDown.Value,'Select One') & ~isempty(app.FeatureSetImageLabelColumnEditField)
                    Labels_to_Experiment_File(app)
                end
            else
                if strcmp(app.ClassLabelColumnDropDown_2.Enable,'off')
                    app.ClassLabelColumnDropDown_2.Enable = 'on';
                end
            end
            
            
        end

        % Value changed function: FeatureSetImageLabelColumnEditField_2
        function FeatureSetImageLabelColumnEditField_2ValueChanged(app, event)
            value = app.FeatureSetImageLabelColumnEditField_2.Value;
            
            if ~isempty(value)
                if ~isempty(app.Full_Feature_set)
                    label_idx = find(ismember(app.Full_Feature_set.Properties.VariableNames,value));
                    app.Full_Feature_set.Properties.VariableNames{label_idx} = 'ImgLabel';
                    if isempty(app.file_label_col)
                        app.file_label_col = app.ImageLabelColumnDropDown.Value;
                    end
                    if isempty(app.class_label_col)
                        app.class_label_col = app.ClassLabelColumnDropDown.Value;
                    end
                    
                    if ~strcmp(app.ClassLabelColumnDropDown.Value, 'Select One') & ~strcmp(app.ImageLabelColumnDropDown.Value,'Select One')
                        Process_StructureLevel(app,app.structure_labels.(app.ImageLabelColumnDropDown.Value),app.structure_labels.(app.ClassLabelColumnDropDown.Value))
                    end
                end
            end
          
        end

        % Value changed function: FeatureSetImageLabelColumnEditField
        function FeatureSetImageLabelColumnEditFieldValueChanged(app, event)
            value = app.FeatureSetImageLabelColumnEditField.Value;
            
            if ~isempty(value)
                if ~isempty(app.Full_Feature_set)
                    label_idx = find(ismember(app.Full_Feature_set.Properties.VariableNames,value));
                    app.Full_Feature_set.Properties.VariableNames{label_idx} = 'ImgLabel';
                        
                    if ~isempty(app.slide_labels)
                        if ~strcmp(app.ClassLabelColumnDropDown_2.Value, 'Select One') & ~strcmp(app.SlideLabelColumnDropDown.Value,'Select One')
                            if any(ismember(app.Full_Feature_set.Properties.VariableNames,'ImgLabel'))
                                
                                if isempty(app.file_label_col)
                                    app.file_label_col = app.SlideLabelColumnDropDown.Value;
                                end
                                if isempty(app.class_label_col)
                                    app.class_label_col = app.ClassLabelColumnDropDown_2.Value;
                                end
                                
                                Process_SlideLevel(app,app.slide_labels.(app.file_label_col),app.slide_labels.(app.class_label_col));
                            
                            end
                        end
                    end
                end
            end
            
        end

        % Selection changed function: SelectTypeofInputButtonGroup
        function SelectTypeofInputButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectTypeofInputButtonGroup.SelectedObject.Text;
            butt_idx = find(strcmp({app.SelectTypeofInputButtonGroup.Buttons.Text},selectedButton));

            % To go to compartment segmentation and feature extraction
            if butt_idx==1
                app.SelectFolderofSlidesButton.Text = 'Select Folder of Slide(s)';
                app.Input_Type = 'NewExtract';
            end

            % To load experiment file
            if butt_idx==2
                app.SelectFolderofSlidesButton.Text = 'Select Experiment File';
                app.Input_Type = 'FromExpFile';
            end
            
        end

        % Button pushed function: SelectFolderofSlidesButton
        function SelectFolderofSlidesButtonPushed(app, event)
            
            app.SelectFolderofSlidesButton.Enable = 'off';
            
            % Dependent on type of input. (app.Input_Type)
            if strcmp(app.Input_Type,'FromExpFile')
                [filename,path] = uigetfile('*');
                
                if ~filename==0
                    app.Experiment_File = strcat(path,filesep,filename);
                    app.CatchNRelease = true;
                    
                    % Outputs structure info and app.Slide_Path
                    app.structure_info = Load_Catch_N_Release(app);
                    
                    dir_contents = dir(app.Slide_Path);
                    dir_contents = dir_contents(~ismember({dir_contents.name},{'.','..'}));
                    %app.Slide_Num = length(dir_contents(contains({dir_contents.name},{'.svs','.ndpi'})));
                    app.Slide_Num = length(dir_contents(contains({dir_contents.name},{'.svs'})));
    
                    app.SlideDirectoryLabel.Text = ['Slide Directory: ',app.Slide_Path];
    
                    app.StructureTypeDropDown.Items = fieldnames(app.structure_info);
                    app.StructureTypeDropDown.Value = app.StructureTypeDropDown.Items(1);
                    app.Structure = app.StructureTypeDropDown.Value;
                    
                    app.Full_Feature_set = readtable(app.structure_info.(app.StructureTypeDropDown.Value).FeatureSet,'VariableNamingRule','preserve','Delimiter',',');
                    app.Seg_Params.(app.StructureTypeDropDown.Value) = app.structure_info.(app.StructureTypeDropDown.Value).CompSeg;
                    
                    if ismember('StainNormalization',fieldnames(app.structure_info.(app.StructureTypeDropDown.Value)))
                        app.Seg_Params.(app.StructureTypeDropDown.Value).StainNormalization = app.structure_info.(app.StructureTypeDropDown.Value).StainNormalization;
                    end

                    app.NumberofStructuresLabel.Text = strcat('Number of Structures:',string(height(app.Full_Feature_set)));
                    app.FeatureSetFileLabel.Text = strcat('Feature Set File: ',app.structure_info.(app.StructureTypeDropDown.Value).FeatureSet);
    
                    app.ExistingNotesFilecsvButton.Enable = 'on';
                    app.DoneButton.Enable = 'on';
    
                    if strcmp(app.structure_info.(app.StructureTypeDropDown.Value).FeatureRanks,'None')
                        app.FeatureRankingsFilescsvButton.Enable = 'on';
                        app.PerformFeatureRankingButton.Enable = 'on';
                    else
                        app.FeatureRankingsFilescsvButton.Enable = 'off';
                        app.PerformFeatureRankingButton.Enable = 'off';
                        %app.Feat_Rank = app.structure_info.(app.StructureTypeDropDown.Value).FeatureRanks;
                        app.FeatureRankingsFileLabel.Text = strjoin(fieldnames(app.structure_info.(app.StructureTypeDropDown.Value).FeatureRanks),',');
                    end
    
                    if ~isempty(app.MPP)
                        if isnumeric(app.MPP)
                            app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Value = round(app.MPP,4);
                            app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Enable = 'off';
                        else
                            app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Value = round(str2double(app.MPP),4);
                            app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Enable = 'off';
                        end
                        
                    end
                    
                    %app.FeatureSetImageLabelColumnEditField.Value = 'ImgLabel';
                    %app.FeatureSetImageLabelColumnEditField.Enable = 'off';
                    
                    app.structure_idx = app.structure_info.(app.StructureTypeDropDown.Value).AnnotationID;
                    
                    if any(ismember(fieldnames(app.structure_info),'LabelType'))
                        if strcmp(app.structure_info.LabelType,'slide')
                            app.file_type = 'slide';
                            app.file_label_col = app.structure_info.FileLabelCol;
                            app.label_file = app.structure_info.LabelFile;
                            app.class_label_col = app.structure_info.ClassLabelCol;
                            
                            app.FeatureSetImageLabelColumnEditField.Value = 'ImgLabel';
                            app.SlideLabelColumnDropDown.Items = {app.structure_info.FileLabelCol};
                            app.SlideLabelColumnDropDown.Value = app.structure_info.FileLabelCol;
                            app.SlideLabelColumnDropDown.Enable = 'off';
                            
                            filename = strsplit(app.structure_info.LabelFile,filesep);
                            app.SlidelevellabelsButton.Text = filename{end};
                            
                            app.ClassLabelColumnDropDown_2.Items = {app.structure_info.ClassLabelCol};
                            app.ClassLabelColumnDropDown_2.Value = app.structure_info.ClassLabelCol;
                            app.ClassLabelColumnDropDown_2.Enable = 'off';
                            
                            if strcmp(app.structure_info.ClassLabelCol,'multi')
                                app.HasmultiplelabelsCheckBox_2.Value = 1;
                                app.Full_Feature_set.Class = repmat({'multi'},size(app.Full_Feature_set,1),1);
                                app.slide_labels = readtable(app.structure_info.LabelFile,'VariableNamingRule','preserve','Delimiter',',');
                                
                            else
                                app.slide_labels = readtable(app.structure_info.LabelFile,"VariableNamingRule",'preserve',"Delimiter",',');
                                Process_SlideLevel(app,app.slide_labels.(app.structure_info.FileLabelCol),app.slide_labels.(app.structure_info.ClassLabelCol))
                            end
                        elseif strcmp(app.structure_info.LabelType,'structure')
                            app.file_type = 'structure';
                            app.file_label_col = app.structure_info.FileLabelCol;
                            app.label_file = app.structure_info.LabelFile;
                            app.class_label_col = app.structure_info.ClassLabelCol;
                            
                            app.FeatureSetImageLabelColumnEditField_2.Value = 'ImgLabel';
                            app.ImageLabelColumnDropDown.Items = {app.structure_info.FileLabelCol};
                            app.ImageLabelColumnDropDown.Value = app.structure_info.FileLabelCol;
                            app.ImageLabelColumnDropDown.Enable = 'off';
                            
                            app.ClassLabelColumnDropDown.Items = {app.structure_info.ClassLabelCol};
                            app.ClassLabelColumnDropDown.Value = app.structure_info.ClassLabelCol;
                            app.ClassLabelColumnDropDown.Enable = 'off';
                            
                            if strcmp(app.structure_info.ClassLabelCol,'multi')
                                app.HasmultiplelabelsCheckBox.Value = 1;
                                app.Full_Feature_set.Class = repmat({'multi'},size(app.Full_Feature_set,1),1);
                                app.structure_labels = readtable(app.structure_info.LabelFile,'VariableNamingRule','preserve','Delimiter',',');
                            else
                                app.structure_labels = readtable(app.structure_info.LabelFile,'VariableNamingRule','preserve','Delimiter',',');
                                Process_StructureLevel(app,app.structure_labels.(app.structure_info.FileLabelCol),app.structure_labels.(app.structure_info.ClassLabelCol))
                                
                            end
                        end
                    else
                        app.SlidelevellabelsButton.Enable = 'on';
                        

                    end

                else
                    app.SelectFolderofSlidesButton.Enable = 'on';
                end


            end

            if strcmp(app.Input_Type,'NewExtract')
                
                app.Slide_Path = uigetdir('Select Folder containing single or multiple slides:');  
                if ~app.Slide_Path==0
                
                    app.StructureTypeDropDown.Enable = 'off';
                    app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Enable = 'off';
                    app.SelectTypeofInputButtonGroup.Enable = 'off';
                    
                    % Defining annotation IDs within WSIs, including,
                    % excluding, and combining annotation IDs into specific
                    % named structures
                    app.HistoLens_Extract = HistoLens_Extract(app);
                    waitfor(app.HistoLens_Extract)
                    
                    % Constructing the compartment segmentation script
                    % (Glom specific?)
                    app.HistoLens_CompartmentSegmentation = HistoLens_CompartmentSegmentation(app);
                    waitfor(app.HistoLens_CompartmentSegmentation)
                    
                    slide_names = dir(app.Slide_Path);
                    slide_names = {slide_names.name};
                    slide_names = slide_names(~ismember(slide_names,{'.','..'}));
                    slide_names = slide_names(contains(slide_names,{'.svs','.ndpi'}));
                    app.Slide_Names = slide_names;
                    app.Slide_Num = length(app.Slide_Names);
                                        
                    % Extracting features from slides using
                    % Catch_N_Release. Currently implementing single
                    % structure (glom) feature extraction and combination
                    app.HistoLens_FeatureExtraction = HistoLens_FeatureExtraction(app);
                    waitfor(app.HistoLens_FeatureExtraction)
                    
                    app.CatchNRelease = true;
    
                    app.structure_info = Load_Catch_N_Release(app);
                    
                    app.StructureTypeDropDown.Items = fieldnames(app.structure_info);
                    app.StructureTypeDropDown.Value = app.StructureTypeDropDown.Items(1);
                    app.Structure = app.StructureTypeDropDown.Value;
                    
                    if ~isempty(app.MPP)
                        app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Value = round(str2double(app.MPP),4);
                        app.MicronsPerPixelMPPifunknownleaveatzeroEditField.Enable = 'off';
                    end
                    
                    app.Full_Feature_set = readtable(app.structure_info.(app.StructureTypeDropDown.Value).FeatureSet,'VariableNamingRule','preserve','Delimiter',',');
                    
                    app.NumberofStructuresLabel.Text = strcat('Number of Structures:',string(height(app.Full_Feature_set)));
                    app.FeatureSetFileLabel.Text = strcat('Feature Set File: ',app.structure_info.(app.StructureTypeDropDown.Value).FeatureSet);
                    %app.FeatureSetFilecsvButton.Enable = 'off';
    
                    app.SlidelevellabelsButton.Enable = 'on';
                    app.ExistingNotesFilecsvButton.Enable = 'on';
                    app.DoneButton.Enable = 'on';
    
                    if strcmp(app.structure_info.(app.StructureTypeDropDown.Value).FeatureRanks,'None')
                        app.FeatureRankingsFilescsvButton.Enable = 'on';
                        app.PerformFeatureRankingButton.Enable = 'on';
                    else
                        app.FeatureRankingsFilescsvButton.Enable = 'off';
                        app.PerformFeatureRankingButton.Enable = 'off';
                    end
                else
                    app.SelectFolderofSlidesButton.Enable = 'on';
                end
            end


        end

        % Button pushed function: PerformFeatureRankingButton
        function PerformFeatureRankingButtonPushed(app, event)
            
            app.FeatureRankingsFilescsvButton.Enable = 'off';
            app.PerformFeatureRankingButton.Enable = 'off';
            app.HistoLens_FeatureRanking = HistoLens_FeatureRanking(app);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLens_FilesUIFigure = uifigure('Visible', 'off');
            app.HistoLens_FilesUIFigure.Name = 'HistoLens_Files';
            app.HistoLens_FilesUIFigure.Icon = 'Logo.png';
            app.HistoLens_FilesUIFigure.CloseRequestFcn = createCallbackFcn(app, @HistoLens_FilesUIFigureCloseRequest, true);
            app.HistoLens_FilesUIFigure.Tag = 'File_Input_Dialog';
            app.StructureTypeDropDownLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.StructureTypeDropDownLabel.Text = 'Structure Type';
            app.StructureTypeDropDown = uidropdown(app.HistoLens_FilesUIFigure);
            app.StructureTypeDropDown.Items = {'Select One', 'Glomeruli', 'Tubules'};
            app.StructureTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StructureTypeDropDownValueChanged, true);
            app.StructureTypeDropDown.Tag = 'Structure_Drop';
            app.StructureTypeDropDown.Value = 'Select One';
            app.FeatureRankingsFilescsvButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.FeatureRankingsFilescsvButton.ButtonPushedFcn = createCallbackFcn(app, @FeatureRankingsFilescsvButtonPushed, true);
            app.FeatureRankingsFilescsvButton.Tag = 'Feat_Rank_Butt';
            app.FeatureRankingsFilescsvButton.Enable = 'off';
            app.FeatureRankingsFilescsvButton.Text = 'Feature Rankings File(s) (''.csv'')';
            app.SlideDirectoryLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.SlideDirectoryLabel.Tag = 'Slide_Path_text';
            app.SlideDirectoryLabel.Enable = 'off';
            app.SlideDirectoryLabel.Text = 'Slide Directory';
            app.FeatureSetFileLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.FeatureSetFileLabel.Tag = 'Feat_Path_text';
            app.FeatureSetFileLabel.Enable = 'off';
            app.FeatureSetFileLabel.Text = 'Feature Set File ';
            app.FeatureRankingsFileLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.FeatureRankingsFileLabel.Enable = 'off';
            app.FeatureRankingsFileLabel.Text = 'Feature Rankings File';
            app.DoneButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tag = 'Done_Butt';
            app.DoneButton.Enable = 'off';
            app.DoneButton.Text = 'Done';
            app.NumberofSlidesLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.NumberofSlidesLabel.Tag = 'Num_Slide_text';
            app.NumberofSlidesLabel.Enable = 'off';
            app.NumberofSlidesLabel.Text = 'Number of Slides: ';
            app.NumberofStructuresLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.NumberofStructuresLabel.Enable = 'off';
            app.NumberofStructuresLabel.Text = 'Number of Structures:';
            app.LabelsFormatButtonGroup = uibuttongroup(app.HistoLens_FilesUIFigure);
            app.LabelsFormatButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LabelsFormatButtonGroupSelectionChanged, true);
            app.LabelsFormatButtonGroup.Title = 'Labels Format';
            app.AttachingseparatefilecontainingstructurelevellabelsButton = uiradiobutton(app.LabelsFormatButtonGroup);
            app.AttachingseparatefilecontainingstructurelevellabelsButton.Enable = 'off';
            app.AttachingseparatefilecontainingstructurelevellabelsButton.Text = 'Attaching separate file containing structure-level labels';
            app.AttachingseparatefilecontainingstructurelevellabelsButton.Value = true;
            app.AttachingseparatefilecontainingslidelevellabelsButton = uiradiobutton(app.LabelsFormatButtonGroup);
            app.AttachingseparatefilecontainingslidelevellabelsButton.Enable = 'off';
            app.AttachingseparatefilecontainingslidelevellabelsButton.Text = 'Attaching separate file containing slide-level labels';
            app.StructurelevellabelsButton = uibutton(app.LabelsFormatButtonGroup, 'push');
            app.StructurelevellabelsButton.ButtonPushedFcn = createCallbackFcn(app, @StructurelevellabelsButtonPushed, true);
            app.StructurelevellabelsButton.Tag = 'Struct_Level_Butt';
            app.StructurelevellabelsButton.Enable = 'off';
            app.StructurelevellabelsButton.Text = 'Structure-level labels';
            app.SlidelevellabelsButton = uibutton(app.LabelsFormatButtonGroup, 'push');
            app.SlidelevellabelsButton.ButtonPushedFcn = createCallbackFcn(app, @SlidelevellabelsButtonPushed, true);
            app.SlidelevellabelsButton.Tag = 'Slide_Level_Butt';
            app.SlidelevellabelsButton.Enable = 'off';
            app.SlidelevellabelsButton.Text = 'Slide-level labels';
            app.ClassLabelColumnDropDownLabel = uilabel(app.LabelsFormatButtonGroup);
            app.ClassLabelColumnDropDownLabel.Enable = 'off';
            app.ClassLabelColumnDropDownLabel.Text = 'Class Label Column';
            app.ClassLabelColumnDropDown = uidropdown(app.LabelsFormatButtonGroup);
            app.ClassLabelColumnDropDown.Items = {};
            app.ClassLabelColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassLabelColumnDropDownValueChanged, true);
            app.ClassLabelColumnDropDown.Enable = 'off';
            app.ClassLabelColumnDropDown.Placeholder = 'Select One';
            app.ClassLabelColumnDropDown.Value = {};
            app.ClassLabelColumnDropDown_2Label = uilabel(app.LabelsFormatButtonGroup);
            app.ClassLabelColumnDropDown_2Label.Enable = 'off';
            app.ClassLabelColumnDropDown_2Label.Text = 'Class Label Column';
            app.ClassLabelColumnDropDown_2 = uidropdown(app.LabelsFormatButtonGroup);
            app.ClassLabelColumnDropDown_2.Items = {};
            app.ClassLabelColumnDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ClassLabelColumnDropDown_2ValueChanged, true);
            app.ClassLabelColumnDropDown_2.Enable = 'off';
            app.ClassLabelColumnDropDown_2.Placeholder = 'Select One';
            app.ClassLabelColumnDropDown_2.Value = {};
            app.ImageLabelColumnDropDownLabel = uilabel(app.LabelsFormatButtonGroup);
            app.ImageLabelColumnDropDownLabel.Enable = 'off';
            app.ImageLabelColumnDropDownLabel.Text = 'Image Label Column';
            app.ImageLabelColumnDropDown = uidropdown(app.LabelsFormatButtonGroup);
            app.ImageLabelColumnDropDown.Items = {};
            app.ImageLabelColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageLabelColumnDropDownValueChanged, true);
            app.ImageLabelColumnDropDown.Enable = 'off';
            app.ImageLabelColumnDropDown.Placeholder = 'Select One';
            app.ImageLabelColumnDropDown.Value = {};
            app.SlideLabelColumnDropDownLabel = uilabel(app.LabelsFormatButtonGroup);
            app.SlideLabelColumnDropDownLabel.Enable = 'off';
            app.SlideLabelColumnDropDownLabel.Text = 'Slide Label Column';
            app.SlideLabelColumnDropDown = uidropdown(app.LabelsFormatButtonGroup);
            app.SlideLabelColumnDropDown.Items = {};
            app.SlideLabelColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @SlideLabelColumnDropDownValueChanged, true);
            app.SlideLabelColumnDropDown.Enable = 'off';
            app.SlideLabelColumnDropDown.Placeholder = 'Select One';
            app.SlideLabelColumnDropDown.Value = {};
            app.HasmultiplelabelsCheckBox = uicheckbox(app.LabelsFormatButtonGroup);
            app.HasmultiplelabelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @HasmultiplelabelsCheckBoxValueChanged, true);
            app.HasmultiplelabelsCheckBox.Enable = 'off';
            app.HasmultiplelabelsCheckBox.Text = 'Has multiple labels';
            app.HasmultiplelabelsCheckBox_2 = uicheckbox(app.LabelsFormatButtonGroup);
            app.HasmultiplelabelsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @HasmultiplelabelsCheckBox_2ValueChanged, true);
            app.HasmultiplelabelsCheckBox_2.Enable = 'off';
            app.HasmultiplelabelsCheckBox_2.Text = 'Has multiple labels';
            app.FeatureSetImageLabelColumnEditFieldLabel = uilabel(app.LabelsFormatButtonGroup);
            app.FeatureSetImageLabelColumnEditFieldLabel.Enable = 'off';
            app.FeatureSetImageLabelColumnEditFieldLabel.Text = 'Feature Set Image Label Column';
            app.FeatureSetImageLabelColumnEditField = uieditfield(app.LabelsFormatButtonGroup, 'text');
            app.FeatureSetImageLabelColumnEditField.ValueChangedFcn = createCallbackFcn(app, @FeatureSetImageLabelColumnEditFieldValueChanged, true);
            app.FeatureSetImageLabelColumnEditField.Enable = 'off';
            app.FeatureSetImageLabelColumnEditField_2Label = uilabel(app.LabelsFormatButtonGroup);
            app.FeatureSetImageLabelColumnEditField_2Label.Enable = 'off';
            app.FeatureSetImageLabelColumnEditField_2Label.Text = 'Feature Set Image Label Column';
            app.FeatureSetImageLabelColumnEditField_2 = uieditfield(app.LabelsFormatButtonGroup, 'text');
            app.FeatureSetImageLabelColumnEditField_2.ValueChangedFcn = createCallbackFcn(app, @FeatureSetImageLabelColumnEditField_2ValueChanged, true);
            app.FeatureSetImageLabelColumnEditField_2.Enable = 'off';
            app.ExistingNotesFilecsvButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.ExistingNotesFilecsvButton.ButtonPushedFcn = createCallbackFcn(app, @ExistingNotesFilecsvButtonPushed, true);
            app.ExistingNotesFilecsvButton.Enable = 'off';
            app.ExistingNotesFilecsvButton.Text = 'Existing Notes File (''.csv'')';
            app.NotesFileLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.NotesFileLabel.Enable = 'off';
            app.NotesFileLabel.Text = 'Notes File';
            app.MicronsPerPixelMPPifunknownleaveatzeroEditFieldLabel = uilabel(app.HistoLens_FilesUIFigure);
            app.MicronsPerPixelMPPifunknownleaveatzeroEditFieldLabel.Text = 'Microns Per Pixel (MPP) (if unknown leave at zero)';
            app.MicronsPerPixelMPPifunknownleaveatzeroEditField = uieditfield(app.HistoLens_FilesUIFigure, 'numeric');
            app.MicronsPerPixelMPPifunknownleaveatzeroEditField.ValueChangedFcn = createCallbackFcn(app, @MicronsPerPixelMPPifunknownleaveatzeroEditFieldValueChanged, true);
            app.SelectTypeofInputButtonGroup = uibuttongroup(app.HistoLens_FilesUIFigure);
            app.SelectTypeofInputButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectTypeofInputButtonGroupSelectionChanged, true);
            app.SelectTypeofInputButtonGroup.Title = 'Select Type of Input';
            app.WSIsandAnnotationFilesButton = uitogglebutton(app.SelectTypeofInputButtonGroup);
            app.WSIsandAnnotationFilesButton.Text = 'WSIs and Annotation Files';
            app.WSIsandAnnotationFilesButton.Value = true;
            app.HistoLensExperimentFileButton = uitogglebutton(app.SelectTypeofInputButtonGroup);
            app.HistoLensExperimentFileButton.Text = 'HistoLens Experiment File';
            app.SelectFolderofSlidesButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.SelectFolderofSlidesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderofSlidesButtonPushed, true);
            app.SelectFolderofSlidesButton.Text = 'Select Folder of Slide(s)';
            app.PerformFeatureRankingButton = uibutton(app.HistoLens_FilesUIFigure, 'push');
            app.PerformFeatureRankingButton.ButtonPushedFcn = createCallbackFcn(app, @PerformFeatureRankingButtonPushed, true);
            app.PerformFeatureRankingButton.Enable = 'off';
            app.PerformFeatureRankingButton.Text = 'Perform Feature Ranking';
            % Show the figure after all components are created
            app.HistoLens_FilesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_File_Input(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLens_FilesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLens_FilesUIFigure)
        end
    end
end