classdef HistoLens_MergeLabels < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLensMergeLabelsUIFigure  matlab.ui.Figure
        DoneButton                    matlab.ui.control.Button
        LabelNameEditField            matlab.ui.control.EditField
        LabelNameEditFieldLabel       matlab.ui.control.Label
        SecondLabelDropDown           matlab.ui.control.DropDown
        FirstLabelDropDown_2Label     matlab.ui.control.Label
        FirstLabelDropDown            matlab.ui.control.DropDown
        FirstLabelDropDownLabel       matlab.ui.control.Label
        MergingtwolabelsintooneLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        main_app
        Aligned_Labels
        Structure
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;
            app.Aligned_Labels = main_app.Aligned_Labels;
            app.Structure = main_app.Structure;
            
            % Getting the labels used in main app
            %app.FirstLabelDropDown.Items = fieldnames(app.Aligned_Labels.(app.Structure));
            app.FirstLabelDropDown.Items = app.Aligned_Labels.(app.Structure).AllLabels;

            value_idx = find(strcmp(app.FirstLabelDropDown.Value,app.Aligned_Labels.(app.Structure).AllLabels));
            app.SecondLabelDropDown.Items = app.Aligned_Labels.(app.Structure).AllLabels;
            app.SecondLabelDropDown.Items(value_idx) = [];
            app.SecondLabelDropDown.Value = app.SecondLabelDropDown.Items{1};

            app.LabelNameEditField.Value = strcat(app.FirstLabelDropDown.Value,'-',app.SecondLabelDropDown.Value);


        end

        % Value changed function: FirstLabelDropDown
        function FirstLabelDropDownValueChanged(app, event)
            value = app.FirstLabelDropDown.Value;
            
            value_idx = find(strcmp(value,app.Aligned_Labels.(app.Structure).AllLabels));
            app.SecondLabelDropDown.Items = app.Aligned_Labels.(app.Structure).AllLabels;
            app.SecondLabelDropDown.Items(value_idx) = [];
            app.SecondLabelDropDown.Value = app.SecondLabelDropDown.Items{1};

            app.LabelNameEditField.Value = strcat(app.FirstLabelDropDown.Value,'-',app.SecondLabelDropDown.Value);


        end

        % Value changed function: LabelNameEditField
        function LabelNameEditFieldValueChanged(app, event)
            value = app.LabelNameEditField.Value;
            
            if isempty(value)
                app.LabelNameEditField.Value = strcat(app.FirstLabelDropDown.Value,'_',app.SecondLabelDropDown.Value);
            elseif ~isvarname(value)
                if isvarname(strrep(value,' ','_'))
                    app.LabelNameEditField.Value = strrep(value,' ','_');
                else
                    msgbox('Uh Oh! That is not a good label name! Please try again.')
                end
            end

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % Merging the two selected labels together
            label_idx_1 = find(strcmp(app.FirstLabelDropDown.Value,app.Aligned_Labels.(app.Structure).AllLabels));
            label_idx_2 = find(strcmp(app.SecondLabelDropDown.Value,app.Aligned_Labels.(app.Structure).AllLabels));

            label1 = app.Aligned_Labels.(app.Structure).(strcat('Label_',num2str(label_idx_1))).Aligned;
            label2 = app.Aligned_Labels.(app.Structure).(strcat('Label_',num2str(label_idx_2))).Aligned;
            
            % Aligning the ImgLabel columns for overlap
            if height(label1)==height(label2)
                new_aligned = innerjoin(label1,label2,'Keys','ImgLabel');
                new_aligned.Class = strcat(new_aligned.Class_label1,'-',new_aligned.Class_label2);
                new_aligned.Class_label1 = [];
                new_aligned.Class_label2 = [];
            elseif height(label1)>height(label2)
                overlap_labels = find(ismember(label1.ImgLabel,label2.ImgLabel));
                overlap_table = label1(overlap_labels,:);
                merged_labels = strcat(overlap_table.Class,'-',label2.Class);
                overlap_table = cell2table([overlap_table.ImgLabel,merged_labels],'VariableNames',{'ImgLabel','Class'});
                non_overlap_labels = find(~ismember(label1.ImgLabel,label2.ImgLabel));
                non_overlap_table = label1(non_overlap_labels,:);
                new_aligned = [overlap_table;non_overlap_table];

            elseif height(label1)<height(label2)
                overlap_labels = find(ismember(label2.ImgLabel,label1.ImgLabel));
                overlap_table = label2(overlap_labels,:);
                merged_labels = strcat(overlap_table.Class,'-',label1.Class);
                overlap_table = cell2table([overlap_table.ImgLabel,merged_labels],'VariableNames',{'ImgLabel','Class'});
                non_overlap_labels = find(~ismember(label2.ImgLabel,label1.ImgLabel));
                non_overlap_table = label2(non_overlap_labels,:);
                new_aligned = [overlap_table;non_overlap_table];
            end

            new_merged_labels.Aligned = new_aligned;
            new_merged_labels.Sub_Class = unique(new_aligned.Class);

            Add_Merged_Label(app.main_app,new_merged_labels,app.LabelNameEditField.Value)
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLensMergeLabelsUIFigure = uifigure('Visible', 'off');
            app.HistoLensMergeLabelsUIFigure.Name = 'HistoLens Merge Labels';
            app.HistoLensMergeLabelsUIFigure.Icon = 'Logo.png';
            app.MergingtwolabelsintooneLabel = uilabel(app.HistoLensMergeLabelsUIFigure);
            app.MergingtwolabelsintooneLabel.Text = 'Merging two labels into one';
            app.FirstLabelDropDownLabel = uilabel(app.HistoLensMergeLabelsUIFigure);
            app.FirstLabelDropDownLabel.Text = 'First Label';
            app.FirstLabelDropDown = uidropdown(app.HistoLensMergeLabelsUIFigure);
            app.FirstLabelDropDown.Items = {};
            app.FirstLabelDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstLabelDropDownValueChanged, true);
            app.FirstLabelDropDown.Value = {};
            app.FirstLabelDropDown_2Label = uilabel(app.HistoLensMergeLabelsUIFigure);
            app.FirstLabelDropDown_2Label.Text = 'Second Label';
            app.SecondLabelDropDown = uidropdown(app.HistoLensMergeLabelsUIFigure);
            app.SecondLabelDropDown.Items = {};
            app.SecondLabelDropDown.Value = {};
            app.LabelNameEditFieldLabel = uilabel(app.HistoLensMergeLabelsUIFigure);
            app.LabelNameEditFieldLabel.Text = 'Label Name';
            app.LabelNameEditField = uieditfield(app.HistoLensMergeLabelsUIFigure, 'text');
            app.LabelNameEditField.ValueChangedFcn = createCallbackFcn(app, @LabelNameEditFieldValueChanged, true);
            app.LabelNameEditField.Placeholder = 'Select Two Labels to Merge';
            app.DoneButton = uibutton(app.HistoLensMergeLabelsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.HistoLensMergeLabelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_MergeLabels(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLensMergeLabelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLensMergeLabelsUIFigure)
        end
    end
end