classdef HistoLens_MultiNotes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonGroup               matlab.ui.container.ButtonGroup
        BothROIsButton            matlab.ui.control.RadioButton
        BlueROIOnlyButton         matlab.ui.control.RadioButton
        RedROIOnlyButton          matlab.ui.control.RadioButton
        DoneButton                matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        NotetocopyEditField       matlab.ui.control.EditField
        NotetocopyEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Notes % Notes table
        Comparing % Whether or not two ROIs are opened
        
        Red_Comp_Img % Images in Red ROI
        Blue_Comp_Img % Images in Blue ROI
        Image_Name_Label % If only a single ROI is opened then these are all of the images in that ROI
        
    end
    
    properties (Access = private)
        main_app % HistoLens window
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Notes = app.main_app.Notes;

            if strcmp(app.main_app.Image_Name_Label.Enable,'on')
                    
                image_names = cellfun(@(x)strsplit(x,','), app.main_app.Image_Name_Label.Items,'UniformOutput',false);
                image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                [~,inc_idx,~] = intersect(app.Notes.ImgLabel,image_names);
                include_notes = app.Notes(inc_idx,:);
                
                app.UITable.Data = include_notes;
                
            else
                image_names = cellfun(@(x)strsplit(x,','),app.main_app.Red_Comp_Image.Items,'UniformOutput',false);
                image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                include_notes = app.Notes(ismember(app.Notes.ImgLabel,image_names),:);
                
                app.UITable.Data = include_notes;                    
                
            end
            
            if app.main_app.Comparing
                app.BlueROIOnlyButton.Enable = 'on';
                app.BothROIsButton.Enable = 'on';
            end          
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Red ROI Only')
                if strcmp(app.main_app.Image_Name_Label.Enable,'on')
                    
                    image_names = cellfun(@(x)strsplit(x,','), app.main_app.Image_Name_Label.Items,'UniformOutput',false);
                    image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                    include_notes = app.Notes(ismember(app.Notes.ImgLabel,image_names),:);
                    
                    app.UITable.Data = include_notes;
                    
                else
                    image_names = cellfun(@(x)strsplit(x,','),app.main_app.Red_Comp_Image.Items,'UniformOutput',false);
                    image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                    include_notes = app.Notes(ismember(app.Notes.ImgLabel,image_names),:);
                    
                    app.UITable.Data = include_notes;                    
                    
                end
            end
            
            if strcmp(selectedButton.Text,'Blue ROI Only')
                image_names = cellfun(@(x)strsplit(x,','), app.main_app.Blue_Comp_Image.Items,'UniformOutput',false);
                image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                include_notes = app.Notes(ismember(app.Notes.ImgLabel,image_names),:);
                
                app.UITable.Data = include_notes;
                
            end
            
            if strcmp(selectedButton.Text, 'Both ROIs')
                if strcmp(app.main_app.Image_Name_Label.Enable,'on')
                    
                    image_names = cellfun(@(x)strsplit(x,','), app.main_app.Image_Name_Label.Items,'UniformOutput',false);
                    image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                    include_notes = app.Notes(ismember(app.Notes.ImgLabel,image_names),:);
                    
                else
                    image_names = cellfun(@(x)strsplit(x,','),app.main_app.Red_Comp_Image.Items,'UniformOutput',false);
                    image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                    include_notes = app.Notes(ismember(app.Notes.ImgLabel,image_names),:);
                                        
                end
                image_names = cellfun(@(x)strsplit(x,','), app.main_app.Blue_Comp_Image.Items,'UniformOutput',false);
                image_names = cellfun(@(x)x{1}, image_names,'UniformOutput',false);
                include_notes = [include_notes;app.Notes(ismember(app.Notes.ImgLabel,image_names),:)];
                
                app.UITable.Data = include_notes;
                
            end
            
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            current_notes = app.Notes;
            height(current_notes)
            % Updating notes 
            for j = 1:length(app.UITable.Data.ImgLabel)
                img_name = app.UITable.Data.ImgLabel{j};
                
                img_notes = current_notes{find(strcmp(current_notes.ImgLabel,img_name)),2};
                img_notes = img_notes{1};
                
                if isempty(img_notes)||strcmp(img_notes,'')
                    img_notes = app.NotetocopyEditField.Value;
                else
                    img_notes = strcat(img_notes,', ',app.NotetocopyEditField.Value);
                end
                current_notes{strcmp(current_notes.ImgLabel,img_name),2} = {img_notes};
                
            end
            
            app.Notes = current_notes;
            app.main_app.CopyLabelforallimagesinregionButton.Enable = 'on';
            Update_Notes(app.main_app,event,app)
            delete(app)
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            try
                app.main_app.CopyLabelforallimagesinregionButton.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NotetocopyEditFieldLabel = uilabel(app.UIFigure);
            app.NotetocopyEditFieldLabel.Text = 'Note to copy :';
            app.NotetocopyEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'ImgLabel'; 'Notes'};
            app.UITable.RowName = {};
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.RedROIOnlyButton = uiradiobutton(app.ButtonGroup);
            app.RedROIOnlyButton.Text = 'Red ROI Only';
            app.RedROIOnlyButton.Value = true;
            app.BlueROIOnlyButton = uiradiobutton(app.ButtonGroup);
            app.BlueROIOnlyButton.Enable = 'off';
            app.BlueROIOnlyButton.Text = 'Blue ROI Only';
            app.BothROIsButton = uiradiobutton(app.ButtonGroup);
            app.BothROIsButton.Enable = 'off';
            app.BothROIsButton.Text = 'Both ROIs';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_MultiNotes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end