classdef HistoLens_MultiStainRegistration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        Stain_UITable                 matlab.ui.control.Table
        MultiStainRegistrationLabel   matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Step4Panel                    matlab.ui.container.Panel
        NextImageButton               matlab.ui.control.Button
        RedoButton                    matlab.ui.control.Button
        Step3Panel                    matlab.ui.container.Panel
        SelectcontrolpointsineachimagestartingwithimageontheleftLabel  matlab.ui.control.Label
        NextStepButton_2              matlab.ui.control.Button
        Step2Panel                    matlab.ui.container.Panel
        NextStepButton                matlab.ui.control.Button
        RectangleAnnotationImage2Button  matlab.ui.control.Button
        RectangleAnnotationImage1Button  matlab.ui.control.Button
        Step1Panel                    matlab.ui.container.Panel
        LeaveAsIsButton               matlab.ui.control.Button
        ZoominonSpecificRegionButton  matlab.ui.control.Button
        BlendAxis                     matlab.ui.control.UIAxes
        ImageAxis_2                   matlab.ui.control.UIAxes
        ImageAxis                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)


    end
    
    properties (Access = public)
        % Properties from main_app
        main_app
        Stain_Table
        Slide_Path

        Slide_Count
        % Properties created by this window
        Needle = false;
        Section = false;
        Current_Thumbnails
        %Slide_Count
        Current_Transform
        Saved_Transforms
        Control_Points
        Next2_Pushed = 0;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;
            
            % Initializing the main table
            app.Slide_Path = main_app.Slide_Path;
            app.Stain_Table = main_app.Stain_Table;
            app.Stain_UITable.Data = [app.Stain_Table{:,:},repmat({'No'},height(app.Stain_Table),1)];
            app.Stain_UITable.ColumnName = [app.Stain_Table.Properties.VariableNames,{'Completed'}];

            % Initializing count property
            app.Slide_Count = 1;

            % Loading up first example 
            Load_Registration(app)


        end

        % Callback function
        function BothNeedleBiopsiesCheckBoxValueChanged(app, event)
            value = app.BothNeedleBiopsiesCheckBox.Value;
            
            if value
                app.Needle = true;
            end

            % Moving to step 2
            app.Step1Panel.Visible = 'off';
            app.Step2Panel.Visible = 'on';
            app.Step2Panel.Enable = 'on';


        end

        % Callback function
        function BothSectionsCheckBoxValueChanged(app, event)
            value = app.BothSectionsCheckBox.Value;
            
            if value
                app.Section = true;
            end

            % Moving to step 2
            app.Step1Panel.Visible = 'off';
            app.Step2Panel.Visible = 'on';
            app.Step2Panel.Enable = 'on';

        end

        % Button pushed function: RectangleAnnotationImage1Button
        function RectangleAnnotationImage1ButtonPushed(app, event)
            
            % First annotation of general tissue area
            first_roi = images.roi.Rectangle(app.Current_Thumbnails.Image1_Axis,'Label','Image 1 Focus');
            first_roi.draw()
            
            app.RectangleAnnotationImage1Button.Enable = 'off';

            app.Current_Thumbnails.Image1_Focus.Position = first_roi.Position;

            addlistener(first_roi,'ROIMoved',@(r,evt)Update_Position(r,evt,first_roi,app.Current_Thumbnails.Image1_Focus.Position));

        end

        % Button pushed function: RectangleAnnotationImage2Button
        function RectangleAnnotationImage2ButtonPushed(app, event)
            
            % Second annotation of general tissue area
            second_roi = images.roi.Rectangle(app.Current_Thumbnails.Image2_Axis,'Label','Image 2 Focus');
            second_roi.draw()

            app.RectangleAnnotationImage2Button.Enable = 'off';

            app.Current_Thumbnails.Image2_Focus.Position = second_roi.Position;
            
            addlistener(second_roi,'ROIMoved',@(r,evt)Update_Position(r,evt,second_roi,app.Current_Thumbnails.Image2_Focus.Position));

        end

        % Button pushed function: NextStepButton
        function NextStepButtonPushed(app, event)
            
            app.Step2Panel.Visible = 'off';
            app.Step2Panel.Enable = 'on';
            app.Step3Panel.Enable = 'on';
            app.Step3Panel.Visible = 'on';
            %app.NextStepButton_2.Enable = 'on';

            % Going to the next step with zoomed in images on focus regions
            focus_1_region = app.Current_Thumbnails.Image1_Focus.Position;
            app.Current_Thumbnails.Image1_Focus_Image = app.Current_Thumbnails.Image1(focus_1_region(2):focus_1_region(2)+focus_1_region(4),...
                focus_1_region(1):focus_1_region(1)+focus_1_region(3),:);
            focus_2_region = app.Current_Thumbnails.Image2_Focus.Position;
            app.Current_Thumbnails.Image2_Focus_Image = app.Current_Thumbnails.Image2(focus_2_region(2):focus_2_region(2)+focus_2_region(4),...
                focus_2_region(1):focus_2_region(1)+focus_2_region(3),:);            

            %axes(app.Current_Thumbnails.Image1_Axis)
            imshow(app.Current_Thumbnails.Image1_Focus_Image,'Parent',app.Current_Thumbnails.Image1_Axis)
            %axes(app.Current_Thumbnails.Image2_Axis)
            imshow(app.Current_Thumbnails.Image2_Focus_Image,'Parent',app.Current_Thumbnails.Image2_Axis);

            app.Control_Points = zeros(1,4);
            point_count = 1;
            while true

                if app.Next2_Pushed
                    break                
                end
                drawnow()

                cp_1 = images.roi.Point(app.Current_Thumbnails.Image1_Axis,'Label',num2str(point_count),...
                    'Parent',app.Current_Thumbnails.Image1_Axis);
                cp_1.draw()
                cp_2 = images.roi.Point(app.Current_Thumbnails.Image2_Axis,'Label',num2str(point_count),...
                    'Parent',app.Current_Thumbnails.Image2_Axis);
                cp_2.draw()
                
                app.Control_Points(point_count,:) = [cp_1.Position,cp_2.Position];
                point_count = point_count+1;


            end


        end

        % Button pushed function: NextStepButton_2
        function NextStepButton_2Pushed(app, event)
            
            app.Next2_Pushed = 1;
            app.Step4Panel.Visible = 'on';
            app.Step4Panel.Enable = 'on';
            app.Step3Panel.Visible = 'off';
            app.Step3Panel.Enable = 'off';

            % Adjusting control points to full thumbnail size
            app.Control_Points = [app.Control_Points(:,1:2)+app.Current_Thumbnails.Image1_Focus.Position(1:2),...
                app.Control_Points(:,3:4)+app.Current_Thumbnails.Image2_Focus.Position(1:2)];

            % Run registration 
            thumbnail_transform = fitgeotrans(app.Control_Points(:,1:2),...
                app.Control_Points(:,3:4),'projective');

            Rfixed = imref2d(size(app.Current_Thumbnails.Image2));
            [registered,B] = imwarp(app.Current_Thumbnails.Image1,thumbnail_transform,'OutputView',Rfixed);
            
            % Getting dimensions of WSI and adjusting control points to
            % full resolution
            img1_full_dims = [app.Current_Thumbnails.Image1_Info(1).Width,...
                app.Current_Thumbnails.Image1_Info(1).Height];
            img2_full_dims = [app.Current_Thumbnails.Image2_Info(1).Width,...
                app.Current_Thumbnails.Image2_Info(1).Height];
            
            relative_dims_1 = img1_full_dims./[size(app.Current_Thumbnails.Image1,2),size(app.Current_Thumbnails.Image1,1)];
            relative_dims_2 = img2_full_dims./[size(app.Current_Thumbnails.Image2,2),size(app.Current_Thumbnails.Image2,1)];
            
            adjusted_points = [app.Control_Points(:,1).*relative_dims_1(1),...
                app.Control_Points(:,2).*relative_dims_1(2),...
                app.Control_Points(:,3).*relative_dims_2(1),...
                app.Control_Points(:,4).*relative_dims_2(2)];
            
            app.Current_Transform.Transform = fitgeotrans(adjusted_points(:,1:2),...
                adjusted_points(:,3:4),'projective');
            app.Current_Transform.Reference = imref2d([img2_full_dims,3]);

            % Have to save both the transform and the spatial referencing
            % object

            %app.Current_Transform.Reference = B;
            % Display registered image
            cla(app.ImageAxis)
            app.ImageAxis.Visible = 'off';
            cla(app.ImageAxis_2)
            app.ImageAxis_2.Visible = 'off';
            app.BlendAxis.Visible = 'on';

            imshowpair(app.Current_Thumbnails.Image2,registered,'blend','Parent',app.BlendAxis)

            % Changing Next button text
            if app.Slide_Count+1==height(app.Stain_Table)
                app.NextImageButton.Text = 'Done';
            else
                app.NextImageButton.Text = 'Next Image';
            end
            
            drawnow()
            app.Next2_Pushed = 0;


        end

        % Button pushed function: RedoButton
        function RedoButtonPushed(app, event)
            % Going back to beginning without progressing or saving
            % Transform
            Load_Registration(app)

        end

        % Button pushed function: NextImageButton
        function NextImageButtonPushed(app, event)

            % Saving Current_Transform and going on to next image
            if ~isempty(app.Saved_Transforms)
                app.Saved_Transforms = [app.Saved_Transforms;app.Current_Transform];
            else
                app.Saved_Transforms = app.Current_Transform;
            end

            % Updating progress table
            app.Stain_UITable.Data(app.Slide_Count,3) = {'Completed'};

            if app.Slide_Count==height(app.Stain_Table)
                app.main_app.Registration_Transforms = app.Saved_Transforms;
                % Going back to File-Input window
                Get_Transforms(app.main_app,app)

                % Saving Transforms and adding that information to
                % experiment file
                Transforms_to_Experiment_File(app,app.main_app)

                delete(app)

            else

                assignin('base','Registration_Transforms',app.Saved_Transforms)
                % Updating slide count
                app.Slide_Count = app.Slide_Count+1;
                
                cla(app.BlendAxis)
                app.BlendAxis.Visible = 'off';
                app.ImageAxis.Visible = 'on';
                app.ImageAxis_2.Visible = 'on';

                app.Step4Panel.Enable = 'off';
                app.Step4Panel.Visible = 'off';
                app.Step1Panel.Enable = 'on';
                app.Step1Panel.Visible = 'on';

                % Moving on to next image
                Load_Registration(app)

            end

        end

        % Button pushed function: ZoominonSpecificRegionButton
        function ZoominonSpecificRegionButtonPushed(app, event)
            
            app.Step1Panel.Visible = 'off';
            app.Step1Panel.Enable = 'off';
            app.Step2Panel.Enable = 'on';
            app.Step2Panel.Visible = 'on';
            app.RectangleAnnotationImage1Button.Enable = 'on';
            app.RectangleAnnotationImage2Button.Enable = 'on';


        end

        % Button pushed function: LeaveAsIsButton
        function LeaveAsIsButtonPushed(app, event)
            
            app.Step1Panel.Visible = 'off';
            app.Step1Panel.Enable = 'off';
            app.Step3Panel.Enable = 'on';
            app.Step3Panel.Visible = 'on';

            app.Current_Thumbnails.Image1_Focus_Image = app.Current_Thumbnails.Image1;
            app.Current_Thumbnails.Image2_Focus_Image = app.Current_Thumbnails.Image2;
            app.Current_Thumbnails.Image1_Focus.Position = [0,0,size(app.Current_Thumbnails.Image1,2),size(app.Current_Thumbnails.Image1,1)];
            app.Current_Thumbnails.Image2_Focus.Position = [0,0,size(app.Current_Thumbnails.Image2,2),size(app.Current_Thumbnails.Image2,1)];


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {257, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MultiStainRegistrationLabel = uilabel(app.LeftPanel);
            app.MultiStainRegistrationLabel.Text = 'Multi-Stain Registration';
            app.Stain_UITable = uitable(app.LeftPanel);
            app.Stain_UITable.ColumnName = {'Stain 1'; 'Stain 2'; 'Completed'};
            app.Stain_UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.ImageAxis = uiaxes(app.RightPanel);
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.ImageAxis_2 = uiaxes(app.RightPanel);
            app.ImageAxis_2.XTick = [];
            app.ImageAxis_2.YTick = [];
            app.BlendAxis = uiaxes(app.RightPanel);
            app.BlendAxis.XTick = [];
            app.BlendAxis.YTick = [];
            app.BlendAxis.Visible = 'off';
            app.Step1Panel = uipanel(app.RightPanel);
            app.Step1Panel.Title = 'Step 1';
            app.ZoominonSpecificRegionButton = uibutton(app.Step1Panel, 'push');
            app.ZoominonSpecificRegionButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominonSpecificRegionButtonPushed, true);
            app.ZoominonSpecificRegionButton.Text = 'Zoom in on Specific Region';
            app.LeaveAsIsButton = uibutton(app.Step1Panel, 'push');
            app.LeaveAsIsButton.ButtonPushedFcn = createCallbackFcn(app, @LeaveAsIsButtonPushed, true);
            app.LeaveAsIsButton.Text = 'Leave As-Is';
            app.Step2Panel = uipanel(app.RightPanel);
            app.Step2Panel.Enable = 'off';
            app.Step2Panel.Title = 'Step 2';
            app.Step2Panel.Visible = 'off';
            app.RectangleAnnotationImage1Button = uibutton(app.Step2Panel, 'push');
            app.RectangleAnnotationImage1Button.ButtonPushedFcn = createCallbackFcn(app, @RectangleAnnotationImage1ButtonPushed, true);
            app.RectangleAnnotationImage1Button.Text = 'Rectangle Annotation: Image 1';
            app.RectangleAnnotationImage2Button = uibutton(app.Step2Panel, 'push');
            app.RectangleAnnotationImage2Button.ButtonPushedFcn = createCallbackFcn(app, @RectangleAnnotationImage2ButtonPushed, true);
            app.RectangleAnnotationImage2Button.Text = 'Rectangle Annotation: Image 2';
            app.NextStepButton = uibutton(app.Step2Panel, 'push');
            app.NextStepButton.ButtonPushedFcn = createCallbackFcn(app, @NextStepButtonPushed, true);
            app.NextStepButton.Text = 'Next Step';
            app.Step3Panel = uipanel(app.RightPanel);
            app.Step3Panel.Enable = 'off';
            app.Step3Panel.Title = 'Step 3';
            app.Step3Panel.Visible = 'off';
            app.NextStepButton_2 = uibutton(app.Step3Panel, 'push');
            app.NextStepButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextStepButton_2Pushed, true);
            app.NextStepButton_2.Text = 'Next Step';
            app.SelectcontrolpointsineachimagestartingwithimageontheleftLabel = uilabel(app.Step3Panel);
            app.SelectcontrolpointsineachimagestartingwithimageontheleftLabel.Text = 'Select control points in each image, starting with image on the left';
            app.Step4Panel = uipanel(app.RightPanel);
            app.Step4Panel.Enable = 'off';
            app.Step4Panel.Title = 'Step 4';
            app.Step4Panel.Visible = 'off';
            app.RedoButton = uibutton(app.Step4Panel, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonPushed, true);
            app.RedoButton.Text = 'Re-do';
            app.NextImageButton = uibutton(app.Step4Panel, 'push');
            app.NextImageButton.ButtonPushedFcn = createCallbackFcn(app, @NextImageButtonPushed, true);
            app.NextImageButton.Text = 'Next Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_MultiStainRegistration(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end