classdef HistoLens_NewAnnotationLayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AnnotationIDEditField          matlab.ui.control.NumericEditField
        AnnotationIDEditFieldLabel     matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        ContinueButton                 matlab.ui.control.Button
        AddingNewAnnotationLayerLabel  matlab.ui.control.Label
        NameofStructureEditField       matlab.ui.control.EditField
        NameofStructureEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        main_app

        New_Annotation_Name
        Continue
        NewName
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            
            % Getting current structure annotation IDs
            structures = app.main_app.Structure_Names(:,1);
            app.current_IDs = zeros(1);
            id_ct = 1;
            for s = 1:length(structures)
                this_structure_ids = app.main_app.Structure_Names{s,2};
                this_structure_ids = str2double(strsplit(this_structure_ids,','));
                for t = 1:length(this_structure_ids)
                    app.current_IDs(id_ct) = this_structure_ids(t);
                    id_ct = id_ct+1;
                end
            end

            max_ID = max(current_IDs);
            app.AnnotationIDEditField.Value = max_ID+1;
            app.NewName.idx = max_ID+1;

        end

        % Value changed function: NameofStructureEditField
        function NameofStructureEditFieldValueChanged(app, event)
            value = app.NameofStructureEditField.Value;
            
            if ~isempty(value)
                if isvarname(value)|| isvarname(strrep(value,' ',''))
                    app.ContinueButton.Enable = 'on';
                    app.NewName.Name = strrep(value,' ','');
                else
                    f = msgbox('Please remove any special characters and try again (including spaces, punctuation, etc)');
                    app.NameofStructureEditField.Value = '';
                end
            else
                app.ContinueButton.Enable = 'off';
                app.NewName.Name = [];
            end

        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            
            app.Continue = true;

            Get_New_Name(app.main_app,app)
            delete(app)

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            app.Continue = false;

            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.Continue = false;
            
            delete(app)
            
        end

        % Value changed function: AnnotationIDEditField
        function AnnotationIDEditFieldValueChanged(app, event)
            value = app.AnnotationIDEditField.Value;
            
            if ismember(value,app.current_IDs)
                f = msgbox('Pick another ID so there is no overlap! Duh!')
            else
                app.NewName.idx = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NameofStructureEditFieldLabel = uilabel(app.UIFigure);
            app.NameofStructureEditFieldLabel.Text = 'Name of Structure';
            app.NameofStructureEditField = uieditfield(app.UIFigure, 'text');
            app.NameofStructureEditField.ValueChangedFcn = createCallbackFcn(app, @NameofStructureEditFieldValueChanged, true);
            app.AddingNewAnnotationLayerLabel = uilabel(app.UIFigure);
            app.AddingNewAnnotationLayerLabel.Text = 'Adding New Annotation Layer';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Enable = 'off';
            app.ContinueButton.Text = 'Continue';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AnnotationIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnnotationIDEditFieldLabel.Text = 'Annotation ID';
            app.AnnotationIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnnotationIDEditField.Limits = [1 Inf];
            app.AnnotationIDEditField.ValueChangedFcn = createCallbackFcn(app, @AnnotationIDEditFieldValueChanged, true);
            app.AnnotationIDEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_NewAnnotationLayer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end