classdef HistoLens_Notes_Search < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotesSearchUIFigure       matlab.ui.Figure
        DoneButton                matlab.ui.control.Button
        ReturntousingALLAVAILABLESAMPLESButton  matlab.ui.control.Button
        LimitAnalysistoONLYTHESESAMPLESButton  matlab.ui.control.Button
        REMOVESAMPLESfromAnalysisButton  matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        NotesQueryEditField       matlab.ui.control.EditField
        NotesQueryEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_app % HistoLens main application window
        
    end
    
    properties (Access = public)
        og_img_paths % Original image paths for analyses
        og_mask_paths % Original mask paths for analyses
        
        base_img_paths % Base image paths for saving and referring to later on
        
        base_Feature_set % Base feature set, uneditable
        Full_Feature_set % Feature set for analyses
        Ignore_idx
        
        Structure
        Structure_Idx_Name
    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function Update_Notes(app)          
            
            % Updating displayed notes table to only include the selected
            % images and their notes
            image_names = app.Ignore_idx.ImgLabel(~app.Ignore_idx.Ignore);
            [~,display_idx,~] = intersect(app.Notes.ImgLabel,image_names);
            
            app.UITable.Data = app.Notes(display_idx,:);
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            
            Notes = app.main_app.Notes.(app.main_app.Structure_Idx_Name);
            app.Full_Feature_set = app.main_app.Full_Feature_set.(app.main_app.Structure_Idx_Name);
            app.base_Feature_set = app.main_app.base_Feature_set.(app.main_app.Structure_Idx_Name);
            app.Ignore_idx = app.main_app.Ignore_idx.(app.main_app.Structure_Idx_Name);
            
            app.Structure = app.main_app.Structure;
            app.Structure_Idx_Name = app.main_app.Structure_Idx_Name;

            include_labels = app.Ignore_idx.ImgLabel(~app.Ignore_idx.Ignore);

            % Making sure to use Notes samples included in Full_Feature_set
            % in the event that there has already been some pruning based
            % on Notes values
            [~,inc_idx,~] = intersect(Notes.ImgLabel,include_labels);
            Notes(setdiff(1:height(Notes.ImgLabel),inc_idx),:) = [];

            app.UITable.Data = Notes;
            
        end

        % Button pushed function: REMOVESAMPLESfromAnalysisButton
        function REMOVESAMPLESfromAnalysisButtonPushed(app, event)
            
            % Remove samples from search results from analyses
            base_images = app.Ignore_idx.ImgLabel;
            base_images = base_images(~app.Ignore_idx.Ignore);
            
            % intersect command output = intersecting elements, index of intersecting elements in A, index of intersecting elements in B 
            [~,~,rem_idx] = intersect(app.UITable.Data.ImgLabel,base_images);
            
            add_to_ignore = zeros(height(app.Ignore_idx),1);
            add_to_ignore(rem_idx) = 1;
            
            app.Ignore_idx.Ignore = app.Ignore_idx.Ignore+add_to_ignore;
            app.Ignore_idx.Ignore(app.Ignore_idx.Ignore>1) = 1;
            
            %app.UITable.Data = app.main_app.Notes;
            display_notes = innerjoin(app.Ignore_idx,app.main_app.Notes.(app.Structure_Idx_Name));
            display_notes.Ignore = [];
            app.UITable.Data = display_notes;
            app.NotesQueryEditField.Value = '';

            
        end

        % Button pushed function: LimitAnalysistoONLYTHESESAMPLESButton
        function LimitAnalysistoONLYTHESESAMPLESButtonPushed(app, event)
            
            % Remove all other samples besides these in search results
            base_images = app.Ignore_idx.ImgLabel;
            base_images = base_images(~app.Ignore_idx.Ignore);
            
            [~,~,inc_idx] = intersect(app.UITable.Data.ImgLabel, base_images);
            
            add_to_ignore = ones(height(app.Ignore_idx),1);
            add_to_ignore(inc_idx) = 0;
            
            app.Ignore_idx.Ignore = app.Ignore_idx.Ignore+add_to_ignore;
            app.Ignore_idx.Ignore(app.Ignore_idx.Ignore>1) = 1;
            
            %app.UITable.Data(setdiff(1:height(app.base_Feature_set),inc_idx),:) = [];
            app.NotesQueryEditField.Value = '';
           
                        
        end

        % Button pushed function: ReturntousingALLAVAILABLESAMPLESButton
        function ReturntousingALLAVAILABLESAMPLESButtonPushed(app, event)

            app.UITable.Data = app.main_app.Notes.(app.Structure_Idx_Name);

            app.NotesQueryEditField.Value = '';
            
            app.Ignore_idx.Ignore = zeros(height(app.Ignore_idx),1);

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % Updating Full_Feature_set using images in ImgLabel            
            Update_Samples(app.main_app, event, app)
            app.main_app.SearchNotesFileButton.Enable = 'on';
            delete(app)
            
            
        end

        % Value changed function: NotesQueryEditField
        function NotesQueryEditFieldValueChanged(app, event)
            value = app.NotesQueryEditField.Value;
            
            if ~isempty(value)
                % Search Notes file (both ImgLabels and Notes)
                label_idx = strfind(app.Full_Feature_set.ImgLabel,value);
                label_idx = find(~cellfun(@isempty,label_idx));
                                
                notes_idx = cellfun(@(x)strfind(x,value),app.main_app.Notes.(app.Structure_Idx_Name).Notes,'UniformOutput',false);
                notes_idx = find(~cellfun(@isempty,notes_idx));
                
                all_idx = [setdiff(label_idx,notes_idx),setdiff(notes_idx,label_idx)];
                app.UITable.Data = app.main_app.Notes.(app.Structure_Idx_Name)(all_idx,:);
                
                app.REMOVESAMPLESfromAnalysisButton.Enable = 'on';
                app.LimitAnalysistoONLYTHESESAMPLESButton.Enable = 'on';
                app.ReturntousingALLAVAILABLESAMPLESButton.Enable = 'on';
            else
                app.UITable.Data = app.main_app.Notes.(app.Structure_Idx_Name);
            end
            
            
        end

        % Close request function: NotesSearchUIFigure
        function NotesSearchUIFigureCloseRequest(app, event)
            
            try
                app.main_app.SearchNotesFileButton.Enable = 'on';
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NotesSearchUIFigure = uifigure('Visible', 'off');
            app.NotesSearchUIFigure.Name = 'Notes Search';
            app.NotesSearchUIFigure.Icon = 'Logo.png';
            app.NotesSearchUIFigure.CloseRequestFcn = createCallbackFcn(app, @NotesSearchUIFigureCloseRequest, true);
            app.NotesQueryEditFieldLabel = uilabel(app.NotesSearchUIFigure);
            app.NotesQueryEditFieldLabel.Text = 'Notes Query:';
            app.NotesQueryEditField = uieditfield(app.NotesSearchUIFigure, 'text');
            app.NotesQueryEditField.ValueChangedFcn = createCallbackFcn(app, @NotesQueryEditFieldValueChanged, true);
            app.UITable = uitable(app.NotesSearchUIFigure);
            app.UITable.ColumnName = {'ImgLabel'; 'Notes'};
            app.UITable.RowName = {};
            app.REMOVESAMPLESfromAnalysisButton = uibutton(app.NotesSearchUIFigure, 'push');
            app.REMOVESAMPLESfromAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @REMOVESAMPLESfromAnalysisButtonPushed, true);
            app.REMOVESAMPLESfromAnalysisButton.Enable = 'off';
            app.REMOVESAMPLESfromAnalysisButton.Text = 'REMOVE SAMPLES from Analysis';
            app.LimitAnalysistoONLYTHESESAMPLESButton = uibutton(app.NotesSearchUIFigure, 'push');
            app.LimitAnalysistoONLYTHESESAMPLESButton.ButtonPushedFcn = createCallbackFcn(app, @LimitAnalysistoONLYTHESESAMPLESButtonPushed, true);
            app.LimitAnalysistoONLYTHESESAMPLESButton.Enable = 'off';
            app.LimitAnalysistoONLYTHESESAMPLESButton.Text = 'Limit Analysis to ONLY THESE SAMPLES';
            app.ReturntousingALLAVAILABLESAMPLESButton = uibutton(app.NotesSearchUIFigure, 'push');
            app.ReturntousingALLAVAILABLESAMPLESButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntousingALLAVAILABLESAMPLESButtonPushed, true);
            app.ReturntousingALLAVAILABLESAMPLESButton.Text = 'Return to using ALL AVAILABLE SAMPLES';
            app.DoneButton = uibutton(app.NotesSearchUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.NotesSearchUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_Notes_Search(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotesSearchUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotesSearchUIFigure)
        end
    end
end