classdef HistoLens_PlotOptions < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HistoLensPlotOptionsUIFigure   matlab.ui.Figure
        DoneButton                     matlab.ui.control.Button
        MarkerSizeEditField            matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel       matlab.ui.control.Label
        BlueSpinner                    matlab.ui.control.Spinner
        BlueSpinnerLabel               matlab.ui.control.Label
        GreenSpinner                   matlab.ui.control.Spinner
        GreenSpinnerLabel              matlab.ui.control.Label
        RedSpinner                     matlab.ui.control.Spinner
        RedSpinnerLabel                matlab.ui.control.Label
        MarkerDropDown                 matlab.ui.control.DropDown
        MarkerDropDownLabel            matlab.ui.control.Label
        LabelsListBox                  matlab.ui.control.ListBox
        LabelsListBoxLabel             matlab.ui.control.Label
        Use3DScatterDropDown           matlab.ui.control.DropDown
        Use3DScatterDropDownLabel      matlab.ui.control.Label
        AvailableOptionsDropDown       matlab.ui.control.DropDown
        AvailableOptionsDropDownLabel  matlab.ui.control.Label
        NewPositionEditField           matlab.ui.control.NumericEditField
        NewPositionEditFieldLabel      matlab.ui.control.Label
        CurrentOrderListBox            matlab.ui.control.ListBox
        CurrentOrderListBoxLabel       matlab.ui.control.Label
        ColorLabel                     matlab.ui.control.Label
        EditScatterPlotMarkerTypeandColorLabel  matlab.ui.control.Label
        EditDimensionalReductionMethodLabel  matlab.ui.control.Label
        EditGroupOrderLabel            matlab.ui.control.Label
        EditCurrentPlotLabel           matlab.ui.control.Label
    end

   
    properties (Access = public)
        main_app
        Dist_Data
        Plot_Options
        Current_Label

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            
            app.main_app = main_app;
            app.Plot_Options = main_app.Plot_Options;
            app.Dist_Data = main_app.Dist_Data;

            if length(app.main_app.map_idx)==1
                labels = unique(app.main_app.Dist_Data.Class);
                if ~isnumeric(labels)
                    app.LabelsListBox.Enable = 'off';
                    app.LabelsListBox.Items = app.Plot_Options.LabelOrder;
                    app.AvailableOptionsDropDown.Enable = 'off';
                    app.Use3DScatterDropDown.Enable = 'off';
    
                    if length(labels)>1
                        label_order = app.Plot_Options.LabelOrder;
    
                        app.CurrentOrderListBox.Enable = 'on';
                        app.CurrentOrderListBox.Items = label_order;
                        app.CurrentOrderListBox.Value = label_order{1};
                        app.NewPositionEditField.Enable = 'on';
                        app.NewPositionEditField.Value = 1;
                        app.NewPositionEditField.Limits = [1,length(label_order)];
    
                        app.Current_Label = label_order{1};
                    else
                        app.CurrentOrderListBox.Enable = 'off';
                        app.CurrentOrderListBox.Items = {'Only one label currently in use'};
                    end
                else
                    app.CurrentOrderListBox.Enable = 'off';
                    app.CurrentOrderListBox.Items = {'Changing order not yet implemented for numeric labels'};
                end
            else
                app.CurrentOrderListBox.Enable = 'off';

                labels = unique(app.Dist_Data.Class);
                if isnumeric(labels)
                    labels = flip(cellstr(string(labels)),2);
                end

                app.LabelsListBox.Items = labels;
                app.LabelsListBox.Enable = 'on';
                app.LabelsListBox.Value = labels{1};
                
                app.MarkerDropDown.Enable = 'on';
                app.MarkerSizeEditField.Enable = 'on';
                app.RedSpinner.Enable = 'on';
                app.GreenSpinner.Enable = 'on';
                app.BlueSpinner.Enable = 'on';

                % Initializing values according to Plot_Options
                app.MarkerDropDown.Value = app.Plot_Options.Label_1.Marker;
                app.MarkerSizeEditField.Value = app.Plot_Options.Label_1.MarkerSize;
                app.RedSpinner.Value = app.Plot_Options.Label_1.Color(1);
                app.GreenSpinner.Value = app.Plot_Options.Label_1.Color(2);
                app.BlueSpinner.Value = app.Plot_Options.Label_1.Color(3);

                app.Current_Label = labels{1};
            end


        end

        % Value changed function: CurrentOrderListBox
        function CurrentOrderListBoxValueChanged(app, event)
            value = app.CurrentOrderListBox.Value;
            
            % Updating the value of NewPositionEditField
            app.Current_Label = value;
            app.NewPositionEditField.Value = find(strcmp(app.Plot_Options.LabelOrder,app.Current_Label));

        end

        % Value changed function: NewPositionEditField
        function NewPositionEditFieldValueChanged(app, event)
            value = app.NewPositionEditField.Value;
            
            % Changing the label order according to the new value
            old_value = find(strcmp(app.Plot_Options.LabelOrder,app.Current_Label));
            if value ~= old_value
                app.Plot_Options.LabelOrder(find(strcmp(app.Plot_Options.LabelOrder,app.Current_Label))) = [];
                if value == 1
                    app.Plot_Options.LabelOrder = [{app.Current_Label};app.Plot_Options.LabelOrder];
                elseif value == length(app.Plot_Options.LabelOrder)+1
                    app.Plot_Options.LabelOrder = [app.Plot_Options.Label_Order;{app.Current_Label}];
                else
                    app.Plot_Options.LabelOrder = [app.Plot_Options.LabelOrder(1:value-1);{app.Current_Label};app.Plot_Options.LabelOrder(value:end)];
                end

                app.CurrentOrderListBox.Items = app.Plot_Options.LabelOrder;
                app.CurrentOrderListBox.Value = app.Current_Label;
            end 

        end

        % Value changed function: LabelsListBox
        function LabelsListBoxValueChanged(app, event)
            value = app.LabelsListBox.Value;
            
            app.Current_Label = value;
            label_idx = find(strcmp(app.LabelsListBox.Items,app.Current_Label));
            label_name = strcat('Label_',string(label_idx));

            % Updating values according to current plot options
            app.MarkerDropDown.Value = app.Plot_Options.(label_name).Marker;
            app.MarkerSizeEditField.Value = app.Plot_Options.(label_name).MarkerSize;
            app.RedSpinner.Value = app.Plot_Options.(label_name).Color(1);
            app.GreenSpinner.Value = app.Plot_Options.(label_name).Color(2);
            app.BlueSpinner.Value = app.Plot_Options.(label_name).Color(3);


        end

        % Value changed function: MarkerDropDown
        function MarkerDropDownValueChanged(app, event)
            value = app.MarkerDropDown.Value;
            
            label_idx = find(strcmp(app.LabelsListBox.Items,app.Current_Label));
            label_name = strcat('Label_',string(label_idx))

            % Updating Marker type
            app.Plot_Options.(label_name).Marker = value;
            
        end

        % Value changed function: MarkerSizeEditField
        function MarkerSizeEditFieldValueChanged(app, event)
            value = app.MarkerSizeEditField.Value;
            
            label_idx = find(strcmp(app.LabelsListBox.Items,app.Current_Label));
            label_name = strcat('Label_',string(label_idx));

            % Updating MarkerSize
            app.Plot_Options.(label_name).MarkerSize = value;

        end

        % Value changed function: RedSpinner
        function RedSpinnerValueChanged(app, event)
            value = app.RedSpinner.Value;
            
            label_idx = find(strcmp(app.LabelsListBox.Items,app.Current_Label));
            label_name = strcat('Label_',string(label_idx));

            % Updating Red value
            app.Plot_Options.(label_name).Color(1) = value;

        end

        % Value changed function: GreenSpinner
        function GreenSpinnerValueChanged(app, event)
            value = app.GreenSpinner.Value;
            
            label_idx = find(strcmp(app.LabelsListBox.Items,app.Current_Label));
            label_name = strcat('Label_',string(label_idx));

            % Updating Green value
            app.Plot_Options.(label_name).Color(2) = value;
            
        end

        % Value changed function: BlueSpinner
        function BlueSpinnerValueChanged(app, event)
            value = app.BlueSpinner.Value;
            
            label_idx = find(strcmp(app.LabelsListBox.Items,app.Current_Label));
            label_name = strcat('Label_',string(label_idx));

            % Updating Blue value
            app.Plot_Options.(label_name).Color(3) = value;
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            % Updating Plot Options in the main app
            New_Plot_Options(app.main_app,app.Plot_Options)

            delete(app)
           
        end

        % Close request function: HistoLensPlotOptionsUIFigure
        function HistoLensPlotOptionsUIFigureCloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HistoLensPlotOptionsUIFigure = uifigure('Visible', 'off');
            app.HistoLensPlotOptionsUIFigure.Name = 'HistoLens Plot Options';
            app.HistoLensPlotOptionsUIFigure.Icon = 'Logo.png';
            app.HistoLensPlotOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @HistoLensPlotOptionsUIFigureCloseRequest, true);
            app.EditCurrentPlotLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.EditCurrentPlotLabel.Text = 'Edit Current Plot';
            app.EditGroupOrderLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.EditGroupOrderLabel.Text = 'Edit Group Order:';
            app.EditDimensionalReductionMethodLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.EditDimensionalReductionMethodLabel.Text = 'Edit Dimensional Reduction Method: Change how visualizations are generated when selecting more than two features';
            app.EditScatterPlotMarkerTypeandColorLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.EditScatterPlotMarkerTypeandColorLabel.Text = 'Edit Scatter Plot Marker Type and Color: ';
            app.ColorLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.ColorLabel.Text = 'Color';
            app.CurrentOrderListBoxLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.CurrentOrderListBoxLabel.Text = 'Current Order';
            app.CurrentOrderListBox = uilistbox(app.HistoLensPlotOptionsUIFigure);
            app.CurrentOrderListBox.Items = {};
            app.CurrentOrderListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentOrderListBoxValueChanged, true);
            app.CurrentOrderListBox.Enable = 'off';
            app.CurrentOrderListBox.Value = {};
            app.NewPositionEditFieldLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.NewPositionEditFieldLabel.Enable = 'off';
            app.NewPositionEditFieldLabel.Text = 'New Position';
            app.NewPositionEditField = uieditfield(app.HistoLensPlotOptionsUIFigure, 'numeric');
            app.NewPositionEditField.ValueChangedFcn = createCallbackFcn(app, @NewPositionEditFieldValueChanged, true);
            app.NewPositionEditField.Enable = 'off';
            app.AvailableOptionsDropDownLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.AvailableOptionsDropDownLabel.Enable = 'off';
            app.AvailableOptionsDropDownLabel.Text = 'Available Options';
            app.AvailableOptionsDropDown = uidropdown(app.HistoLensPlotOptionsUIFigure);
            app.AvailableOptionsDropDown.Items = {'PCA'};
            app.AvailableOptionsDropDown.Enable = 'off';
            app.AvailableOptionsDropDown.Value = 'PCA';
            app.Use3DScatterDropDownLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.Use3DScatterDropDownLabel.Enable = 'off';
            app.Use3DScatterDropDownLabel.Text = 'Use 3D Scatter?';
            app.Use3DScatterDropDown = uidropdown(app.HistoLensPlotOptionsUIFigure);
            app.Use3DScatterDropDown.Items = {'False', 'True'};
            app.Use3DScatterDropDown.Enable = 'off';
            app.Use3DScatterDropDown.Value = 'False';
            app.LabelsListBoxLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.LabelsListBoxLabel.Text = 'Labels';
            app.LabelsListBox = uilistbox(app.HistoLensPlotOptionsUIFigure);
            app.LabelsListBox.Items = {};
            app.LabelsListBox.ValueChangedFcn = createCallbackFcn(app, @LabelsListBoxValueChanged, true);
            app.LabelsListBox.Enable = 'off';
            app.LabelsListBox.Value = {};
            app.MarkerDropDownLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.MarkerDropDownLabel.Enable = 'off';
            app.MarkerDropDownLabel.Text = 'Marker';
            app.MarkerDropDown = uidropdown(app.HistoLensPlotOptionsUIFigure);
            app.MarkerDropDown.Items = {'o', '+', '*', '.', 'x', '-', '''', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MarkerDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerDropDownValueChanged, true);
            app.MarkerDropDown.Enable = 'off';
            app.MarkerDropDown.Value = 'o';
            app.RedSpinnerLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.RedSpinnerLabel.Enable = 'off';
            app.RedSpinnerLabel.Text = 'Red';
            app.RedSpinner = uispinner(app.HistoLensPlotOptionsUIFigure);
            app.RedSpinner.Step = 0.2;
            app.RedSpinner.Limits = [0 1];
            app.RedSpinner.ValueChangedFcn = createCallbackFcn(app, @RedSpinnerValueChanged, true);
            app.RedSpinner.Enable = 'off';
            app.GreenSpinnerLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.GreenSpinnerLabel.Enable = 'off';
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.HistoLensPlotOptionsUIFigure);
            app.GreenSpinner.Step = 0.2;
            app.GreenSpinner.Limits = [0 1];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenSpinnerValueChanged, true);
            app.GreenSpinner.Enable = 'off';
            app.BlueSpinnerLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.BlueSpinnerLabel.Enable = 'off';
            app.BlueSpinnerLabel.Text = 'Blue';
            app.BlueSpinner = uispinner(app.HistoLensPlotOptionsUIFigure);
            app.BlueSpinner.Step = 0.2;
            app.BlueSpinner.Limits = [0 1];
            app.BlueSpinner.ValueChangedFcn = createCallbackFcn(app, @BlueSpinnerValueChanged, true);
            app.BlueSpinner.Enable = 'off';
            app.MarkerSizeEditFieldLabel = uilabel(app.HistoLensPlotOptionsUIFigure);
            app.MarkerSizeEditFieldLabel.Enable = 'off';
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.MarkerSizeEditField = uieditfield(app.HistoLensPlotOptionsUIFigure, 'numeric');
            app.MarkerSizeEditField.Limits = [1 100];
            app.MarkerSizeEditField.RoundFractionalValues = 'on';
            app.MarkerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeEditFieldValueChanged, true);
            app.MarkerSizeEditField.Enable = 'off';
            app.MarkerSizeEditField.Value = 15;
            app.DoneButton = uibutton(app.HistoLensPlotOptionsUIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.HistoLensPlotOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_PlotOptions(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HistoLensPlotOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HistoLensPlotOptionsUIFigure)
        end
    end
end