classdef HistoLens_V2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                         matlab.ui.Figure
        uitoolbar1                      matlab.ui.container.Toolbar
        uitoggletool1                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                   matlab.ui.container.toolbar.ToggleTool
        uitoggletool5                   matlab.ui.container.toolbar.ToggleTool
        PauseVisualizationCheckBox      matlab.ui.control.CheckBox
        StructureDropDown               matlab.ui.control.DropDown
        StructureDropDownLabel          matlab.ui.control.Label
        InfoSym_Notes                   matlab.ui.control.Image
        InfoSym_PersLabels              matlab.ui.control.Image
        InfoSym_TextWin                 matlab.ui.control.Image
        InfoSym_SimThresh               matlab.ui.control.Image
        InfoSym_FeatRank                matlab.ui.control.Image
        InfoSym_ImgSliders              matlab.ui.control.Image
        InfoSym_CustList                matlab.ui.control.Image
        InfoSym_SpecList                matlab.ui.control.Image
        InfoSym_TypeList                matlab.ui.control.Image
        InfoSym_CompList                matlab.ui.control.Image
        InfoSym_CompMask                matlab.ui.control.Image
        TextureWindowSizeLabel          matlab.ui.control.Label
        TextureWindowSizeEditField      matlab.ui.control.NumericEditField
        PersistentLabels                matlab.ui.control.Label
        FeatureRankingsLabel            matlab.ui.control.Label
        SimilarityThresholdLabel        matlab.ui.control.Label
        ScrollAcrossTabsLabel           matlab.ui.control.Label
        Image4_2                        matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        FeatureRankingsUsedDropDown     matlab.ui.control.DropDown
        BlueROIImagesLabel              matlab.ui.control.Label
        RedROIImagesLabel               matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        SearchNotesFileButton           matlab.ui.control.Button
        CopyLabelforallimagesinregionButton  matlab.ui.control.Button
        RemoveLabelButton               matlab.ui.control.Button
        AddLabelButton                  matlab.ui.control.Button
        PersistentImageLabelsListBox    matlab.ui.control.ListBox
        InputFilesButton                matlab.ui.control.Button
        UITable3                        matlab.ui.control.Table
        SimilarityThresholdSpinner      matlab.ui.control.Spinner
        Image                           matlab.ui.control.Image
        Red_Comp_Image                  matlab.ui.control.ListBox
        Remove_Feat_Butt                matlab.ui.control.Button
        View_Cust_Butt                  matlab.ui.control.Button
        Add_Feat_Butt                   matlab.ui.control.Button
        Custom_txt                      matlab.ui.control.Label
        Custom_List                     matlab.ui.control.ListBox
        Img_two_txt                     matlab.ui.control.Label
        Img_one_txt                     matlab.ui.control.Label
        Img_two_Edit                    matlab.ui.control.TextArea
        Notes_txt                       matlab.ui.control.Label
        Img_one_Edit                    matlab.ui.control.TextArea
        Feat_Map_Group                  matlab.ui.container.ButtonGroup
        Nuc_Butt                        matlab.ui.control.Button
        Lum_Butt                        matlab.ui.control.Button
        PAS_Butt                        matlab.ui.control.Button
        Notes_edit                      matlab.ui.control.TextArea
        Rank_Slide                      matlab.ui.control.Slider
        View_Spec_Feat                  matlab.ui.control.Button
        View_Feat_Type                  matlab.ui.control.Button
        View_Comp                       matlab.ui.control.Button
        Spec_Feat                       matlab.ui.control.Label
        Feat_txt                        matlab.ui.control.Label
        Comp_txt                        matlab.ui.control.Label
        Spec_List                       matlab.ui.control.ListBox
        Feat_List                       matlab.ui.control.ListBox
        Heat_Slide                      matlab.ui.control.Slider
        Comp_List                       matlab.ui.control.ListBox
        Prev_Butt                       matlab.ui.control.Button
        Go_Butt                         matlab.ui.control.Button
        Next_Butt                       matlab.ui.control.Button
        Blue_Comp_Image                 matlab.ui.control.ListBox
        Image_Name_Label                matlab.ui.control.ListBox
        TabGroup2                       matlab.ui.container.TabGroup
        FeatureDistributionPlotTab      matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        LabelingComponentsTab           matlab.ui.container.Tab
        InfoSym_Labels                  matlab.ui.control.Image
        CategoricalLabelOptionsButtonGroup  matlab.ui.container.ButtonGroup
        AddComboButton                  matlab.ui.control.Button
        CombinedClassNameEditField      matlab.ui.control.EditField
        CombinedClassNameEditFieldLabel  matlab.ui.control.Label
        Combine2DropDown                matlab.ui.control.DropDown
        Combine2DropDownLabel           matlab.ui.control.Label
        Combine1DropDown                matlab.ui.control.DropDown
        Combine1DropDownLabel           matlab.ui.control.Label
        CombinelabelsButton             matlab.ui.control.RadioButton
        UseallavailablelabelsorresetButton  matlab.ui.control.RadioButton
        NumericLabelOptionsButtonGroup  matlab.ui.container.ButtonGroup
        CutoffValueEditField            matlab.ui.control.NumericEditField
        CutoffValueEditFieldLabel       matlab.ui.control.Label
        SetBinaryLevelsButton           matlab.ui.control.RadioButton
        UseQuartileLabels0to2525to50etcButton  matlab.ui.control.RadioButton
        UseAllRawValuesorresetButton    matlab.ui.control.RadioButton
        LabelType                       matlab.ui.control.Label
        SelectLabelDropDown             matlab.ui.control.DropDown
        SelectLabelDropDownLabel        matlab.ui.control.Label
        SelectRegionsTab                matlab.ui.container.Tab
        ExamplesEditField               matlab.ui.control.NumericEditField
        ExamplesEditFieldLabel          matlab.ui.control.Label
        InfoSym_Comparisons             matlab.ui.control.Image
        ViewExamplesLabel               matlab.ui.control.Label
        MakeComparisonsLabel            matlab.ui.control.Label
        SelectPointsAlongLineButton     matlab.ui.control.Button
        SelectSinglePointButton         matlab.ui.control.Button
        Select_New_Butt                 matlab.ui.control.Button
        Compare_Butt                    matlab.ui.control.Button
        SubsettingComponentsTab         matlab.ui.container.Tab
        ResetDataButton                 matlab.ui.control.Button
        InfoSym_Subsetting              matlab.ui.control.Image
        ofSamplesIncludedLabel          matlab.ui.control.Label
        SubsetDataButton                matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        AddLabelsTab                    matlab.ui.container.Tab
        AddSlideLabelfromFileButton     matlab.ui.control.Button
        AddStructureLabelfromFileButton  matlab.ui.control.Button
        NotesLabelIDEditField           matlab.ui.control.EditField
        LabelIDEditField_2Label         matlab.ui.control.Label
        AddLabelfromCurrentNotesButton  matlab.ui.control.Button
        CurrentModelLabelIDEditField    matlab.ui.control.EditField
        LabelIDEditFieldLabel           matlab.ui.control.Label
        AddCurrentModelPredictedLabelsButton  matlab.ui.control.Button
        OutliersTab                     matlab.ui.container.Tab
        OutlierTable                    matlab.ui.control.Table
        InfoSym_Outliers                matlab.ui.control.Image
        RemoveOutliersCheckBox          matlab.ui.control.CheckBox
        NormalizationLabelsTab          matlab.ui.container.Tab
        AddtolistButton                 matlab.ui.control.Button
        ResetNormalizationButton        matlab.ui.control.Button
        ViewNormalizedButton            matlab.ui.control.Button
        ViewRawRGBButton                matlab.ui.control.Button
        ClickonaSubClasstoremoveitListBox  matlab.ui.control.ListBox
        ClickonaSubClasstoremoveitListBoxLabel  matlab.ui.control.Label
        SubClassDropDown                matlab.ui.control.DropDown
        SubClassDropDownLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MetadataLabelDropDown           matlab.ui.control.DropDown
        MetadataLabelDropDownLabel      matlab.ui.control.Label
        ImpactofNormalizationTab        matlab.ui.container.Tab
        RunColorFeatureComparisonButton  matlab.ui.control.Button
        NormColorTable                  matlab.ui.control.Table
        ExampleColorPatchesTab          matlab.ui.container.Tab
        NucExample_Axes                 matlab.ui.control.UIAxes
        LumExample_Axes                 matlab.ui.control.UIAxes
        PASExample_Axes                 matlab.ui.control.UIAxes
        Dist_Ax                         matlab.ui.control.UIAxes
        StatisticalMeasuresTab          matlab.ui.container.Tab
        InfoSym_Biplot                  matlab.ui.control.Image
        BiplotTable                     matlab.ui.control.Table
        ViewBiplotButton                matlab.ui.control.Button
        CurrentFeaturesListBox          matlab.ui.control.ListBox
        CurrentFeaturesListBoxLabel     matlab.ui.control.Label
        InfoSym_Stats                   matlab.ui.control.Image
        DownloadDistributionDataButton  matlab.ui.control.Button
        UITable4                        matlab.ui.control.Table
        ClassificationModelsTab         matlab.ui.container.Tab
        TrainingSplitOptionsButtonGroup  matlab.ui.container.ButtonGroup
        kfoldEditField                  matlab.ui.control.NumericEditField
        TrainTestSplitEditField         matlab.ui.control.NumericEditField
        kfoldCrossValidationButton      matlab.ui.control.RadioButton
        TrainTestSplitButton            matlab.ui.control.RadioButton
        PerformanceTabGroup             matlab.ui.container.TabGroup
        ClassificationResultsTab        matlab.ui.container.Tab
        Performance_Axes                matlab.ui.control.UIAxes
        ModelPerformanceTab             matlab.ui.container.Tab
        Performance_Table               matlab.ui.control.Table
        GeneratePerformanceReportButton  matlab.ui.control.Button
        SaveModelButton                 matlab.ui.control.Button
        ModelsTabGroup                  matlab.ui.container.TabGroup
        DecisionTreeTab                 matlab.ui.container.Tab
        MaxNumberofCategoriesEditField  matlab.ui.control.NumericEditField
        MaxNumberofCategoriesEditFieldLabel  matlab.ui.control.Label
        MaxNumberofSplitsEditField      matlab.ui.control.NumericEditField
        MaxNumberofSplitsEditFieldLabel  matlab.ui.control.Label
        OpenClassificationTreeViewerButton  matlab.ui.control.Button
        ModelParametersLabel            matlab.ui.control.Label
        FitTreeButton                   matlab.ui.control.Button
        DiscriminantAnalysisTab         matlab.ui.container.Tab
        GammaRegularizationlevelEditField  matlab.ui.control.NumericEditField
        GammaRegularizationlevelEditFieldLabel  matlab.ui.control.Label
        DiscriminantTypeDropDown        matlab.ui.control.DropDown
        DiscriminantTypeDropDownLabel   matlab.ui.control.Label
        ModelParametersLabel_2          matlab.ui.control.Label
        FitDiscrimButton                matlab.ui.control.Button
        NaiveBayesTab                   matlab.ui.container.Tab
        KernelSmootherDropDown          matlab.ui.control.DropDown
        KernelSmootherDropDownLabel     matlab.ui.control.Label
        DistributionNamesDropDown       matlab.ui.control.DropDown
        DistributionNamesDropDownLabel  matlab.ui.control.Label
        ModelParametersLabel_4          matlab.ui.control.Label
        FitNBButton                     matlab.ui.control.Button
        NeuralNetworkTab                matlab.ui.container.Tab
        FitNeuralNetworkButton          matlab.ui.control.Button
        LayerNameDropDown               matlab.ui.control.DropDown
        LayerNameDropDownLabel          matlab.ui.control.Label
        LayerSizeEditField              matlab.ui.control.NumericEditField
        LayerSizeLabel                  matlab.ui.control.Label
        NumberofLayersEditField         matlab.ui.control.NumericEditField
        NumberofLayersEditFieldLabel    matlab.ui.control.Label
        ModelParametersLabel_5          matlab.ui.control.Label
        RelativeFeatureIntensityTab     matlab.ui.container.Tab
        InfoSym_RelFeat                 matlab.ui.control.Image
        text8                           matlab.ui.control.Label
        Rel_Feat_Table                  matlab.ui.control.Table
        Rel_Feat_text                   matlab.ui.control.Label
        Rel_Ax                          matlab.ui.control.UIAxes
        AddAnnotationsTab               matlab.ui.container.Tab
        InfoSym_Annotation              matlab.ui.control.Image
        NewLabelButton                  matlab.ui.control.Button
        TabGroup3                       matlab.ui.container.TabGroup
        RedCompImageTab                 matlab.ui.container.Tab
        TransferAnnotationButton        matlab.ui.control.Button
        TransferAnnotationsDropDown     matlab.ui.control.DropDown
        ResetAnnotationButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        BlueCompImageTab                matlab.ui.container.Tab
        TransferAnnotationButton_2      matlab.ui.control.Button
        TransferAnnotationsDropDown_2   matlab.ui.control.DropDown
        ResetAnnotationButton_2         matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        UITable5                        matlab.ui.control.Table
        SelectclassthatyouwishtoannotateoraddnewclassLabel  matlab.ui.control.Label
        AddNewClasstoListEditField      matlab.ui.control.EditField
        AddNewClasstoListLabel          matlab.ui.control.Label
        AvailableClassesDropDown        matlab.ui.control.DropDown
        AvailableClassesLabel           matlab.ui.control.Label
        FeatureDefinitionsTab_2         matlab.ui.container.Tab
        InfoSym_FeatDef                 matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        FeatureDefinitionsTab           matlab.ui.container.Tab
        UITable2                        matlab.ui.control.Table
        FeatureMapMethodTab             matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ExportImagesTab                 matlab.ui.container.Tab
        SaveBlueROIImagesButton         matlab.ui.control.Button
        SaveRedROIImagesButton          matlab.ui.control.Button
        ExportallimagesinspecificROItouserspecifiedfolderLabel  matlab.ui.control.Label
        SaveimagesselectedusingAddLabelunderthePersistentLabelslist  matlab.ui.control.Label
        SavePersistentLabeledImagesButton  matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        GenerateCompartmentSegmentationCheckButton  matlab.ui.control.Button
        Blue_Img_Ax                     matlab.ui.control.UIAxes
        Red_Img_Ax                      matlab.ui.control.UIAxes
        Img_Ax                          matlab.ui.control.UIAxes
        ContextMenu                     matlab.ui.container.ContextMenu
        EditPlotOptionsMenu             matlab.ui.container.Menu
    end

    
    properties (Access = private)
        HistoLens_File_Input % File Input Dialog window
        HistoLens_MultiNotes % Multi-Notes Dialog window
        HistoLens_Notes_Search % Search Notes file Dialog window
        HistoLens_Info
        HistoLens_CompartmentSegmentation     
                
    end
    
    properties (Access = public)
        
        %% Items from File Input Window
        Notes      
        Slide_Path
        feature_encodings
        weighted_encodings
        Full_Feature_set
        base_Feature_set
        Notes_File
        Experiment_File
      
        Structure 
        shuffle_idx
        Aligned_Labels
        CatchNRelease = false;
        Overlap_Feature_idx
        Seg_Params
        Ignore_idx
        
        %% Feature Visualization Properties
        w_in_range
        texture_window
        map_idx
        map
        global_maps
        sep_feat_map
        Spec_View
        Comp_View
        Feat_View
        Custom_View
        im
        im_red
        im_blue
        red_roi
        blue_roi
        im_roi
        red_rect_position
        blue_rect_position
        rect_position        
        Red_Only
        Blue_Only
        Current_Img
        comp_mask_paths        
        Feat_Rank
        og_map_idx       
        Comparing
        Comp_Img
        Comp_Peek
        dist_roi
        comp_roi
        point_roi
        pair_maps
        Relative_Rank
        min_feat_rank
        updated_rank
        Custom_map_idx
        MPP
        ann_value
        Red_Mask
        Blue_Mask
        new_annot
        rem_out
        checked_master
        New_Label
        structure_idx
        line_roi
        Current_NormImg
        ColorNormParams
        SlideNormalization
        Replace_Features
        Pause_Visualization
        
        %% Properties associated with feature distribution plots
        Dist_Data
        img_text
        red_text
        blue_text        
        Persistent_Labels
        new_Persistent_label
        PCA_Vals
        Subset_Master
        Biplot
        Plot_Options
        Combine_Label

        %% Properties associated with Classification
        Confusion_Matrix
        Current_Model
        Performance_Metrics
        Performance_Violinplot
        Performance_Report
        NN_Layer_Dims
        Current_Model_Labels

    end
    
    methods (Access = private)
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
    end
    
    methods (Access = public)
        
        function Process_Inputs(app, dlg_app,~)
                        
            app.CatchNRelease = dlg_app.CatchNRelease;
            
            % Shuffling feature set
            app.Full_Feature_set = dlg_app.Full_Feature_set;
            assignin('base','app',app)
            structures = fieldnames(app.Full_Feature_set);

            app.Structure = structures{1};

            structure_num = zeros(length(structures),1);
            for s = 1:length(structures)
                current_structure = structures{s};

                app.shuffle_idx.(current_structure) = randperm(height(app.Full_Feature_set.(current_structure)));
                app.Full_Feature_set.(current_structure) = app.Full_Feature_set.(current_structure)(app.shuffle_idx.(current_structure),:);
                app.base_Feature_set.(current_structure) = app.Full_Feature_set.(current_structure);
                
                structure_num(s) = height(app.Full_Feature_set.(current_structure));
                
                try
                    app.base_Feature_set.(current_structure).Class = [];
                
                end

                % Getting format of classes
                if any(isnan(str2double(app.Full_Feature_set.(current_structure).Class)))
                    % For string classes
                    if iscell(unique(app.Full_Feature_set.(current_structure).Class))
                        classes = strjoin(unique(app.Full_Feature_set.(current_structure).Class(:)));
                    else
                        classes = unique(app.Full_Feature_set.(current_structure).Class(:));
                    end
                    % For multi-label inputs
    
                    if strcmp(classes,'multi')
                        Multi_Class_Label(app,dlg_app)
                    else
                        Single_Class_Label(app,dlg_app)
                    end
                else
                    classes = 'Quantitative';
                    Single_Class_Label(app,dlg_app)
                end

            end
        
            app.Go_Butt.Enable = 'on';
            app.UITable3.Visible = 'on';
            app.UITable3.Enable = 'on';
            app.UITable3.ColumnName = {'Input Property Name','Value'};

            app.UITable3.Data = {'Slide Path', dlg_app.Slide_Path;...
                'Number of Slides',num2str(dlg_app.Slide_Num);...
                'Structures',strjoin(structures,',');...
                'Microns Per Pixel (MPP)',dlg_app.MPP;...
                'Number of Structures',num2str(structure_num');...
                'Feature Set File',dlg_app.Feat_Set_File;...
                'Classes',classes;...
                'Feature Rankings',dlg_app.Feat_Rank_File;...
                'Notes File',char(dlg_app.Notes_File)
                };

            app.Seg_Params = dlg_app.Seg_Params;
            app.SlideNormalization = dlg_app.structure_info.SlideNormalization;
            
            app.Feat_Rank = dlg_app.Feat_Rank;
            app.Slide_Path = dlg_app.Slide_Path;
            app.Notes_File = dlg_app.Notes_File;
            app.Notes = dlg_app.Notes;
            
            if isnumeric(dlg_app.MPP)
                app.MPP = dlg_app.MPP;
            else
                if ~isnan(dlg_app.MPP)
                    app.MPP = str2double(dlg_app.MPP);
                end
            end
            app.structure_idx = dlg_app.structure_idx;

        end
        
        function Update_Samples(app,event,dlg_app)
            
            % Get current structure
            app.Ignore_idx.(app.Structure) = dlg_app.Ignore_idx;
            
            % Viewing initial image with feature visualization
            Plot_Feat(app,event)
            Gen_Map(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            
        end
        
        function Update_Notes(app,event,dlg_app)
            
            app.Notes.(app.Structure) = dlg_app.Notes;
            Load_Notes(app,event)
            
        end
        
        function Multi_Class_Label(app,dlg_app)
            
            % Updating for multi-structure
            all_structures = fieldnames(app.Full_Feature_set);

            for s = 1:length(all_structures)

                current_structure = all_structures{s};

                if ~isempty(app.Tree)
                    app.Tree.Children.delete;
                    app.Aligned_Labels.(current_structure) = [];
                end
                % Populating the class selection button group below the feature
                % distribution plot with multiple feature selections
                
                % Getting the class labels table and the column corresponding
                % to either the slide-level labels or the structure-level
                % labels
                file_type = dlg_app.file_type;
                if strcmp(file_type,'slide')
                    all_classes = dlg_app.slide_labels;
    
                    % Pre-setting labeling class
                    app.SelectLabelDropDown.Items = all_classes.Properties.VariableNames;
                    app.SelectLabelDropDown.Items(find(strcmp(app.SelectLabelDropDown.Items,dlg_app.SlideLabelColumnDropDown.Value))) = [];
                                    
                else
                    all_classes = dlg_app.structure_labels;
    
                    % Pre-setting labeling class
                    app.SelectLabelDropDown.Items = all_classes.Properties.VariableNames;
                    app.SelectLabelDropDown.Items(find(strcmp(app.SelectLabelDropDown.Items,dlg_app.ImageLabelColumnDropDown.Value))) = [];
                    
                end            
    
                app.SelectLabelDropDown.Value = app.SelectLabelDropDown.Items(1);
                
                % Iterating through every variable provided
                %ct = 1;
                for cl = 1:width(all_classes)
                    % excluding file label type
                    current_feature = all_classes.Properties.VariableNames{cl};
    
                    if ~strcmp(current_feature,dlg_app.file_label_col)
                        if strcmp(file_type,'slide')
                            
                            % New way of aligning labels just using innerjoin
                            current_class_table = table(all_classes.(dlg_app.file_label_col),all_classes.(cl),'VariableNames',{'Filename','Class'});
    
                            aligned_labels = Align_SlideLevel(app,current_class_table,current_structure);
    
                            app.Aligned_Labels.(current_structure).(current_feature).Aligned = aligned_labels;
                        else
                            current_class_table = all_classes(:,cl);
                            current_class_table.Filename = all_classes.(dlg_app.file_label_col);
    
                            aligned_labels = Align_StructureLevel(app,current_class_table,current_structure);
                            app.Aligned_Labels.(current_structure).(current_feature).Aligned = aligned_labels;
    
                        end
                                 
                        % Initializing struct containing sub-divisions for each
                        % label within Aligned_Labels
                        app.Aligned_Labels.(current_structure).(current_feature).Sub_Class = cell(1);
                        
                        % For numeric features, Add quartiles to Tree for
                        % selection
                        if isnumeric(aligned_labels.Class)
                            quarts = quantile(aligned_labels.Class,[0,0.25,0.5,0.75,1]);

                            app.Aligned_Labels.(current_structure).(current_feature).Sub_Class = [{strcat(num2str(quarts(1)),'<=X<=',num2str(quarts(2)))};...
                                {strcat(num2str(quarts(2)),'<=X<=',num2str(quarts(3)))};...
                                {strcat(num2str(quarts(3)),'<=X<=',num2str(quarts(4)))};...
                                {strcat(num2str(quarts(4)),'<=X<=',num2str(quarts(5)))}];
                        else
                            % For non-numeric features, then add all of the
                            % sub-categories as nodes
                            sub_class = unique(aligned_labels.Class);
                            app.Aligned_Labels.(current_structure).(current_feature).Sub_Class = sub_class;

                        end
                        %checked_list(ct) = N;
                        %ct = ct+1;
                    end
                end
                %app.Tree.CheckedNodes = checked_list;
                % Starting out with all nodes checked
                %app.checked_master.(current_structure) = app.Tree.CheckedNodes;
            end
            
            ct = 1;
            % currently selected structure
            selected_structure = app.Structure;
            features = fieldnames(app.Aligned_Labels.(selected_structure));
            for f = 1:length(features)
                N = uitreenode(app.Tree);
                N.Text = features{f};

                sub_features = app.Aligned_Labels.(selected_structure).(features{f}).Sub_Class;
                for sf = 1:length(sub_features)
                    sN = uitreenode(N);
                    sN.Text = sub_features{sf};
                end
                checked_list(ct) = N;
                ct = ct+1;
            end
            app.Tree.CheckedNodes = checked_list;
            app.checked_master.(selected_structure) = app.Tree.CheckedNodes;

        end
        
        function Single_Class_Label(app,dlg_app)      
            
            all_structures = fieldnames(app.Full_Feature_set);
            
            % Have to make it so that the node tree (app.Tree) is the right
            % one for the currently selected structure

            for s = 1:length(all_structures)

                current_structure = all_structures{s};

                if ~isempty(app.Tree)
                    app.Tree.Children.delete;
                    app.Aligned_Labels.(current_structure) = [];
                end
                file_type = dlg_app.file_type;
                
                % Adding radiobutton for class label sub-selection
                all_classes = app.Full_Feature_set.(current_structure).Class;
                file_labels = app.Full_Feature_set.(current_structure).ImgLabel;
                
                file_labels = cellfun(@(x)strsplit(x,'_'),file_labels,'UniformOutput',false);
                lengths = cellfun('length',file_labels);
                if any(lengths>2)
                    file_labels = cellfun(@(x) strjoin(x(1:end-1),'_'),file_labels,'UniformOutput',false);
                else
                    file_labels = cellfun(@(x)x{1},file_labels,'UniformOutput',false);
                end
                
                app.SelectLabelDropDown.Items = {'Class'};
                app.SelectLabelDropDown.Value = app.SelectLabelDropDown.Items(1);
                current_feature = 'Class';
                
                if strcmp(file_type,'slide')
                    
                    slide_class_table = table(file_labels,all_classes,'VariableNames',{'Filename','Class'});

                    aligned_labels = Align_SlideLevel(app,slide_class_table,current_structure);
    
                    app.Aligned_Labels.(current_structure).(current_feature).Aligned = aligned_labels;
    
                end
                if strcmp(file_type,'structure')
                    
                    structure_class_table = table(file_labels,all_classes,'VariableNames',{'Filename','Class'});

                    aligned_labels = Align_StructureLevel(app,structure_class_table, current_structure);
                    aligned_labels.Properties.VariableNames = {'ImgLabel','Class'};
    
                    app.Aligned_Labels.(current_structure).(current_feature).Aligned = aligned_labels;
                end
                
                % Initializing struct containing sub-divisions for each
                % label within Aligned_Labels
                app.Aligned_Labels.(current_structure).(current_feature).Sub_Class = cell(1);
                
                % For numeric features, Add quartiles to Tree for
                % selection
                if isnumeric(all_classes)
                    quarts = quantile(all_classes,[0,0.25,0.5,0.75,1]);

                    app.Aligned_Labels.(current_structure).(current_feature).Sub_Class = [strcat(num2str(quarts(1)),'<=X<=',num2str(quarts(2))),...
                        strcat(num2str(quarts(2)),'<=X<=',num2str(quarts(3))),...
                        strcat(num2str(quarts(3)),'<=X<=',num2str(quarts(4))),...
                        strcat(num2str(quarts(4)),'<=X<=',num2str(quarts(5)))];

                else
                    % For non-numeric features, then add all of the
                    % sub-categories as nodes
                    sub_class = unique(all_classes);
                    
                    app.Aligned_Labels.(current_structure).(current_feature).Sub_Class = sub_class;
                    
                end
            end

            % currently selected structure
            ct = 1;
            selected_structure = app.Structure;
            features = fieldnames(app.Aligned_Labels.(selected_structure));
            for f = 1:length(features)
                N = uitreenode(app.Tree);
                N.Text = features{f};

                sub_features = app.Aligned_Labels.(selected_structure).(features{f}).Sub_Class;
                for sf = 1:length(sub_features)
                    sN = uitreenode(N);
                    sN.Text = sub_features{sf};
                end
                checked_list(ct) = N;
                ct = ct+1;
            end
            app.checked_master.(selected_structure) = checked_list;
            app.Tree.CheckedNodes = app.checked_master.(selected_structure);

        end
        
        function labels = Align_SlideLevel(app, slide_class_table,current_structure)
            
            slide_names = slide_class_table.Filename;

            if ~isnumeric(slide_names)
                if any(contains(slide_names,'.'))
                    slide_names = cellfun(@(x)strsplit(x,'.'),slide_names,'UniformOutput',false);
                    slide_names = cellfun(@(x)x{1},slide_names,'UniformOutput',false);
                end
            else
                slide_names = cellstr(num2str(slide_names));
            end

            slide_class_table.Filename = [];
            slide_class_table.Filename = slide_names;
            
            % All the image labels including the image index "_###"
            img_labels = app.Full_Feature_set.(current_structure).ImgLabel;
            % Extracting the slide name
            slide_img_labels = cellfun(@(x) strsplit(x,'_'),img_labels,'UniformOutput',false);

            % Checking if the slide labels have underscores
            if any(contains(slide_names,'_'))
                if isstring(img_labels)
                    slide_img_labels = cellfun(@(x) strjoin(x{1:end-1},'_'),slide_img_labels,'UniformOutput',false);
                else
                    img_labels = string(app.Full_Feature_set.(current_structure).ImgLabel);
                    slide_img_labels = cellfun(@(x) strsplit(x,'_'),img_labels,'UniformOutput',false);
                    intermed = cell(1,length(slide_img_labels));
                    for i = 1:length(slide_img_labels)
                        x = slide_img_labels{i};
                        intermed{i} = strjoin(x(1:end-1),'_');
                    end
                    slide_img_labels = intermed';
                end
            else
                slide_img_labels = cellfun(@(x) x{1},slide_img_labels,'UniformOutput',false);
            end
            slide_img_labels = table(slide_img_labels,img_labels,'VariableNames',{'Filename','ImgLabel'});

            % Aligning and keeping order in "img_labels"
            [T,rows_in_T] = innerjoin(slide_class_table,slide_img_labels);
            [~,sortinds] = sort(rows_in_T);
            labels = T(sortinds,:);
            
            % Something funky with innerjoin again
            [~,unique_idx] = unique(labels,'rows','first');
            labels = labels(sort(unique_idx),:);
            labels.ImgLabel = cellstr(labels.ImgLabel);
            labels.Filename = [];
                
        end
        
        function labels = Align_StructureLevel(app,structure_class_table,current_structure)
            
            % Hard to specify which structure this label should go to since
            % the labels will actually be the same, maybe just pick the one
            % that is the same height?
            all_structures = fieldnames(app.Full_Feature_set);

            img_names = structure_class_table.Filename;

            if ~isnumeric(img_names)
                if any(contains(img_names,'.'))
                    img_names = cellfun(@(x)strsplit(x,'.'),img_names,'UniformOutput',false);
                    img_names = cellfun(@(x)x{1},img_names,'UniformOutput',false);
                end
            else
                img_names = cellstr(num2str(img_names));
            end

            structure_class_table.Filename = [];
            structure_class_table.Filename = img_names;

            % All the image labels including the image index "_###"
            img_labels = app.Full_Feature_set.(current_structure).ImgLabel;

            if length(img_labels)==height(structure_class_table)
                % Extracting the slide name
                structure_img_labels = cellfun(@(x) strsplit(x,'_'),img_labels,'UniformOutput',false);

                % Checking if the slide labels have underscores
                if any(contains(slide_names,'_'))
                    structure_img_labels = cellfun(@(x) strjoin(x{1:end-1},'_'),structure_img_labels,'UniformOutput',false);
                else
                    structure_img_labels = cellfun(@(x) x{1},structure_img_labels,'UniformOutput',false);
                end
                structure_img_labels = table(structure_img_labels,'VariableNames',{'Filename'});
            
                % Aligning and keeping order in "img_labels"
                [T,rows_in_T] = innerjoin(structure_img_labels,structure_class_table);
                [~,sortinds] = sort(rows_in_T);
                labels = T(sortinds,:);
                labels.Filename = img_labels(rows_in_T);
            end
        
        end
        
        function Get_New_Name(app,dlg_app)
            app.structure_idx.(dlg_app.NewName.Name) = dlg_app.NewName.idx;
        end
        
        function Replace_in_FeatureSet(app,dlg_app)

            % The index created here is what you would get if you ran base
            % feature names(replace_columns) = replace_feature names
            [~,replace_columns] = ismember(app.base_Feature_set.(app.Structure).Properties.VariableNames,...
                app.Replace_Features.Properties.VariableNames);
            
            old_columns = find(replace_columns~=0);

            [~,replace_rows] = ismember(app.base_Feature_set.(app.Structure).ImgLabel,...
                app.Replace_Features.ImgLabel);

            app.base_Feature_set.(app.Structure)(replace_rows,old_columns) = app.Replace_Features;

        end
        
        function New_Plot_Options(app,new_Plot_Options)
            app.Plot_Options = new_Plot_Options;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function HistoLens_V2_OpeningFcn(app, varargin)
            
            % For WebApp compiling
            %allAxes = findall(app.figure1,'Type','axes');
            %arrayfun(@(ax) disableDefaultInteractivity(ax), allAxes)
            %oncleanup = onCleanup(@() arrayfun(@(ax) enableDefaultInteractivity(ax), allAxes));
            
            % Initializing View button sources to all be false
            app.Comp_View = false;
            app.Feat_View = false;
            app.Spec_View = false;
            app.Custom_View = false;

            % Initializing Pause Visualization
            app.Pause_Visualization = false;
            
            % Initializing with no new comparison
            app.Comparing = false;
            
            % Initializing which axis is used
            app.Red_Only = true;
            app.Blue_Only = false;
            
        end

        % Button pushed function: InputFilesButton
        function InputFilesButtonPushed(app, event)
            app.InputFilesButton.Enable = 'off';
            
            app.HistoLens_File_Input = HistoLens_File_Input(app);
            
        end

        % Button pushed function: Add_Feat_Butt
        function Add_Feat_Butt_Callback(app, event)
 
            if strcmp(app.Custom_List.Enable,'off')
                app.Custom_List.Enable = 'on';
            
                app.Remove_Feat_Butt.Enable = 'on';
            
                app.View_Cust_Butt.Enable = 'on';
            end
            
            % Getting feature from specific list
            sel_comp = app.Comp_List.Value;
            sel_feat = app.Feat_List.Value;
            sel_spec = app.Spec_List.Value;
            
            sub_feature_encodings = app.feature_encodings(app.Overlap_Feature_idx.(app.Structure),:);

            if ~strcmp(sel_comp,'Weighted Visualization')
                row_idx = (strcmp(sub_feature_encodings.Compartment,sel_comp) & ...
                    strcmp(sub_feature_encodings.Type, sel_feat) & ...
                    strcmp(sub_feature_encodings.Specific,sel_spec));
            else
                row_idx = (strcmp(sub_feature_encodings.Type, sel_feat)&...
                    strcmp(sub_feature_encodings.Specific,sel_spec));
            end

            overlapping_idxes = app.Overlap_Feature_idx.(app.Structure);

            % Adding to map indices and custom list string
            if isempty(app.Custom_map_idx)
                app.Custom_map_idx = overlapping_idxes(find(row_idx));
            
                app.Custom_List.Items = app.feature_encodings.Feature_Names(app.Custom_map_idx);

            else
                if ~ismember(overlapping_idxes(find(row_idx)),app.Custom_map_idx)
                    old_custom = app.Custom_map_idx;
                    new_custom = [old_custom,overlapping_idxes(find(row_idx))];
            
                    app.Custom_map_idx = new_custom;
            
                    app.Custom_List.Items = app.feature_encodings.Feature_Names(new_custom);
                    app.Custom_List.Value = app.Custom_List.Items(1);

                end
            end
        end

        % Value changed function: Comp_List
        function Comp_List_Callback(app, event)
            
            sel_comp = app.Comp_List.Value;
            
            overlapping_idxes = app.Overlap_Feature_idx.(app.Structure);
            if strcmp(sel_comp,'Weighted Visualization')

                sub_encodings = app.feature_encodings(overlapping_idxes,:);
                
                feat_types = sub_encodings;
                feat_types = unique(feat_types.Type);
            
                spec_feats = sub_encodings(find(ismember(sub_encodings.Type,feat_types(1))),:);
                spec_feats = unique(spec_feats.Specific);
            else
                sub_encodings = app.feature_encodings(overlapping_idxes,:);
                sub_encodings = sub_encodings(find(ismember(sub_encodings.Compartment,sel_comp)),:);
                
                feat_types = sub_encodings;
                feat_types = unique(feat_types.Type);
            
                spec_feats = sub_encodings(find(ismember(sub_encodings.Type,feat_types(1))),:);
                spec_feats = unique(spec_feats.Specific);
            end
            
            app.Spec_List.Items = spec_feats;
            app.Feat_List.Items = feat_types;
            app.Spec_List.Value = app.Spec_List.Items(1);
            app.Feat_List.Value = app.Feat_List.Items(1);
            
        end

        % Button pushed function: Compare_Butt
        function Compare_Butt_Callback(app, event)

            % This button allows the user to select a new comparison region of points
            % and display a new image alongside the first.
            
            axes(app.Dist_Ax)
            try
                delete(app.comp_roi)
            end
            
            % New ROI that is blue with new label
            if length(app.map_idx)>1
                app.comp_roi = images.roi.Freehand(app.Dist_Ax,'Label','Comparison Range','Color',[0 0 1],'InteractionsAllowed','none');
            else
                app.comp_roi = images.roi.Rectangle(app.Dist_Ax,'Label','Comparison Range','Color',[0 0 1],'InteractionsAllowed','none');
            end
            app.comp_roi.draw;
            
            % New handle indicating that there is a new comparison
            app.Comparing = true;
            app.Blue_Only = true;
            
            % Keep not-enabled until working
            %handles.Pair_Scale.Enable = 'on';
            
            Flip_Image_Listbox(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            Load_Representative(app,event)

            app.SaveBlueROIImagesButton.Enable = 'on';
            
            app.Blue_Only = false;
            
        end

        % Value changed function: Feat_List
        function Feat_List_Callback(app, event)

            sel_comp = app.Comp_List.Value;
            
            sel_feat = app.Feat_List.Value;

            overlapping_idxes = app.Overlap_Feature_idx.(app.Structure);
            
            if strcmp(sel_comp,'Weighted Visualization')
                sub_encodings = app.weighted_encodings(overlapping_idxes,:);
                sub_encodings = sub_encodings(find(ismember(sub_encodings.Compartment,sel_comp)),:);

                spec_feats = sub_encodings(find(ismember(sub_encodings.Type,sel_feat)),:);
                spec_feats = unique(spec_feats.Specific);
            else
                sub_encodings = app.feature_encodings(overlapping_idxes,:);
                sub_encodings = sub_encodings(find(ismember(sub_encodings.Compartment,sel_comp)),:);
            
                spec_feats = sub_encodings(find(ismember(sub_encodings.Type,sel_feat)),:);
                spec_feats = unique(spec_feats.Specific);
            end
            
            app.Spec_List.Items = spec_feats;
            app.Spec_List.Value = app.Spec_List.Items(1);

        end

        % Button pushed function: Go_Butt
        function Go_Butt_Callback(app, event)
            %% Go Button: initializes new set of images+heatmaps
            cla(app.Img_Ax);
            cla(app.Dist_Ax);
            cla(app.Rel_Ax);
            
            % Loading the master feature categories file
            app.feature_encodings = readtable('Master_Feature_Categories.csv','VariableNamingRule',"preserve",'Delimiter',',');
            
            % % % % % % % Include the feature encodings as a part of the
            % feature extraction and the experimental file
                        
            % Enabling Functionality
            Enable_Butts(app,event)
            app.Plot_Options = [];
            
            % Adding compartments to Comp_List based on what is present in
            % feature set (Remember that Matlab does that dumb thing where
            % it won't read more than 63 characters)

            all_structures = fieldnames(app.Full_Feature_set);

            for s = 1:length(all_structures)
                
                current_structure = all_structures{s};
                
                include_features = app.Full_Feature_set.(current_structure).Properties.VariableNames';
                short_names = cell(length(app.feature_encodings.Feature_Names),1);
                for j = 1:length(short_names)
                    current = app.feature_encodings.Feature_Names{j};
                    if length(current)>namelengthmax
                        short_names{j} = current(1:namelengthmax);
                    else
                        short_names{j} = current;
                    end
                end
            
                % This finds the overlap between the total feature list
                % (app.feature_encodings) and the features that are included in
                % the current feature set (app.Full_Feature_set)
                app.Overlap_Feature_idx.(current_structure) = find(ismember(short_names,include_features));
                
                % This is used in subsetting the dataset according to the data
                % subsetting tab and the contents of the notes file (editable
                % using the left notes edit fields)
                app.Ignore_idx.(current_structure) = table(app.base_Feature_set.(current_structure).ImgLabel,...
                    zeros(height(app.base_Feature_set.(current_structure)),1),'VariableNames',{'ImgLabel','Ignore'});
            end

            comps = unique(app.feature_encodings.Compartment(app.Overlap_Feature_idx.(app.Structure)));
            
            % Allowing user to click weighted feature visualization if they uploaded
            % the rankings
            
            % Populating feature categories lists, with a special case if the feature
            % rankings are included where the weighted encodings are used instead.
            % This allows the user to select to view all the features by type weighted
            % according to rank regardless of compartment.
            if ~isempty(app.Feat_Rank)
                if ~isempty(app.Feat_Rank.(app.Structure))
                    %handles.TwoA_txt.Visible = 'off';
                    app.FeatureRankingsUsedDropDown.Enable = 'on';
                    app.FeatureRankingsUsedDropDown.Items = [{'None'};fieldnames(app.Feat_Rank.(app.Structure))];
                
                    app.weighted_encodings = Organize_Feat_Ranks(app,event);
                    app.Comp_List.Items = [comps;{'Weighted Visualization'}];
                    app.Comp_List.Value = app.Comp_List.Items(1);
                
                else
                    app.Comp_List.Items = comps;
                    app.Comp_List.Value = app.Comp_List.Items(1);
                    
                    app.weighted_encodings = [];
                    app.FeatureRankingsUsedDropDown.Items = {'None'};
                end
            else
                app.Comp_List.Items = comps;
                app.Comp_List.Value = app.Comp_List.Items(1);

                app.weighted_encodings = [];
                app.FeatureRankingsUsedDropDown.Items = {'None'};

            end

            sel_comp = app.Comp_List.Value;

            feat_types = app.feature_encodings(app.Overlap_Feature_idx.(app.Structure),:);
            feat_types = feat_types(strcmp(feat_types.Compartment,sel_comp),:);
            app.Feat_List.Items = unique(feat_types.Type);
            
            app.Feat_List.Value = app.Feat_List.Items(1);
            sel_feat = app.Feat_List.Value;
            
            spec_feats = feat_types(strcmp(feat_types.Type,sel_feat),:);
            app.Spec_List.Items = unique(spec_feats.Specific);
            app.Spec_List.Value = app.Spec_List.Items(1);

           
            % New Features
            app.w_in_range = app.SimilarityThresholdSpinner.Value;
            app.texture_window = app.TextureWindowSizeEditField.Value;
            app.Comparing = false;
            app.Persistent_Labels.(app.Structure) = '';
            app.Red_Only = true;
            app.Blue_Only = false;

            % Image Exporting
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
            
            % Plot labeling
            app.New_Label = true;
            app.new_Persistent_label = false;
            app.Combine_Label = false;
    
            % Initializing labels for feature set
            current_feature = app.SelectLabelDropDown.Value;   

            % Initializing structure drop-down items
            app.StructureDropDown.Items = fieldnames(app.Full_Feature_set);
            app.StructureDropDown.Value = app.Structure;
            app.StructureDropDown.Enable = 'on';

            % Initializing Transfer drop-down items
            app.TransferAnnotationsDropDown.Items = [fieldnames(app.Full_Feature_set);{'New Layer'}];
            app.TransferAnnotationsDropDown.Value = app.Structure;
            app.TransferAnnotationsDropDown.Enable = 'on';

            app.TransferAnnotationsDropDown_2.Items = [fieldnames(app.Full_Feature_set);{'New Layer'}];
            app.TransferAnnotationsDropDown.Value = app.Structure;
            
            assignin('base','app',app)
            display('histolens_v2 1075')
            
            if any(ismember(app.Full_Feature_set.(app.Structure).Properties.VariableNames,'Class'))
                app.Full_Feature_set.(app.Structure).Class = [];
            end
        
            % innerjoin sorts by default, thx Matlab
            [T,rows_in_T] = innerjoin(app.Full_Feature_set.(app.Structure),app.Aligned_Labels.(app.Structure).(current_feature).Aligned);
            [~,sortinds] = sort(rows_in_T);
            app.Full_Feature_set.(app.Structure) = T(sortinds,:);
        
            % Getting the labeling options enabled
            if isnumeric(app.Full_Feature_set.(app.Structure).Class)
                app.LabelType.Text = 'Label Type: Numeric';
                app.NumericLabelOptionsButtonGroup.Enable = 'on';
                app.NumericLabelOptionsButtonGroup.Visible = 'on';
                app.CutoffValueEditField.Enable = 'off';

                app.CategoricalLabelOptionsButtonGroup.Enable = 'off';
                app.CategoricalLabelOptionsButtonGroup.Visible = 'off';
            else
                app.LabelType.Text = 'Label Type: Categorical';
                
                app.NumericLabelOptionsButtonGroup.Enable = 'off';
                app.NumericLabelOptionsButtonGroup.Visible = 'off';
                
                app.CategoricalLabelOptionsButtonGroup.Enable = 'on';
                app.CategoricalLabelOptionsButtonGroup.Visible = 'on';
                app.CombinedClassNameEditField.Enable = 'off';
                
            end
            
            Get_Feat(app,event)
            Plot_Feat(app,event)
            Gen_Map(app,event)
            View_Image(app,event)

            app.New_Label = false;
            
            % Enabling Compartment mask buttons
            app.PAS_Butt.Enable = 'on';
            app.Lum_Butt.Enable = 'on';
            app.Nuc_Butt.Enable = 'on';
            
            % Controls for seeing and un-seeing compartment mask
            app.PAS_Butt.Text = 'PAS+';
            app.Lum_Butt.Text = 'Luminal Space';
            app.Nuc_Butt.Text = 'Nuclei';
            
            % Enabling Add Feature button
            app.Add_Feat_Butt.Enable = 'on';

            app.MetadataLabelDropDown.Items = [fieldnames(app.Aligned_Labels.(app.Structure))',{'Slide_Label'}];
            app.SubClassDropDown.Items = app.Aligned_Labels.(app.Structure).(app.MetadataLabelDropDown.Value).Sub_Class;
            app.AddtolistButton.Enable = 'on';

            Save_Notes(app,event)
            
        end

        % Value changed function: Heat_Slide
        function Heat_Slide_Callback(app, event)
            
            % Updating the transparency of feature visualization
            update_trans(app,event)
            
        end

        % Value changed function: Rank_Slide
        function Rank_Slide_Callback(app, event)

            % Updating minimum rank, changes the relative feature intensity
            % plot and feature visualization but not feature distribution
            % plot
            update_rank(app,event)
            
        end

        % Button pushed function: Lum_Butt
        function Lum_Butt_Callback(app, event)

            % Highlights the luminal space compartment in the current
            % image(s)
            app.Comp_Peek = 'Lum';
            Comp_Peeking(app,event)
            
        end

        % Button pushed function: PAS_Butt
        function PAS_Butt_Callback(app, event)

            % Highlights the PAS+ compartment in the current image(s)
            app.Comp_Peek = 'Pas';
            Comp_Peeking(app,event)
            
        end

        % Button pushed function: Nuc_Butt
        function Nuc_Butt_Callback(app, event)
            
            % Highlights the nuclear compartment in the current image(s)
            app.Comp_Peek = 'Nuc';
            Comp_Peeking(app,event)
            
        end

        % Value changed function: Image_Name_Label
        function Image_Name_Label_Callback(app, event)
            % Saving notes from previous image
            cla(app.Img_Ax)
            
            % Resetting Normalization information
            Reset_Normalization_Tabs(app)

            Save_Notes(app,event)                       
            Gen_Map(app,event)
            View_Image(app,event)
            
            % Loading notes for current image
            Load_Notes(app,event)
            
        end

        % Button pushed function: Next_Butt
        function Next_Butt_Callback(app, event)
           
            
            % Resetting compartment mask view buttons
            app.PAS_Butt.Text = 'PAS+';
            app.Lum_Butt.Text = 'Luminal Space';
            app.Nuc_Butt.Text = 'Nuclei';
            
            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            if app.Comparing
                
                next_count = find(strcmp(app.Red_Comp_Image.Items,app.Red_Comp_Image.Value));
                if next_count+1 > length(app.Red_Comp_Image.Items)
                    next_count = 1;
                else
                    next_count = next_count+1;
                end
                
                cla(app.Red_Img_Ax)
                cla(app.Blue_Img_Ax)
                
                next_comp_count = find(strcmp(app.Blue_Comp_Image.Items,app.Blue_Comp_Image.Value));
                if next_comp_count+1 > length(app.Blue_Comp_Image.Items)
                    next_comp_count = 1;
                else
                    next_comp_count = next_comp_count+1;
                end
            
                app.Red_Comp_Image.Value = app.Red_Comp_Image.Items(next_count);
                app.Blue_Comp_Image.Value = app.Blue_Comp_Image.Items(next_comp_count);
            
            else
                cla(app.Img_Ax);

                next_count = find(strcmp(app.Image_Name_Label.Items,app.Image_Name_Label.Value));
                if next_count+1 > length(app.Image_Name_Label.Items)
                    next_count = 1;
                else
                    next_count = next_count+1;
                end
                
                app.Image_Name_Label.Value = app.Image_Name_Label.Items(next_count);         
            end
            
            Gen_Map(app,event)
            View_Image(app,event)
            % Loading notes for current image
            Load_Notes(app,event)
            
        end

        % Button pushed function: Prev_Butt
        function Prev_Butt_Callback(app, event)

            
            % Resetting compartment mask view buttons
            app.PAS_Butt.Text = 'PAS+';
            app.Lum_Butt.Text = 'Luminal Space';
            app.Nuc_Butt.Text = 'Nuclei';
            
            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            if app.Comparing
                
                cla(app.Red_Img_Ax)
                cla(app.Blue_Img_Ax)
                
                prev_count = find(strcmp(app.Red_Comp_Image.Items,app.Red_Comp_Image.Value));
                if prev_count-1<1
                    prev_count = length(app.Red_Comp_Image.Items);
                else
                    prev_count = prev_count-1;
                end
                
                prev_comp_count = find(strcmp(app.Blue_Comp_Image.Items,app.Blue_Comp_Image.Value));
                            
                if prev_comp_count-1<1
                    prev_comp_count = length(app.Blue_Comp_Image.Items);
                else
                    prev_comp_count = prev_comp_count-1;
                end
            
                app.Red_Comp_Image.Value = app.Red_Comp_Image.Items(prev_count);
                app.Blue_Comp_Image.Value = app.Blue_Comp_Image.Items(prev_comp_count);
            
            else
                cla(app.Img_Ax);                
                
                prev_count = find(strcmp(app.Image_Name_Label.Items,app.Image_Name_Label.Value));
                if prev_count-1<1
                    prev_count = length(app.Image_Name_Label.Items);
                else
                    prev_count = prev_count-1;
                end
                
                app.Image_Name_Label.Value = app.Image_Name_Label.Items(prev_count);
            
            end
            
            Gen_Map(app,event)
            View_Image(app,event)
            
            % Loading notes from that image
            Load_Notes(app,event)
        end

        % Value changed function: Red_Comp_Image
        function Red_Comp_Image_Callback(app, event)
            % Saving notes from previous image
            Save_Notes(app,event)
            
            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            app.Red_Only = true;

            Gen_Map(app,event)
            View_Image(app,event)
            
            % Loading notes for current image
            Load_Notes(app,event)
            
            app.Red_Only = false;
        end

        % Value changed function: Blue_Comp_Image
        function Blue_Comp_Image_Callback(app, event)
            % Saving notes from previous image
            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            %app.comp_img_count = find(strcmp(app.Blue_Comp_Image.Items,app.Blue_Comp_Image.Value));
            app.Blue_Only = true;
            
            Gen_Map(app,event)
            View_Image(app,event)
            
            % Loading notes for current image
            Load_Notes(app,event)
            
            app.Blue_Only = false;
            
        end

        % Button pushed function: Remove_Feat_Butt
        function Remove_Feat_Butt_Callback(app, event)

            % Get value selected in custom list
            cust_list = app.Custom_List.Items;
            rem_val = app.Custom_List.Value;
            rem_idx = find(ismember(cust_list,rem_val));
            
            cust_list{rem_idx} = [];
            cust_list = cust_list(~cellfun(@isempty,cust_list));
            
            if isempty(cust_list)
                app.Custom_List.Items = {'Add Features by selecting them in the Specific Feature list and clicking Add Feature to Custom button'};
                app.Custom_List.Enable = 'off';
                app.Remove_Feat_Butt.Enable = 'off';
                
                app.Custom_map_idx = [];
            
                app.View_Cust_Butt.Enable = 'off';
            else
                app.Custom_List.Items = cust_list;
                app.Custom_List.Value = app.Custom_List.Items(1);
                
                app.Custom_map_idx(rem_idx) = [];

            end
            
        end

        % Button pushed function: Select_New_Butt
        function Select_New_Butt_Callback(app, event)
            % This button reverts back to original load representative for a single
            % region within the feature distribution.
            
            axes(app.Dist_Ax)
                        
            % Deleting dist_roi if it already exists
            try
                delete(app.dist_roi)
            end
            
            % Changing so that the shape of the ROI depends on the number
            % of features
            if length(app.map_idx)>1
                app.dist_roi = images.roi.Freehand(app.Dist_Ax,'Label','FeatureRange','Color',[1 0 0],'InteractionsAllowed','none');
            else
                app.dist_roi = images.roi.Rectangle(app.Dist_Ax,'Label','FeatureRange','Color',[1 0 0],'InteractionsAllowed','none');
            end
            
            app.dist_roi.draw;

            app.Compare_Butt.Visible = 'on';
            app.Compare_Butt.Enable = 'on';
            
            app.Red_Only = true;
            app.CopyLabelforallimagesinregionButton.Enable = 'on';
            
            Load_Representative(app,event)

            app.SaveRedROIImagesButton.Enable = 'on';

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            app.Red_Only = false;

        end

        % Button pushed function: SelectSinglePointButton
        function SelectSinglePointButtonPushed(app, event)
            axes(app.Dist_Ax)

            try
                delete(app.point_roi)                
            end
            
            app.point_roi = images.roi.Point(app.Dist_Ax);
            app.point_roi.draw;
            
            selected_point = app.point_roi.Position;

            if length(app.map_idx)==1
                all_class = app.Plot_Options.LabelOrder;
                if isnumeric(all_class)
                    sub_data = app.Dist_Data(find(app.Dist_Data.Class==all_class(round(selected_point(1)))),:);
                else
                    sub_data = app.Dist_Data(find(strcmp(app.Dist_Data.Class,all_class{round(selected_point(1))})),:);
                   
                end
                sub_data{:,1} = (sub_data{:,1}-selected_point(2)).^2;
                
                closest = min(sub_data{:,1});
                [row_idx,~] = find(sub_data{:,1}==closest);
                
                selected_name = sub_data{row_idx,2};
                if iscell(selected_name)
                    selected_name = selected_name{1};
                end
                
                if ~app.Comparing
                    % If not comparing then there is only the one list box
                    % to change
                    image_names = cellfun(@(x)strsplit(x,','),app.Image_Name_Label.Items,'UniformOutput',false);
                    image_names = cellfun(@(x)x{1},image_names,'UniformOutput',false);
                    %app.img_count = find(strcmp(image_names,selected_name));
                    app.Image_Name_Label.Value = app.Image_Name_Label.Items(find(strcmp(image_names,selected_name)));
                else
                    % Checking if that image is in either red or blue comp
                    % image listboxes
                    red_names = cellfun(@(x)strsplit(x,','),app.Red_Comp_Image.Items,'UniformOutput',false);
                    red_names = cellfun(@(x)x{1},red_names,'UniformOutput',false);
                    red_idx = find(strcmp(red_names,selected_name));
                    
                    if ~isempty(red_idx)
                        %app.img_count = red_idx;
                        app.Red_Comp_Image.Value = app.Red_Comp_Image.Items(find(strcmp(red_names,selected_name)));
                        app.Red_Only = true;
                    else
                        blue_names = cellfun(@(x)strsplit(x,','),app.Blue_Comp_Image.Items,'UniformOutput',false);
                        blue_names = cellfun(@(x)x{1},blue_names,'UniformOutput',false);
                        blue_idx = find(strcmp(blue_names,selected_name));
                        
                        if ~isempty(blue_idx)
                            %app.comp_img_count = blue_idx;
                            app.Blue_Comp_Image.Value = app.Blue_Comp_Image.Items(find(strcmp(blue_names,selected_name)));
                            app.Blue_Only = true;
                        else
                            % If not in either, then reset and delete old
                            % ROIs
                            app.Comparing = false;

                            app.SaveRedROIImagesButton.Enable = 'off';
                            app.SaveBlueROIImagesButton.Enable = 'off';
                            
                            Flip_Image_Listbox(app,event)
                            Plot_Feat(app,event)
                            
                            image_names = cellfun(@(x)strsplit(x,','),app.Image_Name_Label.Items,'UniformOutput',false);
                            image_names = cellfun(@(x)x{1},image_names,'UniformOutput',false);
                            %app.img_count = find(strcmp(image_names,selected_name));
                            app.Image_Name_Label.Value = app.Image_Name_Label.Items(find(strcmp(image_names,selected_name)));
                            % Changing visibility of load representative buttons
                            if ~strcmp(app.Compare_Butt.Enable,'on')
                                app.Compare_Butt.Visible = 'off';
                                app.Compare_Butt.Enable = 'off';
                            end
                        end
                    end
                end
                
            else
               % For multi-feature plots
               [k,dist] = dsearchn(table2array(app.Dist_Data(:,1:2)),selected_point);
               selected_name = app.Dist_Data{k,end-1};
               
               if ~app.Comparing
                    % If not comparing then there is only the one list box
                    % to change
                    image_names = cellfun(@(x)strsplit(x,','),app.Image_Name_Label.Items,'UniformOutput',false);
                    image_names = cellfun(@(x)x{1},image_names,'UniformOutput',false);
                    %app.img_count = find(strcmp(image_names,selected_name));
                    app.Image_Name_Label.Value = app.Image_Name_Label.Items(find(strcmp(image_names,selected_name)));
               else
                    % Checking if that image is in either red or blue comp
                    % image listboxes
                    red_names = cellfun(@(x)strsplit(x,','),app.Red_Comp_Image.Items,'UniformOutput',false);
                    red_names = cellfun(@(x)x{1},red_names,'UniformOutput',false);
                    red_idx = find(strcmp(red_names,selected_name));
                    
                    if ~isempty(red_idx)
                        %app.img_count = red_idx;
                        app.Red_Comp_Image.Value = app.Red_Comp_Image.Items(find(strcmp(red_names,selected_name)));
                        app.Red_Only = true;
                    else
                        blue_names = cellfun(@(x)strsplit(x,','),app.Blue_Comp_Image.Items,'UniformOutput',false);
                        blue_names = cellfun(@(x)x{1},blue_names,'UniformOutput',false);
                        blue_idx = find(strcmp(blue_names,selected_name));
                        
                        if ~isempty(blue_idx)
                            %app.comp_img_count = blue_idx;
                            app.Blue_Comp_Image.Value = app.Blue_Comp_Image.Items(find(strcmp(blue_names,selected_name)));
                            app.Blue_Only = true;
                        else
                            % If not in either, then reset and delete old
                            % ROIs
                            app.Comparing = false;

                            app.SaveRedROIImagesButton.Enable = 'off';
                            app.SaveBlueROIImagesButton.Enable = 'off';

                            Flip_Image_Listbox(app,event)
                            Plot_Feat(app,event)
                            
                            image_names = cellfun(@(x)strsplit(x,','),app.Image_Name_Label.Items,'UniformOutput',false);
                            image_names = cellfun(@(x)x{1},image_names,'UniformOutput',false);
                            %app.img_count = find(strcmp(image_names,selected_name));
                            app.Image_Name_Label.Value = app.Image_Name_Label.Items(find(strcmp(image_names,selected_name)));
                            % Changing visibility of load representative buttons
                            if ~strcmp(app.Compare_Butt.Enable,'on')
                                app.Compare_Butt.Visible = 'off';
                                app.Compare_Butt.Enable = 'off';
                            end
                        end
                    end
                end
                
            end
            
            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            Gen_Map(app,event)
            Save_Notes(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            
            try
                delete(app.point_roi)                
            end
            
        end

        % Button pushed function: SelectPointsAlongLineButton
        function SelectPointsAlongLineButtonPushed(app, event)
            
            % Selecting points that fall along a line
            axes(app.Dist_Ax)
                        
            % Deleting line_roi if it already exists
            try
                delete(app.line_roi)
            end
            
            % Drawing line ROI
            app.line_roi = images.roi.Line(app.Dist_Ax,'Color',[0 0 0],'InteractionsAllowed','none');
            app.line_roi.draw;
            
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
            Flip_Image_Listbox(app,event)
            
            if length(app.map_idx)>1
                % For scatter plots with 2 or more features
                scatter_data = app.Dist_Data;
                img_labels = scatter_data.ImgLabel;
                scatter_data = table2array(scatter_data(:,1:2));

                % Start and end point of the line roi
                start_point = app.line_roi.Position(1,:);
                end_point = app.line_roi.Position(2,:);
                n_points = app.ExamplesEditField.Value;
                
                % slope between two lines
                m = (end_point(2)-start_point(2))/(end_point(1)-start_point(1));
                if m==Inf
                    % line is vertical
                    x_pts(1:n_points) = start_point(1);
                    y_pts(1:n_points) = linspace(start_point(2),end_point(2),n_points);
                elseif m == 0
                    % line is horizontal
                    x_pts(1:n_points) = linspace(start_point(1),end_point(1),n_points);
                    y_pts(1:n_points) = start_point(2);
                else
                    x_pts = linspace(start_point(1),end_point(1),n_points);
                    y_pts = m*(x_pts-start_point(1))+start_point(2);
                end
                
                select_points = cell(n_points,1);
                for k = 1:n_points
                    
                    current_point = [x_pts(k),y_pts(k)];
                    [pt_idx,dist] = dsearchn(scatter_data,current_point);

                    select_points(k) = img_labels(pt_idx);
                    img_labels(pt_idx,:) = [];
                    scatter_data(pt_idx,:) = [];
                        
                end
                
                select_samples = select_points;
                
                app.Image_Name_Label.Items = Combine_Name_Label(app,select_samples,[]);
                app.Image_Name_Label.Value = app.Image_Name_Label.Items(1);
                
            else
                
                class_vals = app.Dist_Data.Class;
                img_labels = app.Dist_Data.ImgLabel;

                all_class = app.Plot_Options.LabelOrder;
                scatter_data = zeros(height(app.Dist_Data),2);
                scatter_data(:,2) = table2array(app.Dist_Data(:,1));

                [~,class_idx] = ismember(class_vals,all_class);
                scatter_data(:,1) = class_idx;

                [norm_scatter,center,scale] = normalize(scatter_data);
                
                % Point-to-line distance formula
                start_point = app.line_roi.Position(1,:);
                end_point = app.line_roi.Position(2,:);

                points = normalize([start_point;end_point],'center',center,'scale',scale);
                start_point = points(1,:);
                end_point = points(2,:);

                n_points = app.ExamplesEditField.Value;
                
                % slope between two lines
                m = (end_point(2)-start_point(2))/(end_point(1)-start_point(1));
                if m==Inf
                    % line is vertical
                    x_pts(1:n_points) = start_point(1);
                    y_pts(1:n_points) = linspace(start_point(2),end_point(2),n_points);
                elseif m == 0
                    % line is horizontal
                    x_pts(1:n_points) = linspace(start_point(1),end_point(1),n_points);
                    y_pts(1:n_points) = start_point(2);
                else
                    x_pts = linspace(start_point(1),end_point(1),n_points);
                    y_pts = m*(x_pts-start_point(1))+start_point(2);
                end
                
                select_points = cell(n_points,1);                
                for k = 1:n_points
                    
                    current_point = [x_pts(k),y_pts(k)];
                    [pt_idx,dist] = dsearchn(norm_scatter,current_point);

                    select_points(k) = img_labels(pt_idx);
                    img_labels(pt_idx) = [];
                    norm_scatter(pt_idx,:) = [];

                end           
                
                select_samples = select_points;
                
                app.Image_Name_Label.Items = Combine_Name_Label(app,select_samples,[]);
                app.Image_Name_Label.Value = app.Image_Name_Label.Items(1);                
                
            end
            
            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            Gen_Map(app,event)
            Save_Notes(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            
            
        end

        % Button pushed function: View_Comp
        function View_Comp_Callback(app, event)

            app.Spec_View = false;
            app.Feat_View = false;
            app.Comp_View = true;
            app.Custom_View = false;
            
            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            % Going back to single image viewing
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
            if ~isempty(app.weighted_encodings)
                app.Rank_Slide.MajorTicks = [];
                app.Rank_Slide.Value = 0;
            end
            
            app.CopyLabelforallimagesinregionButton.Enable = 'off';
            
            % Changing visibility of comparison listboxes
            Flip_Image_Listbox(app,event)

            Plot_Feat(app,event)
            Gen_Map(app,event)           
            View_Image(app,event)            
            Load_Notes(app,event)

            % Changing visibility of load representative buttons
            if ~strcmp(app.Compare_Butt.Enable,'on')
                app.Compare_Butt.Visible = 'off';
                app.Compare_Butt.Enable = 'off';
            end
            
        end

        % Button pushed function: View_Cust_Butt
        function View_Cust_Butt_Callback(app, event)
            app.Spec_View = false;
            app.Feat_View = false;
            app.Comp_View = false;
            app.Custom_View = true;
            
            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            % Switching back to single image viewing
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
            
            if ~isempty(app.weighted_encodings)
                app.Rank_Slide.MajorTicks = [];
                app.Rank_Slide.Value = 0;
            end
            
            app.CopyLabelforallimagesinregionButton.Enable = 'off';

            % Changing visibility of comparison listboxes
            Flip_Image_Listbox(app,event)

            Plot_Feat(app,event)
            Gen_Map(app,event)

            View_Image(app,event)
            Load_Notes(app,event)
            
            % Changing visibility of load representative buttons
            if ~strcmp(app.Compare_Butt.Enable,'on')
                app.Compare_Butt.Visible = 'off';
                app.Compare_Butt.Enable = 'off';
            end
            
        end

        % Button pushed function: View_Feat_Type
        function View_Feat_Type_Callback(app, event)

            app.Spec_View = false;
            app.Feat_View = true;
            app.Comp_View = false;
            app.Custom_View = false;
            
            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)
            
            % Switching back to single image viewing
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
                        
            if ~isempty(app.weighted_encodings)
                app.Rank_Slide.MajorTicks = [];
                app.Rank_Slide.Value = 0;
            end
            
            app.CopyLabelforallimagesinregionButton.Enable = 'off';

            % Changing visibility of comparison listboxes
            Flip_Image_Listbox(app,event)   

            Plot_Feat(app,event)
            Gen_Map(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            
            % Changing visibility of load representative buttons
            if ~strcmp(app.Compare_Butt.Enable,'on')
                app.Compare_Butt.Visible = 'off';
                app.Compare_Butt.Enable = 'off';
            end
        end

        % Button pushed function: View_Spec_Feat
        function View_Spec_Feat_Callback(app, event)

            app.Spec_View = true;
            app.Feat_View = false;
            app.Comp_View = false;
            app.Custom_View = false;
            
            Save_Notes(app,event)
            
            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            % Switching back to single image viewing
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
            
            if ~isempty(app.weighted_encodings)
                app.Rank_Slide.MajorTicks = [];
                app.Rank_Slide.Value = 0;
            end
            
            app.CopyLabelforallimagesinregionButton.Enable = 'off';

            % Changing visibility of comparison listboxes
            Flip_Image_Listbox(app,event)

            Plot_Feat(app,event)
            Gen_Map(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            
            % Changing visibility of load representative buttons
            if ~strcmp(app.Compare_Butt.Enable,'on')
                app.Compare_Butt.Visible = 'off';
                app.Compare_Butt.Enable = 'off';
            end

        end

        % Clicked callback: uitoggletool5
        function uitoggletool5_ClickedCallback(app, event)
            % Enable or disable colorbar based on the
            % tool's current state.
            state = app.uitoggletool5.State;
            if state
                colorbar(gca);
            else
                colorbar(gca,'off');
            end
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            pan(app.figure1, char(state));
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            datacursormode(app.figure1, char(state));
        end

        % Value changed function: SimilarityThresholdSpinner
        function SimilarityThresholdSpinnerValueChanged(app, event)
            value = app.SimilarityThresholdSpinner.Value;
            app.w_in_range = value;
            
            Gen_Map(app,event)
            
            View_Image(app,event)
            
        end

        % Value changed function: TextureWindowSizeEditField
        function TextureWindowSizeEditFieldValueChanged(app, event)
            value = app.TextureWindowSizeEditField.Value;
            
            app.texture_window = value;

            Gen_Map(app,event)
            View_Image(app,event)

        end

        % Button pushed function: SearchNotesFileButton
        function SearchNotesFileButtonPushed(app, event)
            
            app.SearchNotesFileButton.Enable = 'off';
            app.HistoLens_Notes_Search = HistoLens_Notes_Search(app);           
            
        end

        % Button pushed function: CopyLabelforallimagesinregionButton
        function CopyLabelforallimagesinregionButtonPushed(app, event)
            
            app.CopyLabelforallimagesinregionButton.Enable = 'off';
            app.HistoLens_MultiNotes = HistoLens_MultiNotes(app);

            Load_Notes(app,event)           
            
        end

        % Button pushed function: AddLabelButton
        function AddLabelButtonPushed(app, event)
            
            current_list = app.PersistentImageLabelsListBox.Items;
            
            % Adding first persistent label
            if strcmp(current_list,'Select Add Persistent Label')
                app.PersistentImageLabelsListBox.Enable = 'on';
                
                if ~app.Comparing
                    add_label = app.Image_Name_Label.Value;
                    
                    add_label = strsplit(add_label,',');
                    add_label = add_label{1};
                    app.PersistentImageLabelsListBox.Items = {add_label};
                    
                else
                    red_add_label = app.Red_Comp_Image.Value;
                    blue_add_label = app.Blue_Comp_Image.Value;
                    
                    add_labels = {red_add_label,blue_add_label};
                    add_labels = cellfun(@(x)strsplit(x,','),add_labels,'UniformOutput',false);
                    add_labels = cellfun(@(x)x{1},add_labels,'UniformOutput',false);
                    
                    app.PersistentImageLabelsListBox.Items = add_labels;
                end
                
            else
                
                % Adding to existing list
                old_labels = app.PersistentImageLabelsListBox.Items;
                if ~app.Comparing
                    add_label = app.Image_Name_Label.Value;
                    
                    add_label = strsplit(add_label,',');
                    add_label = add_label{1};
                    
                    if ~ismember(app.PersistentImageLabelsListBox.Items,add_label)
                        app.PersistentImageLabelsListBox.Items = [old_labels,add_label];
                    end
                    
                else
                    red_add_label = app.Red_Comp_Image.Value;
                    blue_add_label = app.Blue_Comp_Image.Value;
                    
                    add_labels = {red_add_label,blue_add_label};
                    add_labels = cellfun(@(x)strsplit(x,','),add_labels,'UniformOutput',false);
                    add_labels = cellfun(@(x)x{1},add_labels,'UniformOutput',false);
                    
                    app.PersistentImageLabelsListBox.Items = [old_labels,add_labels(~ismember(app.PersistentImageLabelsListBox.Items,add_labels))];
                end                
            end
            
            app.RemoveLabelButton.Enable = 'on';
            app.Persistent_Labels.(app.Structure) = app.PersistentImageLabelsListBox.Items;
            app.SavePersistentLabeledImagesButton.Enable = 'on';
            
            app.new_Persistent_label = true;

            Plot_Feat(app,event)
            
            app.new_Persistent_label = false;
            
        end

        % Button pushed function: RemoveLabelButton
        function RemoveLabelButtonPushed(app, event)
            
            % Get value selected in persistent labels listbox
            current_labels = app.PersistentImageLabelsListBox.Items;
            rem_label = app.PersistentImageLabelsListBox.Value;
            
            current_labels{find(ismember(current_labels,rem_label))} = [];
            current_labels = current_labels(~cellfun(@isempty,current_labels));
            
            if isempty(current_labels)
                app.PersistentImageLabelsListBox.Items = {'Select Add Persistent Label'};
                app.RemoveLabelButton.Enable = 'off';
                app.PersistentImageLabelsListBox.Enable = 'off';
                app.SavePersistentLabeledImagesButton.Enable = 'off';
                
            else
                app.PersistentImageLabelsListBox.Items = current_labels;
            end
            app.Persistent_Labels.(app.Structure) = app.PersistentImageLabelsListBox.Items;
            
            app.new_Persistent_label = true;

            Plot_Feat(app,event)
            
            app.new_Persistent_label = false;            
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            
            delete(app.HistoLens_File_Input)
            delete(app.HistoLens_MultiNotes)
            delete(app.HistoLens_Notes_Search)
            delete(app.HistoLens_Info)
            delete(app.HistoLens_CompartmentSegmentation)
            delete(app)
            
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            % Fun function
            app.HistoLens_Info = HistoLens_Info(app);           
            
        end

        % Button pushed function: DownloadDistributionDataButton
        function DownloadDistributionDataButtonPushed(app, event)
            
            folder = uigetdir('Select Folder to hold distribution data');
            if ~folder==0
                writetable(app.Dist_Data,strcat(folder,filesep,'HistoLens_DistData.csv'),'Delimiter',',')
                
            end
            
        end

        % Value changed function: AddNewClasstoListEditField
        function AddNewClasstoListEditFieldValueChanged(app, event)
            value = app.AddNewClasstoListEditField.Value;
            
            if ~isempty(value)
                if length(app.AvailableClassesDropDown.Items)>0
                    old_items = app.AvailableClassesDropDown.Items;
                    app.AvailableClassesDropDown.Items = [old_items;value];
                    app.AvailableClassesDropDown.Value = value;
                    app.ann_value = find(strcmp(app.AvailableClassesDropDown.Items,value));
                    
                    % Adding new zero value to table
                    old_numbers = app.UITable5.Data;
                    app.UITable5.Data = [old_numbers;{value,0}];
                    
                    % Appending to new_annot counter
                    app.new_annot = [app.new_annot;zeros(1,length(app.Current_Img))];
                    
                else
                    app.AvailableClassesDropDown.Enable = 'on';
                    app.NewLabelButton.Enable = 'on';
                    old_items = app.AvailableClassesDropDown.Items;
                    app.AvailableClassesDropDown.Items = [old_items,value];
                    app.AvailableClassesDropDown.Value = value;
                    app.ann_value = find(strcmp(app.AvailableClassesDropDown.Items,value));
                    
                    % Initializing number of annotated images table
                    app.UITable5.Data = {value,0};
                    
                    % Initializing new_annot counter
                    app.new_annot = zeros(1,length(app.Current_Img));
                end
                
                app.AddNewClasstoListEditField.Value = '';
            end
            
        end

        % Value changed function: AvailableClassesDropDown
        function AvailableClassesDropDownValueChanged(app, event)
            value = app.AvailableClassesDropDown.Value;
            
            if ~strcmp(value,'Add Classes to list')
                app.ann_value = find(strcmp(app.AvailableClassesDropDown.Items,value));
                
            end
            
        end

        % Button pushed function: NewLabelButton
        function NewLabelButtonPushed(app, event)
           
            Gen_Annotation(app,event)
            
        end

        % Button pushed function: ResetAnnotationButton
        function ResetAnnotationButtonPushed(app, event)
            
            app.Red_Mask = zeros(size(app.Current_Img{1},1),size(app.Current_Img{1},2));
            axes(app.UIAxes)
            cla(app.UIAxes)
            imshow(app.Current_Img{1})
            
            % Deleting saved annotated image
            if app.Comparing
                img_name = app.Red_Comp_Image.Value;
                img_name = strsplit(img_name,',');
                img_name = img_name{1};
            else
                img_name = app.Image_Name_Label.Value;
                img_name = strsplit(img_name,',');
                img_name = img_name{1};
            end

            ann_dir = [app.Slide_Path,filesep,img_name,filesep,'Annotated_Images',filesep,...
                app.AvailableClassesDropDown.Value,filesep];
            delete([ann_dir,img_name,'_ann.png'])
            
            % Subtracting one from the number of annotated images
            ann_idx = find(strcmp(app.UITable5.Data.ClassName,app.AvailableClassesDropDown.Value));
            old_val = app.UITable5.NumberofAnnotatedImages(ann_idx);
            app.UITable5.NumberofAnnotatedImages(ann_idx) = old_val-1;
            
            
        end

        % Button pushed function: ResetAnnotationButton_2
        function ResetAnnotationButton_2Pushed(app, event)
            
            app.Blue_Mask = zeros(size(app.Current_Img{2},1),size(app.Current_Img{2},2));
            axes(app.UIAxes2)
            cla(app.UIAxes2)
            imshow(app.Current_Img{2})
            
            % Deleting saved annotated image
            img_name = app.Blue_Comp_Image.Value;
            ann_dir = [app.Slide_Path,filesep,img_name,filesep,'Annotated_Images',filesep,...
                app.AvailableClassesDropDown.Value,filesep];
            delete([ann_dir,img_name,'_ann.png'])
            
            % Subtracting one from the number of annotated images
            ann_idx = find(strcmp(app.UITable5.Data.ClassName,app.AvailableClassesDropDown.Value));
            old_val = app.UITable5.NumberofAnnotatedImages(ann_idx);
            app.UITable5.NumberofAnnotatedImages(ann_idx) = old_val-1;
            
        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            checkedNodes = app.Tree.CheckedNodes;
            selectedNodes = app.Tree.SelectedNodes;
            
            class_opts = fieldnames(app.Aligned_Labels.(app.Structure));
            
            % If user selects parent node then alert and don't do anything
            if ismember(selectedNodes.Text,class_opts)
                f = msgbox('Do NOT click a parent label node');
                
                Fix_Nodes(app,event)
            else
                
                if isempty(app.Subset_Master)
                    app.Subset_Master.(app.Structure) = [];
                end
                if ~ismember(app.Structure,fieldnames(app.Subset_Master))
                    app.Subset_Master.(app.Structure) = [];
                end
               

                if ~any(ismember(selectedNodes,app.Subset_Master.(app.Structure)))
                    app.Subset_Master.(app.Structure) = [app.Subset_Master.(app.Structure),selectedNodes];
                else                
                    app.Subset_Master.(app.Structure)(find(strcmp(selectedNodes.Text,{app.Subset_Master.(app.Structure).Text}))) = [];
                end

                % Updating and displaying number of samples that would be
                % remaining
                data_ind = Subset_Data(app,event);
                app.ofSamplesIncludedLabel.Text = strcat(num2str(sum(data_ind)),'_Samples Included');

            end

            if ~isempty(app.Subset_Master)
                app.ResetDataButton.Enable = 'on';
            else
                app.ResetDataButton.Enable = 'off';
            end


        end

        % Button pushed function: SubsetDataButton
        function SubsetDataButtonPushed(app, event)
            
            data_ind = Subset_Data(app,event);
            
            if length(data_ind)>0
                % Subset data based on checked boxes
                app.checked_master.(app.Structure) = app.Tree.CheckedNodes;
                
                Save_Notes(app,event)

                % Resetting Normalization Information
                Reset_Normalization_Tabs(app)
                
                % Updating subsetting the data
                app.Comparing = false;
                app.SaveRedROIImagesButton.Enable = 'off';
                app.SaveBlueROIImagesButton.Enable = 'off';
                Flip_Image_Listbox(app,event)
                Plot_Feat(app,event)
                Gen_Map(app,event)
                View_Image(app,event)
                Load_Notes(app,event)
            else
                f = msgbox('Uh Oh! You subsetted all the samples away!\n Please de-select some categories');
            end
                
        end

        % Value changed function: SelectLabelDropDown
        function SelectLabelDropDownValueChanged(app, event)
            value = app.SelectLabelDropDown.Value;
            
            current_feature = app.SelectLabelDropDown.Value;
            % innerjoin sorts by default, thx Matlab
            [T,rows_in_T] = innerjoin(app.base_Feature_set.(app.Structure),app.Aligned_Labels.(app.Structure).(current_feature).Aligned);
            [~,sortinds] = sort(rows_in_T);
            app.Full_Feature_set.(app.Structure) = T(sortinds,:);
            
            if isnumeric(app.Full_Feature_set.(app.Structure).Class)
                app.LabelType.Text = 'Label Type: Numeric';
                app.NumericLabelOptionsButtonGroup.Enable = 'on';
                app.NumericLabelOptionsButtonGroup.Visible = 'on';
                app.NumericLabelOptionsButtonGroup.SelectedObject = app.NumericLabelOptionsButtonGroup.Buttons(1);
                app.CutoffValueEditField.Enable = 'off';
                app.CutoffValueEditField.Value = 0;
                
                app.CategoricalLabelOptionsButtonGroup.Enable = 'off';
                app.CategoricalLabelOptionsButtonGroup.Visible = 'off';
            else
                app.LabelType.Text = 'Label Type: Categorical';
                
                app.NumericLabelOptionsButtonGroup.Enable = 'off';
                app.NumericLabelOptionsButtonGroup.Visible = 'off';
                
                app.CategoricalLabelOptionsButtonGroup.Enable = 'on';
                app.CategoricalLabelOptionsButtonGroup.Visible = 'on';
                app.CategoricalLabelOptionsButtonGroup.SelectedObject = app.CategoricalLabelOptionsButtonGroup.Buttons(1);
                app.AddComboButton.Enable = 'off';
                app.Combine1DropDown.Enable = 'off';
                app.Combine1DropDown.Items = {'Select One'};
                app.Combine2DropDown.Enable = 'off';
                app.Combine2DropDown.Items = {'Select One'};
                           
            end
            
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';
            app.New_Label = true;
        
            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            % Changing visibility of comparison listboxes
            Flip_Image_Listbox(app,event)
            Plot_Feat(app,event)
            Gen_Map(app,event)      
            View_Image(app,event)      
            Load_Notes(app,event)      
            app.New_Label = false;
            
        end

        % Value changed function: RemoveOutliersCheckBox
        function RemoveOutliersCheckBoxValueChanged(app, event)
            value = app.RemoveOutliersCheckBox.Value;
            
            app.rem_out = value;
            if ~value
                app.OutlierTable.Visible = 'off';
                app.OutlierTable.Data = [];
            end
            
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';

            Save_Notes(app,event)

            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            Flip_Image_Listbox(app,event)
            Plot_Feat(app,event)
            Gen_Map(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            

        end

        % Value changed function: CombinedClassNameEditField
        function CombinedClassNameEditFieldValueChanged(app, event)
            value = app.CombinedClassNameEditField.Value;
            
            % Enabling the first combo drop down menu after a name has been
            % written in
            if ~isempty(value)
                app.CombinedClassNameEditField.Enable = 'off';
                app.Combine1DropDown.Enable = 'on';
                
            end
            
        end

        % Value changed function: Combine1DropDown
        function Combine1DropDownValueChanged(app, event)
            value = app.Combine1DropDown.Value;
            
            % Enabling the second combo drop down menu after the first one
            % has been selected
            if ~strcmp(value,'Select One')
                app.Combine1DropDown.Enable = 'off';
                
                % Removing previous value from next combo drop down menu
                label_idx = find(ismember(app.Combine2DropDown.Items,value));
                if ~isempty(label_idx)
                    app.Combine2DropDown.Items(label_idx) = [];
                end
                app.Combine2DropDown.Enable = 'on';
                
                
            end
            
        end

        % Value changed function: Combine2DropDown
        function Combine2DropDownValueChanged(app, event)
            value = app.Combine2DropDown.Value;
            
            % Getting second label combo
            if ~strcmp(value,'Select One')
                app.Combine2DropDown.Enable = 'off';
                
                new_name = app.CombinedClassNameEditField.Value;
                
                % Aligning full feature set and Dist_Data along 'Class'
                app.Dist_Data.Class = [];
                
                [T,rows_in_T] = innerjoin(app.Dist_Data,...
                    app.Full_Feature_set.(app.Structure)(:,ismember(app.Full_Feature_set.(app.Structure).Properties.VariableNames,...
                    {'ImgLabel','Class'})));
                [~,sort_inds] = sort(rows_in_T);
                app.Dist_Data = T(sort_inds,:);
                
                % Resetting items in Dist_Data to new combined class name
                app.Dist_Data.Class(find(strcmp(app.Dist_Data.Class,app.Combine1DropDown.Value) ...
                    | strcmp(app.Dist_Data.Class,value))) = {new_name};
                
                app.New_Label = true;
                app.Combine_Label = true;
                Plot_Feat(app,event)
                app.New_Label = false;
                app.Combine_Label = false;

                
            end
            
        end

        % Selection changed function: CategoricalLabelOptionsButtonGroup
        function CategoricalLabelOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.CategoricalLabelOptionsButtonGroup.SelectedObject;
            
            Organize_Labels(app,event)
            
        end

        % Selection changed function: NumericLabelOptionsButtonGroup
        function NumericLabelOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.NumericLabelOptionsButtonGroup.SelectedObject;
            
            Organize_Labels(app,event)
        end

        % Value changed function: CutoffValueEditField
        function CutoffValueEditFieldValueChanged(app, event)
            value = app.CutoffValueEditField.Value;
            
            if ~isempty(value)
                
                app.Dist_Data.Class = [];
        
                [T,rows_in_T] = innerjoin(app.Dist_Data,...
                    app.Full_Feature_set.(app.Structure)(:,ismember(app.Full_Feature_set.(app.Structure).Properties.VariableNames,...
                    {'ImgLabel','Class'})));
                [~,sortinds] = sort(rows_in_T);
                app.Dist_Data = T(sortinds,:);
        
                label_sub_in = cell(height(app.Dist_Data),1);
                
                label_sub_in(find(app.Dist_Data.Class<=value)) = {strcat('Below ',num2str(value))};
                label_sub_in(find(app.Dist_Data.Class>value)) = {strcat('Above ',num2str(value))};
                
                app.Dist_Data.Class = [];
                app.Dist_Data.Class = label_sub_in;
                app.New_Label = true;
                app.Combine_Label = true;
                Plot_Feat(app,event)
                View_Image(app,event)
                app.New_Label = false;
                app.Combine_Label = false;
                
            end
            
        end

        % Value changed function: FeatureRankingsUsedDropDown
        function FeatureRankingsUsedDropDownValueChanged(app, event)
            value = app.FeatureRankingsUsedDropDown.Value;
            
            % Changing to different set of feature scores
            app.weighted_encodings = Organize_Feat_Ranks(app,event);
            
            Gen_Map(app,event)
            View_Image(app,event)
            Load_Notes(app,event)
            
            
        end

        % Key press function: figure1
        function figure1KeyPress(app, event)
            key = event.Key;

            if isequal(event.Modifier,{'shift'})
                switch key
                    case 'uparrow'
                        
                        % increasing value of Rank_Slide
                        if app.Rank_Slide.Value < 1-1/length(app.map_idx)
                            app.Rank_Slide.Value = app.Rank_Slide.Value+1/length(app.map_idx);
                            update_rank(app,event)
                        end
                        
                    case 'downarrow'
                        
                        % decreasing value of Rank_Slide
                        if app.Rank_Slide.Value>0+1/length(app.map_idx)
                            app.Rank_Slide.Value = app.Rank_Slide.Value-1/length(app.map_idx);
                            update_rank(app,event)
                        end
                    case 'rightarrow'
                        
                        % Shifting selected tab up 1
                        tabs = {'Feature Distribution Plot','Statistical Measures',...
                            'Classification Models','Relative Feature Intensity',...
                            'Add Annotations','Feature Definitions','Export Images'};
                        current_tab_idx = find(strcmp(app.TabGroup2.SelectedTab.Title,tabs));
                        if current_tab_idx ~= length(tabs)
                            app.TabGroup2.SelectedTab = app.TabGroup2.Children(current_tab_idx+1);
                        else
                            app.TabGroup2.SelectedTab = app.TabGroup2.Children(1);
                        end

                    case 'leftarrow'
                        
                        % Shifting selected tab down 1
                        tabs = {'Feature Distribution Plot','Statistical Measures',...
                            'Classification Models','Relative Feature Intensity',...
                            'Add Annotations','Feature Definitions'};
                        current_tab_idx = find(strcmp(app.TabGroup2.SelectedTab.Title,tabs));
                        if current_tab_idx ~= 1
                            app.TabGroup2.SelectedTab = app.TabGroup2.Children(current_tab_idx-1);
                        else
                            app.TabGroup2.SelectedTab = app.TabGroup2.Children(length(tabs));
                        end
                        
                end
            else
                switch key
                    case 'uparrow'
                        
                        % increasing value of Heat_Slide
                        if app.Heat_Slide.Value<0.9
                            app.Heat_Slide.Value = app.Heat_Slide.Value+0.1;
                            update_trans(app,event)
                        end
                        
                    case 'downarrow'
                        
                        % decreasing value of Heat_Slide
                        if app.Heat_Slide.Value>0.1
                            app.Heat_Slide.Value = app.Heat_Slide.Value-0.1;
                            update_trans(app,event)
                        end
                        
                    case 'rightarrow'
                        
                        % Going to next image            
                        % Resetting compartment mask view buttons
                        app.PAS_Butt.Text = 'PAS+';
                        app.Lum_Butt.Text = 'Luminal Space';
                        app.Nuc_Butt.Text = 'Nuclei';
                        
                        Save_Notes(app,event)
                        
                        %next_count = app.img_count+1;
                        
                        if app.Comparing
                            cla(app.Red_Img_Ax)
                            cla(app.Blue_Img_Ax)
                            
                            current_red = find(strcmp(app.Red_Comp_Image.Items,app.Red_Comp_Image.Value));
                            next_count = current_red+1;
                            
                            if next_count > length(app.Red_Comp_Image.Items)
                                next_count = 1;
                            end
                            
                            current_blue = find(strcmp(app.Blue_Comp_Image.Items,app.Blue_Comp_Image.Value));
                            next_comp_count = current_blue+1;
                            
                            %next_comp_count = app.comp_img_count+1;
                            if next_comp_count > length(app.Blue_Comp_Image.Items)
                                next_comp_count = 1;
                            end
                            %app.comp_img_count = next_comp_count;
                            %app.img_count = next_count;
                        
                            app.Red_Comp_Image.Value = app.Red_Comp_Image.Items(next_count);
                            app.Blue_Comp_Image.Value = app.Blue_Comp_Image.Items(next_comp_count);
                        
                        else
                            cla(app.Img_Ax)
                            
                            current_red = find(strcmp(app.Image_Name_Label.Items,app.Image_Name_Label.Value));
                            next_count = current_red+1;
                            
                            if next_count > length(app.Image_Name_Label.Items)
                                next_count = 1;
                            end

                            %app.img_count = next_count;
                            app.Image_Name_Label.Value = app.Image_Name_Label.Items(next_count);
                        
                        end
                        
                        Gen_Map(app,event)
                        
                        View_Image(app,event)
                        
                        % Loading notes for current image
                        Load_Notes(app,event)
                        
                    case 'leftarrow'
                        
                        % Go to previous image
                        
                        % Resetting compartment mask view buttons
                        app.PAS_Butt.Text = 'PAS+';
                        app.Lum_Butt.Text = 'Luminal Space';
                        app.Nuc_Butt.Text = 'Nuclei';
                        
                        Save_Notes(app,event)
                        
                        %prev_count = app.img_count-1;
                        
                        if app.Comparing
                            cla(app.Red_Img_Ax)
                            cla(app.Blue_Img_Ax)
                            
                            current_red = find(strcmp(app.Red_Comp_Image.Items, app.Red_Comp_Image.Value));
                            prev_count = current_red-1;
                            
                            if prev_count<1
                                prev_count = length(app.Red_Comp_Image.Items);
                            end
                            
                            current_blue = find(strcmp(app.Blue_Comp_Image.Items, app.Blue_Comp_Image.Value));
                            prev_comp_count = current_blue-1;
                            
                            %prev_comp_count = app.comp_img_count-1;
                            if prev_comp_count<1
                                prev_comp_count = length(app.Blue_Comp_Image.Items);
                            end
                            %app.comp_img_count = prev_comp_count;
                            %app.img_count = prev_count;
                        
                            app.Red_Comp_Image.Value = app.Red_Comp_Image.Items(prev_count);
                            app.Blue_Comp_Image.Value = app.Blue_Comp_Image.Items(prev_comp_count);
                        
                        else
                            cla(app.Img_Ax)
                            
                            current_red = find(strcmp(app.Image_Name_Label.Items, app.Image_Name_Label.Value));
                            prev_count = current_red-1;
                            
                            if prev_count < 1
                                prev_count = length(app.Image_Name_Label.Items);
                            end
                            
                            %app.img_count = prev_count;
                            app.Image_Name_Label.Value = app.Image_Name_Label.Items(prev_count);
                        
                        end
                        
                        Gen_Map(app,event)
                        View_Image(app,event)
                        
                        % Loading notes from that image
                        Load_Notes(app,event)
                    
                end
            end


        end

        % Image clicked function: InfoSym_Comparisons
        function InfoSym_ComparisonsClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Use these buttons to view samples within the Feature Distribution Plot';...
                'Select New Region and Select Comparison use FreeHand ROIs';...
                'Select Single Point to view individual sample'; ...
                'Select Points Along Line to view multiple random examples between two points'},...
                'Selecting Comparisons','custom',imread('logo.png'));
            
        end

        % Image clicked function: InfoSym_Labels
        function InfoSym_LabelsImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Use this tab to change how samples are labeled within the Feature Distribution Plot';...
                'Select Label chooses from labels provided in the File Input window';...
                'Depending on the data type of labels, additional customization will be available below';...
                'If the labels are categorical, you can combine two different labels to make one single category';...
                'If the labels are quantitative, you can choose to view all values, view quartile binned values (Q1, Median, Q3), or set a binary threshold for two categories'});
            
        end

        % Image clicked function: InfoSym_Subsetting
        function InfoSym_SubsettingImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Use this tab to subset the samples that are included in the analysis';...
                'Click on specific sub-categories under each label to include/exclude and then click Subset Data to confirm choice';...
                'The number of samples included will be listed above the Subset Data button'});
        end

        % Image clicked function: InfoSym_Outliers
        function InfoSym_OutliersImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Check this box to remove outliers in the Feature Distribution Plot';...
                'Any sample that is outside 3 standard deviations will be excluded.'});
            
        end

        % Image clicked function: InfoSym_CompMask
        function InfoSym_CompMaskImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Click on one of the buttons below in order to view the sub-compartment segmentation mask'});
            
        end

        % Image clicked function: InfoSym_CompList
        function InfoSym_CompListImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Click on a compartment below to see all of the feature types and specific features for that compartment';...
                'To view the combined feature distributions and feature visualizations for that compartment, click View Compartment Features'});
            
        end

        % Image clicked function: InfoSym_TypeList
        function InfoSym_TypeListImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Click on a feature type below to see all of the specific features within this feature sub-category';...
                'To view the combined feature distributions and feature visualizations for this type of feature within this compartment, click View Feature Type'});
            
        end

        % Image clicked function: InfoSym_SpecList
        function InfoSym_SpecListImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Click on a specific feature below that is within the feature sub-category in the Feature Type listbox and in the compartment in the Compartment listbox';...
                'To view the distribution of this feature across the current dataset, click View Specific Feature'});
            
        end

        % Image clicked function: InfoSym_CustList
        function InfoSym_CustListImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Add features to a custom list below by selecting specific features and then clicking Add Feature to Custom';...
                'Remove a feature from this list by selecting it and then clicking Remove from Custom';...
                'This list allows for the viewing of feature distributions and feature visualizations from different categories and compartments'});
            
        end

        % Image clicked function: InfoSym_ImgSliders
        function InfoSym_ImgSlidersImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'These sliders adjust the feature visualizations overlaid onto the image(s) in the central axis(es)';...
                'Move the Heatmap Transparency slider up and down using the mouse or the up and down arrow keys to adjust the transparency of the feature visualization';...
                'Move the Minimum Feature Rank slider up and down using the mouse or Shift+up and Shift+down arrow keys to adjust which features are included in the feature visualization based on current feature ranks'});
            
        end

        % Image clicked function: InfoSym_FeatRank
        function InfoSym_FeatRankImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Select Feature ranks from the dropdown menu to change feature visualization weighting and to see relative feature ranks in the Relative Feature Intensity tab';...
                'These feature rankings are derived from the initial File Input window';...
                'Feature rankings are determined by summing together the relative rankings determined by multiple feature selection methods including MRMR, ReliefF, and Chi-Square';...
                'Features with a higher ranking (lower value, e.g. 1 = highest ranked) are more informative towards a specific classification task'});
            
        end

        % Image clicked function: InfoSym_SimThresh
        function InfoSym_SimThreshImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Adjust this value for features that are mapped using the Similarity Map method';...
                'This value means that all objects/pixels in the feature visualization have a feature value within this percentage of the value for the image as a whole';...
                'Increasing this value will increase the acceptance range for objects to be included while decreasing it will only include objects closer to the feature value for the image'});
            
        end

        % Image clicked function: InfoSym_TextWin
        function InfoSym_TextWinImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Adjusting this value will increase/decrease the sliding window size used to generate the texture feature visualizations';...
                'Increasing the size of the sliding window will result in squares in the image to be selected and weighted and will also reduce time required to generate visualization';...
                'Decrease the size of the sliding window to view more fine grained texture distributions across a sub-compartment'});
            
        end

        % Image clicked function: InfoSym_PersLabels
        function InfoSym_PersLabelsImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Click Add Label to "put a pin" in the current image(s) for later';...
                'Image labels in this list appear persistently in the Feature Distribution Plot and can be referred back to by clicking on their labels';...
                'Remove a label by clicking on the desired (or undesired) label and clicking Remove Label'});
            
        end

        % Image clicked function: InfoSym_Notes
        function InfoSym_NotesImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Notes recorded in the below field(s) are saved to an external ".csv" file with a table where one column is an image label and the other is the notes recorded about that image';...
                'Use the buttons below the notes field(s) to subset samples based on recorded notes or to bulk add a note to all samples within a selected region'});
            
        end

        % Image clicked function: InfoSym_Stats
        function InfoSym_StatsImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Statistics determined using values plotted in current Feature Distribution Plot';...
                'To run your own statistics, click on the Download Distribution Data button'});
            
        end

        % Image clicked function: InfoSym_RelFeat
        function InfoSym_RelFeatImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'This tab shows relative feature intensity within the rectangular ROIs in the central image(s)';...
                'Move the rectangular ROIs around in order to see what feature values (when individual features are being viewed) or which features (when viewing multiple) are expressed in a given area';...
                'If feature rankings are provided, the relative feature rank(s) will be listed either in the plot title (for individual features) or in the table below the relative intensity plot'});
            
        end

        % Image clicked function: InfoSym_Annotation
        function InfoSym_AnnotationImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Annotate custom classes within the image(s) currently displayed in central axis';...
                'Name a class to add it to the table below and then a freehand ROI will allow you to annotate on the image';...
                'Annotation masks are saved to a folder in your current directory with (Slide Name)/(Annotated Class)/(Image name)+"_ann.png"'});
            
        end

        % Image clicked function: InfoSym_FeatDef
        function InfoSym_FeatDefImageClicked(app, event)
            
            % Information symbol clickie
            f = msgbox({'Feature definitions and the methods used to derive feature visualizations are listed below'});
            
        end

        % Button pushed function: FitTreeButton
        function FitTreeButtonPushed(app, event)
            
            app.FitTreeButton.Enable = 'off';

            app.FitNBButton.Enable = 'on';
            app.FitDiscrimButton.Enable = 'on';
            app.FitNeuralNetworkButton.Enable = 'on';

            
            [train_data,test_data,test_labels] = Prep_Classification(app);
            
            if app.TrainTestSplitButton.Value

                if ~isnumeric(app.Full_Feature_set.(app.Structure).Class)
                    app.Current_Model = fitctree(train_data,'Class','MaxNumSplits',app.MaxNumberofSplitsEditField.Value);
                else
                    app.Current_Model = fitctree(train_data,'Class','MaxNumCategories',app.MaxNumberofCategoriesEditField.Value,...
                        'MaxNumSplits',app.MaxNumberofSplitsEditField.Value);
                end

                % Testing model on test data
                predictions = predict(app.Current_Model,test_data);
                app.OpenClassificationTreeViewerButton.Enable = 'on';

            else
                if ~isnumeric(app.Full_Feature_set.(app.Structure).Class)
                    app.Current_Model = fitctree(train_data,'Class','MaxNumSplits',app.MaxNumberofSplitsEditField.Value,...
                        'KFold',app.kfoldEditField.Value);
                    
                else
                    app.Current_Model = fitctree(train_data,'Class','MaxNumCategories',app.MaxNumberofCategoriesEditField.Value,...
                        'MaxNumSplits',app.MaxNumberofSplitsEditField.Value,'KFold',app.kfoldEditField.Value);
                end

                % Testing 5-fold CV model on training data
                predictions = kfoldPredict(app.Current_Model);
                
                app.OpenClassificationTreeViewerButton.Enable = 'off';

            end
            
            app.SaveModelButton.Enable = 'on';
            app.GeneratePerformanceReportButton.Enable = 'on';
            
            Results_Classification(app,test_data,test_labels,predictions)
            
        end

        % Callback function
        function TrainTestSplitEditFieldValueChanged(app, event)
            value = app.TrainTestSplitEditField.Value;
            
            app.FitTreeButton.Enable = 'on';
            
        end

        % Value changed function: MaxNumberofSplitsEditField
        function MaxNumberofSplitsEditFieldValueChanged(app, event)
            value = app.MaxNumberofSplitsEditField.Value;
            
            app.FitTreeButton.Enable = 'on';
        end

        % Value changed function: MaxNumberofCategoriesEditField
        function MaxNumberofCategoriesEditFieldValueChanged(app, event)
            value = app.MaxNumberofCategoriesEditField.Value;
            
            app.FitTreeButton.Enable = 'on';
        end

        % Button pushed function: OpenClassificationTreeViewerButton
        function OpenClassificationTreeViewerButtonPushed(app, event)
            
            view(app.Current_Model,'Mode','graph')

        end

        % Selection changed function: TrainingSplitOptionsButtonGroup
        function TrainingSplitOptionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrainingSplitOptionsButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Train/Test Split')
                app.TrainTestSplitEditField.Enable = 'on';
                app.kfoldEditField.Enable = 'off';
            else
                app.TrainTestSplitEditField.Enable = 'off';
                app.kfoldEditField.Enable = 'on';
            end
            
            app.FitTreeButton.Enable = 'on';
            app.FitDiscrimButton.Enable = 'on';
            app.FitNBButton.Enable = 'on';


        end

        % Button pushed function: FitNBButton
        function FitNBButtonPushed(app, event)
            
            app.FitNBButton.Enable = 'off';
            app.FitDiscrimButton.Enable = 'on';
            app.FitTreeButton.Enable = 'on';
            app.FitNeuralNetworkButton.Enable = 'on';

            
            [train_data,test_data,test_labels] = Prep_Classification(app);
            
            try
                if app.TrainTestSplitButton.Value
    
                    app.Current_Model = fitcnb(train_data,'Class',...
                        'Kernel',app.KernelSmootherDropDown.Value,...
                        'DistributionNames',app.DistributionNamesDropDown.Value);
    
                    % Testing model on test data
                    predictions = predict(app.Current_Model,test_data);
                else
                    if ~isnumeric(app.Full_Feature_set.(app.Structure).Class)
                        app.Current_Model = fitcnb(train_data,'Class',...
                            'Kernel',app.KernelSmootherDropDown.Value,...
                            'DistributionNames',app.DistributionNamesDropDown.Value,...
                            'KFold',app.kfoldEditField.Value);
                    else
                        app.Current_Model = fitcnb(train_data,'Class',...
                            'Kernel',app.KernelSmootherDropDown.Value,...
                            'DistributionNames',app.DistributionNamesDropDown.Value,...
                            'KFold',app.kfoldEditField.Value);
                    end
    
                    % Testing 5-fold CV model on training data
                    predictions = kfoldPredict(app.Current_Model);
                end
                
                app.SaveModelButton.Enable = 'on';
                app.GeneratePerformanceReportButton.Enable = 'on';
                
                Results_Classification(app,test_data,test_labels,predictions)
            catch
                f = msgbox('Invalid Combination, Try something else');
            end

        end

        % Value changed function: DistributionNamesDropDown
        function DistributionNamesDropDownValueChanged(app, event)
            value = app.DistributionNamesDropDown.Value;
            
            app.FitNBButton.Enable = 'on';
            

        end

        % Value changed function: KernelSmootherDropDown
        function KernelSmootherDropDownValueChanged(app, event)
            value = app.KernelSmootherDropDown.Value;
            
            app.FitNBButton.Enable = 'on';
            
        end

        % Button pushed function: FitDiscrimButton
        function FitDiscrimButtonPushed(app, event)
            
            app.FitDiscrimButton.Enable = 'off';
            app.FitNBButton.Enable = 'on';
            app.FitTreeButton.Enable = 'on';
            app.FitNeuralNetworkButton.Enable = 'on';

            [train_data,test_data,test_labels] = Prep_Classification(app);

            if app.TrainTestSplitButton.Value
                
                if strcmp(app.GammaRegularizationlevelEditField.Enable,'on')
                    app.Current_Model = fitcdiscr(train_data,'Class',...
                        'Gamma',app.GammaRegularizationlevelEditField.Value,...
                        'DiscrimType',app.DiscriminantTypeDropDown.Value);
                else
                    app.Current_Model = fitcdiscr(train_data,'Class',...
                        'DiscrimType',app.DiscriminantTypeDropDown.Value);
                end

                % Testing model on test data
                predictions = predict(app.Current_Model,test_data);
            else
                if ~isnumeric(app.Full_Feature_set.(app.Structure).Class)
                    if strcmp(app.GammaRegularizationlevelEditField.Enable,'on')
                        app.Current_Model = fitcdiscr(train_data,'Class',...
                            'Gamma',app.GammaRegularizationlevelEditField.Value,...
                            'DiscrimType',app.DiscriminantTypeDropDown.Value,...
                            'KFold',app.kfoldEditField.Value);
                    else
                        app.Current_Model = fitcdiscr(train_data,'Class',...
                            'DiscrimType',app.DiscriminantTypeDropDown.Value,...
                            'KFold',app.kfoldEditField.Value);
                    end
                else
                    if strcmp(app.GammaRegularizationlevelEditField.Enable,'on')
                        app.Current_Model = fitcdiscr(train_data,'Class',...
                            'Gamma',app.GammaRegularizationlevelEditField.Value,...
                            'DiscrimType',app.DiscriminantTypeDropDown.Value,...
                            'KFold',app.kfoldEditField.Value);
                    else
                        app.Current_Model = fitcdiscr(train_data,'Class',...
                            'DiscrimType',app.DiscriminantTypeDropDown.Value,...
                            'KFold',app.kfoldEditField.Value);
                    end
                end

                % Testing 5-fold CV model on training data
                predictions = kfoldPredict(app.Current_Model);
            end
            
            app.SaveModelButton.Enable = 'on';
            app.GeneratePerformanceReportButton.Enable = 'on';
            
            Results_Classification(app,test_data,test_labels,predictions)


        end

        % Value changed function: GammaRegularizationlevelEditField
        function GammaRegularizationlevelEditFieldValueChanged(app, event)
            value = app.GammaRegularizationlevelEditField.Value;
            
            app.FitDiscrimButton.Enable = 'on';
        end

        % Value changed function: DiscriminantTypeDropDown
        function DiscriminantTypeDropDownValueChanged(app, event)
            value = app.DiscriminantTypeDropDown.Value;
            
            if strcmp(value,'linear')
                app.GammaRegularizationlevelEditField.Enable = 'on';
            else
                app.GammaRegularizationlevelEditField.Enable = 'off';
            end
            
            app.FitDiscrimButton.Enable = 'on';
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            
            % Save model to an individual folder along with additional file
            % listing features used to generate model as well as
            % training/testing information
            Save_Model(app)
            

        end

        % Button pushed function: GeneratePerformanceReportButton
        function GeneratePerformanceReportButtonPushed(app, event)
            
            % Save Performance_Report object to path
            
            model_type = class(app.Current_Model);
            slide_dir = app.Slide_Path;
            
            t = now;
            d = datetime(t,'ConvertFrom','datenum','Format','dd-MMM-uuuu HH:mm:ss');
            current_time = strrep(strrep(string(d),' ','__'),':','_');
            
            model_dir = strcat(slide_dir,filesep,'HistoLens_Models_Archive',filesep);
            if ~exist(model_dir,'dir')
                mkdir(model_dir)
            end
            
            model_filename = strcat(model_dir,model_type,'_',current_time);
            if ~exist(model_filename,'file')
                mkdir(model_filename)
            end
            
            writecell(app.Performance_Report.Model_Performance,strcat(model_filename,'.xlsx'),'Sheet','Model Performance')
            writetable(app.Performance_Report.Test_Samples,strcat(model_filename,'.xlsx'),'Sheet','Test Samples')
            writematrix(app.Performance_Report.ClassLabel,strcat(model_filename,'.xlsx'),'Sheet','Class Label')
            writecell(app.Performance_Report.FeaturesUsed,strcat(model_filename,'.xlsx'),'Sheet','Features Used')


        end

        % Value changed function: TrainTestSplitEditField
        function TrainTestSplitEditFieldValueChanged2(app, event)
            value = app.TrainTestSplitEditField.Value;
            
            app.FitTreeButton.Enable = 'on';
            app.FitDiscrimButton.Enable = 'on';
            app.FitNBButton.Enable = 'on';
            app.FitNeuralNetworkButton.Enable = 'on';

        end

        % Value changed function: kfoldEditField
        function kfoldEditFieldValueChanged(app, event)
            value = app.kfoldEditField.Value;
            
            app.FitTreeButton.Enable = 'on';
            app.FitDiscrimButton.Enable = 'on';
            app.FitNBButton.Enable = 'on';
            app.FitNeuralNetworkButton.Enable = 'on';

        end

        % Value changed function: NumberofLayersEditField
        function NumberofLayersEditFieldValueChanged(app, event)
            value = app.NumberofLayersEditField.Value;
            
            % Adding layer names as options in Drop Down
            layer_names = repmat({'Layer'},value,1);
            layer_names = [layer_names,strsplit(num2str((1:value)),' ')'];
            layer_names = cellfun(@(x,y) [x,' ',y],layer_names(:,1),layer_names(:,2),'UniformOutput',false);

            app.LayerNameDropDown.Items = layer_names;
            app.LayerNameDropDown.Value = layer_names{1};

            % Updating the NN_Layer_Dims property
            if length(layer_names)>length(app.NN_Layer_Dims)
                diff = length(layer_names)-length(app.NN_Layer_Dims);
                app.NN_Layer_Dims = [app.NN_Layer_Dims,10.*ones(1,diff)];
            else
                diff = length(app.NN_Layer_Dims)-length(layer_names);
                app.NN_Layer_Dims = app.NN_Layer_Dims(1:length(app.NN_Layer_Dims)-diff);
            end
            
            app.FitNeuralNetworkButton.Enable = 'on';


        end

        % Value changed function: LayerNameDropDown
        function LayerNameDropDownValueChanged(app, event)
            value = app.LayerNameDropDown.Value;
            
            layer_idx = strsplit(value,' ');
            layer_idx = layer_idx{end};
            layer_idx = str2double(layer_idx);

            app.LayerSizeEditField.Value = app.NN_Layer_Dims(layer_idx);

            app.FitNeuralNetworkButton.Enable = 'on';

        end

        % Value changed function: LayerSizeEditField
        function LayerSizeEditFieldValueChanged(app, event)
            value = app.LayerSizeEditField.Value;

            layer_idx = app.LayerNameDropDown.Value;
            layer_idx = strsplit(layer_idx,' ');
            layer_idx = layer_idx{end};
            layer_idx = str2double(layer_idx);

            app.NN_Layer_Dims(layer_idx) = value;
            
        end

        % Button pushed function: FitNeuralNetworkButton
        function FitNeuralNetworkButtonPushed(app, event)

            app.FitNeuralNetworkButton.Enable = 'off';

            app.FitNBButton.Enable = 'on';
            app.FitDiscrimButton.Enable = 'on';
            app.FitTreeButton.Enable = 'on';
            
            [train_data,test_data,test_labels] = Prep_Classification(app);
            
            if app.TrainTestSplitButton.Value

                class_num = length(unique(app.Full_Feature_set.(app.Structure).Class));
                app.Current_Model = fitcnet(train_data,'Class',...
                    'LayerSizes',[app.NN_Layer_Dims,class_num]);

                % Testing model on test data
                predictions = predict(app.Current_Model,test_data);

            else
                class_num = length(unique(app.Full_Feature_set.(app.Structure).Class));
                app.Current_Model = fitcnet(train_data,'Class',...
                    'LayerSizes',[app.NN_Layer_Dims,class_num],...
                    'KFold',app.kfoldEditField.Value);
                    
                % Testing 5-fold CV model on training data
                predictions = kfoldPredict(app.Current_Model);
                
            end
            
            app.SaveModelButton.Enable = 'on';
            app.GeneratePerformanceReportButton.Enable = 'on';
            
            Results_Classification(app,test_data,test_labels,predictions)

        end

        % Button pushed function: AddCurrentModelPredictedLabelsButton
        function AddCurrentModelPredictedLabelsButtonPushed(app, event)
            
            app.CurrentModelLabelIDEditField.Enable = 'on';
            
        end

        % Button pushed function: AddLabelfromCurrentNotesButton
        function AddLabelfromCurrentNotesButtonPushed(app, event)
            
            app.NotesLabelIDEditField.Enable = 'on';
            
        end

        % Value changed function: CurrentModelLabelIDEditField
        function CurrentModelLabelIDEditFieldValueChanged(app, event)
            value = app.CurrentModelLabelIDEditField.Value;
            
            if ~isempty(value)
                if ~isnumeric(value(1))
                    value = strrep(value,' ','_');

                    app.Aligned_Labels.(app.Structure).(value).Aligned = app.Current_Model_Labels;
                    
                    sub_class = unique(app.Current_Model_Labels.Class);
                    
                    app.Aligned_Labels.(app.Structure).(value).Sub_Class = sub_class;

                    app.SelectLabelDropDown.Items = [app.SelectLabelDropDown.Items,value];
                    
                    N = uitreenode(app.Tree);
                    N.Text = value;
                  
                    for sub_cl = 1:length(sub_class)
                        sN = uitreenode(N);
                        sN.Text = sub_class{sub_cl};
                    end
        
                    app.checked_master.(app.Structure) = [app.checked_master.(app.Structure);N];
                    Fix_Nodes(app,event)
        
                    app.CurrentModelLabelIDEditField.Enable = 'off';
                    app.CurrentModelLabelIDEditField.Value = '';

                else
                    f = msgbox('Make sure the first character is not a number');
                end


            end
            

        end

        % Value changed function: NotesLabelIDEditField
        function NotesLabelIDEditFieldValueChanged(app, event)
            value = app.NotesLabelIDEditField.Value;
            
            if ~isempty(value)
                if ~isnumeric(value(1))
                    value = strrep(value,' ','_');

                    current_notes = app.Notes.(app.Structure);
                    current_notes.Properties.VariableNames = {'ImgLabel','Class'};
    
                    app.Aligned_Labels.(app.Structure).(value).Aligned = current_notes;
                    sub_class = unique(current_notes.Class);
                    app.Aligned_Labels.(app.Structure).(value).Sub_Class = sub_class;

                    app.SelectLabelDropDown.Items = [app.SelectLabelDropDown.Items,value];
    
                    N = uitreenode(app.Tree);
                    N.Text = value;
    
                    for sub_cl = 1:length(sub_class)
                        sN = uitreenode(N);
                        sN.Text = sub_class{sub_cl};
                    end
                    app.checked_master.(app.Structure) = [app.checked_master.(app.Structure);N];
                    Fix_Nodes(app,event)
        
                    app.NotesLabelIDEditField.Enable = 'off';
                    app.NotesLabelIDEditField.Value = '';

                else
                    f = msgbox('Make sure the first character is not a number');
                end


            end


        end

        % Button pushed function: AddStructureLabelfromFileButton
        function AddStructureLabelfromFileButtonPushed(app, event)
            
            %app.AddStructureLabelfromFileButton.Enable = 'off';

            [filename, path] = uigetfile('*','Select any Structure-level label files','MultiSelect','on');

            if ~path==0
                if iscell(filename)
                    for i = 1:length(filename)
                        current_file = strcat(path,filename{i});
                        new_labels = readtable(current_file,'Delimiter',',','ReadVariableNames',true,'VariableNamingRule','preserve');
                        new_labels.Properties.VariableNames = {'ImgLabel','Class'};

                        new_labels.Class(cell2mat(cellfun(@(x) isempty(x),new_labels.Class,'UniformOutput',false))) = {'Unlabeled'};
                        
                        new_label_name = strsplit(filename{i},'.csv');
                        new_label_name = new_label_name{1};

                        if ~isvarname(new_label_name)
                            if ~ismember('User_Input_Structure_Label_1',fieldnames(app.Aligned_Labels.(app.Structure)))
                                new_label_name = 'User_Input_Structure_Label_1';
                            else
                                current_labels = fieldnames(app.Aligned_Labels.(app.Structure));
                                user_input_labels = current_labels(contains(current_labels,'User_Input_Structure_Label'));
                                new_label_name = strcat('User_Input_Structure_Label_',num2str(length(user_input_labels)+1));
                            end
                        end
                                
                        % Checking if there is a label for every sample
                        if height(new_labels)== height(app.base_Feature_set.(app.Structure))
                            app.Aligned_Labels.(app.Structure).(new_label_name).Aligned = new_labels;
                            sub_class = unique(new_label.Class);
                            app.Aligned_Labels.(app.Structure).(new_label_name).Sub_Class = sub_class;
                        else
                            % Assigning all the other samples "Unlabeled"
                            non_overlap_names = app.base_Feature_set.(app.Structure).ImgLabel(~ismember(app.base_Feature_set.(app.Structure).ImgLabel,new_labels.ImgLabel));

                            new_table = table(non_overlap_names',repmat({'Unlabeled'},length(non_overlap_names),1),'VariableNames',{'ImgLabel','Class'});
                            new_label_table = [new_labels;new_table];
                            app.Aligned_Labels.(app.Structure).(new_label_name).Aligned = new_label_table;
                            sub_class = unique(new_label_table.Class);
                            app.Aligned_Labels.(app.Structure).(new_label_name).Sub_Class = sub_class;
                        end

                        app.SelectLabelDropDown.Items = [app.SelectLabelDropDown.Items,new_label_name];

                        N = uitreenode(app.Tree);
                        N.Text = new_label_name;

                        for sub_cl = 1:length(sub_class)
                            sN = uitreenode(N);
                            sN.Text = sub_class{sub_cl};
                        end
                        new_check_list(i) = N;
                    end
                    app.checked_master.(app.Structure) = [app.checked_master.(app.Structure);new_check_list];
                    Fix_Nodes(app,event)
                    
                else
                    current_file = strcat(path,filename);
                    new_labels = readtable(current_file,'Delimiter',',','ReadVariableNames',true,'VariableNamingRule','preserve');
                    new_labels.Properties.VariableNames = {'ImgLabel','Class'};
                    
                    new_labels.Class(cell2mat(cellfun(@(x) isempty(x),new_labels.Class,'UniformOutput',false))) = {'Unlabeled'};

                    new_label_name = strsplit(filename,'.csv');
                    new_label_name = new_label_name{1};

                    if ~isvarname(new_label_name)
                        if ~ismember('User_Input_Structure_Label_1',fieldnames(app.Aligned_Labels.(app.Structure)))
                            new_label_name = 'User_Input_Structure_Label_1';
                        else
                            current_labels = fieldnames(app.Aligned_Labels.(app.Structure));
                            user_input_labels = current_labels(contains(current_labels,'User_Input_Structure_Label'));
                            new_label_name = strcat('User_Input_Structure_Label_',num2str(length(user_input_labels)+1));
                        end
                    end

                    % Checking if there is a label for every sample
                    if height(new_labels)== height(app.base_Feature_set.(app.Structure))
                        app.Aligned_Labels.(app.Structure).(new_label_name).Aligned = new_labels;
                        sub_class = unique(new_labels.Class);
                        app.Aligned_Labels.(app.Structure).(new_label_name).Sub_Class = sub_class;
                    else
                        % Assigning all the other samples "Unlabeled"
                        non_overlap_names = app.base_Feature_set.(app.Structure).ImgLabel(~ismember(app.base_Feature_set.(app.Structure).ImgLabel,new_labels.ImgLabel));

                        new_table = table(non_overlap_names,repmat({'Unlabeled'},length(non_overlap_names),1),'VariableNames',{'ImgLabel','Class'});
                        new_label_table = [new_labels;new_table];
                        app.Aligned_Labels.(app.Structure).(new_label_name).Aligned = new_label_table;
                        sub_class = unique(new_label_table.Class);
                        app.Aligned_Labels.(app.Structure).(new_label_name).Sub_Class = sub_class;
                    end

                    app.SelectLabelDropDown.Items = [app.SelectLabelDropDown.Items,new_label_name];

                    N = uitreenode(app.Tree);
                    N.Text = new_label_name;

                    for sub_cl = 1:length(sub_class)
                        sN = uitreenode(N);
                        sN.Text = sub_class{sub_cl};
                    end

                    app.checked_master.(app.Structure) = [app.checked_master.(app.Structure);N];
                    Fix_Nodes(app,event)
                end

            end

            app.AddStructureLabelfromFileButton.Enable = 'on';

        end

        % Button pushed function: AddSlideLabelfromFileButton
        function AddSlideLabelfromFileButtonPushed(app, event)
            
            app.AddSlideLabelfromFileButton.Enable = 'off';

            [filename,path] = uigetfile('*.csv','Select any Slide-level label files','MultiSelect','on');

            if ~path==0
                
                all_structures = fieldnames(app.Full_Feature_set);

                for s = 1:length(all_structures)

                    current_structure = all_structures{s};

                    % Getting all of the slide labels
                    all_img_labels = app.base_Feature_set.(current_structure).ImgLabel;
                    all_img_slide_labels = cellfun(@(x) strsplit(x,'_'),all_img_labels,'UniformOutput',false);
                    
                    lengths = cellfun('length',all_img_slide_labels);
                    if any(lengths>2)
                        all_img_slide_labels = cellfun(@(x) strjoin(x(1:end-1),'_'),all_img_slide_labels,'UniformOutput',false);
                    else
                        all_img_slide_labels = cellfun(@(x) x{1},all_img_slide_labels,'UniformOutput',false);
                    end
                    
                    align_table = table(all_img_labels,all_img_slide_labels,'VariableNames',{'ImgLabel','SlideLabel'});
                    if iscell(filename)
                        for i = 1:length(filename)
                            current_file = strcat(path,filename{i});
                            new_label = readtable(current_file,'Delimiter',',','ReadVariableNames',true,'VariableNamingRule','preserve');
                            new_label.Properties.VariableNames = {'SlideLabel','Class'};
                            
                            if ~any(contains(new_label.SlideLabel,'.'))
                                aligned_labels = innerjoin(align_table,new_table);
                             
                            else
                                fixed_labels = cellfun(@(x) strsplit(x,'.'),new_label.SlideLabel,'UniformOutput',false);
                                fixed_labels = cellfun(@(x) x{1},fixed_labels,'UniformOutput',false);
                                new_label.SlideLabel = fixed_labels;
    
                                aligned_labels = innerjoin(align_table,new_table);
                            end
                            aligned_labels.SlideLabel = [];
    
                            new_label_name = strsplit(filename{i},'.csv');
                            new_label_name = new_label_name{1};
    
                            app.SelectLabelDropDown.Items = [app.SelectLabelDropDown.Items,new_label_name];
    
                            app.Aligned_Labels.(current_structure).(new_label_name).Aligned = aligned_labels;
    
                            sub_class = unique(aligned_labels.Class);
                            app.Aligned_Labels.(current_structure).(new_label_name).Sub_Class = sub_class;
                        end
    
                    else
                        current_file = strcat(path,filename);
                        new_label = readtable(current_file,'Delimiter',',','ReadVariableNames',true,'VariableNamingRule','preserve');
                        new_label.Properties.VariableNames = {'SlideLabel','Class'};
    
                        if ~any(contains(new_label.SlideLabel,'.'))
                            aligned_labels = innerjoin(align_table,new_label);
                            
                        else
                            fixed_labels = cellfun(@(x) strsplit(x,'.'),new_label.SlideLabel,'UniformOutput',false);
                            fixed_labels = cellfun(@(x) x{1},fixed_labels,'UniformOutput',false);
    
                            new_label.SlideLabel = fixed_labels;
    
                            aligned_labels = innerjoin(align_table,new_label);
                        end
    
                        aligned_labels.SlideLabel = [];
    
                        new_label_name = strsplit(filename,'.csv');
                        new_label_name = new_label_name{1};
    
                        app.Aligned_Labels.(current_structure).(new_label_name).Aligned = aligned_labels;
    
                        sub_class = unique(aligned_labels.Class);
    
                        app.Aligned_Labels.(current_structure).(new_label_name).Sub_Class = sub_class;
    
                        app.SelectLabelDropDown.Items = [app.SelectLabelDropDown.Items,new_label_name];

                    end
                end
            end
            
            % currently selected structure
            
            selected_structure = app.Structure;
            features = fieldnames(app.Aligned_Labels.(selected_structure));
            most_recent_feature = features{end};

            N = uitreenode(app.Tree);
            N.Text = most_recent_feature;

            sub_features = app.Aligned_Labels.(selected_structure).(most_recent_feature).Sub_Class;
            for sf = 1:length(sub_features)
                sN = uitreenode(N);
                sN.Text = sub_features{sf};
            end

            app.checked_master.(selected_structure) = [app.checked_master.(selected_structure);N];
            app.Tree.CheckedNodes = app.checked_master.(selected_structure);

            app.AddSlideLabelfromFileButton.Enable = 'on';


        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            
            app.Subset_Master.(app.Structure) = [];

            % Updating and displaying number of samples that would be
            % remaining
            data_ind = Subset_Data(app,event);
            app.ofSamplesIncludedLabel.Text = strcat(num2str(sum(data_ind)),'_Samples Included');
            
            app.checked_master.(app.Structure) = app.Tree.Children;
            app.Tree.CheckedNodes = app.checked_master.(app.Structure);

            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';

            Flip_Image_Listbox(app,event)
            Plot_Feat(app,event)
            Gen_Map(app,event)
            View_Image(app,event)
            Load_Notes(app,event)


        end

        % Value changed function: CurrentFeaturesListBox
        function CurrentFeaturesListBoxValueChanged(app, event)
            value = app.CurrentFeaturesListBox.Value;
            
            % Clicking a member of this list box will compare with the features
            % included in the biplot features, if that feature is not in
            % the biplot features then it will be added. If it is included
            % then it will be removed

            if ~isempty(app.BiplotTable.Data)
                
                if ismember(value,app.BiplotTable.Data)
                    app.BiplotTable.Data(find(ismember(app.BiplotTable.Data,value))) = [];
                    if isempty(app.BiplotTable.Data)
                        app.BiplotTable.Enable = 'off';
                        app.ViewBiplotButton.Enable = 'off';
                    end
                else
                    app.BiplotTable.Data = [app.BiplotTable.Data;{value}];
                end
            else
                app.BiplotTable.Enable = 'on';
                app.ViewBiplotButton.Enable = 'on';
                app.BiplotTable.Data = {value};
            end


        end

        % Button pushed function: ViewBiplotButton
        function ViewBiplotButtonPushed(app, event)
            
            if strcmp(app.ViewBiplotButton.Text,'View Biplot')
                % Getting the index of the features that are present in the
                % BiplotTable
                biplot_idx = find(ismember(app.BiplotTable.Data,app.CurrentFeaturesListBox.Items));
                
                coeff = app.PCA_Vals{2};
                hold(app.Dist_Ax,'on')
                app.Biplot = biplot(coeff(biplot_idx,1:2),'VarLabels',app.BiplotTable.Data','Parent',app.Dist_Ax);
                hold(app.Dist_Ax,'off')

                app.ViewBiplotButton.Text = 'Hide Biplot';
            else
                delete(app.Biplot)

                app.ViewBiplotButton.Text = 'View Biplot';
            end


        end

        % Image clicked function: InfoSym_Biplot
        function InfoSym_BiplotImageClicked(app, event)
            
            f = msgbox('Biplots display the influence of each feature on the principal components. The direction of the resulting lines indicate the direction of greatest variation of that specific feature.');


        end

        % Value changed function: StructureDropDown
        function StructureDropDownValueChanged(app, event)
            value = app.StructureDropDown.Value;
            
            % Switching between structures in an analysis
            Save_Notes(app,event)
            %display('switching structure')
            app.Structure = value;

            Save_Notes(app,event)

            cla(app.Img_Ax);
            cla(app.Dist_Ax);
            cla(app.Rel_Ax);

            % Resetting compartment mask view buttons
            app.PAS_Butt.Text = 'PAS+';
            app.Lum_Butt.Text = 'Luminal Space';
            app.Nuc_Butt.Text = 'Nuclei';

            comps = unique(app.feature_encodings.Compartment(app.Overlap_Feature_idx.(app.Structure)));

            % Populating feature categories lists, with a special case if the feature
            % rankings are included where the weighted encodings are used instead.
            % This allows the user to select to view all the features by type weighted
            % according to rank regardless of compartment.
            if ~isempty(app.Feat_Rank.(app.Structure))
                %handles.TwoA_txt.Visible = 'off';
                app.FeatureRankingsUsedDropDown.Enable = 'on';
                app.FeatureRankingsUsedDropDown.Items = [{'None'};fieldnames(app.Feat_Rank.(app.Structure))];
            
                app.weighted_encodings = Organize_Feat_Ranks(app,event);
                app.Comp_List.Items = [comps;{'Weighted Visualization'}];
                app.Comp_List.Value = app.Comp_List.Items(1);
            
            else
                app.Comp_List.Items = comps;
                app.Comp_List.Value = app.Comp_List.Items(1);
                
                app.weighted_encodings = [];
                app.FeatureRankingsUsedDropDown.Items = {'None'};
            end

            sel_comp = app.Comp_List.Value;

            feat_types = app.feature_encodings(app.Overlap_Feature_idx.(app.Structure),:);
            feat_types = feat_types(strcmp(feat_types.Compartment,sel_comp),:);
            app.Feat_List.Items = unique(feat_types.Type);
            
            app.Feat_List.Value = app.Feat_List.Items(1);
            sel_feat = app.Feat_List.Value;
            
            spec_feats = feat_types(strcmp(feat_types.Type,sel_feat),:);
            app.Spec_List.Items = unique(spec_feats.Specific);
            app.Spec_List.Value = app.Spec_List.Items(1);


            current_feature = app.SelectLabelDropDown.Value;

            if ismember('Class',app.Full_Feature_set.(app.Structure).Properties.VariableNames)
                app.Full_Feature_set.(app.Structure).Class = [];
            end

            % innerjoin sorts by default, thx Matlab
            [T,rows_in_T] = innerjoin(app.Full_Feature_set.(app.Structure),app.Aligned_Labels.(app.Structure).(current_feature).Aligned);
            [~,sortinds] = sort(rows_in_T);
            app.Full_Feature_set.(app.Structure) = T(sortinds,:);
        
            % Getting the labeling options enabled
            if isnumeric(app.Full_Feature_set.(app.Structure).Class)
                app.LabelType.Text = 'Label Type: Numeric';
                app.NumericLabelOptionsButtonGroup.Enable = 'on';
                app.NumericLabelOptionsButtonGroup.Visible = 'on';
                app.CutoffValueEditField.Enable = 'off';

                app.CategoricalLabelOptionsButtonGroup.Enable = 'off';
                app.CategoricalLabelOptionsButtonGroup.Visible = 'off';
            else
                app.LabelType.Text = 'Label Type: Categorical';
                
                app.NumericLabelOptionsButtonGroup.Enable = 'off';
                app.NumericLabelOptionsButtonGroup.Visible = 'off';
                
                app.CategoricalLabelOptionsButtonGroup.Enable = 'on';
                app.CategoricalLabelOptionsButtonGroup.Visible = 'on';
                app.CombinedClassNameEditField.Enable = 'off';
                
            end

            % New Features
            app.w_in_range = app.SimilarityThresholdSpinner.Value;
            app.texture_window = app.TextureWindowSizeEditField.Value;
            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';

            app.Persistent_Labels.(app.Structure) = '';
            app.Red_Only = true;
            app.Blue_Only = false;

            % Transfer Annotations drop down menu
            app.TransferAnnotationsDropDown.Value = app.Structure;
            app.TransferAnnotationsDropDown_2.Value = app.Structure;
            
            % Plot labeling
            app.New_Label = false;
            app.new_Persistent_label = false;

            Plot_Feat(app,event)
            
            Gen_Map(app,event)
            View_Image(app,event)
            % Loading notes for current image
            Load_Notes(app,event)


        end

        % Value changed function: MetadataLabelDropDown
        function MetadataLabelDropDownValueChanged(app, event)
            value = app.MetadataLabelDropDown.Value;
            
            % Making sure to not allow a sub-class to be added twice
            if ~isempty(app.ColorNormParams)

                if strcmp(value,'Slide_Label')
                    slides = cellfun(@(x)strsplit(x,'_'),app.base_Feature_set.(app.Structure).ImgLabel,'UniformOutput',false);
                    lengths = cellfun('length',slides,'UniformOutput',false);
                    if any(lengths>2)
                        slides = cellfun(@(x) strjoin(x{1:end-1},'_'),slides,'UniformOutput',false);
                    else
                        slides = cellfun(@(x)x{1},slides,'UniformOutput',false);
                    end
                    if ~ismember(value,fieldnames(app.ColorNormParams.IncludeClass))
                        app.SubClassDropDown.Items = slides;
                    else
                        app.SubClassDropDown.Items = setxor(slides,app.ColorNormParams.IncludeClass.(value));
                    end
                else
                    if ~ismember(value,fieldnames(app.ColorNormParams.IncludeClass))
                        app.SubClassDropDown.Items = app.Aligned_Labels.(app.Structure).(value).Sub_Class;
                    else
                        app.SubClassDropDown.Items = setxor(app.Aligned_Labels.(app.Structure).(value).Sub_Class,app.ColorNormParams.IncludeClass.(value));
            
                    end
                end
            else
                if strcmp(value,'Slide_Label')
                    slides = cellfun(@(x)strsplit(x,'_'),app.base_Feature_set.(app.Structure).ImgLabel,'UniformOutput',false);
                    lengths = cellfun('length',slides);
                    if any(lengths>2)
                        for b = 1:length(slides)
                            current_slide = slides{b};
                            joined_slides{b} = strjoin(current_slide(1:end-1),'_');
                        end
        
                        slides = joined_slides;                    
                    else
                        slides = cellfun(@(x)x{1},slides,'UniformOutput',false);
                    end

                    app.SubClassDropDown.Items = unique(slides);
                else
    
                    app.SubClassDropDown.Items = app.Aligned_Labels.(app.Structure).(value).Sub_Class;
        
                end
            end
            app.AddtolistButton.Enable = 'on';

        end

        % Button pushed function: AddtolistButton
        function AddtolistButtonPushed(app, event)
            
            main_class = app.MetadataLabelDropDown.Value;
            sub_class = app.SubClassDropDown.Value;
            
            % Adding it to list
            app.ClickonaSubClasstoremoveitListBox.Items = [app.ClickonaSubClasstoremoveitListBox.Items,sub_class];
            app.ClickonaSubClasstoremoveitListBox.Enable = 'on';

            if ~strcmp(main_class,'Slide_Label')

                % Images with that label
                current_images = app.Aligned_Labels.(app.Structure).(main_class).Aligned;
                
                if ~isnumeric(current_images.Class)
                    current_images = current_images.ImgLabel(find(ismember(current_images.Class,sub_class)));
                else
                    % Have to get the values for numeric labels
                    first_val = strsplit(sub_class,'<');
                    first_val = first_val{1};
                    first_val = str2num(first_val);
                    sec_val = strsplit(sub_class,'=');
                    sec_val = sec_val{end};
                    sec_val = str2num(sec_val);
    
                    current_images = current_images.ImgLabel(find(current_images.Class>=first_val & current_images.Class<=sec_val));
                end               

                slides = cellfun(@(x)strsplit(x,'_'),current_images,'UniformOutput',false);
                lengths = cellfun('length',slides);
                if any(lengths>2)
                    for b = 1:length(slides)
                        current_slide = slides{b};
                        joined_slides{b} = strjoin(current_slide(1:end-1),'_');
                    end
    
                    slides = joined_slides;
                else
                    slides = cellfun(@(x)x{1},slides,'UniformOutput',false);
                end
            else

                slides = {sub_class};
            end

            if ~isempty(app.ColorNormParams)
                
                app.ColorNormParams.IncludeSlides = unique([app.ColorNormParams.IncludeSlides,slides]);
                
                if ismember(main_class,fieldnames(app.ColorNormParams.IncludeClass))
                    app.ColorNormParams.IncludeClass.(main_class) = [app.ColorNormParams.IncludeClass.(main_class),sub_class];
                else
                    app.ColorNormParams.IncludeClass.(main_class) = sub_class;
                end
            else
                app.ColorNormParams.IncludeSlides = unique(slides);
                app.ColorNormParams.IncludeClass.(main_class) = sub_class;
            
            end
       

            Generate_Norm_Params(app)

            app.ResetNormalizationButton.Enable = 'on';
            if strcmp(app.ViewNormalizedButton.Enable,'off')
                app.ViewNormalizedButton.Enable = 'on';
            end

        end

        % Value changed function: ClickonaSubClasstoremoveitListBox
        function ClickonaSubClasstoremoveitListBoxValueChanged(app, event)
            value = app.ClickonaSubClasstoremoveitListBox.Value;
            
            app.ClickonaSubClasstoremoveitListBox.Items(find(ismember(app.ClickonaSubClasstoremoveitListBox.Items,value))) = [];

            % Updating IncludeSlides, find all the slides that are
            % exclusive to the selected sub-class label and remove those
            
            if ~isempty(app.ClickonaSubClasstoremoveitListBox.Items)
                main_labels = fieldnames(app.ColorNormParams.IncludeClass);
                include_slides = [];
                for i = 1:length(main_labels)
                    current_label = main_labels{i};
                    
                    if ~strcmp(current_label,'Slide_Label')
                        % conditional statement, this means that the selected
                        % sub-class is not a part of the current_label's
                        % sub-classes
                        if ~ismember(value,app.ColorNormParams.IncludeClass.(current_label))
                            % Finding all the slides that are not exclusive to
                            % selected sub-class
                            include_sub_classes = app.ColorNormParams.IncludeClass.(current_label);
                            slides = app.Aligned_Labels.(app.Structure).(current_label).Aligned;
        
                            slides = slides.ImgLabel(find(ismember(slides.Class,include_sub_classes)));
                            slides = cellfun(@(x)strsplit(x,'_'),slides,'UniformOutput',false);
                            lengths = cellfun('length',slides);
                            if any(lengths>2)
                                for b = 1:length(slides)
                                    current_slide = slides{b};
                                    joined_slides{b} = strjoin(current_slide(1:end-1),'_');
                                end
                
                                slides = joined_slides;                        
                            else
                                slides = cellfun(@(x) x{1}, slides, 'UniformOutput',false);
                            end
        
                            if ~isempty(include_slides)
                                include_slides = [include_slides;slides];
                                include_slides = unique(include_slides);
                            else
                                include_slides = slides;
                            end
        
                        else
                            app.ColorNormParams.IncludeClass.(current_label)(find(ismember(value,app.ColorNormParams.IncludeClass.(current_label)))) = [];
        
                            exclude_slides = app.Aligned_Labels.(app.Structure).(current_label).Aligned;
                            
                            if ~isnumeric(exclude_slides.Class)
                                exclude_slides = exclude_slides.ImgLabel(find(ismember(exclude_slides.Class,value)));
                            else
                                % Have to get the values for numeric labels
                                first_val = strsplit(value,'<');
                                first_val = first_val{1};
                                first_val = str2num(first_val);
                                sec_val = strsplit(value,'=');
                                sec_val = sec_val{end};
                                sec_val = str2num(sec_val);
                
                                exclude_slides = exclude_slides.ImgLabel(find(exclude_slides.Class>=first_val & exclude_slides.Class<=sec_val));
                            end
        
                            exclude_slides = cellfun(@(x)strsplit(x,'_'),exclude_slides,'UniformOutput',false);
                            lengths = cellfun('length',exclude_slides,'UniformOutput',false);
                            if any(lengths>2)
                                exclude_slides = cellfun(@(x) strjoin(x{1:end-1},'_'),exclude_slides,'UniformOutput',false);
                            else
                                exclude_slides = cellfun(@(x)x{1},exclude_slides,'UniformOutput',false);
                            end
                            
                        end
                    else

                        % If the current_label is Slide_Label, have to sort through the slides that are included and not included
                        % This if statement is if the currently selected
                        % value is a slide label, so you have to exclude
                        % value from the IncludeSlides list
                        if ismember(value,app.ColorNormParams.IncludeClass.Slide_Label)
                           
                            app.ColorNormParams.IncludeClass.Slide_Label(find(ismember(value,app.ColorNormParams.IncludeClass.Slide_Label))) = [];
                            exclude_slides = {value};
                            
                            if ~isempty(include_slides)
                                include_slides = [include_slides;app.ColorNormParams.IncludeClass.Slide_Label];
                            else
                                include_slides = app.ColorNormParams.IncludeClass.Slide_Label;
                            end
                        end


                    end
                end
    
                % Specific slide labels that are included in the list box
                % will still be included even if they are excluded by
                % deselecting other slide-level labels that may describe
                % them
                exclusively_exclude = find(~ismember(exclude_slides,include_slides));
                app.ColorNormParams.IncludeSlides(exclusively_exclude) = [];
    
                Generate_Norm_Params(app)
            else
                app.ClickonaSubClasstoremoveitListBox.Enable = 'off';
                app.ResetNormalizationButton.Enable = 'off';
                app.ColorNormParams = [];

                display('Clearing ColorNormParams HistoLens_V2_App 3848')
                assignin('base','ColorNormParams',app.ColorNormParams)
            end


        end

        % Button pushed function: ViewRawRGBButton
        function ViewRawRGBButtonPushed(app, event)
            
            % View the Raw RGB image(s) in current axes
            if ~app.Comparing

                axes(app.Img_Ax)
                imshow(app.Current_Img{1}), axis image
                Scale_Text(app.Current_Img{1},app.Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map)
                    colormap jet
                    app.im = imagesc(app.Img_Ax, app.map);
                    app.im.AlphaData = app.Heat_Slide.Value;
                    hold off
                end

                % Moving rectangular ROI to contain all map data
                bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map,0)),'BoundingBox'));
        
                % For maps that are all zeros clear out histogram and table
                if ~isempty(bin_roi)
                    app.red_roi = images.roi.Rectangle(app.Img_Ax,'Position',cell2mat(bin_roi),...
                        'FaceAlpha',0);
        
                    app.red_rect_position = app.red_roi.Position;
        
                    click_vis(app,event,app.red_roi);
                    addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
        
                else
                    cla(app.Rel_Ax)
                    app.Rel_Feat_Table.Data = {};
                end

            else
                
                axes(app.Red_Img_Ax)
                imshow(app.Current_Img{1}), axis image
                Scale_Text(app.Current_Img{1}, app.Red_Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map{1})
                    colormap jet
                    app.im_red = imagesc(app.Red_Img_Ax,app.map{1});
                    app.im_red.AlphaData = app.Heat_Slide.Value;
                    hold off
                end

                axes(app.Blue_Img_Ax)
                imshow(app.Current_Img{2}), axis image
                Scale_Text(app.Current_Img{2}, app.Blue_Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map{2})
                    colormap jet 
                    app.im_blue = imagesc(app.Blue_Img_Ax,app.map{2});
                    app.im_blue.AlphaData = app.Heat_Slide.Value;
                    hold off
                end

                % Moving rectangular ROI to contain all map data
                red_bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map{1},0)),'BoundingBox'));
    
                % For maps that are all zeros clear out histogram and table
                if ~isempty(red_bin_roi)
                    app.red_roi = images.roi.Rectangle(app.Red_Img_Ax,'Position',cell2mat(red_bin_roi),'Color','r',...
                        'FaceAlpha',0);
    
                    app.red_rect_position = app.red_roi.Position;
    
                else
                    % Only clear red columns
                    prev_data = app.Rel_Feat_Table.Data;
                    prev_columns = app.Rel_Feat_Table.ColumnName;
                    red_col = find(contains(prev_columns,'Red: Relative Area Contained'));
    
                    new_data = prev_data;
                    
                    new_data{:,red_col} = 0*new_data(:,red_col);
    
                    app.Rel_Feat_Table.Data = new_data;
    
                end
    
                % Moving rectangular ROI to contain all map data
                blue_bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map{2},0)),'BoundingBox'));
    
                % For maps that are all zeros clear out histogram and table
                if ~isempty(blue_bin_roi)
                    app.blue_roi = images.roi.Rectangle(app.Blue_Img_Ax,'Position',cell2mat(blue_bin_roi),'Color','b',...
                        'FaceAlpha',0);
                    app.blue_rect_position = app.blue_roi.Position;
                    
                else
                    % Only clear blue columns
                    prev_data = app.Rel_Feat_Table.Data;
                    prev_columns = app.Rel_Feat_Table.ColumnName;
                    blue_cols = find(contains(prev_columns,'Blue'));
    
                    new_data = prev_data;
                    for k = 1:length(blue_cols)
                        new_data(:,blue_cols(k)) = zeros(size(prev_data,1),1);
                    end
                    app.Rel_Feat_Table.Data = new_data;
    
                end
                
                % Only adding the listener to ROIs that aren't empty
                if ~isempty(red_bin_roi) && ~isempty(blue_bin_roi)
                    
                    % Might work with only specifying the one initially since
                    % handles.Comparing is true
                    click_vis(app,event,app.red_roi);
                    addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
                    addlistener(app.blue_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.blue_roi));
                else
                    if ~isempty(red_bin_roi)
                        click_vis(app,event,app.red_roi);
                        addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
                    end
                    if ~isempty(blue_bin_roi)
                        click_vis(app,event,app.blue_roi);
                        addlistener(app.blue_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.blue_roi));
                    end
                end

            end
            
            app.ViewRawRGBButton.Enable = 'off';
            app.ViewNormalizedButton.Enable = 'on';

        end

        % Button pushed function: ViewNormalizedButton
        function ViewNormalizedButtonPushed(app, event)
            
            for i = 1:length(app.Current_Img)
                if isempty(app.ColorNormParams)
                    app.Current_NormImg{i} = normalizeStaining(app.Current_Img{i},255,0.15,1,...
                        app.Seg_Params.(app.Structure).StainNormalization.Means,...
                        app.Seg_Params.(app.Structure).StainNormalization.Maxs);
                else
                    app.Current_NormImg{i} = normalizeStaining(app.Current_Img{i},255,0.15,1,...
                        app.ColorNormParams.Means,app.ColorNormParams.Maxs);
                end
            end

            if ~app.Comparing
                axes(app.Img_Ax)

                imshow(app.Current_NormImg{1}), axis image
                Scale_Text(app.Current_NormImg{1},app.Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map)
                    colormap jet
                    app.im = imagesc(app.Img_Ax, app.map);
                    app.im.AlphaData = app.Heat_Slide.Value;
                    hold off
                end
                
                % Moving rectangular ROI to contain all map data
                bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map,0)),'BoundingBox'));
        
                % For maps that are all zeros clear out histogram and table
                if ~isempty(bin_roi)
                    app.red_roi = images.roi.Rectangle(app.Img_Ax,'Position',cell2mat(bin_roi),...
                        'FaceAlpha',0);
        
                    app.red_rect_position = app.red_roi.Position;
        
                    click_vis(app,event,app.red_roi);
                    addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
        
                else
                    cla(app.Rel_Ax)
                    app.Rel_Feat_Table.Data = {};
                end

            else
                axes(app.Red_Img_Ax)

                imshow(app.Current_NormImg{1}), axis image
                Scale_Text(app.Current_NormImg{1},app.Red_Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map{1})
                    colormap jet
                    app.im_red = imagesc(app.Red_Img_Ax, app.map{1});
                    app.im_red.AlphaData = app.Heat_Slide.Value;
                    hold off
                end

                axes(app.Blue_Img_Ax)

                imshow(app.Current_NormImg{2}), axis image
                Scale_Text(app.Current_NormImg{2},app.Blue_Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map{2})
                    colormap jet
                    app.im_red = imagesc(app.Blue_Img_Ax, app.map{2});
                    app.im_red.AlphaData = app.Heat_Slide.Value;
                    hold off
                end     
                
                % Moving rectangular ROI to contain all map data
                red_bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map{1},0)),'BoundingBox'));
    
                % For maps that are all zeros clear out histogram and table
                if ~isempty(red_bin_roi)
                    app.red_roi = images.roi.Rectangle(app.Red_Img_Ax,'Position',cell2mat(red_bin_roi),'Color','r',...
                        'FaceAlpha',0);
    
                    app.red_rect_position = app.red_roi.Position;
    
                else
                    % Only clear red columns
                    prev_data = app.Rel_Feat_Table.Data;
                    prev_columns = app.Rel_Feat_Table.ColumnName;
                    red_col = find(contains(prev_columns,'Red: Relative Area Contained'));
    
                    new_data = prev_data;
                    
                    new_data{:,red_col} = 0*new_data(:,red_col);
    
                    app.Rel_Feat_Table.Data = new_data;
    
                end
    
                % Moving rectangular ROI to contain all map data
                blue_bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map{2},0)),'BoundingBox'));
    
                % For maps that are all zeros clear out histogram and table
                if ~isempty(blue_bin_roi)
                    app.blue_roi = images.roi.Rectangle(app.Blue_Img_Ax,'Position',cell2mat(blue_bin_roi),'Color','b',...
                        'FaceAlpha',0);
                    app.blue_rect_position = app.blue_roi.Position;
                    
                else
                    % Only clear blue columns
                    prev_data = app.Rel_Feat_Table.Data;
                    prev_columns = app.Rel_Feat_Table.ColumnName;
                    blue_cols = find(contains(prev_columns,'Blue'));
    
                    new_data = prev_data;
                    for k = 1:length(blue_cols)
                        new_data(:,blue_cols(k)) = zeros(size(prev_data,1),1);
                    end
                    app.Rel_Feat_Table.Data = new_data;
    
                end
                
                % Only adding the listener to ROIs that aren't empty
                if ~isempty(red_bin_roi) && ~isempty(blue_bin_roi)
                    
                    % Might work with only specifying the one initially since
                    % handles.Comparing is true
                    click_vis(app,event,app.red_roi);
                    addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
                    addlistener(app.blue_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.blue_roi));
                else
                    if ~isempty(red_bin_roi)
                        click_vis(app,event,app.red_roi);
                        addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
                    end
                    if ~isempty(blue_bin_roi)
                        click_vis(app,event,app.blue_roi);
                        addlistener(app.blue_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.blue_roi));
                    end
                end

            end

            Color_Stats_Norm(app)
            app.ViewNormalizedButton.Enable = 'off';
            app.ViewRawRGBButton.Enable = 'on';

        end

        % Button pushed function: ResetNormalizationButton
        function ResetNormalizationButtonPushed(app, event)
            
            app.ColorNormParams = [];
            app.ClickonaSubClasstoremoveitListBox.Items = {};

            % View the Raw RGB image(s) in current axes
            if ~app.Comparing

                axes(app.Img_Ax)
                imshow(app.Current_Img{1}), axis image
                Scale_Text(app.Current_Img{1},app.Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map)
                    colormap jet
                    app.im = imagesc(app.Img_Ax, app.map);
                    app.im.AlphaData = app.Heat_Slide.Value;
                    hold off
                end
                % Moving rectangular ROI to contain all map data
                bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map,0)),'BoundingBox'));
        
                % For maps that are all zeros clear out histogram and table
                if ~isempty(bin_roi)
                    app.red_roi = images.roi.Rectangle(app.Img_Ax,'Position',cell2mat(bin_roi),...
                        'FaceAlpha',0);
        
                    app.red_rect_position = app.red_roi.Position;
        
                    click_vis(app,event,app.red_roi);
                    addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
        
                else
                    cla(app.Rel_Ax)
                    app.Rel_Feat_Table.Data = {};
                end
                
            else
                
                axes(app.Red_Img_Ax)
                imshow(app.Current_Img{1}), axis image
                Scale_Text(app.Current_Img{1}, app.Red_Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map{1})
                    colormap jet
                    app.im_red = imagesc(app.Red_Img_Ax,app.map{1});
                    app.im_red.AlphaData = app.Heat_Slide.Value;
                    hold off
                end
                
                axes(app.Blue_Img_Ax)
                imshow(app.Current_Img{2}), axis image
                Scale_Text(app.Current_Img{2}, app.Blue_Img_Ax, app.MPP)
                hold on
                if ~isempty(app.map{2})
                    colormap jet 
                    app.im_blue = imagesc(app.Blue_Img_Ax,app.map{2});
                    app.im_blue.AlphaData = app.Heat_Slide.Value;
                    hold off
                end

                % Moving rectangular ROI to contain all map data
                red_bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map{1},0)),'BoundingBox'));
    
                % For maps that are all zeros clear out histogram and table
                if ~isempty(red_bin_roi)
                    app.red_roi = images.roi.Rectangle(app.Red_Img_Ax,'Position',cell2mat(red_bin_roi),'Color','r',...
                        'FaceAlpha',0);
    
                    app.red_rect_position = app.red_roi.Position;
    
                else
                    % Only clear red columns
                    prev_data = app.Rel_Feat_Table.Data;
                    prev_columns = app.Rel_Feat_Table.ColumnName;
                    red_col = find(contains(prev_columns,'Red: Relative Area Contained'));
    
                    new_data = prev_data;
                    
                    new_data{:,red_col} = 0*new_data(:,red_col);
    
                    app.Rel_Feat_Table.Data = new_data;
    
                end
    
                % Moving rectangular ROI to contain all map data
                blue_bin_roi = struct2cell(regionprops(bwconvhull(imbinarize(app.map{2},0)),'BoundingBox'));
    
                % For maps that are all zeros clear out histogram and table
                if ~isempty(blue_bin_roi)
                    app.blue_roi = images.roi.Rectangle(app.Blue_Img_Ax,'Position',cell2mat(blue_bin_roi),'Color','b',...
                        'FaceAlpha',0);
                    app.blue_rect_position = app.blue_roi.Position;
                    
                else
                    % Only clear blue columns
                    prev_data = app.Rel_Feat_Table.Data;
                    prev_columns = app.Rel_Feat_Table.ColumnName;
                    blue_cols = find(contains(prev_columns,'Blue'));
    
                    new_data = prev_data;
                    for k = 1:length(blue_cols)
                        new_data(:,blue_cols(k)) = zeros(size(prev_data,1),1);
                    end
                    app.Rel_Feat_Table.Data = new_data;
    
                end
                
                % Only adding the listener to ROIs that aren't empty
                if ~isempty(red_bin_roi) && ~isempty(blue_bin_roi)
                    
                    % Might work with only specifying the one initially since
                    % handles.Comparing is true
                    click_vis(app,event,app.red_roi);
                    addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
                    addlistener(app.blue_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.blue_roi));
                else
                    if ~isempty(red_bin_roi)
                        click_vis(app,event,app.red_roi);
                        addlistener(app.red_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.red_roi));
                    end
                    if ~isempty(blue_bin_roi)
                        click_vis(app,event,app.blue_roi);
                        addlistener(app.blue_roi, 'ROIMoved',@(varargin)click_vis(app,event,app.blue_roi));
                    end
                end

            end

            app.ViewRawRGBButton.Enable = 'off';
            app.ViewNormalizedButton.Enable = 'on';
            app.ResetNormalizationButton.Enable = 'off';

        end

        % Value changed function: SubClassDropDown
        function SubClassDropDownValueChanged(app, event)
            value = app.SubClassDropDown.Value;
            
        end

        % Value changed function: TransferAnnotationsDropDown
        function TransferAnnotationsDropDownValueChanged(app, event)
            value = app.TransferAnnotationsDropDown.Value;
            
            % Cycling through available structures (including add new)
            if ~strcmp(value,app.Structure)
                app.TransferAnnotationButton.Enable = 'on';
            else
                app.TransferAnnotationButton.Enable = 'off';
            end

        end

        % Button pushed function: TransferAnnotationButton
        function TransferAnnotationButtonPushed(app, event)
            
            % Button transfers current structure vertices and features to
            % selected structure.  If it is a new structure than an
            % additional layer is generated.

            Transfer_Annotation(app)
            
            app.TransferAnnotationsDropDown.Value = app.Structure;
            app.TransferAnnotationButton.Enable = 'off';


        end

        % Value changed function: TransferAnnotationsDropDown_2
        function TransferAnnotationsDropDown_2ValueChanged(app, event)
            value = app.TransferAnnotationsDropDown_2.Value;
            
            % Cycling through available structures (including add new)
            if ~strcmp(value,app.Structure)
                app.TransferAnnotationButton_2.Enable = 'on';
            else
                app.TransferAnnotationButton_2.Enable = 'off';
            end
            
        end

        % Button pushed function: TransferAnnotationButton_2
        function TransferAnnotationButton_2Pushed(app, event)
            
            % Button transfers current structure vertices and features to
            % selected structure.  If it is a new structure than an
            % additional layer is generated.

            Transfer_Annotation(app)
            
            app.TransferAnnotationsDropDown_2.Value = app.Structure;
            app.TransferAnnotationButton_2.Enable = 'off';
            
        end

        % Button pushed function: RunColorFeatureComparisonButton
        function RunColorFeatureComparisonButtonPushed(app, event)
            
            % Run color & texture feature extraction with new normalization manually
            % defined normalization

            % Allow user to look at how the distributions change for each
            % feature + label and give the option to replace current color
            % and texture features with normalized.
            
            %if isempty(app.Replace_Features)
            Extract_Color_Norm_Features(app)
            %end

            waitfor(HistoLens_ColorNormalizationComparison(app))
            
            % innerjoin sorts by default, thx Matlab
            current_feature = app.SelectLabelDropDown.Value;
            [T,rows_in_T] = innerjoin(app.base_Feature_set.(app.Structure),app.Aligned_Labels.(app.Structure).(current_feature).Aligned);
            [~,sortinds] = sort(rows_in_T);
            app.Full_Feature_set.(app.Structure) = T(sortinds,:);

            app.Comparing = false;
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Enable = 'off';

            % Resetting compartment mask view buttons
            app.PAS_Butt.Text = 'PAS+';
            app.Lum_Butt.Text = 'Luminal Space';
            app.Nuc_Butt.Text = 'Nuclei';
            
            Save_Notes(app,event)

        
            % Resetting Normalization Information
            Reset_Normalization_Tabs(app)

            % Changing visibility of comparison listboxes
            Flip_Image_Listbox(app,event)
            Plot_Feat(app,event)
            Gen_Map(app,event)      
            View_Image(app,event)      
            Load_Notes(app,event)     


        end

        % Button pushed function: 
        % GenerateCompartmentSegmentationCheckButton
        function GenerateCompartmentSegmentationCheckButtonPushed(app, event)
            % Generating a folder of example compartment segmentations
            save_folder = uigetdir('Select a folder to save compartment segmentations in');
            save_num = 10;
            
            app.GenerateCompartmentSegmentationCheckButton.Enable = 'off';
            Generate_Sample_Compartments(app,save_folder, save_num)
            app.GenerateCompartmentSegmentationCheckButton.Enable = 'on';
        end

        % Menu selected function: EditPlotOptionsMenu
        function EditPlotOptionsMenuSelected(app, event)
            
            % Open additional window with plot options
            waitfor(HistoLens_PlotOptions(app))
            Update_Plot_Options(app)

        end

        % Button pushed function: SaveRedROIImagesButton
        function SaveRedROIImagesButtonPushed(app, event)
            % Generating a folder of images in the Red ROI
            save_folder = uigetdir('Select a folder to save images from Red ROI in');
            
            %app.SaveRedROIImagesButton.Enable = 'off';
            Extract_ROI_Images(app,save_folder,'red')
            app.SaveRedROIImagesButton.Enable = 'on';

        end

        % Button pushed function: SaveBlueROIImagesButton
        function SaveBlueROIImagesButtonPushed(app, event)
            % Generating a folder of images in the Blue ROI
            save_folder = uigetdir('Select a folder to save images from Blue ROI in');

            %app.SaveBlueROIImagesButton.Enable = 'off';
            Extract_ROI_Images(app,save_folder,'blue')
            app.SaveBlueROIImagesButton.Enable = 'on';
            
        end

        % Button pushed function: SavePersistentLabeledImagesButton
        function SavePersistentLabeledImagesButtonPushed(app, event)
            % Generating a folder of images in the Persistent Labels box
            save_folder = uigetdir('Select a folder to save Persistent Labeled Images in');

            %app.SavePersistentLabeledImagesButtonPushed.Enable = 'off';
            Extract_ROI_Images(app,save_folder,'persistent')
            app.SavePersistentLabeledImagesButton.Enable = 'on';

        end

        % Value changed function: PauseVisualizationCheckBox
        function PauseVisualizationCheckBoxValueChanged(app, event)
            value = app.PauseVisualizationCheckBox.Value;
            
            app.Pause_Visualization = value;

            if value
                app.SimilarityThresholdSpinner.Enable = 'off';
                app.TextureWindowSizeEditField.Enable = 'off';
                app.Rank_Slide.Enable = 'off';
            else
                app.SimilarityThresholdSpinner.Enable = 'on';
                app.TextureWindowSizeEditField.Enable = 'on';
                if length(app.map_idx)>1 && ~isempty(app.Feat_Rank)
                    app.Rank_Slide.Enable = 'on';
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'HistoLensV2';
            app.figure1.Icon = 'Logo.png';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1KeyPress, true);
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Data Tips';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Pan';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Zoom In';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Zoom Out';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool5 = uitoggletool(app.uitoolbar1);
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Insert Colorbar';
            app.uitoggletool5.ClickedCallback = createCallbackFcn(app, @uitoggletool5_ClickedCallback, true);
            app.uitoggletool5.Icon = 'uitoggletool5_image.png';
            app.Img_Ax = uiaxes(app.figure1);
            app.Img_Ax.XTick = [];
            app.Img_Ax.YTick = [];
            app.Img_Ax.Tag = 'Img_Ax';
            app.Red_Img_Ax = uiaxes(app.figure1);
            app.Red_Img_Ax.Visible = 'off';
            app.Red_Img_Ax.Tag = 'Red_Img_Ax';
            app.Blue_Img_Ax = uiaxes(app.figure1);
            app.Blue_Img_Ax.Visible = 'off';
            app.Blue_Img_Ax.Tag = 'Blue_Img_Ax';
            app.TabGroup2 = uitabgroup(app.figure1);
            app.FeatureDistributionPlotTab = uitab(app.TabGroup2);
            app.FeatureDistributionPlotTab.Title = 'Feature Distribution Plot';
            app.Dist_Ax = uiaxes(app.FeatureDistributionPlotTab);
            app.Dist_Ax.Tag = 'Dist_Ax';
            app.TabGroup4 = uitabgroup(app.FeatureDistributionPlotTab);
            app.LabelingComponentsTab = uitab(app.TabGroup4);
            app.LabelingComponentsTab.Title = 'Labeling Components';
            app.SelectLabelDropDownLabel = uilabel(app.LabelingComponentsTab);
            app.SelectLabelDropDownLabel.Enable = 'off';
            app.SelectLabelDropDownLabel.Text = 'Select Label';
            app.SelectLabelDropDown = uidropdown(app.LabelingComponentsTab);
            app.SelectLabelDropDown.Items = {};
            app.SelectLabelDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectLabelDropDownValueChanged, true);
            app.SelectLabelDropDown.Enable = 'off';
            app.SelectLabelDropDown.Placeholder = 'Select One';
            app.SelectLabelDropDown.Value = {};
            app.LabelType = uilabel(app.LabelingComponentsTab);
            app.LabelType.Enable = 'off';
            app.LabelType.Text = 'Label Type:';
            app.NumericLabelOptionsButtonGroup = uibuttongroup(app.LabelingComponentsTab);
            app.NumericLabelOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NumericLabelOptionsButtonGroupSelectionChanged, true);
            app.NumericLabelOptionsButtonGroup.Enable = 'off';
            app.NumericLabelOptionsButtonGroup.Title = 'Numeric Label Options';
            app.NumericLabelOptionsButtonGroup.Visible = 'off';
            app.UseAllRawValuesorresetButton = uiradiobutton(app.NumericLabelOptionsButtonGroup);
            app.UseAllRawValuesorresetButton.Text = 'Use All Raw Values (or reset)';
            app.UseAllRawValuesorresetButton.Value = true;
            app.UseQuartileLabels0to2525to50etcButton = uiradiobutton(app.NumericLabelOptionsButtonGroup);
            app.UseQuartileLabels0to2525to50etcButton.Text = 'Use Quartile Labels (0%to 25%, 25% to 50%, etc.)';
            app.SetBinaryLevelsButton = uiradiobutton(app.NumericLabelOptionsButtonGroup);
            app.SetBinaryLevelsButton.Text = 'Set Binary Levels';
            app.CutoffValueEditFieldLabel = uilabel(app.NumericLabelOptionsButtonGroup);
            app.CutoffValueEditFieldLabel.Text = 'Cutoff Value';
            app.CutoffValueEditField = uieditfield(app.NumericLabelOptionsButtonGroup, 'numeric');
            app.CutoffValueEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffValueEditFieldValueChanged, true);
            app.CategoricalLabelOptionsButtonGroup = uibuttongroup(app.LabelingComponentsTab);
            app.CategoricalLabelOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CategoricalLabelOptionsButtonGroupSelectionChanged, true);
            app.CategoricalLabelOptionsButtonGroup.Enable = 'off';
            app.CategoricalLabelOptionsButtonGroup.Title = 'Categorical Label Options';
            app.CategoricalLabelOptionsButtonGroup.Visible = 'off';
            app.UseallavailablelabelsorresetButton = uiradiobutton(app.CategoricalLabelOptionsButtonGroup);
            app.UseallavailablelabelsorresetButton.Text = 'Use all available labels (or reset)';
            app.UseallavailablelabelsorresetButton.Value = true;
            app.CombinelabelsButton = uiradiobutton(app.CategoricalLabelOptionsButtonGroup);
            app.CombinelabelsButton.Text = 'Combine labels';
            app.Combine1DropDownLabel = uilabel(app.CategoricalLabelOptionsButtonGroup);
            app.Combine1DropDownLabel.Text = 'Combine 1';
            app.Combine1DropDown = uidropdown(app.CategoricalLabelOptionsButtonGroup);
            app.Combine1DropDown.Items = {};
            app.Combine1DropDown.ValueChangedFcn = createCallbackFcn(app, @Combine1DropDownValueChanged, true);
            app.Combine1DropDown.Placeholder = 'Select One';
            app.Combine1DropDown.Value = {};
            app.Combine2DropDownLabel = uilabel(app.CategoricalLabelOptionsButtonGroup);
            app.Combine2DropDownLabel.Text = 'Combine 2';
            app.Combine2DropDown = uidropdown(app.CategoricalLabelOptionsButtonGroup);
            app.Combine2DropDown.Items = {};
            app.Combine2DropDown.ValueChangedFcn = createCallbackFcn(app, @Combine2DropDownValueChanged, true);
            app.Combine2DropDown.Placeholder = 'Select One';
            app.Combine2DropDown.Value = {};
            app.CombinedClassNameEditFieldLabel = uilabel(app.CategoricalLabelOptionsButtonGroup);
            app.CombinedClassNameEditFieldLabel.Text = 'Combined Class Name';
            app.CombinedClassNameEditField = uieditfield(app.CategoricalLabelOptionsButtonGroup, 'text');
            app.CombinedClassNameEditField.ValueChangedFcn = createCallbackFcn(app, @CombinedClassNameEditFieldValueChanged, true);
            app.AddComboButton = uibutton(app.CategoricalLabelOptionsButtonGroup, 'push');
            app.AddComboButton.Text = 'Add Combo';
            app.InfoSym_Labels = uiimage(app.LabelingComponentsTab);
            app.InfoSym_Labels.ImageClickedFcn = createCallbackFcn(app, @InfoSym_LabelsImageClicked, true);
            app.InfoSym_Labels.ImageSource = 'info_symbol.png';
            app.SelectRegionsTab = uitab(app.TabGroup4);
            app.SelectRegionsTab.Title = 'Select Regions';
            app.Compare_Butt = uibutton(app.SelectRegionsTab, 'push');
            app.Compare_Butt.ButtonPushedFcn = createCallbackFcn(app, @Compare_Butt_Callback, true);
            app.Compare_Butt.Tag = 'Compare_Butt';
            app.Compare_Butt.Enable = 'off';
            app.Compare_Butt.Text = 'Select Comparison Region';
            app.Select_New_Butt = uibutton(app.SelectRegionsTab, 'push');
            app.Select_New_Butt.ButtonPushedFcn = createCallbackFcn(app, @Select_New_Butt_Callback, true);
            app.Select_New_Butt.Tag = 'Select_New_Butt';
            app.Select_New_Butt.Enable = 'off';
            app.Select_New_Butt.Text = 'Select New Region';
            app.SelectSinglePointButton = uibutton(app.SelectRegionsTab, 'push');
            app.SelectSinglePointButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSinglePointButtonPushed, true);
            app.SelectSinglePointButton.Enable = 'off';
            app.SelectSinglePointButton.Text = 'Select Single Point';
            app.SelectPointsAlongLineButton = uibutton(app.SelectRegionsTab, 'push');
            app.SelectPointsAlongLineButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPointsAlongLineButtonPushed, true);
            app.SelectPointsAlongLineButton.Enable = 'off';
            app.SelectPointsAlongLineButton.Text = 'Select Points Along Line';
            app.MakeComparisonsLabel = uilabel(app.SelectRegionsTab);
            app.MakeComparisonsLabel.Text = 'Make Comparisons:';
            app.ViewExamplesLabel = uilabel(app.SelectRegionsTab);
            app.ViewExamplesLabel.Text = 'View Examples:';
            app.InfoSym_Comparisons = uiimage(app.SelectRegionsTab);
            app.InfoSym_Comparisons.ImageClickedFcn = createCallbackFcn(app, @InfoSym_ComparisonsClicked, true);
            app.InfoSym_Comparisons.ImageSource = 'info_symbol.png';
            app.ExamplesEditFieldLabel = uilabel(app.SelectRegionsTab);
            app.ExamplesEditFieldLabel.Enable = 'off';
            app.ExamplesEditFieldLabel.Text = '# Examples';
            app.ExamplesEditField = uieditfield(app.SelectRegionsTab, 'numeric');
            app.ExamplesEditField.Limits = [2 Inf];
            app.ExamplesEditField.RoundFractionalValues = 'on';
            app.ExamplesEditField.Enable = 'off';
            app.ExamplesEditField.Value = 10;
            app.SubsettingComponentsTab = uitab(app.TabGroup4);
            app.SubsettingComponentsTab.Title = 'Subsetting Components';
            app.Tree = uitree(app.SubsettingComponentsTab, 'checkbox');
            app.Tree.Enable = 'off';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.SubsetDataButton = uibutton(app.SubsettingComponentsTab, 'push');
            app.SubsetDataButton.ButtonPushedFcn = createCallbackFcn(app, @SubsetDataButtonPushed, true);
            app.SubsetDataButton.Enable = 'off';
            app.SubsetDataButton.Text = 'Subset Data';
            app.ofSamplesIncludedLabel = uilabel(app.SubsettingComponentsTab);
            app.ofSamplesIncludedLabel.Text = '# of Samples Included: ';
            app.InfoSym_Subsetting = uiimage(app.SubsettingComponentsTab);
            app.InfoSym_Subsetting.ImageClickedFcn = createCallbackFcn(app, @InfoSym_SubsettingImageClicked, true);
            app.InfoSym_Subsetting.ImageSource = 'info_symbol.png';
            app.ResetDataButton = uibutton(app.SubsettingComponentsTab, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Enable = 'off';
            app.ResetDataButton.Text = 'Reset Data';
            app.AddLabelsTab = uitab(app.TabGroup4);
            app.AddLabelsTab.Title = 'Add Labels';
            app.AddCurrentModelPredictedLabelsButton = uibutton(app.AddLabelsTab, 'push');
            app.AddCurrentModelPredictedLabelsButton.ButtonPushedFcn = createCallbackFcn(app, @AddCurrentModelPredictedLabelsButtonPushed, true);
            app.AddCurrentModelPredictedLabelsButton.Enable = 'off';
            app.AddCurrentModelPredictedLabelsButton.Text = 'Add Current Model Predicted Labels ';
            app.LabelIDEditFieldLabel = uilabel(app.AddLabelsTab);
            app.LabelIDEditFieldLabel.Enable = 'off';
            app.LabelIDEditFieldLabel.Text = 'Label ID';
            app.CurrentModelLabelIDEditField = uieditfield(app.AddLabelsTab, 'text');
            app.CurrentModelLabelIDEditField.ValueChangedFcn = createCallbackFcn(app, @CurrentModelLabelIDEditFieldValueChanged, true);
            app.CurrentModelLabelIDEditField.Enable = 'off';
            app.AddLabelfromCurrentNotesButton = uibutton(app.AddLabelsTab, 'push');
            app.AddLabelfromCurrentNotesButton.ButtonPushedFcn = createCallbackFcn(app, @AddLabelfromCurrentNotesButtonPushed, true);
            app.AddLabelfromCurrentNotesButton.Enable = 'off';
            app.AddLabelfromCurrentNotesButton.Text = 'Add Label from Current Notes';
            app.LabelIDEditField_2Label = uilabel(app.AddLabelsTab);
            app.LabelIDEditField_2Label.Enable = 'off';
            app.LabelIDEditField_2Label.Text = 'Label ID';
            app.NotesLabelIDEditField = uieditfield(app.AddLabelsTab, 'text');
            app.NotesLabelIDEditField.ValueChangedFcn = createCallbackFcn(app, @NotesLabelIDEditFieldValueChanged, true);
            app.NotesLabelIDEditField.Enable = 'off';
            app.AddStructureLabelfromFileButton = uibutton(app.AddLabelsTab, 'push');
            app.AddStructureLabelfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddStructureLabelfromFileButtonPushed, true);
            app.AddStructureLabelfromFileButton.Enable = 'off';
            app.AddStructureLabelfromFileButton.Text = 'Add Structure Label from File';
            app.AddSlideLabelfromFileButton = uibutton(app.AddLabelsTab, 'push');
            app.AddSlideLabelfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddSlideLabelfromFileButtonPushed, true);
            app.AddSlideLabelfromFileButton.Enable = 'off';
            app.AddSlideLabelfromFileButton.Text = 'Add Slide Label from File';
            app.OutliersTab = uitab(app.TabGroup4);
            app.OutliersTab.Title = 'Outliers';
            app.RemoveOutliersCheckBox = uicheckbox(app.OutliersTab);
            app.RemoveOutliersCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemoveOutliersCheckBoxValueChanged, true);
            app.RemoveOutliersCheckBox.Enable = 'off';
            app.RemoveOutliersCheckBox.Text = 'Remove Outliers';
            app.InfoSym_Outliers = uiimage(app.OutliersTab);
            app.InfoSym_Outliers.ImageClickedFcn = createCallbackFcn(app, @InfoSym_OutliersImageClicked, true);
            app.InfoSym_Outliers.ImageSource = 'info_symbol.png';
            app.OutlierTable = uitable(app.OutliersTab);
            app.OutlierTable.ColumnName = {'Image Name'};
            app.OutlierTable.RowName = {};
            app.OutlierTable.Visible = 'off';
            app.NormalizationLabelsTab = uitab(app.TabGroup4);
            app.NormalizationLabelsTab.Title = 'Normalization Labels';
            app.MetadataLabelDropDownLabel = uilabel(app.NormalizationLabelsTab);
            app.MetadataLabelDropDownLabel.Text = 'Metadata Label';
            app.MetadataLabelDropDown = uidropdown(app.NormalizationLabelsTab);
            app.MetadataLabelDropDown.Items = {};
            app.MetadataLabelDropDown.ValueChangedFcn = createCallbackFcn(app, @MetadataLabelDropDownValueChanged, true);
            app.MetadataLabelDropDown.Enable = 'off';
            app.MetadataLabelDropDown.Value = {};
            app.Label = uilabel(app.NormalizationLabelsTab);
            app.Label.Text = 'Select slides based on labels below to modify how normalization is applied';
            app.SubClassDropDownLabel = uilabel(app.NormalizationLabelsTab);
            app.SubClassDropDownLabel.Text = 'Sub-Class';
            app.SubClassDropDown = uidropdown(app.NormalizationLabelsTab);
            app.SubClassDropDown.Items = {};
            app.SubClassDropDown.ValueChangedFcn = createCallbackFcn(app, @SubClassDropDownValueChanged, true);
            app.SubClassDropDown.Enable = 'off';
            app.SubClassDropDown.Value = {};
            app.ClickonaSubClasstoremoveitListBoxLabel = uilabel(app.NormalizationLabelsTab);
            app.ClickonaSubClasstoremoveitListBoxLabel.Text = 'Click on a Sub-Class to remove it';
            app.ClickonaSubClasstoremoveitListBox = uilistbox(app.NormalizationLabelsTab);
            app.ClickonaSubClasstoremoveitListBox.Items = {};
            app.ClickonaSubClasstoremoveitListBox.ValueChangedFcn = createCallbackFcn(app, @ClickonaSubClasstoremoveitListBoxValueChanged, true);
            app.ClickonaSubClasstoremoveitListBox.Enable = 'off';
            app.ClickonaSubClasstoremoveitListBox.Value = {};
            app.ViewRawRGBButton = uibutton(app.NormalizationLabelsTab, 'push');
            app.ViewRawRGBButton.ButtonPushedFcn = createCallbackFcn(app, @ViewRawRGBButtonPushed, true);
            app.ViewRawRGBButton.Enable = 'off';
            app.ViewRawRGBButton.Text = 'View Raw RGB';
            app.ViewNormalizedButton = uibutton(app.NormalizationLabelsTab, 'push');
            app.ViewNormalizedButton.ButtonPushedFcn = createCallbackFcn(app, @ViewNormalizedButtonPushed, true);
            app.ViewNormalizedButton.Enable = 'off';
            app.ViewNormalizedButton.Text = 'View Normalized';
            app.ResetNormalizationButton = uibutton(app.NormalizationLabelsTab, 'push');
            app.ResetNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetNormalizationButtonPushed, true);
            app.ResetNormalizationButton.Enable = 'off';
            app.ResetNormalizationButton.Text = 'Reset Normalization';
            app.AddtolistButton = uibutton(app.NormalizationLabelsTab, 'push');
            app.AddtolistButton.ButtonPushedFcn = createCallbackFcn(app, @AddtolistButtonPushed, true);
            app.AddtolistButton.Enable = 'off';
            app.AddtolistButton.Text = 'Add to list';
            app.ImpactofNormalizationTab = uitab(app.TabGroup4);
            app.ImpactofNormalizationTab.Title = 'Impact of Normalization';
            app.NormColorTable = uitable(app.ImpactofNormalizationTab);
            app.NormColorTable.ColumnName = {'Image Raw'; 'Image Norm'};
            app.NormColorTable.RowName = {'PAS Red Mean'; 'PAS Green Mean'; 'PAS Blue Mean'; 'Lum Red Mean'; 'Lum Green Mean'; 'Lum Blue Mean'; 'Nuc Red Mean'; 'Nuc Green Mean'; 'Nuc Blue Mean'; 'PAS  Red Std'; 'PAS Green Std'; 'PAS Blue Std'; 'Lum Red Std'; 'Lum Green Std'; 'Lum Blue Std'; 'Nuc Red Std'; 'Nuc Green Std'; 'Nuc Blue Std'};
            app.RunColorFeatureComparisonButton = uibutton(app.ImpactofNormalizationTab, 'push');
            app.RunColorFeatureComparisonButton.ButtonPushedFcn = createCallbackFcn(app, @RunColorFeatureComparisonButtonPushed, true);
            app.RunColorFeatureComparisonButton.Text = 'Run Color Feature Comparison';
            app.ExampleColorPatchesTab = uitab(app.TabGroup4);
            app.ExampleColorPatchesTab.Title = 'Example Color Patches';
            app.PASExample_Axes = uiaxes(app.ExampleColorPatchesTab);
            zlabel(app.PASExample_Axes, 'Z')
            app.PASExample_Axes.XTick = [];
            app.PASExample_Axes.YTick = [];
            app.LumExample_Axes = uiaxes(app.ExampleColorPatchesTab);
            zlabel(app.LumExample_Axes, 'Z')
            app.LumExample_Axes.XTick = [];
            app.LumExample_Axes.YTick = [];
            app.NucExample_Axes = uiaxes(app.ExampleColorPatchesTab);
            zlabel(app.NucExample_Axes, 'Z')
            app.NucExample_Axes.XTick = [];
            app.NucExample_Axes.YTick = [];
            app.StatisticalMeasuresTab = uitab(app.TabGroup2);
            app.StatisticalMeasuresTab.Title = 'Statistical Measures';
            app.UITable4 = uitable(app.StatisticalMeasuresTab);
            app.UITable4.ColumnName = {'Descriptor'; 'Value'};
            app.UITable4.RowName = {};
            app.DownloadDistributionDataButton = uibutton(app.StatisticalMeasuresTab, 'push');
            app.DownloadDistributionDataButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadDistributionDataButtonPushed, true);
            app.DownloadDistributionDataButton.Enable = 'off';
            app.DownloadDistributionDataButton.Text = 'Download Distribution Data';
            app.InfoSym_Stats = uiimage(app.StatisticalMeasuresTab);
            app.InfoSym_Stats.ImageClickedFcn = createCallbackFcn(app, @InfoSym_StatsImageClicked, true);
            app.InfoSym_Stats.ImageSource = 'info_symbol.png';
            app.CurrentFeaturesListBoxLabel = uilabel(app.StatisticalMeasuresTab);
            app.CurrentFeaturesListBoxLabel.Text = 'Current Features';
            app.CurrentFeaturesListBox = uilistbox(app.StatisticalMeasuresTab);
            app.CurrentFeaturesListBox.Items = {};
            app.CurrentFeaturesListBox.ValueChangedFcn = createCallbackFcn(app, @CurrentFeaturesListBoxValueChanged, true);
            app.CurrentFeaturesListBox.Enable = 'off';
            app.CurrentFeaturesListBox.Value = {};
            app.ViewBiplotButton = uibutton(app.StatisticalMeasuresTab, 'push');
            app.ViewBiplotButton.ButtonPushedFcn = createCallbackFcn(app, @ViewBiplotButtonPushed, true);
            app.ViewBiplotButton.Enable = 'off';
            app.ViewBiplotButton.Text = 'View Biplot';
            app.BiplotTable = uitable(app.StatisticalMeasuresTab);
            app.BiplotTable.ColumnName = {'Biplot Variables'};
            app.BiplotTable.RowName = {};
            app.BiplotTable.Enable = 'off';
            app.InfoSym_Biplot = uiimage(app.StatisticalMeasuresTab);
            app.InfoSym_Biplot.ImageClickedFcn = createCallbackFcn(app, @InfoSym_BiplotImageClicked, true);
            app.InfoSym_Biplot.ImageSource = 'info_symbol.png';
            app.ClassificationModelsTab = uitab(app.TabGroup2);
            app.ClassificationModelsTab.Title = 'Classification Models';
            app.ModelsTabGroup = uitabgroup(app.ClassificationModelsTab);
            app.DecisionTreeTab = uitab(app.ModelsTabGroup);
            app.DecisionTreeTab.Title = 'Decision Tree';
            app.FitTreeButton = uibutton(app.DecisionTreeTab, 'push');
            app.FitTreeButton.ButtonPushedFcn = createCallbackFcn(app, @FitTreeButtonPushed, true);
            app.FitTreeButton.Enable = 'off';
            app.FitTreeButton.Text = 'Fit Model';
            app.ModelParametersLabel = uilabel(app.DecisionTreeTab);
            app.ModelParametersLabel.Text = 'Model Parameters';
            app.OpenClassificationTreeViewerButton = uibutton(app.DecisionTreeTab, 'push');
            app.OpenClassificationTreeViewerButton.ButtonPushedFcn = createCallbackFcn(app, @OpenClassificationTreeViewerButtonPushed, true);
            app.OpenClassificationTreeViewerButton.Enable = 'off';
            app.OpenClassificationTreeViewerButton.Text = 'Open Classification Tree Viewer';
            app.MaxNumberofSplitsEditFieldLabel = uilabel(app.DecisionTreeTab);
            app.MaxNumberofSplitsEditFieldLabel.Enable = 'off';
            app.MaxNumberofSplitsEditFieldLabel.Text = 'Max Number of Splits';
            app.MaxNumberofSplitsEditField = uieditfield(app.DecisionTreeTab, 'numeric');
            app.MaxNumberofSplitsEditField.Limits = [0 Inf];
            app.MaxNumberofSplitsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNumberofSplitsEditFieldValueChanged, true);
            app.MaxNumberofSplitsEditField.Enable = 'off';
            app.MaxNumberofSplitsEditField.Value = 10;
            app.MaxNumberofCategoriesEditFieldLabel = uilabel(app.DecisionTreeTab);
            app.MaxNumberofCategoriesEditFieldLabel.Enable = 'off';
            app.MaxNumberofCategoriesEditFieldLabel.Text = 'Max Number of Categories';
            app.MaxNumberofCategoriesEditField = uieditfield(app.DecisionTreeTab, 'numeric');
            app.MaxNumberofCategoriesEditField.Limits = [0 Inf];
            app.MaxNumberofCategoriesEditField.ValueChangedFcn = createCallbackFcn(app, @MaxNumberofCategoriesEditFieldValueChanged, true);
            app.MaxNumberofCategoriesEditField.Enable = 'off';
            app.DiscriminantAnalysisTab = uitab(app.ModelsTabGroup);
            app.DiscriminantAnalysisTab.Title = 'Discriminant Analysis';
            app.FitDiscrimButton = uibutton(app.DiscriminantAnalysisTab, 'push');
            app.FitDiscrimButton.ButtonPushedFcn = createCallbackFcn(app, @FitDiscrimButtonPushed, true);
            app.FitDiscrimButton.Enable = 'off';
            app.FitDiscrimButton.Text = 'Fit Model';
            app.ModelParametersLabel_2 = uilabel(app.DiscriminantAnalysisTab);
            app.ModelParametersLabel_2.Text = 'Model Parameters';
            app.DiscriminantTypeDropDownLabel = uilabel(app.DiscriminantAnalysisTab);
            app.DiscriminantTypeDropDownLabel.Enable = 'off';
            app.DiscriminantTypeDropDownLabel.Text = 'Discriminant Type';
            app.DiscriminantTypeDropDown = uidropdown(app.DiscriminantAnalysisTab);
            app.DiscriminantTypeDropDown.Items = {'linear', 'quadratic', 'diaglinear', 'diagquadratic', 'pseudolinear', 'pseudoquadratic'};
            app.DiscriminantTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DiscriminantTypeDropDownValueChanged, true);
            app.DiscriminantTypeDropDown.Enable = 'off';
            app.DiscriminantTypeDropDown.Value = 'linear';
            app.GammaRegularizationlevelEditFieldLabel = uilabel(app.DiscriminantAnalysisTab);
            app.GammaRegularizationlevelEditFieldLabel.Enable = 'off';
            app.GammaRegularizationlevelEditFieldLabel.Text = 'Gamma Regularization level';
            app.GammaRegularizationlevelEditField = uieditfield(app.DiscriminantAnalysisTab, 'numeric');
            app.GammaRegularizationlevelEditField.Limits = [0 1];
            app.GammaRegularizationlevelEditField.ValueChangedFcn = createCallbackFcn(app, @GammaRegularizationlevelEditFieldValueChanged, true);
            app.GammaRegularizationlevelEditField.Enable = 'off';
            app.NaiveBayesTab = uitab(app.ModelsTabGroup);
            app.NaiveBayesTab.Title = 'Naive Bayes';
            app.FitNBButton = uibutton(app.NaiveBayesTab, 'push');
            app.FitNBButton.ButtonPushedFcn = createCallbackFcn(app, @FitNBButtonPushed, true);
            app.FitNBButton.Enable = 'off';
            app.FitNBButton.Text = 'Fit Model';
            app.ModelParametersLabel_4 = uilabel(app.NaiveBayesTab);
            app.ModelParametersLabel_4.Text = 'Model Parameters';
            app.DistributionNamesDropDownLabel = uilabel(app.NaiveBayesTab);
            app.DistributionNamesDropDownLabel.Enable = 'off';
            app.DistributionNamesDropDownLabel.Text = 'Distribution Names';
            app.DistributionNamesDropDown = uidropdown(app.NaiveBayesTab);
            app.DistributionNamesDropDown.Items = {'kernel', 'mn', 'mvmn', 'normal'};
            app.DistributionNamesDropDown.ValueChangedFcn = createCallbackFcn(app, @DistributionNamesDropDownValueChanged, true);
            app.DistributionNamesDropDown.Enable = 'off';
            app.DistributionNamesDropDown.Value = 'kernel';
            app.KernelSmootherDropDownLabel = uilabel(app.NaiveBayesTab);
            app.KernelSmootherDropDownLabel.Enable = 'off';
            app.KernelSmootherDropDownLabel.Text = 'Kernel Smoother';
            app.KernelSmootherDropDown = uidropdown(app.NaiveBayesTab);
            app.KernelSmootherDropDown.Items = {'normal', 'box', 'epanechnikov', 'triangle'};
            app.KernelSmootherDropDown.ValueChangedFcn = createCallbackFcn(app, @KernelSmootherDropDownValueChanged, true);
            app.KernelSmootherDropDown.Enable = 'off';
            app.KernelSmootherDropDown.Value = 'normal';
            app.NeuralNetworkTab = uitab(app.ModelsTabGroup);
            app.NeuralNetworkTab.Title = 'Neural Network';
            app.ModelParametersLabel_5 = uilabel(app.NeuralNetworkTab);
            app.ModelParametersLabel_5.Text = 'Model Parameters';
            app.NumberofLayersEditFieldLabel = uilabel(app.NeuralNetworkTab);
            app.NumberofLayersEditFieldLabel.Enable = 'off';
            app.NumberofLayersEditFieldLabel.Text = 'Number of Layers';
            app.NumberofLayersEditField = uieditfield(app.NeuralNetworkTab, 'numeric');
            app.NumberofLayersEditField.Limits = [1 Inf];
            app.NumberofLayersEditField.RoundFractionalValues = 'on';
            app.NumberofLayersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofLayersEditFieldValueChanged, true);
            app.NumberofLayersEditField.Enable = 'off';
            app.NumberofLayersEditField.Value = 1;
            app.LayerSizeLabel = uilabel(app.NeuralNetworkTab);
            app.LayerSizeLabel.Tag = 'LayerIdxSize';
            app.LayerSizeLabel.Enable = 'off';
            app.LayerSizeLabel.Text = 'Layer Size';
            app.LayerSizeEditField = uieditfield(app.NeuralNetworkTab, 'numeric');
            app.LayerSizeEditField.Limits = [1 Inf];
            app.LayerSizeEditField.RoundFractionalValues = 'on';
            app.LayerSizeEditField.ValueChangedFcn = createCallbackFcn(app, @LayerSizeEditFieldValueChanged, true);
            app.LayerSizeEditField.Enable = 'off';
            app.LayerSizeEditField.Value = 10;
            app.LayerNameDropDownLabel = uilabel(app.NeuralNetworkTab);
            app.LayerNameDropDownLabel.Enable = 'off';
            app.LayerNameDropDownLabel.Text = 'Layer Name';
            app.LayerNameDropDown = uidropdown(app.NeuralNetworkTab);
            app.LayerNameDropDown.Items = {'Layer 1'};
            app.LayerNameDropDown.ValueChangedFcn = createCallbackFcn(app, @LayerNameDropDownValueChanged, true);
            app.LayerNameDropDown.Enable = 'off';
            app.LayerNameDropDown.Value = 'Layer 1';
            app.FitNeuralNetworkButton = uibutton(app.NeuralNetworkTab, 'push');
            app.FitNeuralNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @FitNeuralNetworkButtonPushed, true);
            app.FitNeuralNetworkButton.Enable = 'off';
            app.FitNeuralNetworkButton.Text = 'Fit Neural Network';
            app.SaveModelButton = uibutton(app.ClassificationModelsTab, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Enable = 'off';
            app.SaveModelButton.Text = 'Save Model';
            app.GeneratePerformanceReportButton = uibutton(app.ClassificationModelsTab, 'push');
            app.GeneratePerformanceReportButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePerformanceReportButtonPushed, true);
            app.GeneratePerformanceReportButton.Enable = 'off';
            app.GeneratePerformanceReportButton.Text = 'Generate Performance Report';
            app.PerformanceTabGroup = uitabgroup(app.ClassificationModelsTab);
            app.ClassificationResultsTab = uitab(app.PerformanceTabGroup);
            app.ClassificationResultsTab.Title = 'Classification Results';
            app.Performance_Axes = uiaxes(app.ClassificationResultsTab);
            app.Performance_Axes.XTick = [];
            app.Performance_Axes.YTick = [];
            app.ModelPerformanceTab = uitab(app.PerformanceTabGroup);
            app.ModelPerformanceTab.Title = 'Model Performance';
            app.Performance_Table = uitable(app.ModelPerformanceTab);
            app.Performance_Table.ColumnName = {''};
            app.Performance_Table.RowName = {};
            app.TrainingSplitOptionsButtonGroup = uibuttongroup(app.ClassificationModelsTab);
            app.TrainingSplitOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrainingSplitOptionsButtonGroupSelectionChanged, true);
            app.TrainingSplitOptionsButtonGroup.Title = 'Training Split Options';
            app.TrainTestSplitButton = uiradiobutton(app.TrainingSplitOptionsButtonGroup);
            app.TrainTestSplitButton.Enable = 'off';
            app.TrainTestSplitButton.Text = 'Train/Test Split';
            app.TrainTestSplitButton.Value = true;
            app.kfoldCrossValidationButton = uiradiobutton(app.TrainingSplitOptionsButtonGroup);
            app.kfoldCrossValidationButton.Enable = 'off';
            app.kfoldCrossValidationButton.Text = 'k-fold Cross Validation';
            app.TrainTestSplitEditField = uieditfield(app.TrainingSplitOptionsButtonGroup, 'numeric');
            app.TrainTestSplitEditField.Limits = [0 1];
            app.TrainTestSplitEditField.ValueChangedFcn = createCallbackFcn(app, @TrainTestSplitEditFieldValueChanged2, true);
            app.TrainTestSplitEditField.Enable = 'off';
            app.TrainTestSplitEditField.Value = 0.6;
            app.kfoldEditField = uieditfield(app.TrainingSplitOptionsButtonGroup, 'numeric');
            app.kfoldEditField.Limits = [2 10];
            app.kfoldEditField.RoundFractionalValues = 'on';
            app.kfoldEditField.ValueChangedFcn = createCallbackFcn(app, @kfoldEditFieldValueChanged, true);
            app.kfoldEditField.Enable = 'off';
            app.kfoldEditField.Value = 5;
            app.RelativeFeatureIntensityTab = uitab(app.TabGroup2);
            app.RelativeFeatureIntensityTab.Title = 'Relative Feature Intensity';
            app.Rel_Ax = uiaxes(app.RelativeFeatureIntensityTab);
            app.Rel_Ax.Tag = 'Rel_Ax';
            app.Rel_Feat_text = uilabel(app.RelativeFeatureIntensityTab);
            app.Rel_Feat_text.Tag = 'Rel_Feat_text';
            app.Rel_Feat_text.Text = 'Relative Feature Intensity:';
            app.Rel_Feat_Table = uitable(app.RelativeFeatureIntensityTab);
            app.Rel_Feat_Table.Tag = 'Rel_Feat_Table';
            app.text8 = uilabel(app.RelativeFeatureIntensityTab);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Relative Feature Intensity on Specific Region';
            app.InfoSym_RelFeat = uiimage(app.RelativeFeatureIntensityTab);
            app.InfoSym_RelFeat.ImageClickedFcn = createCallbackFcn(app, @InfoSym_RelFeatImageClicked, true);
            app.InfoSym_RelFeat.ImageSource = 'info_symbol.png';
            app.AddAnnotationsTab = uitab(app.TabGroup2);
            app.AddAnnotationsTab.Title = 'Add Annotations';
            app.AvailableClassesLabel = uilabel(app.AddAnnotationsTab);
            app.AvailableClassesLabel.Text = 'Available Classes:';
            app.AvailableClassesDropDown = uidropdown(app.AddAnnotationsTab);
            app.AvailableClassesDropDown.Items = {};
            app.AvailableClassesDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableClassesDropDownValueChanged, true);
            app.AvailableClassesDropDown.Enable = 'off';
            app.AvailableClassesDropDown.Placeholder = 'Add Classes to list';
            app.AvailableClassesDropDown.Value = {};
            app.AddNewClasstoListLabel = uilabel(app.AddAnnotationsTab);
            app.AddNewClasstoListLabel.Enable = 'off';
            app.AddNewClasstoListLabel.Text = 'Add New Class to List:';
            app.AddNewClasstoListEditField = uieditfield(app.AddAnnotationsTab, 'text');
            app.AddNewClasstoListEditField.ValueChangedFcn = createCallbackFcn(app, @AddNewClasstoListEditFieldValueChanged, true);
            app.AddNewClasstoListEditField.Enable = 'off';
            app.SelectclassthatyouwishtoannotateoraddnewclassLabel = uilabel(app.AddAnnotationsTab);
            app.SelectclassthatyouwishtoannotateoraddnewclassLabel.Text = 'Select class that you wish to annotate or add new class. Click New Label to label new object';
            app.UITable5 = uitable(app.AddAnnotationsTab);
            app.UITable5.ColumnName = {'Class Name'; 'Number of Annotated Images'};
            app.UITable5.RowName = {};
            app.UITable5.Enable = 'off';
            app.TabGroup3 = uitabgroup(app.AddAnnotationsTab);
            app.RedCompImageTab = uitab(app.TabGroup3);
            app.RedCompImageTab.Title = 'Red Comp Image';
            app.UIAxes = uiaxes(app.RedCompImageTab);
            zlabel(app.UIAxes, 'Z')
            app.ResetAnnotationButton = uibutton(app.RedCompImageTab, 'push');
            app.ResetAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAnnotationButtonPushed, true);
            app.ResetAnnotationButton.Enable = 'off';
            app.ResetAnnotationButton.Text = 'Reset Annotation';
            app.TransferAnnotationsDropDown = uidropdown(app.RedCompImageTab);
            app.TransferAnnotationsDropDown.Items = {};
            app.TransferAnnotationsDropDown.ValueChangedFcn = createCallbackFcn(app, @TransferAnnotationsDropDownValueChanged, true);
            app.TransferAnnotationsDropDown.Enable = 'off';
            app.TransferAnnotationsDropDown.Value = {};
            app.TransferAnnotationButton = uibutton(app.RedCompImageTab, 'push');
            app.TransferAnnotationButton.ButtonPushedFcn = createCallbackFcn(app, @TransferAnnotationButtonPushed, true);
            app.TransferAnnotationButton.Enable = 'off';
            app.TransferAnnotationButton.Text = 'Transfer Annotation';
            app.BlueCompImageTab = uitab(app.TabGroup3);
            app.BlueCompImageTab.Title = 'Blue Comp Image';
            app.UIAxes2 = uiaxes(app.BlueCompImageTab);
            title(app.UIAxes2, 'Title')
            zlabel(app.UIAxes2, 'Z')
            app.ResetAnnotationButton_2 = uibutton(app.BlueCompImageTab, 'push');
            app.ResetAnnotationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetAnnotationButton_2Pushed, true);
            app.ResetAnnotationButton_2.Enable = 'off';
            app.ResetAnnotationButton_2.Text = 'Reset Annotation';
            app.TransferAnnotationsDropDown_2 = uidropdown(app.BlueCompImageTab);
            app.TransferAnnotationsDropDown_2.Items = {};
            app.TransferAnnotationsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TransferAnnotationsDropDown_2ValueChanged, true);
            app.TransferAnnotationsDropDown_2.Enable = 'off';
            app.TransferAnnotationsDropDown_2.Value = {};
            app.TransferAnnotationButton_2 = uibutton(app.BlueCompImageTab, 'push');
            app.TransferAnnotationButton_2.ButtonPushedFcn = createCallbackFcn(app, @TransferAnnotationButton_2Pushed, true);
            app.TransferAnnotationButton_2.Enable = 'off';
            app.TransferAnnotationButton_2.Text = 'Transfer Annotation';
            app.NewLabelButton = uibutton(app.AddAnnotationsTab, 'push');
            app.NewLabelButton.ButtonPushedFcn = createCallbackFcn(app, @NewLabelButtonPushed, true);
            app.NewLabelButton.Enable = 'off';
            app.NewLabelButton.Text = 'New Label';
            app.InfoSym_Annotation = uiimage(app.AddAnnotationsTab);
            app.InfoSym_Annotation.ImageClickedFcn = createCallbackFcn(app, @InfoSym_AnnotationImageClicked, true);
            app.InfoSym_Annotation.ImageSource = 'info_symbol.png';
            app.FeatureDefinitionsTab_2 = uitab(app.TabGroup2);
            app.FeatureDefinitionsTab_2.Title = 'Feature Definitions';
            app.TabGroup = uitabgroup(app.FeatureDefinitionsTab_2);
            app.TabGroup.Tag = 'Feat_Desc_Tabs';
            app.FeatureDefinitionsTab = uitab(app.TabGroup);
            app.FeatureDefinitionsTab.Title = 'Feature Definitions';
            app.FeatureDefinitionsTab.Tag = 'Feat_Def_Tab';
            app.UITable2 = uitable(app.FeatureDefinitionsTab);
            app.UITable2.ColumnName = '';
            app.UITable2.RowName = {};
            app.UITable2.Tag = 'Feat_Defs';
            app.FeatureMapMethodTab = uitab(app.TabGroup);
            app.FeatureMapMethodTab.Title = 'Feature Map Method';
            app.FeatureMapMethodTab.Tag = 'Feat_Meth_Tab';
            app.UITable = uitable(app.FeatureMapMethodTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.Tag = 'Feat_Meth';
            app.InfoSym_FeatDef = uiimage(app.FeatureDefinitionsTab_2);
            app.InfoSym_FeatDef.ImageClickedFcn = createCallbackFcn(app, @InfoSym_FeatDefImageClicked, true);
            app.InfoSym_FeatDef.ImageSource = 'info_symbol.png';
            app.ExportImagesTab = uitab(app.TabGroup2);
            app.ExportImagesTab.Title = 'Export Images';
            app.GenerateCompartmentSegmentationCheckButton = uibutton(app.ExportImagesTab, 'push');
            app.GenerateCompartmentSegmentationCheckButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCompartmentSegmentationCheckButtonPushed, true);
            app.GenerateCompartmentSegmentationCheckButton.Enable = 'off';
            app.GenerateCompartmentSegmentationCheckButton.Text = 'Generate Compartment Segmentation Check';
            app.Label_2 = uilabel(app.ExportImagesTab);
            app.Label_2.Text = 'Output the compartment segmentation images for 10 randomly selected structures in each slide to user-specified folder';
            app.SavePersistentLabeledImagesButton = uibutton(app.ExportImagesTab, 'push');
            app.SavePersistentLabeledImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePersistentLabeledImagesButtonPushed, true);
            app.SavePersistentLabeledImagesButton.Enable = 'off';
            app.SavePersistentLabeledImagesButton.Text = 'Save Persistent Labeled Images';
            app.SaveimagesselectedusingAddLabelunderthePersistentLabelslist = uilabel(app.ExportImagesTab);
            app.SaveimagesselectedusingAddLabelunderthePersistentLabelslist.Text = 'Save images selected using "Add Label" under the Persistent Labels list to user-specified folder';
            app.ExportallimagesinspecificROItouserspecifiedfolderLabel = uilabel(app.ExportImagesTab);
            app.ExportallimagesinspecificROItouserspecifiedfolderLabel.Text = 'Export all images in specific ROI to user-specified folder';
            app.SaveRedROIImagesButton = uibutton(app.ExportImagesTab, 'push');
            app.SaveRedROIImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRedROIImagesButtonPushed, true);
            app.SaveRedROIImagesButton.Enable = 'off';
            app.SaveRedROIImagesButton.Text = 'Save Red ROI Images';
            app.SaveBlueROIImagesButton = uibutton(app.ExportImagesTab, 'push');
            app.SaveBlueROIImagesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveBlueROIImagesButtonPushed, true);
            app.SaveBlueROIImagesButton.Enable = 'off';
            app.SaveBlueROIImagesButton.Text = 'Save Blue ROI Images';
            app.Image_Name_Label = uilistbox(app.figure1);
            app.Image_Name_Label.Items = {'Image Name and Label'};
            app.Image_Name_Label.ValueChangedFcn = createCallbackFcn(app, @Image_Name_Label_Callback, true);
            app.Image_Name_Label.Tag = 'Image_Name_Label';
            app.Image_Name_Label.Enable = 'off';
            app.Image_Name_Label.Value = 'Image Name and Label';
            app.Blue_Comp_Image = uilistbox(app.figure1);
            app.Blue_Comp_Image.Items = {'Blue Comparison Image'};
            app.Blue_Comp_Image.ValueChangedFcn = createCallbackFcn(app, @Blue_Comp_Image_Callback, true);
            app.Blue_Comp_Image.Tag = 'Blue_Comp_Image';
            app.Blue_Comp_Image.Enable = 'off';
            app.Blue_Comp_Image.Visible = 'off';
            app.Blue_Comp_Image.Value = 'Blue Comparison Image';
            app.Next_Butt = uibutton(app.figure1, 'push');
            app.Next_Butt.ButtonPushedFcn = createCallbackFcn(app, @Next_Butt_Callback, true);
            app.Next_Butt.Tag = 'Next_Butt';
            app.Next_Butt.Icon = 'NextButt.png';
            app.Next_Butt.Enable = 'off';
            app.Next_Butt.Text = '';
            app.Go_Butt = uibutton(app.figure1, 'push');
            app.Go_Butt.ButtonPushedFcn = createCallbackFcn(app, @Go_Butt_Callback, true);
            app.Go_Butt.Tag = 'Go_Butt';
            app.Go_Butt.Enable = 'off';
            app.Go_Butt.Text = 'Go!';
            app.Prev_Butt = uibutton(app.figure1, 'push');
            app.Prev_Butt.ButtonPushedFcn = createCallbackFcn(app, @Prev_Butt_Callback, true);
            app.Prev_Butt.Tag = 'Prev_Butt';
            app.Prev_Butt.Icon = 'PrevButt.png';
            app.Prev_Butt.Enable = 'off';
            app.Prev_Butt.Text = '';
            app.Comp_List = uilistbox(app.figure1);
            app.Comp_List.Items = {'Compartments listed here'};
            app.Comp_List.ValueChangedFcn = createCallbackFcn(app, @Comp_List_Callback, true);
            app.Comp_List.Tag = 'Comp_List';
            app.Comp_List.Enable = 'off';
            app.Comp_List.Value = 'Compartments listed here';
            app.Heat_Slide = uislider(app.figure1);
            app.Heat_Slide.Limits = [0 1];
            app.Heat_Slide.MajorTicks = [];
            app.Heat_Slide.ValueChangedFcn = createCallbackFcn(app, @Heat_Slide_Callback, true);
            app.Heat_Slide.MinorTicks = [];
            app.Heat_Slide.Tag = 'Heat_Slide';
            app.Heat_Slide.Enable = 'off';
            app.Feat_List = uilistbox(app.figure1);
            app.Feat_List.Items = {'Feature types listed here'};
            app.Feat_List.ValueChangedFcn = createCallbackFcn(app, @Feat_List_Callback, true);
            app.Feat_List.Tag = 'Feat_List';
            app.Feat_List.Enable = 'off';
            app.Feat_List.Value = 'Feature types listed here';
            app.Spec_List = uilistbox(app.figure1);
            app.Spec_List.Items = {'Specific features listed here'};
            app.Spec_List.Tag = 'Spec_List';
            app.Spec_List.Enable = 'off';
            app.Spec_List.Value = 'Specific features listed here';
            app.Comp_txt = uilabel(app.figure1);
            app.Comp_txt.Tag = 'Comp_txt';
            app.Comp_txt.Text = 'Compartment:';
            app.Feat_txt = uilabel(app.figure1);
            app.Feat_txt.Tag = 'Feat_txt';
            app.Feat_txt.Text = 'Feature Type:';
            app.Spec_Feat = uilabel(app.figure1);
            app.Spec_Feat.Tag = 'Spec_Feat';
            app.Spec_Feat.Text = 'Specific Feature:';
            app.View_Comp = uibutton(app.figure1, 'push');
            app.View_Comp.ButtonPushedFcn = createCallbackFcn(app, @View_Comp_Callback, true);
            app.View_Comp.Tag = 'View_Comp';
            app.View_Comp.Enable = 'off';
            app.View_Comp.Text = 'View Compartment Features';
            app.View_Feat_Type = uibutton(app.figure1, 'push');
            app.View_Feat_Type.ButtonPushedFcn = createCallbackFcn(app, @View_Feat_Type_Callback, true);
            app.View_Feat_Type.Tag = 'View_Feat_Type';
            app.View_Feat_Type.Enable = 'off';
            app.View_Feat_Type.Text = 'View Feature Type';
            app.View_Spec_Feat = uibutton(app.figure1, 'push');
            app.View_Spec_Feat.ButtonPushedFcn = createCallbackFcn(app, @View_Spec_Feat_Callback, true);
            app.View_Spec_Feat.Tag = 'View_Spec_Feat';
            app.View_Spec_Feat.Enable = 'off';
            app.View_Spec_Feat.Text = 'View Specific Feature';
            app.Rank_Slide = uislider(app.figure1);
            app.Rank_Slide.Limits = [0 1];
            app.Rank_Slide.MajorTicks = [];
            app.Rank_Slide.MajorTickLabels = {};
            app.Rank_Slide.ValueChangedFcn = createCallbackFcn(app, @Rank_Slide_Callback, true);
            app.Rank_Slide.MinorTicks = [];
            app.Rank_Slide.Tag = 'Rank_Slide';
            app.Rank_Slide.Enable = 'off';
            app.Notes_edit = uitextarea(app.figure1);
            app.Notes_edit.Tag = 'Notes_edit';
            app.Notes_edit.Enable = 'off';
            app.Notes_edit.Value = {'(Current Notes for this image)'};
            app.Feat_Map_Group = uibuttongroup(app.figure1);
            app.Feat_Map_Group.Title = 'View Compartment Mask';
            app.Feat_Map_Group.Tag = 'Feat_Map_Group';
            app.PAS_Butt = uibutton(app.Feat_Map_Group, 'push');
            app.PAS_Butt.ButtonPushedFcn = createCallbackFcn(app, @PAS_Butt_Callback, true);
            app.PAS_Butt.Tag = 'Mes_Butt';
            app.PAS_Butt.Enable = 'off';
            app.PAS_Butt.Text = 'PAS+';
            app.Lum_Butt = uibutton(app.Feat_Map_Group, 'push');
            app.Lum_Butt.ButtonPushedFcn = createCallbackFcn(app, @Lum_Butt_Callback, true);
            app.Lum_Butt.Tag = 'Lum_Butt';
            app.Lum_Butt.Enable = 'off';
            app.Lum_Butt.Text = 'Luminal Space';
            app.Nuc_Butt = uibutton(app.Feat_Map_Group, 'push');
            app.Nuc_Butt.ButtonPushedFcn = createCallbackFcn(app, @Nuc_Butt_Callback, true);
            app.Nuc_Butt.Tag = 'Nuc_Butt';
            app.Nuc_Butt.Enable = 'off';
            app.Nuc_Butt.Text = 'Nuclei';
            app.Img_one_Edit = uitextarea(app.figure1);
            app.Img_one_Edit.Tag = 'Img_one_Edit';
            app.Img_one_Edit.Enable = 'off';
            app.Img_one_Edit.Visible = 'off';
            app.Notes_txt = uilabel(app.figure1);
            app.Notes_txt.Tag = 'Notes_txt';
            app.Notes_txt.Text = 'Notes:';
            app.Img_two_Edit = uitextarea(app.figure1);
            app.Img_two_Edit.Tag = 'Img_two_Edit';
            app.Img_two_Edit.Enable = 'off';
            app.Img_two_Edit.Visible = 'off';
            app.Img_one_txt = uilabel(app.figure1);
            app.Img_one_txt.Tag = 'Img_one_txt';
            app.Img_one_txt.Enable = 'off';
            app.Img_one_txt.Visible = 'off';
            app.Img_one_txt.Text = '';
            app.Img_two_txt = uilabel(app.figure1);
            app.Img_two_txt.Tag = 'Img_two_txt';
            app.Img_two_txt.Enable = 'off';
            app.Img_two_txt.Visible = 'off';
            app.Img_two_txt.Text = '';
            app.Custom_List = uilistbox(app.figure1);
            app.Custom_List.Items = {'Add Features by selecting them in the Specific Feature List clicking Add Feature to Custom Button'};
            app.Custom_List.Tag = 'Custom_List';
            app.Custom_List.Enable = 'off';
            app.Custom_List.Value = 'Add Features by selecting them in the Specific Feature List clicking Add Feature to Custom Button';
            app.Custom_txt = uilabel(app.figure1);
            app.Custom_txt.Tag = 'Custom_txt';
            app.Custom_txt.Text = 'Custom List:';
            app.Add_Feat_Butt = uibutton(app.figure1, 'push');
            app.Add_Feat_Butt.ButtonPushedFcn = createCallbackFcn(app, @Add_Feat_Butt_Callback, true);
            app.Add_Feat_Butt.Tag = 'Add_Feat_Butt';
            app.Add_Feat_Butt.Enable = 'off';
            app.Add_Feat_Butt.Text = 'Add Feature to Custom';
            app.View_Cust_Butt = uibutton(app.figure1, 'push');
            app.View_Cust_Butt.ButtonPushedFcn = createCallbackFcn(app, @View_Cust_Butt_Callback, true);
            app.View_Cust_Butt.Tag = 'View_Cust_Butt';
            app.View_Cust_Butt.Enable = 'off';
            app.View_Cust_Butt.Text = 'View Custom List';
            app.Remove_Feat_Butt = uibutton(app.figure1, 'push');
            app.Remove_Feat_Butt.ButtonPushedFcn = createCallbackFcn(app, @Remove_Feat_Butt_Callback, true);
            app.Remove_Feat_Butt.Tag = 'Remove_Feat_Butt';
            app.Remove_Feat_Butt.Enable = 'off';
            app.Remove_Feat_Butt.Text = 'Remove from Custom';
            app.Red_Comp_Image = uilistbox(app.figure1);
            app.Red_Comp_Image.Items = {'Red Comparison Image'};
            app.Red_Comp_Image.ValueChangedFcn = createCallbackFcn(app, @Red_Comp_Image_Callback, true);
            app.Red_Comp_Image.Tag = 'Red_Comp_Image';
            app.Red_Comp_Image.Enable = 'off';
            app.Red_Comp_Image.Visible = 'off';
            app.Red_Comp_Image.Value = 'Red Comparison Image';
            app.Image = uiimage(app.figure1);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'Logo.png';
            app.SimilarityThresholdSpinner = uispinner(app.figure1);
            app.SimilarityThresholdSpinner.Step = 0.1;
            app.SimilarityThresholdSpinner.Limits = [0 Inf];
            app.SimilarityThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @SimilarityThresholdSpinnerValueChanged, true);
            app.SimilarityThresholdSpinner.Tag = 'Similar_Thresh';
            app.SimilarityThresholdSpinner.Enable = 'off';
            app.SimilarityThresholdSpinner.Value = 0.1;
            app.UITable3 = uitable(app.figure1);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable3.RowName = {};
            app.UITable3.Enable = 'off';
            app.UITable3.Visible = 'off';
            app.InputFilesButton = uibutton(app.figure1, 'push');
            app.InputFilesButton.ButtonPushedFcn = createCallbackFcn(app, @InputFilesButtonPushed, true);
            app.InputFilesButton.Text = 'Input Files';
            app.PersistentImageLabelsListBox = uilistbox(app.figure1);
            app.PersistentImageLabelsListBox.Items = {'Select Add Persistent Label'};
            app.PersistentImageLabelsListBox.Tag = 'Pers_Label_Table';
            app.PersistentImageLabelsListBox.Enable = 'off';
            app.PersistentImageLabelsListBox.Value = 'Select Add Persistent Label';
            app.AddLabelButton = uibutton(app.figure1, 'push');
            app.AddLabelButton.ButtonPushedFcn = createCallbackFcn(app, @AddLabelButtonPushed, true);
            app.AddLabelButton.Tag = 'Add_Label_Butt';
            app.AddLabelButton.Enable = 'off';
            app.AddLabelButton.Text = 'Add Label';
            app.RemoveLabelButton = uibutton(app.figure1, 'push');
            app.RemoveLabelButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLabelButtonPushed, true);
            app.RemoveLabelButton.Tag = 'Remove_Label_Butt';
            app.RemoveLabelButton.Enable = 'off';
            app.RemoveLabelButton.Text = 'Remove Label';
            app.CopyLabelforallimagesinregionButton = uibutton(app.figure1, 'push');
            app.CopyLabelforallimagesinregionButton.ButtonPushedFcn = createCallbackFcn(app, @CopyLabelforallimagesinregionButtonPushed, true);
            app.CopyLabelforallimagesinregionButton.Tag = 'Copy_Notes_Butt';
            app.CopyLabelforallimagesinregionButton.Enable = 'off';
            app.CopyLabelforallimagesinregionButton.Text = 'Copy Label for all images in region';
            app.SearchNotesFileButton = uibutton(app.figure1, 'push');
            app.SearchNotesFileButton.ButtonPushedFcn = createCallbackFcn(app, @SearchNotesFileButtonPushed, true);
            app.SearchNotesFileButton.Tag = 'Search_Notes_Butt';
            app.SearchNotesFileButton.Enable = 'off';
            app.SearchNotesFileButton.Text = 'Search Notes File';
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = 'HeatmapTransparency.png';
            app.Image3 = uiimage(app.figure1);
            app.Image3.ImageSource = 'MinimumFeatureRank.png';
            app.RedROIImagesLabel = uilabel(app.figure1);
            app.RedROIImagesLabel.Visible = 'off';
            app.RedROIImagesLabel.Text = 'Red ROI Images';
            app.BlueROIImagesLabel = uilabel(app.figure1);
            app.BlueROIImagesLabel.Visible = 'off';
            app.BlueROIImagesLabel.Text = 'Blue ROI Images';
            app.FeatureRankingsUsedDropDown = uidropdown(app.figure1);
            app.FeatureRankingsUsedDropDown.Items = {};
            app.FeatureRankingsUsedDropDown.ValueChangedFcn = createCallbackFcn(app, @FeatureRankingsUsedDropDownValueChanged, true);
            app.FeatureRankingsUsedDropDown.Enable = 'off';
            app.FeatureRankingsUsedDropDown.Placeholder = 'Select One';
            app.FeatureRankingsUsedDropDown.Value = {};
            app.Image4 = uiimage(app.figure1);
            app.Image4.ImageSource = 'LeftTab.png';
            app.Image4_2 = uiimage(app.figure1);
            app.Image4_2.ImageSource = 'RightTab.png';
            app.ScrollAcrossTabsLabel = uilabel(app.figure1);
            app.ScrollAcrossTabsLabel.Text = 'Scroll Across Tabs';
            app.SimilarityThresholdLabel = uilabel(app.figure1);
            app.SimilarityThresholdLabel.Text = 'Similarity Threshold';
            app.FeatureRankingsLabel = uilabel(app.figure1);
            app.FeatureRankingsLabel.Text = 'Feature Rankings';
            app.PersistentLabels = uilabel(app.figure1);
            app.PersistentLabels.Text = 'Persistent Labels';
            app.TextureWindowSizeEditField = uieditfield(app.figure1, 'numeric');
            app.TextureWindowSizeEditField.Limits = [1 Inf];
            app.TextureWindowSizeEditField.RoundFractionalValues = 'on';
            app.TextureWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @TextureWindowSizeEditFieldValueChanged, true);
            app.TextureWindowSizeEditField.Enable = 'off';
            app.TextureWindowSizeEditField.Value = 25;
            app.TextureWindowSizeLabel = uilabel(app.figure1);
            app.TextureWindowSizeLabel.Text = 'Texture Window Size';
            app.InfoSym_CompMask = uiimage(app.figure1);
            app.InfoSym_CompMask.ImageClickedFcn = createCallbackFcn(app, @InfoSym_CompMaskImageClicked, true);
            app.InfoSym_CompMask.ImageSource = 'info_symbol.png';
            app.InfoSym_CompList = uiimage(app.figure1);
            app.InfoSym_CompList.ImageClickedFcn = createCallbackFcn(app, @InfoSym_CompListImageClicked, true);
            app.InfoSym_CompList.ImageSource = 'info_symbol.png';
            app.InfoSym_TypeList = uiimage(app.figure1);
            app.InfoSym_TypeList.ImageClickedFcn = createCallbackFcn(app, @InfoSym_TypeListImageClicked, true);
            app.InfoSym_TypeList.ImageSource = 'info_symbol.png';
            app.InfoSym_SpecList = uiimage(app.figure1);
            app.InfoSym_SpecList.ImageClickedFcn = createCallbackFcn(app, @InfoSym_SpecListImageClicked, true);
            app.InfoSym_SpecList.ImageSource = 'info_symbol.png';
            app.InfoSym_CustList = uiimage(app.figure1);
            app.InfoSym_CustList.ImageClickedFcn = createCallbackFcn(app, @InfoSym_CustListImageClicked, true);
            app.InfoSym_CustList.ImageSource = 'info_symbol.png';
            app.InfoSym_ImgSliders = uiimage(app.figure1);
            app.InfoSym_ImgSliders.ImageClickedFcn = createCallbackFcn(app, @InfoSym_ImgSlidersImageClicked, true);
            app.InfoSym_ImgSliders.ImageSource = 'info_symbol.png';
            app.InfoSym_FeatRank = uiimage(app.figure1);
            app.InfoSym_FeatRank.ImageClickedFcn = createCallbackFcn(app, @InfoSym_FeatRankImageClicked, true);
            app.InfoSym_FeatRank.ImageSource = 'info_symbol.png';
            app.InfoSym_SimThresh = uiimage(app.figure1);
            app.InfoSym_SimThresh.ImageClickedFcn = createCallbackFcn(app, @InfoSym_SimThreshImageClicked, true);
            app.InfoSym_SimThresh.ImageSource = 'info_symbol.png';
            app.InfoSym_TextWin = uiimage(app.figure1);
            app.InfoSym_TextWin.ImageClickedFcn = createCallbackFcn(app, @InfoSym_TextWinImageClicked, true);
            app.InfoSym_TextWin.ImageSource = 'info_symbol.png';
            app.InfoSym_PersLabels = uiimage(app.figure1);
            app.InfoSym_PersLabels.ImageClickedFcn = createCallbackFcn(app, @InfoSym_PersLabelsImageClicked, true);
            app.InfoSym_PersLabels.ImageSource = 'info_symbol.png';
            app.InfoSym_Notes = uiimage(app.figure1);
            app.InfoSym_Notes.ImageClickedFcn = createCallbackFcn(app, @InfoSym_NotesImageClicked, true);
            app.InfoSym_Notes.ImageSource = 'info_symbol.png';
            app.StructureDropDownLabel = uilabel(app.figure1);
            app.StructureDropDownLabel.Text = 'Structure';
            app.StructureDropDown = uidropdown(app.figure1);
            app.StructureDropDown.Items = {};
            app.StructureDropDown.ValueChangedFcn = createCallbackFcn(app, @StructureDropDownValueChanged, true);
            app.StructureDropDown.Enable = 'off';
            app.StructureDropDown.Value = {};
            app.PauseVisualizationCheckBox = uicheckbox(app.figure1);
            app.PauseVisualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PauseVisualizationCheckBoxValueChanged, true);
            app.PauseVisualizationCheckBox.Enable = 'off';
            app.PauseVisualizationCheckBox.Text = 'Pause Visualization';
            app.ContextMenu = uicontextmenu(app.figure1);
            app.EditPlotOptionsMenu = uimenu(app.ContextMenu);
            app.EditPlotOptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @EditPlotOptionsMenuSelected, true);
            app.EditPlotOptionsMenu.Text = 'Edit Plot Options';
            % Assign app.ContextMenu
            app.Dist_Ax.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HistoLens_V2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)HistoLens_V2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end